classdef ionNew_giugno2023_NewPhFunction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ionizer                    matlab.ui.Figure
        CalibrationoptionsanddatasetPanel  matlab.ui.container.Panel
        loadCalibration            matlab.ui.control.Button
        SelectLabel                matlab.ui.control.Label
        calibrationSetDD           matlab.ui.control.DropDown
        fromGUIButton              matlab.ui.control.Button
        fromClipButton             matlab.ui.control.Button
        frommatButton              matlab.ui.control.Button
        ComputeButton              matlab.ui.control.Button
        ThresholdinEditFieldLabel  matlab.ui.control.Label
        thresholdEF                matlab.ui.control.NumericEditField
        InstrumentandBTcoeffLabel  matlab.ui.control.Label
        instrumentSet              matlab.ui.control.DropDown
        fromExcelButton            matlab.ui.control.Button
        LastROIbkgrCheckBox        matlab.ui.control.CheckBox
        RmvbkgrCheckBox            matlab.ui.control.CheckBox
        ComputationoptionsPanel    matlab.ui.container.Panel
        bleedthroughCB             matlab.ui.control.CheckBox
        intraCorrCB                matlab.ui.control.CheckBox
        Rhod6GduringBTmeasurementEditFieldLabel  matlab.ui.control.Label
        R6GBTEF                    matlab.ui.control.NumericEditField
        Rhod6GratioduringexperimentEditFieldLabel  matlab.ui.control.Label
        R6GexpEF                   matlab.ui.control.NumericEditField
        Rhod6Gratioat0ClLabel      matlab.ui.control.Label
        R6GcalR0EF                 matlab.ui.control.NumericEditField
        wideRedCB                  matlab.ui.control.CheckBox
        normalizeToIsoCB           matlab.ui.control.CheckBox
        CalibrationDataPanel       matlab.ui.container.Panel
        pKaTxt                     matlab.ui.control.Label
        isoTxt                     matlab.ui.control.Label
        aTxt                       matlab.ui.control.Label
        R0Txt                      matlab.ui.control.Label
        R0instr                    matlab.ui.control.Label
        calibrationAx              matlab.ui.control.UIAxes
        CorrectionofemissionButtonGroup  matlab.ui.container.ButtonGroup
        NoneRB                     matlab.ui.control.RadioButton
        ImagingdepthRB             matlab.ui.control.RadioButton
        UseKate2RB                 matlab.ui.control.RadioButton
        Label                      matlab.ui.control.Label
        imagingZ                   matlab.ui.control.NumericEditField
        zFromStructureRB           matlab.ui.control.RadioButton
        CorrectionofexcitationButtonGroup  matlab.ui.container.ButtonGroup
        noExcCorrRB                matlab.ui.control.RadioButton
        individualKate2RB          matlab.ui.control.RadioButton
        meanKate2RB                matlab.ui.control.RadioButton
        results                    matlab.ui.container.Panel
        pHtxt                      matlab.ui.control.Label
        residueTxt                 matlab.ui.control.Label
        ClTxt                      matlab.ui.control.Label
        ToClipboardButton          matlab.ui.control.Button
        omitOutliersCB             matlab.ui.control.CheckBox
        OutlierthresholdLabel      matlab.ui.control.Label
        outlierThr                 matlab.ui.control.NumericEditField
        NmaxoutliersLabel          matlab.ui.control.Label
        outlierMaxN                matlab.ui.control.NumericEditField
        stats                      matlab.ui.control.Label
        statsNoOut                 matlab.ui.control.Label
        mapRB                      matlab.ui.container.ButtonGroup
        pHmapRB                    matlab.ui.control.RadioButton
        KdmapRB                    matlab.ui.control.RadioButton
        ClmapRB                    matlab.ui.control.RadioButton
        noneRB                     matlab.ui.control.RadioButton
        AdjR2Txt                   matlab.ui.control.Label
        pHplot                     matlab.ui.control.UIAxes
        TabGroup                   matlab.ui.container.TabGroup
        DataexplorerTab            matlab.ui.container.Tab
        Panel                      matlab.ui.container.Panel
        ROISpinnerLabel            matlab.ui.control.Label
        ROISpinner                 matlab.ui.control.Spinner
        RawGCB                     matlab.ui.control.CheckBox
        RawRCB                     matlab.ui.control.CheckBox
        GstarCB                    matlab.ui.control.CheckBox
        RstarCB                    matlab.ui.control.CheckBox
        GprimeCB                   matlab.ui.control.CheckBox
        RprimeCB                   matlab.ui.control.CheckBox
        plotFitCB                  matlab.ui.control.CheckBox
        plotRedTemplateCB          matlab.ui.control.CheckBox
        plotG6CB                   matlab.ui.control.CheckBox
        plotG8CB                   matlab.ui.control.CheckBox
        nRoiTxt                    matlab.ui.control.Label
        spectraAx                  matlab.ui.control.UIAxes
        WavelengthselectTab        matlab.ui.container.Tab
        WavelengthTable            matlab.ui.control.Table
    end

    
    properties (Access = public)

        calibrationSet      % Structure that contains all the calibration data
                            % This structure contains several field described below:
                
        SI                  % Index of the selected instrument
        instrumentSettings  % Structure that contains the bleedtrough coefficents and other instrument
                                % specific settings
        instrument          % points to the correct instrument-specific parameters 
        cal                 % this structure contains all the parameters that are wavelength dependent and that have 
                            % been compute at all wavelengths present in the input data
                            
        LambdaCal           % (1 To 30) As Single
        filterCorrection    % Used to correct the relative intensity of the red channel because of changes in the filter set
        pHmaps              % pH maps
        map_delta
        map_epsilon
        
        KdMap
        ClMap
        map                 % handle to the ph or Cl map
        cb                  % handle to the calibration bar
        
        GreenIn             % first index run on cell N second on WL
        RedIn               % these are the raw input data (corrected for dark current)
        lambdaIn            % (1 to 30)  Wavelength of the input sequence
        zeta                % /1 to 200) Depth of each ROI
        lambdaN             % n of wl in the spectral sequence
        roiN                % n of rois
        GreenBuffer         % Temporary buffer for correction of scattering of green channel
        RedBuffer
        i910                % index of the iso point
        depth               % (1 to 200) Imagin depth of each cell
        Gstar               % (1 To 200, 1 To 30) As Single
        Rstar               % (1 To 200, 1 To 30) As Single
        RedMean             % (1 To 200)
        ExcCorrection       % (1 To 200, 1 To 30)         ' correction function(s) for excitation
        Gprime              %
        Rprime              
        calSet              % index of the calibration data set in use
        
        statsOut            % statistics of the output data with and without outliers
        outliers            % 
        
        % results
        gamma               % gamma coefficent for correction of depth-dependent excitation scattering
        delta               % (roi) delta of pH computatio
        epsilon
        theta
        res                 % residue computed on each ROI
        R2_adj              % adjusted R2
        R2median            % median of the residue on all ROIs
        pH
        Kd
        Giso                % G prime spline-interpolated to the iso point
        Riso                % R template spline interpolated to the iso point
        rCl                 % ratio at the interpolated iso point for each roi
        Cl
        pHmodel             % fit only on the selected wl
        pHspectra           % fit to each cell

        computationF        % flag = true if spectra have been loaded and fitted. false otherwise 
        dataInF             % flag = true if data have been loaded
        ncalset             % number of calibration files
        instrumentSettings_n % number of instruments
        
        excelIn_file % last selected Excel file
        excelIn_folder % folder of the last selected Excel file
        excelIn_sheet
        
        GreenRaw % Description
        RedRaw
        
    end
    
    methods (Access = public)
        
        function plotCurrentCalibrationSet(app)
            % first thing first: what is the current calibration set?
            cla(app.calibrationAx);
            lambda = app.calibrationSet(app.calSet).setLambdaCal;
            gp = app.calibrationSet(app.calSet).setGpH6;
            gdp = app.calibrationSet(app.calSet).setGpH8;
            r = app.calibrationSet(app.calSet).RedTemplate;
            hold(app.calibrationAx,'on')
            plot(app.calibrationAx,lambda,gp,'g');
            plot(app.calibrationAx,lambda,gdp,'b');
            if isempty(r) || any(isnan(r)) % 2023 calibrations have no r
                app.calibrationAx.YLim=[0 3];
            else
                plot(app.calibrationAx,lambda,r,'r');
                app.calibrationAx.YLim=[0 1.8];
            end
            hold(app.calibrationAx,'off')
            app.pKaTxt.Text = sprintf('pKa: %4.2f',app.calibrationSet(app.calSet).pKa); 
            app.isoTxt.Text = sprintf('Iso point (nm): %4.1f',app.calibrationSet(app.calSet).CalIsoPoint); 
%             app.aTxt.Text = sprintf('Cooperativity (a): %4.2f',app.calibrationSet(app.calSet).a);
            app.R0Txt.Text = sprintf('R0 at iso point: %4.3f',app.calibrationSet(app.calSet).R0);     
            insSel = ismember(app.instrumentSet.Items,app.instrumentSet.Value);
            
            thisR0_i=find(strcmp(app.calibrationSet(app.calSet).calDescriptor,app.instrumentSettings(insSel).R0(:,1)));
            if isempty(thisR0_i)
                app.R0instr.Text = sprintf('R0 instr not available\nfor this calib. set');
            else
                app.R0instr.Text = sprintf('R0 instrument: %4.3f',app.instrumentSettings(insSel).R0{thisR0_i,2});
            end
        end
        
        function cal = extractCalibrations(app)
        
            % this function reads the list of input wavelegth and extract all
            % the relevant calibration data from the proper calibration file.
            % If some wavelengths are missing the relevant data are obtained by
            % spline interpolation
            
            % November 29, 2019. GMR
            
            inLambda = app.lambdaIn;
            app.instrument = find(ismember(app.instrumentSet.Items,app.instrumentSet.Value));
            
            % obtain the bleed through coefficents by using the file specific to the selected instrument
            lambda = app.instrumentSettings(app.instrument).AlfaBetaL(:,1);  
            beta = app.instrumentSettings(app.instrument).AlfaBetaL(:,2);
            alpha = app.instrumentSettings(app.instrument).AlfaBetaL(:,3);
            
            % now we resample alfa and beta on the data wavelength
            cal.alphaRs = spline(lambda, alpha, inLambda);
            cal.betaRs = spline(lambda, beta, inLambda);
                
            % now extract the calibration spectra
            lambda = app.calibrationSet(app.calSet).setLambdaCal;
            cal.G6 =  spline(lambda,app.calibrationSet(app.calSet).setGpH6,inLambda);
            cal.G8 =  spline(lambda,app.calibrationSet(app.calSet).setGpH8,inLambda);
            if isempty(app.calibrationSet(app.calSet).RedTemplate) || any(isnan(app.calibrationSet(app.calSet).RedTemplate))
                cal.redTmp = [];
            else
                cal.redTmp = spline(lambda,app.calibrationSet(app.calSet).RedTemplate,inLambda);
            end

        end
        
        function normalizeRaw(app)
            % this function normalizes all raw fluorescence data to the red fluorescence at 910 nm
            for roi = 1:app.roiN
                normVal = app.RedIn(roi, app.i910);
                app.GreenIn(roi,1:app.lambdaN) = app.GreenIn(roi,1:app.lambdaN)/normVal;
                app.RedIn(roi,1:app.lambdaN) = app.RedIn(roi,1:app.lambdaN)/normVal;  
            end    
        end
        
        function plotData (app)
            % this function plot the data according to the selection checks
            selRoi = app.ROISpinner.Value;
            cla(app.spectraAx)
            hold(app.spectraAx,'on')

            if app.RawGCB.Value
%                 if app.calibrationSet(app.calSet).NormalizedSpectra
%                     tmp = app.GreenIn(selRoi,:)./app.RedIn(selRoi,:);
%                 else
                    tmp = app.GreenIn(selRoi,:);
%                 end
                scatter(app.spectraAx,app.lambdaIn,tmp,'green')
            end
            if app.RawRCB.Value
                tmp(:) = app.RedIn(selRoi,:);
                scatter(app.spectraAx,app.lambdaIn,tmp,'red')
            end
            if app.GstarCB.Value
                if app.calibrationSet(app.calSet).NormalizedSpectra
                    tmp = app.Gstar(selRoi,:)./app.Rstar(selRoi,:);
                else
                    tmp(:) = app.Gstar(selRoi,:);
                end
                scatter(app.spectraAx,app.lambdaIn,tmp,'green','x')
            end
            if app.RstarCB.Value && ~app.calibrationSet(app.calSet).NormalizedSpectra
                tmp(:) = app.Rstar(selRoi,:);
                scatter(app.spectraAx,app.lambdaIn,tmp,'red','x')
            end
            if app.GprimeCB.Value
                tmp(:) = app.Gprime(selRoi,:);
                scatter(app.spectraAx,app.lambdaIn,tmp,'green','filled','d')
            end
            if app.RprimeCB.Value && ~app.calibrationSet(app.calSet).NormalizedSpectra
                tmp(:) = app.Rprime(selRoi,:);
                scatter(app.spectraAx,app.lambdaIn,tmp,'red','filled','d')
            end
            if app.plotRedTemplateCB.Value && ~app.calibrationSet(app.calSet).NormalizedSpectra
                lambda = app.calibrationSet(app.calSet).setLambdaCal;
                r = app.calibrationSet(app.calSet).RedTemplate;
                plot(app.spectraAx,lambda,r,'r');
            end
            if app.plotFitCB.Value
                tmp = app.pHspectra(selRoi,:);
                lambda = app.calibrationSet(app.calSet).setLambdaCal;
                plot(app.spectraAx,lambda,tmp,'g');
            end
            
%             if app.calibrationSet(app.calSet).NormalizedSpectra % 2023 calibrations have no r
%                 app.spectraAx.YLim=[0 3];
%             else
%                 plot(app.spectraAx,lambda,r,'r');
%                 app.spectraAx.YLim=[0 1.8];
%             end
            
        end
        
        function correctGreenAndRed (app)
            % Correct the Green channel
            % This must be performed before of BT correction. There are two steps:
            % 1) Correction for the relative gain change of the 2 channels between the experiment day and
            % the BT measurement
            % 2) Correction for the extinction of emission
            % The corrected green fluorescence is loaded in the GreenBuffer file so that the 
            % original data can still be retrieved
            
            % Compute correction towards the BT relative gain
            BTratio = app.R6GBTEF.Value;
            expRatio = app.R6GexpEF.Value;
            expCorr = BTratio/expRatio;    % if > 1 the experiment is 'greener' than the reference
            app.GreenBuffer=app.GreenIn./expCorr;
            app.RedBuffer=app.RedIn;

            % correction for extinction.
            % Also this correction must be applied BEFORE of the bleed through subtraction since the imaging
            % depth affects the relative intensity of the fluorescence signal in the two bandwidht.
            % The extinction is computed from the imaging depth by using the data obtained from
            % the YFP imaging (fig 3D). The fit is performed by the following law:
            % gamma(0>z<32 um)=1 gamma(z>32)=0.974 + z*8.17e-4
            % Depth expressed in microns
            % This empiric law has been obtained in young adult mice of the h line (Feng&Sanes, Neuron
            % 2002) with the 60X lens on the Prairie microscope. It should be rederived in the
            % other setups since it could change because of:
            % a) different spectral band passes of the emission filters
            % b) better collection of non-ballistic photons with high NA objectives.
            % Regardless, these should be second order effects.
            
            dec='lin'; % linear decay from sulis sato 2017
            q=0.974; % sulis sato 2017
            m=8.17E-4; % sulis sato 2017
            z_min=32; % sulis sato 2017
            k=1; % sulis sato 2017
            
%             dec='lin'; % linear decay, 2023 estimate
%             q=1.057; % 2023
%             m=-1.38E-3; % 2023
%             z_min=30; % 2023
%             k=-1; % 2023
%           
%             dec='expo'; % exponential decay, 2023 estimate
%             q2=0.4368; % 2023
%             A=0.5370; % 2023
%             z_min2=36.77; % 2023
%             tau=175.47; % 2023
            if app.ImagingdepthRB.Value
                z = app.imagingZ.Value;
                switch dec
                    case 'expo'
                        app.gamma = repmat((q2+A.*exp(-(max(z-z_min2,0)./tau))).^-1,app.roiN,1);
                    case 'lin'
                        app.gamma = repmat((q + max(z,z_min) * m).^k,app.roiN,1);
                end
                
            end
            if app.zFromStructureRB.Value
                % the extinction is computed from the imaging depth that is contained in the app.zeta(1:nRoi)
                % array. 
                z = app.zeta(:); % make sure it's a column vector
                switch dec
                    case 'expo'
                        app.gamma = (q2+A.*exp(-(max(z-z_min2,0)./tau))).^-1;
                    case 'lin'
                        app.gamma = (q + max(z,z_min) * m).^k;
                end
            end    
            if app.NoneRB.Value
                app.gamma = ones(app.roiN,1);
            end
            if app.UseKate2RB.Value
            end
            app.GreenBuffer = app.GreenBuffer .* app.gamma;
            if app.normalizeToIsoCB.Value
                [app.GreenBuffer,app.RedBuffer] = normalizeToIso (app.GreenBuffer,app.RedBuffer,...
                    app.lambdaIn,app.calibrationSet(app.calSet).CalIsoPoint);
            end
        end
        
        function removeBT(app)
            if app.bleedthroughCB.Value 
                % use the selected instrument specific file
                app.Gstar=[];
                app.Rstar=[];
                app.Gstar = app.GreenBuffer - app.cal.alphaRs .* app.RedBuffer;
                app.Rstar = app.RedBuffer - app.cal.betaRs .* app.GreenBuffer;
                % normalize to the iso point
                if app.normalizeToIsoCB.Value
                    [app.Gstar,app.Rstar] = normalizeToIso (app.Gstar,app.Rstar,...
                        app.lambdaIn,app.calibrationSet(app.calSet).CalIsoPoint);
                end
            else
                % no bleed through correction
                app.Gstar = app.GreenBuffer;
                app.Rstar = app.RedBuffer;
            end
        end
        
        function compensateExcitation (app)
            % September 16, 2019
            % The correction is computed by normalizing the Rstar spectra on the LssKate2 template
            % and applying these coefficents to Gstar
            
            % First, we must extract a sub-array of the calibration red spectra at the wl of our data set
            app.Gprime=[];
            app.Rprime=[];
            if app.calibrationSet(app.calSet).NormalizedSpectra
                % Calibration only produced normalized green spectra (green
                % fluorescence normalized over red fluorescence, i.e.
                % kate-relative excitation spectra. This is equivalent to
                % the "individualKate" normalization.)
                app.Gprime = app.Gstar./app.Rstar;
                app.Rprime = ones(size(app.Gprime));
            else
                if app.noExcCorrRB.Value
                    % no correction: copy Gstar and Rstar to Gprime, Rprime
                    app.Gprime = app.Gstar;
                    app.Rprime = app.Rstar;
                end
                if app.individualKate2RB.Value
                    corr = app.cal.redTmp ./ app.Rstar;
                    app.Rprime = app.Rstar .* corr;
                    app.Gprime = app.Gstar .* corr;
                    % normalize to the iso point
                    if app.normalizeToIsoCB.Value
                        [app.Gprime,app.Rprime] = normalizeToIso (app.Gprime,app.Rprime,...
                            app.lambdaIn,app.calibrationSet(app.calSet).CalIsoPoint);
                    end
                end
                if app.meanKate2RB.Value
                    corr = app.cal.redTmp ./ mean(app.Rstar,1);
                    app.Rprime = app.Rstar .* corr;
                    app.Gprime = app.Gstar .* corr;
                    % normalize to the iso point
                    if app.normalizeToIsoCB.Value
                        [app.Gprime,app.Rprime] = normalizeToIso (app.Gprime,app.Rprime,...
                            app.lambdaIn,app.calibrationSet(app.calSet).CalIsoPoint);
                    end
                end
            end
        end
        
        function fitpH (app)
            % September 2019. Modified in 2023 by GAB
            % epsilon and delta are computed by the method outlined in the PNAS paper
            
            % 0) preallocate arrays
            app.Cl = zeros(app.roiN,1);
            app.Kd=zeros(app.roiN,1);
            app.rCl=zeros(app.roiN,1);
            
            % Only fit the selected datapoints
            lambdaSel=[app.WavelengthTable.Data{:,2}];
            % compute pH
            [app.pH,app.delta,app.epsilon,app.theta,app.res,app.R2_adj] = compute_pH(...
                app.Gprime(:,lambdaSel),... % measured green spectra
                app.cal.G6(:,lambdaSel),app.cal.G8(:,lambdaSel),... % green spectra from calibrations
                app.calibrationSet(app.calSet).pH_function,... % function for pH computation
                app.calibrationSet(app.calSet).pH_function_params); % parameters for pH computation
            
            % compute "pH spectra" based on fitting using the wavelengths
            % available in the calibration file
            app.pHspectra = app.delta .* app.calibrationSet(app.calSet).setGpH6' +...
                app.epsilon .* app.calibrationSet(app.calSet).setGpH8';
            
            % Compute the correction for intracellular environment
            if app.intraCorrCB.Value
                app.pH = -162.70417 + 72.8297 .* app.pH -...
                    10.5698 .* app.pH.^ 2 + 0.51909 .* app.pH.^ 3;
            end
            
            
            % compute chloride concentration.
            % adjust R0 according to the relative gain of PMTs.
            % reminder: relative gain has been adjusted to the day of BT
            % caibration at the very beginning!
            R0_corr = app.getR0() ./ app.R6GBTEF.Value .* app.R6GcalR0EF.Value; 
            [app.Cl,app.rCl,app.Kd] = compute_Cl(app.pH, ...
                app.calibrationSet(app.calSet).setLambdaCal, ...
                app.pHspectra, ...
                app.Rprime,...
                app.cal.redTmp,...
                app.calibrationSet(app.calSet).RedTemplate, ...
                app.calibrationSet(app.calSet).pKa, ...
                app.calibrationSet(app.calSet).Kd, ... % Kd of the protonated form
                app.calibrationSet(app.calSet).CalIsoPoint, ... % isosbestic point
                app.calibrationSet(app.calSet).NormalizedSpectra, ...
                R0_corr);


            app.R2median = median(app.R2_adj);
        end
        
        function refreshTxt(app)
            if app.computationF
                % refresh the text in the output panel
                selRoi = app.ROISpinner.Value;
                out(1) = app.pH(selRoi);
                out(2) = app.theta(selRoi);
                out(3) = app.delta(selRoi);
                out(4) = app.epsilon(selRoi);
                txt = sprintf('pH: %.2f   Theta: %1.1f   pH6: %1.2f   pH8: %1.2f',out);
                app.pHtxt.Text = txt;
                
                out = [];
                out(1) = app.Cl(selRoi);
                out(2) = app.Kd(selRoi);
                out(3) = app.rCl(selRoi);
                txt = sprintf('Cl: %1.1f mM   App. Kd: %1.1f mM   rCl: %.3f',out);
                app.ClTxt.Text = txt;
    
                out = [];
                out(1) = app.res(selRoi);
                out(2) = median(app.res,'omitnan');
                txt = sprintf('Fit residue: %1.4f   Median residue: %1.4f',out);
                app.residueTxt.Text = txt;
                
                out = [];
                out(1) = app.R2_adj(selRoi);
                out(2) = median(app.R2_adj,'omitnan');
                txt = sprintf('Fit adj R^2: %1.4f   Median: %1.4f',out);
                app.AdjR2Txt.Text = txt;
                
                out(1) = app.statsOut.pH;
                out(2) = app.statsOut.pHstd;
                out(3) = app.statsOut.Cl;
                out(4) = app.statsOut.ClStd;
                txt = sprintf('With outliers pH: %.2f±%.2f  Cl: %1.1f±%1.1f mM',out);
                app.stats.Text = txt;

                out(1) = app.outliers.n;
                out(2) = app.statsOut.pHno;
                out(3) = app.statsOut.pHstdno;
                out(4) = app.statsOut.Clno;
                out(5) = app.statsOut.ClStdno;
                
                txt = sprintf('Without %1i outliers: pH: %.2f±%.2f Cl: %1.1f±%1.1f',out);
                app.statsNoOut.Text = txt;
            end
        end
        
        function plotScatter(app)
            cla (app.pHplot)
            selectedButton = app.mapRB.SelectedObject;
            pos=app.pHplot.InnerPosition;
            colorbar(app.pHplot,'off')
            switch selectedButton.Text
                case 'none'
                    % do nothing, as far as I know...
                case 'pH map'                    
                    colormap(app.pHplot,[[0 0 0]; flipud(winter)])            
%                     m = app.pHmaps(:,:,app.calSet);
                    app.pHmaps=computepHmaps(app);
                    app.map = image(app.pHplot,'CData',app.pHmaps,'XData',app.map_delta,'YData',app.map_epsilon,'CDataMapping','scaled');
%                     app.cb = colorbar(app.pHplot);
                    app.cb = colorbar(app.pHplot,'position',[pos(1)+pos(3)-0.025, pos(2), 0.03, pos(4)]);
%                     app.cb.Position = [0.78 0.18 0.035 0.75];
                    app.cb.Visible = 'on';
                    app.pHplot.CLim = [5.8 8.2];
                    app.pHplot.ColorScale = 'linear';
                    
                case 'log Kd map' 
                    colormap(app.pHplot,[[0 0 0]; cool])
%                     m= app.KdMap(:,:,app.calSet);
                    app.pHmaps=computepHmaps(app);
                    app.KdMap=computeKdmaps(app);
                    app.map = image(app.pHplot,'CData',log10(app.KdMap),'XData',app.map_delta,'YData',app.map_epsilon,'CDataMapping','scaled');
                    app.cb = colorbar(app.pHplot,'position',[pos(1)+pos(3)-0.025, pos(2), 0.03, pos(4)]);
                    app.cb.Visible = 'on';
                    prevPow10 = floor(log10(min(app.KdMap,[],'all')));
%                     nextPow10 = ceil(log10(max(m,[],'all')));
                    app.pHplot.CLim = [prevPow10, log10(200)];
                    app.pHplot.ColorScale = 'linear';
                    app.cb.TickLabels = 10.^cellfun(@str2double,app.cb.TickLabels);
                    
                case 'Log Cl'
                    app.pHmaps=computepHmaps(app);
                    app.KdMap=computeKdmaps(app);
                    app.ClMap=computeClmaps(app);
                    colormap(app.pHplot,[[0 0 0]; cool])
                    app.map = image(app.pHplot,'CData',log10(app.ClMap),'XData',app.map_delta,'YData',app.map_epsilon,'CDataMapping','scaled');
                    app.cb = colorbar(app.pHplot,'position',[pos(1)+pos(3), pos(2), 0.025, pos(4)]);
                    app.cb.Visible = 'on';
                    app.pHplot.CLim = [log10(0.1), log10(200)];
                    app.pHplot.ColorScale = 'linear';
                    app.cb.TickLabels = 10.^cellfun(@str2double,app.cb.TickLabels);
%                     newc = cool;
%                     newc(1,:) = 0.8;  
%                     colormap(app.pHplot,newc)            
%                     app.map = image(app.pHplot,'CData',app.ClMap,'XData',[0 1],'YData',[0 1],'CDataMapping','scaled');
%                     app.cb = colorbar(app.pHplot,'position',[pos(1)+pos(3), pos(2), 0.025, pos(4)]);
%                     app.cb.Visible = 'on';
            end
              
            if app.computationF
                selRoi = app.ROISpinner.Value;
                hold (app.pHplot,'on')
                included = logical(~app.outliers.global); 
                scatter(app.pHplot,app.delta(included),app.epsilon(included),[],"green")
                scatter(app.pHplot,app.delta(logical(app.outliers.global)),app.epsilon(logical(app.outliers.global)),[],"red")
                scatter(app.pHplot,app.delta(selRoi),app.epsilon(selRoi),'filled')
            end
        end
        
        function [statsOut, outliers] = computeStats(app)
            % November 21 2019.
            % after the end of the world
            statsOut.pH = mean(app.pH);
            statsOut.pHstd = std(app.pH);
            statsOut.Cl = mean(app.Cl);
            statsOut.ClStd = std(app.Cl);
            
            % compute outliers
            thr = app.outlierThr.Value;
            maxNout = app.outlierMaxN.Value;
            outliers.epsilon = isoutlier(app.epsilon,"gesd","ThresholdFactor",thr,"MaxNumOutliers",maxNout);
            outliers.delta = isoutlier(app.delta,"gesd","ThresholdFactor",thr,"MaxNumOutliers",maxNout);
            outliers.R2 =  isoutlier(app.res,"gesd","ThresholdFactor",thr,"MaxNumOutliers",maxNout);
            outliers.global = outliers.epsilon | outliers.delta | outliers.R2;
            outliers.n = sum(outliers.global);
            
            statsOut.pHno = mean(app.pH(~outliers.global),'omitnan');
            statsOut.pHstdno = std(app.pH(~outliers.global),'omitnan');
            statsOut.Clno = mean(app.Cl(~outliers.global),'omitnan');
            statsOut.ClStdno = std(app.Cl(~outliers.global),'omitnan');           
        end

        function call_loadDataClipboard(app)
            loadDataClipboard(app, [])
        end
        
        function call_computeAll(app)
            computeAll(app, [])
        end
        
        function call_toClip(app)
            toClip(app, [])
        end
        
        function openTxt(app,file,folder)
            txt_in=readmatrix([folder file]);
            app.storeStr(txt_in);
            
        end
        
        function storeStr(app,clipStr)
            % November 2019
            % Remove all special chars and substitute them with a legal
            % number separator 
            indexBad = isstrprop(clipStr,"cntrl");
            clipStr(indexBad) = ';';
            numIn = str2num(clipStr);
            app.roiN = numIn(1);                % extract the number of ROIs
            app.lambdaN = numIn(2);             % extract the number of wl
            app.lambdaIn = zeros(1,app.lambdaN);
            for i=1:app.lambdaN
                app.lambdaIn(i) = numIn(2+i);
            end
            app.i910 = find(app.lambdaIn==910);
            % load green channel first
            for wl=1:app.lambdaN
                offset = app.lambdaN + 2 + (wl-1) * app.roiN;
                % app.labdaN + 2 is the legth of the header
                for roi=1:app.roiN
                    app.GreenIn(roi,wl) =  numIn(offset+roi);
                end
            end
            % load red channel
            for wl=1:app.lambdaN
                offset = 2 + app.lambdaN + app.lambdaN * app.roiN + (wl-1) * app.roiN;
                for roi=1:app.roiN
                    app.RedIn(roi,wl) =  numIn(offset+roi);
                end
            end  
            
            % normalize the fluorescence data on the Red value at 910
            if app.normalizeToIsoCB.Value
                normalizeRaw (app)
            end
            % Prepare the interface for plotting of the data
            app.ROISpinner.Enable = 1;
            app.ROISpinner.Value = 1;
            if app.roiN>1
                app.ROISpinner.Limits = [1 app.roiN];
                app.ROISpinner.Enable = 'on';
            else
                app.ROISpinner.Limits = [1 inf];
                app.ROISpinner.Enable = 'off';
            end
            app.nRoiTxt.Text = sprintf('of %i', app.roiN);
            app.dataInF = 1;
            app.RawGCB.Enable = 1;
            app.RawRCB.Enable = 1;
        end
        
        function fromWorkspace(app)
            data = evalin('base','dataForIonizer');
            importFromStruct(app,data)
        end
        
        function importFromStruct(app,data)
            % GAB 2023. This function is meant to be a general way to load
            % files on Ionizer2. Ideally, specific functions can load data
            % from any source (system clipboard, Excel files, matlab
            % structures...) and store them in the scalar structure that
            % here is called data. Data must have the following fields:
            % -g    2D matrix with dimensions roi*wavelength, with green
            %       fluorescence values.
            % -r    2D matrix with dimensions roi*wavelength, with red
            %       fluorescence values.
            % -wl   row array with excitation wavelengths in nm.
            % -z    (optional) column array with the imaging depth (in
            %       microns) for each roi.
            % -r6g  (optional) Rhodamine 6G value (red over green at 910 nm, using
            %       Rhodamine6G 10uM). NOTE: if no r6g value is given in
            %       this way, it MUST be entered manually by the user in
            %       the proper EditField. 
            % 
            % Thus, a proper data loading pipeline should be: 
            % 1) Import data with a specific function
            % 2) Arrange data as in the structure described above
            % 3) Call the function "importFromStruct"
            
            
            app.roiN = size(data.g,1);                % extract the number of ROIs
            app.lambdaN = numel(data.wl);             % extract the number of wl
            app.lambdaIn = data.wl;
            app.i910 = find(app.lambdaIn==910);
            if isfield(data,'z')
                app.zeta=data.z;
            end
            if isfield(data,'r6g')
                app.R6GexpEF.Value=data.r6g;
            end
            app.GreenRaw =  data.g;
            app.RedRaw =  data.r;
            
            % exclude cells lower than threshold
            thr=app.thresholdEF.Value;
            exclude = any(app.GreenRaw<thr,2) | any(app.RedRaw<thr,2);
            app.GreenRaw(exclude,:)=[];
            app.RedRaw(exclude,:)=[];
            if ~isempty(app.zeta)
                app.zeta(exclude)=[];
            end
            n_excluded=sum(exclude);
            fprintf('%i out of %i cells excluded because of low fluorescence.\n',n_excluded,app.roiN)
            if n_excluded==app.roiN
                disp('No cell above threshold. Return')
                return
            end
            app.roiN=size(app.GreenRaw,1);
            
            app.GreenIn = app.GreenRaw;
            app.RedIn = app.RedRaw;
            if app.normalizeToIsoCB.Value
                normalizeRaw (app)
            end
            % Prepare the interface for data plot
            app.ROISpinner.Enable = 1;
            app.ROISpinner.Value = 1;
            if app.roiN>1
                app.ROISpinner.Limits = [1 app.roiN];
                app.ROISpinner.Enable = 'on';
            else
                app.ROISpinner.Limits = [1 inf];
                app.ROISpinner.Enable = 'off';
            end
            app.nRoiTxt.Text = sprintf('of %i', app.roiN);
            app.dataInF = 1;
            app.RawGCB.Enable = 1;
            app.RawRCB.Enable = 1;
            
            % update wavelength table
            updateWavelengthTable(app)
        end
        
        function R0inst = getR0(app)
            insSel = ismember(app.instrumentSet.Items,app.instrumentSet.Value);
            thisR0_i=find(strcmp(app.calibrationSet(app.calSet).calDescriptor,app.instrumentSettings(insSel).R0(:,1)));
            if isempty(thisR0_i)
                R0inst = nan;
                warning('R0 instr not available for this calibration set!');
            else
                R0inst = app.instrumentSettings(insSel).R0{thisR0_i,2};
            end
        end
        
        function [data_in,sel_sheet]=import_FromExcel(app, filename,is_bkg_there,remove_bkg)
            % This function imports data properly arranged in an Excel
            % sheet. A template is available.
            
            sheets = sheetnames(filename);
            disp('Select the sheet to process:')
            for i=1:numel(sheets)
                fprintf('-%i. %s\n',i,sheets(i))
            end
            i_sel=input(''); 
            figure(app.ionizer)
            sel_sheet=sheets(i_sel);
            c=readcell(filename,'FileType','spreadsheet','sheet',i_sel);
            
            firstline=3; % after headers.
            gcol=find(strcmpi (c(1,:),'ch1')); % first column (wavelength) of ch1
            rcol=find(strcmpi (c(1,:),'ch2')); % first column (wavelength) of ch2
            wl=[c{2,gcol:rcol-1}];
            n_wl=numel(wl);
            
            % remove spectra with NaN
            contains_nan=any(strcmp(c,'NaN'),2);
            c(contains_nan,:)=[];
            
            emptyline=cellfun(@ismissing,c(firstline:end,gcol),'UniformOutput',true);
            startline=[firstline;find(diff(emptyline)==-1)+firstline];
            endline=find(diff(emptyline)==1)+firstline-1;
            
            if numel(endline)<numel(startline)
                endline=[endline;size(c,1)];
            end
            
            assert(numel(endline)==numel(startline),'endline startline mismatch')
            
            % data=c(firstline:endline(end),gcol:gcol+2*n_wl-1);
            % data(cellfun(@ismissing,data(:,1)),:)=[];
            r6g=c{2,1};
            
            nfields = numel(endline);
            g=[];
            r=[];
            z=[];
            f=[]; %field
            for i=1:nfields
                
                g_now=cell2mat(c(startline(i):endline(i),gcol:gcol+n_wl-1));
                r_now=cell2mat(c(startline(i):endline(i),gcol+n_wl:gcol+2*n_wl-1));
                % subtract bkg, which is the last line of the field
                if remove_bkg
                    g_now=g_now(1:end-1,:)-g_now(end,:);
                    r_now=r_now(1:end-1,:)-r_now(end,:);
                elseif is_bkg_there
                    g_now=g_now(1:end-1,:);
                    r_now=r_now(1:end-1,:);
                end
                g=cat(1,g,g_now);
                r=cat(1,r,r_now);
                try % collect depth data, if it's there
                    z_now=cell2mat(c(startline(i):endline(i),gcol-2));
                    if is_bkg_there
                        z_now(end)=[];
                    end
                    z=cat(1,z,z_now);
                catch
                    warning('No z info')
                    z=[];
                end
                try % collect field data, if it's there
                    f_now=cell2mat(c(startline(i):endline(i),gcol-1));
                    if is_bkg_there
                        f_now(end)=[];
                    end
                    f=cat(1,f,f_now);
                catch
                    warning('No field info')
                    f=[];
                end
            end
            data_in=struct('r6g',r6g,'g',g,'r',r,'wl',wl,'z',z,'f',f);
            
        end
        
        function updateWavelengthTable(app)
            if isempty(app.WavelengthTable.Data)
                app.WavelengthTable.Data=[num2cell(app.lambdaIn'),num2cell(true(size(app.lambdaIn')))];
            else
                % remove wavelengths that don't belong to the input dataset
                toBeRemoved=~ismember([app.WavelengthTable.Data{:,1}],app.lambdaIn');
                app.WavelengthTable.Data(toBeRemoved,:)=[];
                % find new wavelengths
                newlambda=~ismember(app.lambdaIn',[app.WavelengthTable.Data{:,1}]);
                % add them at the end of the table
                app.WavelengthTable.Data = cat(1,app.WavelengthTable.Data,...
                    [num2cell(app.lambdaIn(newlambda)'),num2cell(true(sum(newlambda),1))]);
                % sort
                [~,sorted_i]=sort([app.WavelengthTable.Data{:,1}]);
                app.WavelengthTable.Data=app.WavelengthTable.Data(sorted_i,:);
            end
        end
        
        function pHmaps = computepHmaps(app)
            % Good morning! Nov. 2019
            % This function compute an 8 bit map representing pH in the
            % range 6 to 8 in the delta (pH 6), epsilon (pH 8) space.
            app.map_delta = -0.5:0.01:1.5;
            app.map_epsilon = -0.5:0.01:1.5;
            [X,Y] = meshgrid(app.map_delta,app.map_epsilon);
%             ndelta=numel(app.map_delta);
%             nepsilon=numel(app.map_epsilon);
%             pHmaps=zeros(nepsilon,ndelta,app.ncalset);
%             for iSet = 1:app.ncalset
%                 % compute the map for each calibration set
            iSet=app.calSet;
                fit_fcn = app.calibrationSet(iSet).pH_function;
                fit_param = app.calibrationSet(iSet).pH_function_params;
                ph = fit_fcn(Y./X,fit_param);
                if app.intraCorrCB.Value
                    ph = -162.70417 + 72.8297 .* ph - ...
                        10.5698 .* ph .^ 2 + 0.51909 .* ph .^ 3;
                end
                % set to nan the complex values
                ph(abs(imag(ph))>0)=nan;
%                 pHmaps(:,:,iSet)=ph;
                %             end
                pHmaps=ph;
        end
        
        function kdmaps = computeKdmaps(app)
%             kdmaps=zeros(size(app.pHmaps));
            iSet=app.calSet;
%             for iSet = 1:app.ncalset
%                 pHmap = app.pHmaps(:,:,iSet);
                kd0= app.calibrationSet(iSet).Kd;
%                 kdmaps(:,:,iSet) = kd0.*(10.^(pHmap-app.calibrationSet(iSet).pKa)+1);
                kdmaps = kd0.*(10.^(app.pHmaps-app.calibrationSet(iSet).pKa)+1);
%             end
        end
        
        function clmaps = computeClmaps(app)
%             clmaps=zeros(size(app.pHmaps));
            iSet=app.calSet;
%             for iSet = 1:app.ncalset
                % The residual fluorescence of the Cl bound sensor is posed = 0
                % The Rz ratio is corrected according to the value of the Rhod6G calibration at the time of bleed through
                % the rationale being that, in the bleedthough removal we scaled the experiment to the R6G ratio measured
                % at the time of the bleedtrough measure
%                 R0 = app.instrumentSettings.R0{app.instrument}(2,iSet);
                R0 = getR0(app);
                R0corrF = app.R6GBTEF.Value/app.R6GcalR0EF.Value;    % this term is used to align the calibration R0
                % with the specific experiment
                R0corr = R0/R0corrF;
                % compute the module of G at each point of the epsilon-delta space. This is equal to the G/R ratio
                % since the spectra are normalized to the Kate fluorescence at the isosbestic point
                Rmatrix = (app.map_delta + app.map_epsilon').*app.calibrationSet(iSet).R0;
                if ~app.calibrationSet(iSet).NormalizedSpectra
                    Rmatrix = Rmatrix ./ spline(app.calibrationSet(iSet).setLambdaCal,...
                        app.calibrationSet(iSet).RedTemplate,app.calibrationSet(iSet).CalIsoPoint);
                end
                %app.ClMap = Rmatrix;
                cl_tmp = app.KdMap .* (R0corr ./ Rmatrix - 1);
                %                    app.ClMap = R0corr ./ Rmatrix;
                %app.ClMap(app.ClMap>1) = 0
                cl_tmp(cl_tmp<0.1) = nan;
                cl_tmp(cl_tmp>150) = 150;
%                 cl_tmp = log10(cl_tmp);
%                 app.ClMap(app.ClMap==0) = 0;
%                 clmaps(:,:,iSel)=cl_tmp;
                clmaps=cl_tmp;
%             end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize the GUI
            app.calibrationSetDD.Visible = 0;
            app.computationF = 0;
            app.dataInF = 0;
            app.RawGCB.Enable = 0;
            app.RawRCB.Enable = 0;
            app.RstarCB.Enable = 0;
            app.GstarCB.Enable = 0;
            
            app.GprimeCB.Enable = 0;
            app.RprimeCB.Enable = 0;
            
            app.plotFitCB.Enable = 0;
            
            app.pHtxt.Visible = 0;
            app.ClTxt.Visible = 0;
            app.residueTxt.Visible = 0;
            app.stats.Visible = 0;
            app.statsNoOut.Visible = 0;
            
            this_file = mfilename('fullpath');
            sep=regexp(this_file,filesep);
            this_folder = this_file(1:sep(end)-1);
            addpath([this_folder filesep 'functions'])
            addpath([this_folder filesep 'calibration_files'])
            
            app.excelIn_folder = [pwd, filesep];
            
            app.pHplot.Units = 'normalized';
        end

        % Button pushed function: loadCalibration
        function loadCalibrationData(app, event)
            % July 11, 2019
            % GMR & VB
            % Opening of all the calibration data. The calibration files must be located in the current
            % directory, i.e. the directory that contains the app.
            % 
            % July 2023
            % Revision by GAB
            
            % 1) Open the matlab variable CalibrationSet_ containing
            load('CalibrationSet_New.mat') %#ok<LOAD> 
            app.calibrationSet = calStruct; % 'CalibrationSet_.mat' contains a structure called 'calStruct'
            app.ncalset = numel(app.calibrationSet);
            clear calStruct

            % Now we open the app.calibrationSet.nSet calibration files
            for i=1:app.ncalset
                % Open the E2GFP calibration set
                calH=readmatrix(app.calibrationSet(i).fileNameG);
                app.calibrationSet(i).setLambdaCal = calH(:,1);
                app.calibrationSet(i).setGpH6 = calH(:,2);
                app.calibrationSet(i).setGpH8 = calH(:,3);
                % Open the LssKate2 calibration set
                if ~isempty(app.calibrationSet(i).fileNameR)
                    calH=readmatrix(app.calibrationSet(i).fileNameR);
                    app.calibrationSet(i).setLambdaCal = calH(:,1);
                    app.calibrationSet(i).RedTemplate = calH(:,2);
                else
                    % 2023 calibrations: green spectra are normalized to
                    % red spectra and there's no red template.
                    app.calibrationSet(i).RedTemplate = nan;
                end
            end
            
            % Compute the isosbestic point of each calibration set
            % July 2019. 
            % The iso point is computed by finding the zero of the difference between the pH6 and pH8 spectra
            for i=1:app.ncalset
                x = app.calibrationSet(i).setLambdaCal;
                xq = x(1):0.1:x(app.calibrationSet(i).calNlambda);
                deltaSp = app.calibrationSet(i).setGpH6 - app.calibrationSet(i).setGpH8;
                s = spline(x,deltaSp,xq);       % spline interpolation of the difference spectra
                s = s.^2;                       % square of the interpolated difference
                [~,imin] = min (s);
                app.calibrationSet(i).CalIsoPoint = xq(imin);
            end                
            
            % Load the files holding the wavelength dependent bleed through coefficents
            % These data are instrument dependent so it is to be expected that each site has its own
            % data set that can be chosen by the following code.
            % GAB: I replaced the old txt file with a matlab variable.
            load("InstrumentsSet.mat"); %#ok<LOAD> the variable name is 'instrStruct'
            app.instrumentSettings=instrStruct;
            app.instrumentSettings_n = numel(instrStruct);
            for i=1:app.instrumentSettings_n
                % open all instrument descriptors
                % the following structure will contain the info associated to the selected file
                c=readcell(app.instrumentSettings(i).BT_fileName,'delimiter','\t');
                % The first line is a header and its format is "r6g=x",
                % where x is the r/g value for r6g at the time of the BT
                % calibration. Collect this number
                app.instrumentSettings(i).r6g_BT=c{1,2};
                % The rest is a w*3 matrix, with w=number of
                % wavelengths. The format is 'wavelength    beta    alpha';
                app.instrumentSettings(i).AlfaBetaL=readmatrix(...
                    app.instrumentSettings(i).BT_fileName,'delimiter','\t',...
                    "NumHeaderLines",1);
                
                % Open the instrument specific Rzero file
                if strcmp(app.instrumentSettings(i).R0_fileName,'none')
                    app.instrumentSettings(i).R0=[]; % assigned later
                else
                    c=readcell(app.instrumentSettings(i).R0_fileName,...
                        'delimiter',',');
                    % Again, the first line is a header and its format is "r6g=x",
                    % where x is the r/g value for r6g at the time of the
                    % R0 calibration. Collect this number
                    app.instrumentSettings(i).r6g_R0=c{1,2};
                    % The rest is a n by 2 cell array. The first element of
                    % each row is the descriptor of a calibration file. The
                    % second one is the value of R0 for that calibration.
                    app.instrumentSettings(i).R0=c(2:end,:);
                    
                end
                
            end
            % let's populate the drop down menu control
            app.instrumentSet.Visible = 1; % make it visible
            app.instrumentSet.Items = {};
            app.instrumentSet.Value = {};
            for i=1:app.instrumentSettings_n
                app.instrumentSet.Items(i) = {char(app.instrumentSettings(i).Descriptor)};
            end
            app.instrumentSet.Value = app.instrumentSet.Items{1};
            app.SI=1;
            app.R6GBTEF.Value = app.instrumentSettings(1).r6g_BT;
            app.R6GcalR0EF.Value = app.instrumentSettings(1).r6g_R0;
            
            % let's populate the drop down menu control
            app.calibrationSetDD.Visible = 1;       % make it visible
            app.calibrationSetDD.Items = {};
            app.calibrationSetDD.Value = {};
            for i=1:app.ncalset
                app.calibrationSetDD.Items{i} = app.calibrationSet(i).calDescriptor;              
            end
            app.calibrationSetDD.Value = app.calibrationSetDD.Items{1};
            % END of the calibration input section

            % Set the first calibration set and instrument set as default
            app.instrument = 1;
            app.calSet = 1;
            
            % Compute the color maps for pH
            app.pHmaps = computepHmaps (app);
            app.KdMap = computeKdmaps (app);
            app.ClMap = computeClmaps(app);
            
            % Plot the calibration set
            plotCurrentCalibrationSet(app)

        end

        % Value changed function: calibrationSetDD
        function selectCal(app, event)
            selCal = app.calibrationSetDD.Value;
            app.calSet = find(strcmp(app.calibrationSetDD.Items,selCal));
            if app.calibrationSet(app.calSet).NormalizedSpectra
                app.CorrectionofexcitationButtonGroup.Enable='off';
            else
                app.CorrectionofexcitationButtonGroup.Enable='on';
            end
            plotCurrentCalibrationSet(app)
        end

        % Button pushed function: fromGUIButton
        function loadDataGUI(app, event)
            % September 14, 2019
            % placeholder function
            % load data for app development
            
        end

        % Button pushed function: fromClipButton
        function loadDataClipboard(app, event)
            % September 15, 2019
            % This function loads the raw fluorescence data from the system clipboard using 
            % the data format applied by the imageJ macros
            % Data Format
            % Number of ROIs (nR), Number of wavelength (nW), list of wavelength
            % Roi(1) Wl(1) green, Roi(1) Wl(2) green, ... , Roi(1) Wl(nW) green
            % ...
            % Roi(nR) Wl(1) green, ... , Roi(nR) Wl(nW) green
            % Roi(1) Wl(1) red, Roi(1) Wl(2) red, ... , Roi(1) Wl(nW) red
            % ...
            % Roi(nR) Wl(1) red, ... , Roi(nR) Wl(nW) red
      
            clipStr = clipboard('paste');       %load the entire CB in a string
            app.storeStr(clipStr);
            %plotData (app)
        end

        % Button pushed function: ComputeButton
        function computeAll(app, event)
            % September 2019
            % compute pH and Cl from the raw data using the present settings
                        
            % identify the present calibration set
            % remember that at the end of each computation the spectra are normalized to the iso point
            % of the specific calibration set.
            % At the time of data loading, the raw fluorescences are all normalized to the value of
            % the Red at 910 nm. During computation the normalization is performed at the isosbetic point
            % associated to the specific calibration set
                        
            % November 29, 2019
            % The function 'extractCalibrations' reads the wavelenths associated to the input data and it compares 
            % this with the available wls in the calibration set. If some wls are not present in the files are 
            % computed by spline interpolation
            app.cal = extractCalibrations (app);
            
            % 1) Correct the Green.
            % This must be performed before of BT correction. There are to steps:
            % 1.1) Correction for the extinction of emission
            % 1.2) Correction for the relative gain change of the PMTs
            correctGreenAndRed (app)
            
            % 2) Remove bleed through (compute Gstar, Rstar)
            removeBT (app)
            
            % 3) Correct spectra for the excitation scattering (compute Gprime, Rprime)
            compensateExcitation (app)
            
            % 4) Compute pH and Chloride
            fitpH (app)
            
            % 5) Compute statistics ad outliers
            [app.statsOut, app.outliers] = computeStats (app);
            
            % set the GUI
            app.RstarCB.Enable = 1;
            app.GstarCB.Enable = 1;
            app.plotFitCB.Enable = 1;

            app.GprimeCB.Enable = 1;
            app.RprimeCB.Enable = 1;            
            app.pHtxt.Visible = 1;
            app.ClTxt.Visible = 1;
            app.residueTxt.Visible = 1;
            app.computationF = 1;
            app.stats.Visible = 1;
            app.statsNoOut.Visible = 1;
            
            refreshOutput(app, event)
        end

        % Value changed function: GprimeCB, GstarCB, ROISpinner, 
        % RawGCB, RawRCB, RprimeCB, RstarCB, plotFitCB, 
        % plotRedTemplateCB
        function refreshOutput(app, event)

%             value = app.ROISpinner.Value;
            % this method refresh the graphic and text output of the gui as you navigate the ROI set
            % complete with the number of ROIs. 
            
            plotData(app)
            plotScatter(app)
            refreshTxt(app)
        end

        % Button pushed function: frommatButton
        function loadMatFile(app, event)
            % Sept 22 2019.
            % Load a .mat structure holding the fluoresce data and the imaging depth of every roi.
            % The structure is compatible with the output structure provided by Andy's code
            
            % Rois are entered only if ALL its values at ALL avelength are strictly positive and, larger
            % than the threshold specified in app.threshold.Value
            
            [fileIn, pathIn] = uigetfile('*.mat');
            fileIn = [pathIn fileIn];
            load (fileIn)
            % fields of the structure:
            %
            thr = app.thresholdEF.Value;
            Gtmp = [];
            Rtmp = [];
            app.GreenIn = [];
            app.RedIn = [];
            app.zeta = CellData.Z;
%             Gtmp = CellData.G;     %row are ROIs, columns are WL
%             Rtmp = CellData.R;
            Gtmp = CellData.Gfd;     %row are ROIs, columns are WL
            Rtmp = CellData.Rfd;
            % now inspect the input buffer to reject all ROIs that do not respect the threshold criteria
            Gtmpmin = min (Gtmp,[],2);      % min values for each ROI
            Rtmpmin = min (Rtmp,[],2);
            superMin = min(Gtmpmin,Rtmpmin);
            goodI = find(superMin>thr);
            app.GreenIn = Gtmp(goodI,:);
            app.RedIn = Rtmp(goodI,:);
%            app.GreenIn = CellData.Gfd;     %row are ROIs, columns are WL
%            app.RedIn = CellData.Rfd;
%             app.lambdaIn = CellData.lambda;
            app.lambdaIn = lambda;
            app.lambdaN = length(lambda);
            sz = size(app.GreenIn);
            app.roiN = sz(1);
            app.nRoiTxt.Text = sprintf('of: %i',app.roiN);
            
            % normalize input data to the red fluorescence at the iso point
            app.i910 = find(app.lambdaIn==910);
            % normalize the fluorescence data on the Red value at 910
            normalizeRaw (app)

            % Prepare the interface for plotting of the data
            app.ROISpinner.Enable = 1;
            app.ROISpinner.Limits = [1 app.roiN];
            app.dataInF = 1;
            app.RawGCB.Enable = 1;
            app.RawRCB.Enable = 1;            
        end

        % Button pushed function: ToClipboardButton
        function toClip(app, event)
            % this function copies the computed pH and Cl data to the system clipboard for export
            strOut = [];
            out = [];
            if (app.omitOutliersCB.Value)
                mask = logical(~app.outliers.global);
            else
                mask = true(app.roiN,1);
            end
            for i=1:app.roiN
                if mask(i)
                    out(1) = i;
                    out(2) = app.pH(i);
                    out(3) = app.Cl(i);
                    out(4) = app.R2_adj(i);
                    out(5) = app.gamma(i);
                    out(6) = app.Kd(i);
                    out(7) = app.rCl(i);
                    out(8) = app.outliers.global(i);
                    strOut = [strOut sprintf('%i \t %.2f \t %.2f \t %.5f \t %.3f \t %.1f \t %.3f \t %i \n', out)];                
                end    
            end
            clipboard ('copy',strOut)
        end

        % Callback function
        function pHmapToggle(app, event)
            value = app.pHmapCB.Value;
            if ~value
                % clear CData and the color bar from the scatter plot
                app.map.CData = [];
%                set(app.pHplot,'CData', []);
                colorbar(app.cb,'off')
%                app.cb.Visible = 'off';
            else
                plotScatter(app)
            end
        end

        % Value changed function: instrumentSet
        function selInstr(app, event)
            app.instrument = find(ismember(app.instrumentSet.Items,app.instrumentSet.Value));
%             app.SI=1;
            app.R6GBTEF.Value = app.instrumentSettings(app.instrument).r6g_BT;
            app.R6GcalR0EF.Value = app.instrumentSettings(app.instrument).r6g_R0;
            plotCurrentCalibrationSet(app)
        end

        % Selection changed function: mapRB
        function mapChoice(app, event)
            selectedButton = app.mapRB.SelectedObject;
            if ishandle(app.cb), colorbar(app.cb,'off')
            end
            switch selectedButton.Text
                case 'none'
                    % clear CData and the color bar from the scatter plot
                    app.map.CData = [];
                    
                otherwise              
                    plotScatter(app)
            end
        end

        % Callback function
        function culoButtonPushed(app, event)
            [file,folder]=uigetfile('*.txt');
            app.openTxt(file,folder);
        end

        % Button pushed function: fromExcelButton
        function fromExcelButtonPushed(app, event)
            [fname,tmp] = uigetfile([app.excelIn_folder '*.xlsx']);
            figure(app.ionizer)
            if tmp==0
                return
            end
            app.excelIn_folder=tmp;
            app.excelIn_file=fname;
            % is the LAST roi background? if yes, subtract it
            is_bkg_there = app.LastROIbkgrCheckBox.Value;
            remove_bkg = app.RmvbkgrCheckBox.Value;
            [data,sheet]=import_FromExcel(app,[tmp,fname],is_bkg_there,remove_bkg);
            app.excelIn_sheet=sheet;
            importFromStruct(app,data)
        end

        % Value changed function: RmvbkgrCheckBox
        function RmvbkgrCheckBoxValueChanged(app, event)
            value = app.RmvbkgrCheckBox.Value;
            if value
                app.LastROIbkgrCheckBox.Value=true;
            end
        end

        % Value changed function: LastROIbkgrCheckBox
        function LastROIbkgrCheckBoxValueChanged(app, event)
            value = app.LastROIbkgrCheckBox.Value;
            if ~value
                app.RmvbkgrCheckBox.Value = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ionizer = uifigure('Visible', 'off');
            app.ionizer.Name = 'Ionizer';
            app.CalibrationoptionsanddatasetPanel = uipanel(app.ionizer);
            app.CalibrationoptionsanddatasetPanel.Title = 'Calibration options and data set';
            app.loadCalibration = uibutton(app.CalibrationoptionsanddatasetPanel, 'push');
            app.loadCalibration.ButtonPushedFcn = createCallbackFcn(app, @loadCalibrationData, true);
            app.loadCalibration.Text = 'Load Cal';
            app.SelectLabel = uilabel(app.CalibrationoptionsanddatasetPanel);
            app.SelectLabel.Text = 'Select:';
            app.calibrationSetDD = uidropdown(app.CalibrationoptionsanddatasetPanel);
            app.calibrationSetDD.ValueChangedFcn = createCallbackFcn(app, @selectCal, true);
            app.fromGUIButton = uibutton(app.CalibrationoptionsanddatasetPanel, 'push');
            app.fromGUIButton.ButtonPushedFcn = createCallbackFcn(app, @loadDataGUI, true);
            app.fromGUIButton.Text = 'from GUI';
            app.fromClipButton = uibutton(app.CalibrationoptionsanddatasetPanel, 'push');
            app.fromClipButton.ButtonPushedFcn = createCallbackFcn(app, @loadDataClipboard, true);
            app.fromClipButton.Text = 'from Clip';
            app.frommatButton = uibutton(app.CalibrationoptionsanddatasetPanel, 'push');
            app.frommatButton.ButtonPushedFcn = createCallbackFcn(app, @loadMatFile, true);
            app.frommatButton.Text = 'from mat';
            app.ComputeButton = uibutton(app.CalibrationoptionsanddatasetPanel, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @computeAll, true);
            app.ComputeButton.Text = 'Compute';
            app.ThresholdinEditFieldLabel = uilabel(app.CalibrationoptionsanddatasetPanel);
            app.ThresholdinEditFieldLabel.Text = 'Threshold in:';
            app.thresholdEF = uieditfield(app.CalibrationoptionsanddatasetPanel, 'numeric');
            app.InstrumentandBTcoeffLabel = uilabel(app.CalibrationoptionsanddatasetPanel);
            app.InstrumentandBTcoeffLabel.Text = 'Instrument and BT coeff.:';
            app.instrumentSet = uidropdown(app.CalibrationoptionsanddatasetPanel);
            app.instrumentSet.ValueChangedFcn = createCallbackFcn(app, @selInstr, true);
            app.fromExcelButton = uibutton(app.CalibrationoptionsanddatasetPanel, 'push');
            app.fromExcelButton.ButtonPushedFcn = createCallbackFcn(app, @fromExcelButtonPushed, true);
            app.fromExcelButton.Text = 'from Excel';
            app.LastROIbkgrCheckBox = uicheckbox(app.CalibrationoptionsanddatasetPanel);
            app.LastROIbkgrCheckBox.ValueChangedFcn = createCallbackFcn(app, @LastROIbkgrCheckBoxValueChanged, true);
            app.LastROIbkgrCheckBox.Text = 'Last ROI bkgr';
            app.RmvbkgrCheckBox = uicheckbox(app.CalibrationoptionsanddatasetPanel);
            app.RmvbkgrCheckBox.ValueChangedFcn = createCallbackFcn(app, @RmvbkgrCheckBoxValueChanged, true);
            app.RmvbkgrCheckBox.Text = 'Rmv bkgr';
            app.ComputationoptionsPanel = uipanel(app.ionizer);
            app.ComputationoptionsPanel.Title = 'Computation options';
            app.bleedthroughCB = uicheckbox(app.ComputationoptionsPanel);
            app.bleedthroughCB.Text = 'Correct for bleed through';
            app.bleedthroughCB.Value = true;
            app.intraCorrCB = uicheckbox(app.ComputationoptionsPanel);
            app.intraCorrCB.Text = 'Correction for intracellular environment';
            app.Rhod6GduringBTmeasurementEditFieldLabel = uilabel(app.ComputationoptionsPanel);
            app.Rhod6GduringBTmeasurementEditFieldLabel.Text = 'Rhod 6G during BT measurement:';
            app.R6GBTEF = uieditfield(app.ComputationoptionsPanel, 'numeric');
            app.R6GBTEF.Limits = [0 Inf];
            app.Rhod6GratioduringexperimentEditFieldLabel = uilabel(app.ComputationoptionsPanel);
            app.Rhod6GratioduringexperimentEditFieldLabel.Text = 'Rhod 6G ratio during experiment:';
            app.R6GexpEF = uieditfield(app.ComputationoptionsPanel, 'numeric');
            app.R6GexpEF.Limits = [0 Inf];
            app.Rhod6Gratioat0ClLabel = uilabel(app.ComputationoptionsPanel);
            app.Rhod6Gratioat0ClLabel.Text = 'Rhod 6G ratio at 0 Cl:';
            app.R6GcalR0EF = uieditfield(app.ComputationoptionsPanel, 'numeric');
            app.R6GcalR0EF.Limits = [0 Inf];
            app.wideRedCB = uicheckbox(app.ComputationoptionsPanel);
            app.wideRedCB.Enable = 'off';
            app.wideRedCB.Text = 'Correct for wide band red filter';
            app.normalizeToIsoCB = uicheckbox(app.ComputationoptionsPanel);
            app.normalizeToIsoCB.Text = 'Normalize to Kate2 isosbestic point';
            app.normalizeToIsoCB.Value = true;
            app.CalibrationDataPanel = uipanel(app.ionizer);
            app.CalibrationDataPanel.Title = 'Calibration Data';
            app.pKaTxt = uilabel(app.CalibrationDataPanel);
            app.pKaTxt.Text = 'pKa:';
            app.isoTxt = uilabel(app.CalibrationDataPanel);
            app.isoTxt.Text = 'Iso point (nm):';
            app.aTxt = uilabel(app.CalibrationDataPanel);
            app.aTxt.Text = 'Cooperativity (a):';
            app.R0Txt = uilabel(app.CalibrationDataPanel);
            app.R0Txt.Text = 'R0 at iso point:';
            app.R0instr = uilabel(app.CalibrationDataPanel);
            app.R0instr.Tooltip = {'R0 for the selected instrument'; ''};
            app.R0instr.Text = 'R0 instrument:';
            app.calibrationAx = uiaxes(app.CalibrationDataPanel);
            app.calibrationAx.XLim = [790 980];
            app.calibrationAx.YLim = [0 1.8];
            app.calibrationAx.XTick = [800 830 860 910 960 980];
            app.calibrationAx.XTickLabel = {'800'; '830'; '860'; '910'; '960'; '980'};
            app.calibrationAx.YTick = [0 0.4 0.7 1 1.3 1.8 2 2.5];
            app.calibrationAx.YTickLabel = {''; '0.4'; '0.7'; '1'; '1.3'; '1.8'; '2'; '2.5'};
            app.CorrectionofemissionButtonGroup = uibuttongroup(app.ionizer);
            app.CorrectionofemissionButtonGroup.Title = 'Correction of emission';
            app.NoneRB = uiradiobutton(app.CorrectionofemissionButtonGroup);
            app.NoneRB.Text = 'None';
            app.NoneRB.Value = true;
            app.ImagingdepthRB = uiradiobutton(app.CorrectionofemissionButtonGroup);
            app.ImagingdepthRB.Text = 'Imaging depth (um):';
            app.UseKate2RB = uiradiobutton(app.CorrectionofemissionButtonGroup);
            app.UseKate2RB.Text = 'Use Kate2';
            app.Label = uilabel(app.CorrectionofemissionButtonGroup);
            app.Label.Text = '';
            app.imagingZ = uieditfield(app.CorrectionofemissionButtonGroup, 'numeric');
            app.imagingZ.Limits = [0 Inf];
            app.zFromStructureRB = uiradiobutton(app.CorrectionofemissionButtonGroup);
            app.zFromStructureRB.Text = 'Imaging depth from data';
            app.CorrectionofexcitationButtonGroup = uibuttongroup(app.ionizer);
            app.CorrectionofexcitationButtonGroup.Title = 'Correction of excitation';
            app.noExcCorrRB = uiradiobutton(app.CorrectionofexcitationButtonGroup);
            app.noExcCorrRB.Text = 'None';
            app.noExcCorrRB.Value = true;
            app.individualKate2RB = uiradiobutton(app.CorrectionofexcitationButtonGroup);
            app.individualKate2RB.Text = 'Use individual Kate2 spectra';
            app.meanKate2RB = uiradiobutton(app.CorrectionofexcitationButtonGroup);
            app.meanKate2RB.Text = 'Use mean Kate2 spectra';
            app.results = uipanel(app.ionizer);
            app.results.Title = 'Result window';
            app.pHtxt = uilabel(app.results);
            app.pHtxt.Text = 'pH:';
            app.residueTxt = uilabel(app.results);
            app.residueTxt.Text = 'Fit residue:';
            app.ClTxt = uilabel(app.results);
            app.ClTxt.Text = 'Chloride (mM):';
            app.ToClipboardButton = uibutton(app.results, 'push');
            app.ToClipboardButton.ButtonPushedFcn = createCallbackFcn(app, @toClip, true);
            app.ToClipboardButton.Text = 'To Clipboard';
            app.omitOutliersCB = uicheckbox(app.results);
            app.omitOutliersCB.Text = 'Output data without outliers';
            app.omitOutliersCB.Value = true;
            app.OutlierthresholdLabel = uilabel(app.results);
            app.OutlierthresholdLabel.Text = 'Outlier threshold:';
            app.outlierThr = uieditfield(app.results, 'numeric');
            app.outlierThr.Limits = [0 1];
            app.outlierThr.ValueDisplayFormat = '%1.2f';
            app.outlierThr.Value = 0.5;
            app.NmaxoutliersLabel = uilabel(app.results);
            app.NmaxoutliersLabel.Text = 'N max outliers:';
            app.outlierMaxN = uieditfield(app.results, 'numeric');
            app.outlierMaxN.Limits = [0 14];
            app.outlierMaxN.ValueDisplayFormat = '%2i';
            app.outlierMaxN.Tooltip = {'Max number of outlier for each parameter'};
            app.outlierMaxN.Value = 2;
            app.stats = uilabel(app.results);
            app.stats.Text = 'Fit residue:';
            app.statsNoOut = uilabel(app.results);
            app.statsNoOut.Text = 'Fit residue:';
            app.mapRB = uibuttongroup(app.results);
            app.mapRB.SelectionChangedFcn = createCallbackFcn(app, @mapChoice, true);
            app.pHmapRB = uiradiobutton(app.mapRB);
            app.pHmapRB.Text = 'pH map';
            app.KdmapRB = uiradiobutton(app.mapRB);
            app.KdmapRB.Text = 'log Kd map';
            app.ClmapRB = uiradiobutton(app.mapRB);
            app.ClmapRB.Text = 'Log Cl';
            app.noneRB = uiradiobutton(app.mapRB);
            app.noneRB.Text = 'none';
            app.noneRB.Value = true;
            app.AdjR2Txt = uilabel(app.results);
            app.AdjR2Txt.Text = 'Fit R2 adj:';
            app.pHplot = uiaxes(app.results);
            xlabel(app.pHplot, 'Component pH 6.0')
            ylabel(app.pHplot, 'Component pH 8.0')
            app.pHplot.XLim = [-0.1 1.5];
            app.pHplot.YLim = [-0.1 1.5];
            app.pHplot.XTick = [-0.25 0 0.5 1 1.5];
            app.pHplot.YTick = [-0.25 0 0.5 1 1.5];
            app.TabGroup = uitabgroup(app.ionizer);
            app.DataexplorerTab = uitab(app.TabGroup);
            app.DataexplorerTab.Title = 'Data explorer';
            app.Panel = uipanel(app.DataexplorerTab);
            app.ROISpinnerLabel = uilabel(app.Panel);
            app.ROISpinnerLabel.Text = 'ROI:';
            app.ROISpinner = uispinner(app.Panel);
            app.ROISpinner.Limits = [1 Inf];
            app.ROISpinner.ValueChangedFcn = createCallbackFcn(app, @refreshOutput, true);
            app.ROISpinner.Enable = 'off';
            app.ROISpinner.Value = 1;
            app.RawGCB = uicheckbox(app.Panel);
            app.RawGCB.ValueChangedFcn = createCallbackFcn(app, @refreshOutput, true);
            app.RawGCB.Text = 'Raw G';
            app.RawRCB = uicheckbox(app.Panel);
            app.RawRCB.ValueChangedFcn = createCallbackFcn(app, @refreshOutput, true);
            app.RawRCB.Text = 'R';
            app.GstarCB = uicheckbox(app.Panel);
            app.GstarCB.ValueChangedFcn = createCallbackFcn(app, @refreshOutput, true);
            app.GstarCB.Text = 'G Star';
            app.RstarCB = uicheckbox(app.Panel);
            app.RstarCB.ValueChangedFcn = createCallbackFcn(app, @refreshOutput, true);
            app.RstarCB.Text = 'R *';
            app.GprimeCB = uicheckbox(app.Panel);
            app.GprimeCB.ValueChangedFcn = createCallbackFcn(app, @refreshOutput, true);
            app.GprimeCB.Text = 'G Prime';
            app.RprimeCB = uicheckbox(app.Panel);
            app.RprimeCB.ValueChangedFcn = createCallbackFcn(app, @refreshOutput, true);
            app.RprimeCB.Text = 'R ''';
            app.plotFitCB = uicheckbox(app.Panel);
            app.plotFitCB.ValueChangedFcn = createCallbackFcn(app, @refreshOutput, true);
            app.plotFitCB.Text = 'Plot fit';
            app.plotRedTemplateCB = uicheckbox(app.Panel);
            app.plotRedTemplateCB.ValueChangedFcn = createCallbackFcn(app, @refreshOutput, true);
            app.plotRedTemplateCB.Text = 'Plot Red template';
            app.plotG6CB = uicheckbox(app.Panel);
            app.plotG6CB.Text = 'E2 pH 6';
            app.plotG8CB = uicheckbox(app.Panel);
            app.plotG8CB.Text = 'pH 8';
            app.nRoiTxt = uilabel(app.Panel);
            app.nRoiTxt.Text = '0';
            app.spectraAx = uiaxes(app.Panel);
            app.spectraAx.XLim = [790 980];
            app.spectraAx.YLim = [0 1.8];
            app.spectraAx.XTick = [800 830 860 910 960 980];
            app.spectraAx.XTickLabel = {'800'; '830'; '860'; '910'; '960'; '980'};
            app.WavelengthselectTab = uitab(app.TabGroup);
            app.WavelengthselectTab.Title = 'Wavelength select';
            app.WavelengthTable = uitable(app.WavelengthselectTab);
            app.WavelengthTable.ColumnName = {'Wavelength (nm)'; 'Selected'};
            app.WavelengthTable.RowName = {};
            app.WavelengthTable.ColumnEditable = [false true];
            % Show the figure after all components are created
            app.ionizer.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ionNew_giugno2023_NewPhFunction

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ionizer)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ionizer)
        end
    end
end