classdef FlatFieldAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FlatFieldAnalyzerUIFigure      matlab.ui.Figure
        LoadPanel                      matlab.ui.container.Panel
        OpenandprocessButton           matlab.ui.control.Button
        Remove1stframeCheckBox         matlab.ui.control.CheckBox
        Darkch1EditFieldLabel          matlab.ui.control.Label
        Darkch1EditField               matlab.ui.control.NumericEditField
        Darkch2EditFieldLabel          matlab.ui.control.Label
        Darkch2EditField               matlab.ui.control.NumericEditField
        SelectthefolderscontainingLabel  matlab.ui.control.Label
        ReadDarkFileButton             matlab.ui.control.Button
        Panel                          matlab.ui.container.Panel
        SaveButton                     matlab.ui.control.Button
        nm000framesRGratio0000Label    matlab.ui.control.Label
        YlogscaleCheckBox              matlab.ui.control.CheckBox
        greenUIAxes                    matlab.ui.control.UIAxes
        redUIAxes                      matlab.ui.control.UIAxes
        ratioUIAxes                    matlab.ui.control.UIAxes
        noiseUIAxes                    matlab.ui.control.UIAxes
        histoUIAxes                    matlab.ui.control.UIAxes
        InfoPanel                      matlab.ui.container.Panel
        ZoomEditFieldLabel             matlab.ui.control.Label
        ZoomEditField                  matlab.ui.control.NumericEditField
        DwelltimeusEditFieldLabel      matlab.ui.control.Label
        DwelltimeusEditField           matlab.ui.control.NumericEditField
        ImgsizeEditFieldLabel          matlab.ui.control.Label
        ImgsizeEditField               matlab.ui.control.EditField
        VisualizePanel                 matlab.ui.container.Panel
        WavelengthLabel                matlab.ui.control.Label
        WavelengthSpinner              matlab.ui.control.Spinner
        CalcPanel                      matlab.ui.container.Panel
        ButtonGroup                    matlab.ui.container.ButtonGroup
        RGButton                       matlab.ui.control.RadioButton
        GRButton                       matlab.ui.control.RadioButton
        ReprocessButton                matlab.ui.control.Button
        MedianfilterpxlEditFieldLabel  matlab.ui.control.Label
        MedianfilterpxlEditField       matlab.ui.control.NumericEditField
        MedianfilterforlowpasspxlEditFieldLabel  matlab.ui.control.Label
        MedianfilterforlowpasspxlEditField  matlab.ui.control.NumericEditField
        medFiltCheckBox                matlab.ui.control.CheckBox
        medFilt_LP_CheckBox            matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        data % Description
        mainfolder
    end
    
    methods (Access = public)
        
        function updateGE(app)
            % get the selected wavelength
            wl=app.WavelengthSpinner.Value;
            % display green ch
            imagesc(app.greenUIAxes,app.data(wl).data_flat(:,:,1))
            set(app.greenUIAxes,'Clim',quantile(app.data(wl).data_flat(:,:,1),[2.5 97.5]./100,'all'),...
                'XLim',[1 size(app.data(wl).data_flat,2)],'Ylim',[1 size(app.data(wl).data_flat,1)])
            % display red ch
            imagesc(app.redUIAxes,app.data(wl).data_flat(:,:,2))
            set(app.redUIAxes,'Clim',quantile(app.data(wl).data_flat(:,:,2),[2.5 97.5]./100,'all'),...
                'XLim',[1 size(app.data(wl).data_flat,2)],'Ylim',[1 size(app.data(wl).data_flat,1)])
            % display ratio
            imagesc(app.ratioUIAxes,app.data(wl).data_ratio)
            set(app.ratioUIAxes,'Clim',quantile(app.data(wl).data_ratio(:,:,1),[2.5 97.5]./100,'all'),...
                'XLim',[1 size(app.data(wl).data_ratio,2)],'Ylim',[1 size(app.data(wl).data_ratio,1)])
            % display noise
            imagesc(app.noiseUIAxes,app.data(wl).noise)
            set(app.noiseUIAxes,'Clim',quantile(app.data(wl).noise(:,:,1),[2.5 97.5]./100,'all'),...
                'XLim',[1 size(app.data(wl).noise,2)],'Ylim',[1 size(app.data(wl).noise,1)])
            % display histo
            cla(app.histoUIAxes)
            yyaxis(app.histoUIAxes,"left")
            histogram(app.histoUIAxes,app.data(wl).noise(20:end-20,20:end-20),...
                linspace(app.data(wl).r6g_ratio-0.2,app.data(wl).r6g_ratio+0.2,128))
            yyaxis(app.histoUIAxes,"right")
            h = fspecial('disk',16); % a round cell of radius = 16 pxl (at zoom2 it means ~20um dimeter).
            histogram(app.histoUIAxes,imfilter(app.data(wl).noise(20:end-20,20:end-20), h, 'replicate'),...
                linspace(app.data(wl).r6g_ratio-0.2,app.data(wl).r6g_ratio+0.2,128))
            legend(app.histoUIAxes,'full image','average of ~314 pxl^2')
            
            
            if app.YlogscaleCheckBox.Value
                set(app.histoUIAxes,'yscale','log')
            else
                set(app.histoUIAxes,'yscale','linear')
            end
            
            % info
            app.ZoomEditField.Value = app.data(wl).zoom;
            app.DwelltimeusEditField.Value = app.data(wl).dwell;
            app.ImgsizeEditField.Value = sprintf('%i x %i',app.data(wl).img_size);
            
            % string descriptor
            if app.RGButton.Value
                app.nm000framesRGratio0000Label.Text = ...
                    sprintf('%s, %i frames, R/G ratio = %.3f',...
                    app.data(wl).wl,app.data(wl).n_frames,app.data(wl).r6g_ratio);
            else
                app.nm000framesRGratio0000Label.Text = ...
                    sprintf('%s, %i frames, G/R ratio = %.3f',...
                    app.data(wl).wl,app.data(wl).n_frames,app.data(wl).r6g_ratio);
            end
        end
        
        function process(app)
            size_medFilt=app.MedianfilterpxlEditField.Value;
            for i=1:numel(app.data)
                % median projection of green and red channel
                app.data(i).data_medFilt=zeros(size(app.data(i).data_medProj));
                % median filter of the median projections
                n_ch = size(app.data(i).data_raw,3); % make sure again that the number of channels is 2
                if app.medFiltCheckBox.Value
                    for i_ch=1:n_ch
                        app.data(i).data_medFilt(:,:,i_ch) = medfilt2(app.data(i).data_medProj(:,:,i_ch),[1 1].*size_medFilt);
                    end
                else
                    app.data(i).data_medFilt = app.data(i).data_medProj;
                end
                % mean fluorescence of the filtered median projection
                app.data(i).mean_fluo = mean(app.data(i).data_medFilt(20:end-20,20:end-20,:),[1 2],'omitnan');
                % obtain the flat: normalize the median projection so that
                % it has mean=1.
                app.data(i).data_flat = app.data(i).data_medFilt ./ app.data(i).mean_fluo; % ch1 and ch2 median projections normalized to mean=1
                % compute the flat ratio as an image and as a single value
                if app.RGButton.Value % Red over Green
                    app.data(i).data_ratio = app.data(i).data_medFilt(:,:,2) ./ app.data(i).data_medFilt(:,:,1); % pixel-wise R/G ratio
                    app.data(i).r6g_ratio = app.data(i).mean_fluo(:,:,2) ./ app.data(i).mean_fluo(:,:,1); % average R/G ratio (proportional to PMTs relative efficiency)
                else  % Green over Red
                    app.data(i).data_ratio = app.data(i).data_medFilt(:,:,1) ./ app.data(i).data_medFilt(:,:,2); % pixel-wise R/G ratio
                    app.data(i).r6g_ratio = app.data(i).mean_fluo(:,:,1) ./ app.data(i).mean_fluo(:,:,2); % average R/G ratio (proportional to PMTs relative efficiency)
                end
                % detrend the ratio image to get the "noise" level of the
                % flat ratio
                size_medFiltLowpass=app.MedianfilterforlowpasspxlEditField.Value;
                if app.medFilt_LP_CheckBox.Value
                    app.data(i).lowpass = medfilt2(app.data(i).data_ratio,[1 1].*size_medFiltLowpass);
                else
                    app.data(i).lowpass = app.data(i).r6g_ratio;
                end
                app.data(i).noise = app.data(i).data_ratio - app.data(i).lowpass + app.data(i).r6g_ratio;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % add folder with functions
            this_file = mfilename('fullpath');
            sep=regexp(this_file,filesep);
            this_folder = this_file(1:sep(end)-1);
            addpath([this_folder filesep 'preprocess_utilities'])
            
            app.mainfolder= [pwd filesep];
            
            % initialize green axes
            app.greenUIAxes.PositionConstraint = 'innerposition';
            app.greenUIAxes.Colormap = [zeros(4096,1),linspace(0,1,4096)',zeros(4096,1)];
            pos0=app.greenUIAxes.InnerPosition;
            colorbar(app.greenUIAxes)
            app.greenUIAxes.InnerPosition=pos0;
            % initialize red axes
            app.redUIAxes.PositionConstraint = 'innerposition';
            app.redUIAxes.Colormap = [linspace(0,1,4096)',zeros(4096,1),zeros(4096,1)];
            pos0=app.redUIAxes.InnerPosition;
            colorbar(app.redUIAxes)
            app.redUIAxes.InnerPosition=pos0;
            % initialize ratio axes
            app.ratioUIAxes.PositionConstraint = 'innerposition';
            app.ratioUIAxes.Colormap = turbo;
            pos0=app.ratioUIAxes.InnerPosition;
            colorbar(app.ratioUIAxes)
            app.ratioUIAxes.InnerPosition=pos0;
            % initialize noise axes
            app.noiseUIAxes.PositionConstraint = 'innerposition';
            app.noiseUIAxes.Colormap = turbo;
            pos0=app.noiseUIAxes.InnerPosition;
            colorbar(app.noiseUIAxes)
            app.noiseUIAxes.InnerPosition=pos0;
        end

        % Button pushed function: OpenandprocessButton
        function OpenandprocessButtonPushed(app, event)
            % load images
            folders=uigetmultidir(app.mainfolder);
            figure(app.FlatFieldAnalyzerUIFigure)
            if isempty(folders)
                return
            else
                i_sep=regexp(folders{1},filesep);
                app.mainfolder=folders{1}(1:i_sep(end)-1);
            end
            n_wl=numel(folders);
            
            % get wavelength names
            wl_names = regexp(folders,'\d*nm',"match");
            wl_names = cellfun(@(x)(x{1}),wl_names,'UniformOutput',false);
            % read dark value
            dark = [app.Darkch1EditField.Value, app.Darkch1EditField.Value];
            
            % import and process
            empt=cell(n_wl,1);
            app.data=struct('folder',empt,'wl',empt,'data_raw',empt,'data_noDark',empt,...
                'data_medProj',empt,'data_medFilt',empt,'data_flat',empt,'data_ratio',empt,'r6g_ratio',empt,...
                'n_frames',empt,'dwell',empt,'zoom',empt,'img_size',empt,'mean_fluo',empt);
            for i=1:n_wl
                fprintf('Importing wavelength %i/%i (%s)...\n',i,n_wl,wl_names{i})
                app.data(i).wl      = wl_names{i};
                app.data(i).folder  = folders{i};
                [app.data(i).data_raw,metadata]=...
                                import_PrairieTimeSequence(app.data(i).folder);
                app.data(i).n_frames= metadata.frames;
                app.data(i).dwell   = metadata.dwellTime;
                app.data(i).zoom    = metadata.zoom;
                
                
                % remove 1st frame
                if app.Remove1stframeCheckBox.Value
                    app.data(i).data_raw(:,:,:,1)=[];
                end
                
                % size
                [app.data(i).img_size(1),... %y
                    app.data(i).img_size(2),... %x
                    n_ch,app.data(i).n_frames] = size(app.data(i).data_raw);
                assert(n_ch==2,'Exactly 2 channels are needed for flat field processing')
                
                % set saturating pixels to NaN
                app.data(i).data_raw(app.data(i).data_raw>=4095)=nan;
                
                % dark subtraction
                app.data(i).data_noDark=double(app.data(i).data_raw)-reshape(dark,1,1,2);
                
                % median proj 
                app.data(i).data_medProj = median(app.data(i).data_noDark,4,'omitnan');
            end
            disp('data loaded')
            
            app.process
            disp('processed')
            
            % return warnings in case of mismatches of important parameters
            if any(app.data(1).dwell~=[app.data(:).dwell]) % dwell time
                warning('Dwell time mismatch between wavelengths')
            end
            if any(app.data(1).zoom~=[app.data(:).zoom]) % zoom
                warning('Zoom mismatch between wavelengths')
            end
            if any(cat(1,app.data(:).img_size)-app.data(1).img_size,'all') % image size
                warning('Zoom mismatch between wavelengths')
            end
            
            % reset spinner to 1
            app.WavelengthSpinner.Value = 1;
            if n_wl>1
                app.WavelengthSpinner.Limits = [1 n_wl];
                app.WavelengthSpinner.Enable = 'on';
            else
                app.WavelengthSpinner.Limits = [1 Inf];
                app.WavelengthSpinner.Enable = 'off';
            end
            
            % update graphic elements
            app.updateGE
        end

        % Value changed function: WavelengthSpinner
        function WavelengthSpinnerValueChanged(app, event)
            app.updateGE
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Save Images
            % prepare Tiff object tags structures
            tagstruct.Photometric=Tiff.Photometric.MinIsBlack;
            tagstruct.Compression=Tiff.Compression.PackBits;
            tagstruct.BitsPerSample=32;
            tagstruct.SamplesPerPixel=1;
            tagstruct.SubFileType=2;
            tagstruct.SampleFormat=Tiff.SampleFormat.IEEEFP;
%             tagstruct.ImageLength=n_y;
%             tagstruct.ImageWidth=n_x;
            tagstruct.PlanarConfiguration=Tiff.PlanarConfiguration.Chunky;
            
            outDir=app.mainfolder;
            for i_wl=1:numel(app.data)
                filename = sprintf('Flat_%s_%ipx_Z%i.tif',app.data(i_wl).wl,app.data(i_wl).img_size(1),app.data(i_wl).zoom);
                t = Tiff([outDir filesep filename],'w');
                tagstruct.ImageLength=app.data(i_wl).img_size(1);
                tagstruct.ImageWidth=app.data(i_wl).img_size(2);
                tagstruct.TileLength=tagstruct.ImageLength;
                tagstruct.TileWidth=tagstruct.ImageWidth;
                setTag(t,tagstruct)
                t.write(single(app.data(i_wl).data_flat(:,:,1))); % ch1
                writeDirectory(t);
                setTag(t,tagstruct)
                t.write(single(app.data(i_wl).data_flat(:,:,2))); % ch2
            end
            close(t)
            disp('Flat field(s) saved')
            
            % Save log file
            id=fopen([outDir filesep 'flatAnalyzerOut.txt'],'w');
            header='File name,green avg,red avg,r/g,g/r\n';
            fprintf(id,header);
            if app.RGButton.Value
                expo=[1 -1]; % fist r/g (app.data.r6g_ratio), then its reciprocal
            else
                expo=[-1 1]; % first the reciprocal, then g/r (app.data.r6g_ratio)
            end
            for i_wl=1:numel(app.data)
                fprintf(id,'Flat_%s_%ipx_Z%i,%.4f,%.4f,%.4f,%.4f\n',...
                    app.data(i_wl).wl,app.data(i_wl).img_size(1),app.data(i_wl).zoom,... % file name
                    app.data(i_wl).mean_fluo,... % ch1 and ch2 fluorescence
                    app.data(i_wl).r6g_ratio.^expo(1),... % r/g ratio
                    app.data(i_wl).r6g_ratio.^expo(2)); % g/r ratio
            end
            fclose(id);
        end

        % Callback function
        function EvaluateGRnoiseButtonPushed(app, event)
%             medFilt=app.MedianfilterpxlEditField.Value;
%             x=medfilt2(app.data(1).data_med(:,:,1),[medFilt medFilt]) ./ ...
%                 medfilt2(app.data(1).data_med(:,:,2),[medFilt medFilt]); % g/r
%             lowpass=medfilt2(x,[1 1].*app.MedianfilterforlowpasspxlEditField.Value);
%             ratio_mean=mean(lowpass,'all','omitnan');
%             noise=x-lowpass+ratio_mean;
%             
%             figure('Name',sprintf('Medfilt = %i',medFilt))
%             subplot(1,3,1)
%             imagesc(x)
%             colorbar
%             set(gca,'clim',quantile(x,[0.025 0.975],'all'))
%             title('Flat ratio (g/r)')
%             
%             subplot(1,3,2)
%             imagesc(noise)
%             colorbar
%             colormap(turbo)
%             set(gca,'clim',quantile(noise,[0.025 0.975],'all'))
%             title('Flat detrended (noise)')
%             
%             subplot(1,3,3)
%             histogram(noise(20:end-20,20:end-20),...
%                 linspace(ratio_mean-0.4,ratio_mean+0.4,128))
%             set(gca,'yscale','log')
%             title('Flat noise distrib')

        end

        % Value changed function: YlogscaleCheckBox
        function YlogscaleCheckBoxValueChanged(app, event)
            value = app.YlogscaleCheckBox.Value;
            if value
                yyaxis(app.histoUIAxes,'left')
                set(app.histoUIAxes,'YScale','log')
                yyaxis(app.histoUIAxes,'right')
                set(app.histoUIAxes,'YScale','log')
            else
                yyaxis(app.histoUIAxes,'left')
                set(app.histoUIAxes,'YScale','linear')
                yyaxis(app.histoUIAxes,'right')
                set(app.histoUIAxes,'YScale','linear')
            end
        end

        % Button pushed function: ReprocessButton
        function ReprocessButtonPushed(app, event)
            app.process
            app.updateGE
        end

        % Button pushed function: ReadDarkFileButton
        function ReaddarkButtonPushed(app, event)
            dark_folder = uigetdir(app.mainfolder);
            figure(app.FlatFieldAnalyzerUIFigure)
            if dark_folder==0
                return
            end
            disp('Reading dark...')
            dark = readDark(dark_folder);
            app.Darkch1EditField.Value=dark(1);
            app.Darkch2EditField.Value=dark(2);
            disp('dark values updated')
            slash=regexp(dark_folder,filesep);
            app.mainfolder=dark_folder(1:slash(end)-1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FlatFieldAnalyzerUIFigure = uifigure('Visible', 'off');
            app.FlatFieldAnalyzerUIFigure.Name = 'FlatFieldAnalyzer';
            app.LoadPanel = uipanel(app.FlatFieldAnalyzerUIFigure);
            app.LoadPanel.Title = 'Load';
            app.OpenandprocessButton = uibutton(app.LoadPanel, 'push');
            app.OpenandprocessButton.ButtonPushedFcn = createCallbackFcn(app, @OpenandprocessButtonPushed, true);
            app.OpenandprocessButton.Text = 'Open and process';
            app.Remove1stframeCheckBox = uicheckbox(app.LoadPanel);
            app.Remove1stframeCheckBox.Text = 'Remove 1st frame';
            app.Darkch1EditFieldLabel = uilabel(app.LoadPanel);
            app.Darkch1EditFieldLabel.Text = 'Dark ch1';
            app.Darkch1EditField = uieditfield(app.LoadPanel, 'numeric');
            app.Darkch1EditField.Value = 100;
            app.Darkch2EditFieldLabel = uilabel(app.LoadPanel);
            app.Darkch2EditFieldLabel.Text = 'Dark ch2';
            app.Darkch2EditField = uieditfield(app.LoadPanel, 'numeric');
            app.Darkch2EditField.Value = 100;
            app.SelectthefolderscontainingLabel = uilabel(app.LoadPanel);
            app.SelectthefolderscontainingLabel.Text = 'Select the folders containing flat field images. Select multiple wavelengths to process all of them at once.';
            app.ReadDarkFileButton = uibutton(app.LoadPanel, 'push');
            app.ReadDarkFileButton.ButtonPushedFcn = createCallbackFcn(app, @ReaddarkButtonPushed, true);
            app.ReadDarkFileButton.Text = 'Read Dark File';
            app.Panel = uipanel(app.FlatFieldAnalyzerUIFigure);
            app.SaveButton = uibutton(app.Panel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.nm000framesRGratio0000Label = uilabel(app.Panel);
            app.nm000framesRGratio0000Label.Text = '000nm, 000 frames, R/G ratio = 0.000';
            app.YlogscaleCheckBox = uicheckbox(app.Panel);
            app.YlogscaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @YlogscaleCheckBoxValueChanged, true);
            app.YlogscaleCheckBox.Text = 'Y log scale';
            app.greenUIAxes = uiaxes(app.Panel);
            title(app.greenUIAxes, 'G')
            app.greenUIAxes.XTick = [];
            app.greenUIAxes.YTick = [];
            app.redUIAxes = uiaxes(app.Panel);
            title(app.redUIAxes, 'R')
            app.redUIAxes.XTick = [];
            app.redUIAxes.YTick = [];
            app.ratioUIAxes = uiaxes(app.Panel);
            title(app.ratioUIAxes, 'Ratio')
            app.ratioUIAxes.XTick = [];
            app.ratioUIAxes.YTick = [];
            app.noiseUIAxes = uiaxes(app.Panel);
            title(app.noiseUIAxes, 'Ratio noise')
            app.noiseUIAxes.XTick = [];
            app.noiseUIAxes.YTick = [];
            app.histoUIAxes = uiaxes(app.Panel);
            title(app.histoUIAxes, 'Noise distribution')
            xlabel(app.histoUIAxes, 'Ratio')
            ylabel(app.histoUIAxes, 'Counts')
            app.InfoPanel = uipanel(app.FlatFieldAnalyzerUIFigure);
            app.InfoPanel.Title = 'Info';
            app.ZoomEditFieldLabel = uilabel(app.InfoPanel);
            app.ZoomEditFieldLabel.Text = 'Zoom';
            app.ZoomEditField = uieditfield(app.InfoPanel, 'numeric');
            app.ZoomEditField.Value = 1;
            app.DwelltimeusEditFieldLabel = uilabel(app.InfoPanel);
            app.DwelltimeusEditFieldLabel.Text = 'Dwell time (us)';
            app.DwelltimeusEditField = uieditfield(app.InfoPanel, 'numeric');
            app.ImgsizeEditFieldLabel = uilabel(app.InfoPanel);
            app.ImgsizeEditFieldLabel.Text = 'Img size';
            app.ImgsizeEditField = uieditfield(app.InfoPanel, 'text');
            app.ImgsizeEditField.Value = '512 x 512';
            app.VisualizePanel = uipanel(app.FlatFieldAnalyzerUIFigure);
            app.VisualizePanel.Title = 'Visualize';
            app.WavelengthLabel = uilabel(app.VisualizePanel);
            app.WavelengthLabel.Text = 'Wavelength ';
            app.WavelengthSpinner = uispinner(app.VisualizePanel);
            app.WavelengthSpinner.ValueChangedFcn = createCallbackFcn(app, @WavelengthSpinnerValueChanged, true);
            app.WavelengthSpinner.Value = 1;
            app.CalcPanel = uipanel(app.FlatFieldAnalyzerUIFigure);
            app.CalcPanel.Title = 'Calc';
            app.ButtonGroup = uibuttongroup(app.CalcPanel);
            app.RGButton = uiradiobutton(app.ButtonGroup);
            app.RGButton.Text = 'R/G';
            app.RGButton.Value = true;
            app.GRButton = uiradiobutton(app.ButtonGroup);
            app.GRButton.Text = 'G/R';
            app.ReprocessButton = uibutton(app.CalcPanel, 'push');
            app.ReprocessButton.ButtonPushedFcn = createCallbackFcn(app, @ReprocessButtonPushed, true);
            app.ReprocessButton.Text = 'Re-process';
            app.MedianfilterpxlEditFieldLabel = uilabel(app.CalcPanel);
            app.MedianfilterpxlEditFieldLabel.Text = 'Median filter (pxl):';
            app.MedianfilterpxlEditField = uieditfield(app.CalcPanel, 'numeric');
            app.MedianfilterpxlEditField.Tooltip = {'This applies to G and R channels before projection and normalization, thus it affects the output.'};
            app.MedianfilterpxlEditField.Value = 1;
            app.MedianfilterforlowpasspxlEditFieldLabel = uilabel(app.CalcPanel);
            app.MedianfilterforlowpasspxlEditFieldLabel.Text = {'Median filter for'; 'lowpass (pxl):'};
            app.MedianfilterforlowpasspxlEditField = uieditfield(app.CalcPanel, 'numeric');
            app.MedianfilterforlowpasspxlEditField.Tooltip = {'Compute a low-pass filtered version of the "ratio" map and subtract it from itself to obtain the "ratio noise" map. This DOES NOT affect the output'};
            app.MedianfilterforlowpasspxlEditField.Value = 13;
            app.medFiltCheckBox = uicheckbox(app.CalcPanel);
            app.medFiltCheckBox.Text = '';
            app.medFilt_LP_CheckBox = uicheckbox(app.CalcPanel);
            app.medFilt_LP_CheckBox.Text = '';
            app.medFilt_LP_CheckBox.Value = true;
            % Show the figure after all components are created
            app.FlatFieldAnalyzerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FlatFieldAnalyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FlatFieldAnalyzerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FlatFieldAnalyzerUIFigure)
        end
    end
end