classdef LUTmanager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LUTmanagerFig             matlab.ui.Figure
        ButtonGroup               matlab.ui.container.ButtonGroup
        StandardButton            matlab.ui.control.RadioButton
        SymmetricButton           matlab.ui.control.RadioButton
        GammaEditFieldLabel       matlab.ui.control.Label
        GammaEditField            matlab.ui.control.NumericEditField
        ShowLUTButton             matlab.ui.control.Button
        Panel                     matlab.ui.container.Panel
        MinEditFieldLabel         matlab.ui.control.Label
        MinEditField              matlab.ui.control.NumericEditField
        MaxEditFieldLabel         matlab.ui.control.Label
        MaxEditField              matlab.ui.control.NumericEditField
        ResetButton               matlab.ui.control.Button
        refreshAxesContentButton  matlab.ui.control.Button
        refreshAxesListButton     matlab.ui.control.Button
        SelectedaxesLabel         matlab.ui.control.Label
        axesDropDown              matlab.ui.control.DropDown
        LUTLabel                  matlab.ui.control.Label
        LUTDropDown               matlab.ui.control.DropDown
        BinsEditFieldLabel        matlab.ui.control.Label
        BinsEditField             matlab.ui.control.NumericEditField
        fromEditFieldLabel        matlab.ui.control.Label
        fromEditField             matlab.ui.control.EditField
        mainAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        parentApp
%         parentFig
        parentAxes % Description
        targetAxesListener % Description
        axesParentApp % Description
    end
    
    methods (Access = public)
        
        function plotFcn(app)
            selAxes = strcmp( app.axesDropDown.Items, app.axesDropDown.Value);
            if ~isempty(app.parentAxes(selAxes).Children)
                % among all the axes children, find the Image (ROIs are
                % axes children too)
                % selImg = app.parentAxes(selAxes).Children.CData;
                allChildren = findobj(app.parentAxes(selAxes),'Type','Image');
                % ROIs MIGHT BE image subtype(they were in 2019a) but can be distinguished:
                imgIdx = strcmp( arrayfun(@(x) x.Type, allChildren,'UniformOutput',false),'image');
                actualImg = allChildren(imgIdx); % arrayfun returns a cell array with
                
                if ~isempty(actualImg)
                    % is there a single visible ROI?
                    roiIdx = arrayfun(@(x) ~isempty(regexp(x.Type,'images.roi.')), app.parentAxes(selAxes).Children);
                    visibleRois = arrayfun(@(x) x.Visible == 'on', app.parentAxes(selAxes).Children(roiIdx));
                    if sum(visibleRois) == 1
                        % only one selected ROI: show its distribution
                        selImg = actualImg.CData( getSelectedPixels(actualImg, app.parentAxes(selAxes).Children(visibleRois)) ); % getSelectedPixels
                                                % is a custom function that
                                                % returns a linear logical
                                                % indexing of the image pixels
                                                % that belong to the ROI.
                    else
                        % show the distribution of the entire image
                        selImg = actualImg.CData;
                    end
                    
                    % plot the histogram
                    cla(app.mainAxes)
                    app.mainAxes.YLimMode = 'auto';
                    app.mainAxes.XLim = [min(selImg,[],'all'), max(selImg,[],'all')];
%                     histogram(app.mainAxes,selImg,app.BinsEditField.Value,'EdgeColor',[0.00,0.45,0.74],'FaceColor',[0.00,0.45,0.74])
                    histogram(app.mainAxes,selImg,'EdgeColor',[0.00,0.45,0.74],'FaceColor',[0.00,0.45,0.74])
                    drawnow
                    app.mainAxes.YLimMode = 'manual';
                    app.mainAxes.YLim(1)=0;
                    yLimits = app.mainAxes.YLim;
                    % draw the mininum
                    if app.MinEditField.Value == -Inf
                        minX = min(selImg,[],'all');
                        if ~isnan(minX)
                            app.MinEditField.Value = minX;
                        else
                            app.MinEditField.Value = 0;
                        end
                    end
                    line(app.mainAxes, [app.MinEditField.Value, app.MinEditField.Value], yLimits,'Color','k','LineStyle','-','LineWidth',1)
                    % draw the maximum
                    if app.MaxEditField.Value == Inf
                        maxX = max(selImg,[],'all');
                        if ~isnan(maxX)
                            app.MaxEditField.Value = maxX;
                        else
                            app.MaxEditField.Value = 1;
                        end
                    end
                    line(app.mainAxes, [app.MaxEditField.Value, app.MaxEditField.Value], yLimits,'Color','k','LineStyle','-','LineWidth',1)
                    fun_x = linspace(app.MinEditField.Value,app.MaxEditField.Value,1000);
                    % draw the gamma function
                    plot(app.mainAxes, fun_x,  ((linspace(0,1,1000)).^(app.GammaEditField.Value))*yLimits(2),'Color','k','LineStyle','-','LineWidth',1)
                    app.mainAxes.CLim = [app.MinEditField.Value, app.MaxEditField.Value];
                    eval(['mycmap = ' app.LUTDropDown.Value ';'])
                    cbar = colorbar(app.mainAxes,"southoutside");
                    cbar.Ticks = [];
                    %app.mainAxes.Children = circshift(app.mainAxes.Children,1);
                else
                    cla(app.mainAxes)
                end
            else
                cla(app.mainAxes)
            end
        end
        
        function refreshHisto(app)
            axesDropDownValueChanged(app,[])
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp)
            app.parentApp = parentApp;
%             app.parentFig = parentFig;
            app.LUTDropDown.Items = colormapList();
            app.LUTDropDown.Value = app.LUTDropDown.Items(8);
                        
            refreshAxesListButtonPushed(app,[])
        end

        % Button pushed function: ShowLUTButton
        function ShowLUTButtonPushed(app, event)
            figure
            ax = axes;
            selMap = eval(app.LUTDropDown.Value);
            rgbplot(selMap)
            title(ax,app.LUTDropDown.Value)
            xlim([ax.Children(1).XData(1) ax.Children(1).XData(end)])
            hold on
            colormap(ax,selMap)
            colorbar(ax,'Ticks',[],'Location','southoutside')
        end

        % Callback function: axesDropDown, refreshAxesContentButton
        function axesDropDownValueChanged(app, event)
            selAxes = strcmp( app.axesDropDown.Items, app.axesDropDown.Value);
            app.MinEditField.Value = app.parentAxes(selAxes).CLim(1);
            app.MaxEditField.Value = app.parentAxes(selAxes).CLim(2);
            
            % update the axes parentApp
            app.fromEditField.Value = app.axesParentApp{selAxes};
            % focus on that App, and then again to the LUTmanager.
            figure(ancestor(app.parentAxes(selAxes),'figure'));
            figure(app.LUTmanagerFig);
            
            % add a listener for the Children property of the target axes.
            % If the plot is updated or a ROI is drawn, also update the
            % histogram.
%             app.targetAxesListener = event.proplistener(app.parentAxes(selAxes),...
%                 findprop(matlab.ui.control.UIAxes,'Children'),'PostSet',@(src,ev) plotFcn(app));
            % I realized it's impossible: the property 'Children' of uiaxes
            % is not 'observable'! As a workaround, I'm going to add a
            % button for manually refresh the histogram
            
            plotFcn(app)
        end

        % Value changed function: BinsEditField
        function BinsEditFieldValueChanged(app, event)
            plotFcn(app);            
        end

        % Value changed function: MinEditField
        function MinEditFieldValueChanged(app, event)
            % quality control first
            if app.MinEditField.Value >= app.MaxEditField.Value
                app.MinEditField.Value = app.MaxEditField.Value-1;
            end
            
            plotFcn(app);
            % change CLim to the target axes
            selAxes = strcmp( app.axesDropDown.Items, app.axesDropDown.Value);
            app.parentAxes(selAxes).CLim(1) = app.MinEditField.Value;
        end

        % Value changed function: MaxEditField
        function MaxEditFieldValueChanged(app, event)
            % quality control first
            if app.MaxEditField.Value <= app.MinEditField.Value
                app.MaxEditField.Value = app.MinEditField.Value+1;
            end
            
            plotFcn(app);
            % change CLim to the target axes
            selAxes = strcmp( app.axesDropDown.Items, app.axesDropDown.Value);
            app.parentAxes(selAxes).CLim(2) = app.MaxEditField.Value;
        end

        % Value changed function: GammaEditField
        function GammaEditFieldValueChanged(app, event)
            gamma = app.GammaEditField.Value;
            % check for gamma and turn it into beta, a parameter for the
            % 'brighten' function. The relation between beta and gamma is
            % the following:
            % gamma = 1-beta when 0<beta<=1
            % gamma = 1/(1+beta) when -1<=beta<=0
            if (gamma>=0) && (gamma<1)
                beta = 1-gamma;
            elseif gamma >= 1
                beta = 1/gamma -1;
            else 
                error('Gamma must be >= 0')
            end
            
            plotFcn(app);
            % apply this gamma to the target axes
            selAxes = strcmp( app.axesDropDown.Items, app.axesDropDown.Value);
            newMap = eval(app.LUTDropDown.Value);
            app.parentAxes(selAxes).Colormap = brighten(newMap,beta);
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.MinEditField.Value = -Inf;
            app.MaxEditField.Value = Inf;
            plotFcn(app);
        end

        % Value changed function: LUTDropDown
        function LUTDropDownValueChanged(app, event)
            gamma = app.GammaEditField.Value;    
            if gamma >= 1
                beta = 1-gamma;
            elseif (gamma>=0) && (gamma<1)
                beta = 1/gamma -1;
            else 
                error('Gamma must be >= 0')
            end
            
            plotFcn(app);
            % apply this gamma to the target axes
            selAxes = strcmp( app.axesDropDown.Items, app.axesDropDown.Value);
            newMap = eval(app.LUTDropDown.Value);
            app.parentAxes(selAxes).Colormap = brighten(newMap,beta);
        end

        % Button pushed function: refreshAxesListButton
        function refreshAxesListButtonPushed(app, event)
%             app.parentAxes = findobj(app.parentFig,'Type','axes');
            
            [app.parentAxes,app.axesParentApp,~] = lookForAxes(app,[],[],[]);
            

            app.axesDropDown.Items = {app.parentAxes(:).Tag};
            app.axesDropDown.Value = app.axesDropDown.Items{1};
            app.fromEditField.Value = app.axesParentApp{1};
            app.MinEditField.Value = -Inf;
            app.MaxEditField.Value = Inf;
            app.GammaEditField.Value = 1;
            app.StandardButton.Value = 1;
            
            
            plotFcn(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LUTmanagerFig = uifigure('Visible', 'off');
            app.LUTmanagerFig.Name = 'LUT manager';
            app.ButtonGroup = uibuttongroup(app.LUTmanagerFig);
            app.StandardButton = uiradiobutton(app.ButtonGroup);
            app.StandardButton.Text = 'Standard';
            app.StandardButton.Value = true;
            app.SymmetricButton = uiradiobutton(app.ButtonGroup);
            app.SymmetricButton.Enable = 'off';
            app.SymmetricButton.Text = 'Symmetric';
            app.GammaEditFieldLabel = uilabel(app.ButtonGroup);
            app.GammaEditFieldLabel.Text = 'Gamma';
            app.GammaEditField = uieditfield(app.ButtonGroup, 'numeric');
            app.GammaEditField.ValueChangedFcn = createCallbackFcn(app, @GammaEditFieldValueChanged, true);
            app.GammaEditField.Value = 1;
            app.ShowLUTButton = uibutton(app.LUTmanagerFig, 'push');
            app.ShowLUTButton.ButtonPushedFcn = createCallbackFcn(app, @ShowLUTButtonPushed, true);
            app.ShowLUTButton.Text = 'Show LUT';
            app.Panel = uipanel(app.LUTmanagerFig);
            app.MinEditFieldLabel = uilabel(app.Panel);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.Panel, 'numeric');
            app.MinEditField.ValueChangedFcn = createCallbackFcn(app, @MinEditFieldValueChanged, true);
            app.MaxEditFieldLabel = uilabel(app.Panel);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.Panel, 'numeric');
            app.MaxEditField.ValueChangedFcn = createCallbackFcn(app, @MaxEditFieldValueChanged, true);
            app.MaxEditField.Value = 1;
            app.ResetButton = uibutton(app.Panel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.refreshAxesContentButton = uibutton(app.LUTmanagerFig, 'push');
            app.refreshAxesContentButton.ButtonPushedFcn = createCallbackFcn(app, @axesDropDownValueChanged, true);
            app.refreshAxesContentButton.Icon = 'Reset_24.png';
            app.refreshAxesContentButton.Tooltip = {'Refresh axes content'};
            app.refreshAxesContentButton.Text = '';
            app.refreshAxesListButton = uibutton(app.LUTmanagerFig, 'push');
            app.refreshAxesListButton.ButtonPushedFcn = createCallbackFcn(app, @refreshAxesListButtonPushed, true);
            app.refreshAxesListButton.Icon = 'Reset_24_mod.png';
            app.refreshAxesListButton.Tooltip = {'Refresh axes list'};
            app.refreshAxesListButton.Text = '';
            app.SelectedaxesLabel = uilabel(app.LUTmanagerFig);
            app.SelectedaxesLabel.Text = 'Selected axes:';
            app.axesDropDown = uidropdown(app.LUTmanagerFig);
            app.axesDropDown.Items = {'Axes1'};
            app.axesDropDown.ValueChangedFcn = createCallbackFcn(app, @axesDropDownValueChanged, true);
            app.axesDropDown.Value = 'Axes1';
            app.LUTLabel = uilabel(app.LUTmanagerFig);
            app.LUTLabel.Text = 'LUT:';
            app.LUTDropDown = uidropdown(app.LUTmanagerFig);
            app.LUTDropDown.Items = {};
            app.LUTDropDown.ValueChangedFcn = createCallbackFcn(app, @LUTDropDownValueChanged, true);
            app.LUTDropDown.Value = {};
            app.BinsEditFieldLabel = uilabel(app.LUTmanagerFig);
            app.BinsEditFieldLabel.Text = 'Bins';
            app.BinsEditField = uieditfield(app.LUTmanagerFig, 'numeric');
            app.BinsEditField.ValueChangedFcn = createCallbackFcn(app, @BinsEditFieldValueChanged, true);
            app.BinsEditField.Value = 1024;
            app.fromEditFieldLabel = uilabel(app.LUTmanagerFig);
            app.fromEditFieldLabel.Text = 'from:';
            app.fromEditField = uieditfield(app.LUTmanagerFig, 'text');
            app.fromEditField.Editable = 'off';
            app.mainAxes = uiaxes(app.LUTmanagerFig);
            title(app.mainAxes, 'Intensity distribution')
            app.mainAxes.Tag = 'LUTmanDistrAxes';
            % Show the figure after all components are created
            app.LUTmanagerFig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LUTmanager(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LUTmanagerFig)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LUTmanagerFig)
        end
    end
end