classdef ROImanager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ROImanagerFigure           matlab.ui.Figure
        ROIshapePanel              matlab.ui.container.Panel
        RectangleButton            matlab.ui.control.Button
        EllipseButton              matlab.ui.control.Button
        PolygonButton              matlab.ui.control.Button
        FreehandButton             matlab.ui.control.Button
        TabGroup                   matlab.ui.container.TabGroup
        ZaxisprofileTab            matlab.ui.container.Tab
        xToEditField               matlab.ui.control.NumericEditField
        xFromEditField             matlab.ui.control.NumericEditField
        yFromEditField             matlab.ui.control.NumericEditField
        yToEditField               matlab.ui.control.NumericEditField
        holdyButton                matlab.ui.control.StateButton
        holdxButton                matlab.ui.control.StateButton
        Showch1CheckBox            matlab.ui.control.CheckBox
        Showch2CheckBox            matlab.ui.control.CheckBox
        XResetButton               matlab.ui.control.Button
        XBackwardButton            matlab.ui.control.Button
        XForwardButton             matlab.ui.control.Button
        YResetButton               matlab.ui.control.Button
        ExportprofileButton        matlab.ui.control.Button
        zProfileAxes               matlab.ui.control.UIAxes
        MeasureTab                 matlab.ui.container.Tab
        measuresTable              matlab.ui.control.Table
        SaveButton                 matlab.ui.control.Button
        MeasureButton              matlab.ui.control.Button
        AllFramesCheckBox          matlab.ui.control.CheckBox
        SetMeasuresTab             matlab.ui.container.Tab
        MinimumCheckBox            matlab.ui.control.CheckBox
        MaximumCheckBox            matlab.ui.control.CheckBox
        MeanCheckBox               matlab.ui.control.CheckBox
        MedianCheckBox             matlab.ui.control.CheckBox
        StandarddeviationCheckBox  matlab.ui.control.CheckBox
        SumCheckBox                matlab.ui.control.CheckBox
        AreaCheckBox               matlab.ui.control.CheckBox
        saveRoiButton              matlab.ui.control.Button
        showAllButton              matlab.ui.control.StateButton
        TargetAxesButtonGroup      matlab.ui.container.ButtonGroup
        FreeButton                 matlab.ui.control.RadioButton
        SelectaxesButton           matlab.ui.control.RadioButton
        axesDropDown               matlab.ui.control.DropDown
        refreshAxesButton          matlab.ui.control.Button
        fromEditFieldLabel         matlab.ui.control.Label
        fromEditField              matlab.ui.control.EditField
        loadRoiButton              matlab.ui.control.Button
        StoreButton                matlab.ui.control.Button
        DiscardButton              matlab.ui.control.Button
        ROIlistListBoxLabel        matlab.ui.control.Label
        roiListBox                 matlab.ui.control.ListBox
        deleteRoiButton            matlab.ui.control.Button
        ROIlistContextMenu         matlab.ui.container.ContextMenu
        RenameMenu                 matlab.ui.container.Menu
        SelectAllMenu              matlab.ui.container.Menu
        DuplicateMenu              matlab.ui.container.Menu
        tableContextMenu           matlab.ui.container.ContextMenu
        CopyMenu                   matlab.ui.container.Menu
        DeleteMenu                 matlab.ui.container.Menu
    end

    
    properties (Access = public)
        newROI % The last ROI that has been drawn
        storedROI % structure with fields:
%               roi         % stored ROI object
%               class       % class of the ROI (i.e. rectangle, ellipse ecc...)
%               posBackup   % ROI Position, for backup porposes. It is a
%                                   structure whose fields depend on the
%                                   ROI class.
%               listeners   % ROI associated listeners
        roiTable_idx % selected indices of the roi table
        parentApp % Handle to the parent app
        parentAxes % object array containing the parent figure uiaxes
        zProfileLine % handle to the line of the z-profile axes in the parent axes of newROI
        roiCounter % Description
        colTypes
        colNames
        completeTable
        temporaryListeners % it store the listeners associated to the temporary ROI
        axesParentApp % for each axes in app.axesDropDown.Item, it stores the name of the parent App
        
        zProfileTrials % lines of z-axis profile
        emptyListenerArray % useful because Matlab sucks, sometimes
        sourceListener % listens to current axes UserData
        metrics % cell array of function handles. inizialized in startupFcn
        measuresTableSelectedIndices % Description
        outDir % output directory
        inDir % input directory
    end
    
    methods (Access = public)
        
        function drawROI(app,flag)
            if app.SelectaxesButton.Value
                currAxes = app.parentAxes(strcmp( app.axesDropDown.Items, app.axesDropDown.Value));
            else
                set(groot,'ShowHiddenHandles',"on");
                allFigs = findobj(groot,'type','figure');
                if numel(allFigs)>1
                    % current figure is now ROImanager figure
                    tmpfig = figure('Position',[0 0 0 0]);
                    
                    % wait for another figure to be selected
                    disp('Select a Figure')
                    waitfor(groot,'CurrentFigure')
                    delete(tmpfig)
                    
                    % create a dimensionless axes which will become the current axes
                    tmpax = axes('Position',[0 0 0 0]);
                    
                    % and wait until the user cliks on one of the actual axes
                    disp('Select an Axes')
                    waitfor(gcf,'CurrentAxes')
                    currAxes = gca;
                    
                    % get rid of the fake axes
                    delete(tmpax)
                    clear tmpax
                else
                    disp('there is no figure other than ROImanager!')
%                     currAxes = [];
                    return
                end
%                 disp('now: axes') % debug purposes
%                 disp(getfield(getfield(currAxes),'Title'),String') % debug purposes
                % the current axes changed! update the source listener
                sourceListenerUpdate(app)
                % the current axes changed! reassign rois
                refreshROI(app) % questo andrebbe sostituito con un listener
                                % alla proprietà 'CurrenFigure' dell'oggetto "groot", che
                                % a sua volta attivi un listener alla proprietà
                                % 'CurrentAxes' della suddetta CurrentFigure
                                drawnow
            end
            if ~isempty(currAxes)
                % Delete the previous "temporary" ROI, if it exists.
                if ~isempty(app.newROI)
                    roiDeleting(app,app.newROI,[])
                end
                
                pause(0.1)
                % Draw the ROI
                switch flag
                    case 'Rectangle'
                        app.newROI = drawrectangle(currAxes);
                    case 'Ellipse'   
                        app.newROI = drawellipse(currAxes);
                    case 'Polygon'
                        app.newROI = drawpolygon(currAxes);
                    case 'Freehand'
                        app.newROI = drawfreehand(currAxes);
                end
                
                % Allow the possibility to save this ROI
                app.StoreButton.Enable = 'on';
                app.DiscardButton.Enable = 'on';
                
                % Create a listener so that, when the ROI is moved or reshaped,
                % the Z profile plot is updated. Store it in the UserData of the
                % ROI object itself.
                app.temporaryListeners = addlistener(app.newROI,'MovingROI',@(src,ev) zProfile(app,src,ev));
                % Similarly, create a listener to trigger a function when the
                % roi is deleted interactively by the user.
    %             app.newROI.UserData(2) = addlistener(app.newROI,'DeletingROI',@(src,ev) roiDeleting(app,src,ev));
                app.temporaryListeners(2) = addlistener(app.newROI,'DeletingROI',@(src,ev) roiDeleting(app,src,ev));
                % THE THIRD LISTENER IS NOW A PROOPERTY OF THE APP (gab, 2020-05-26)
                % Also associate to this ROI a listener to the UserData property
                % of its parent Axes. When the pointer changes, (i.e. the
                % source image stack changes), then refresh the ROI.
    %             tmp = app.newROI;
    %             app.newROI.UserData(3) = event.proplistener(app.newROI.Parent,findprop(matlab.ui.control.UIAxes,'UserData'),'PostSet',@(src,ev) refreshSource(app,tmp));
    %             app.temporaryListeners(3) = event.proplistener(app.newROI.Parent,findprop(matlab.ui.control.UIAxes,'UserData'),'PostSet',@(src,ev) refreshSource(app,tmp));
                
                % now the 3rd listener is a roimoved listener
                app.temporaryListeners(3) = addlistener(app.newROI,'ROIMoved',@(src,ev) roiMoved(app,src,ev));
                
                % Create a z axis profile plot.
                zProfile(app,app.newROI,[])
                
                % Deselect previously selected ROIs
                app.roiListBox.Value = {};
                
                % Enable the buttons to store or discard the current ROI
                app.StoreButton.Enable = 'on';
                app.DiscardButton.Enable = 'on';
                
                if (~app.showAllButton.Value) && (~isempty(app.storedROI))
    %                 set(app.storedROI(:),'Visible','off');
                    % questo le mette tutte invisibili. potrei invece chiamare
                    % il callback di showall, che vede quelle selezionate e
                    % mette invisibili le altre.
                    showAllButtonValueChanged(app, [])
                end
            end
        end
        
        function varargout=zProfile(app,ROI,ev)
            % plot and store the z profile
            % GAB: for this function to work, a pointer to the "source" stac must be saved in the
            % UserData property of the axes in which figures are shown.
            % Here it is an example on how to use pointers to matlab arrays
            %             mat = randn(10,20,30);
            %             ptr = libpointer('MATLAB array',mat); % define the pointer
            %             ax.UserData = ptr; % assign it to the user data
            %             c = get(ax.UserData); % fet the pointer
            %             d = c.Value; % now d=mat
            % 
            % Ev: event data. If this function is called when a ROI is
            %       created, then ev is empty. If this function is called when a
            %       ROI is moved or reshaped, it stores the event object.
%             tic
            varargout = {};
%             if strcmp(app.TabGroup.SelectedTab.Title,'Z axis profile') || isempty(ev)
                
                % Go on with the function only if the 'Z axis profile' tab
                % is selected. The only exception is when a ROI has just
                % been created. In this case we have to set up the plot,
                % such that it can be updated if 'Z axis profile'
                % will be selected in the future.
                
                src = ROI.Parent.UserData; % UserData must cointain a pointer to the source matrix
                if ~isempty(src)
                    % proceed only if there's a non-empty structure
                    
                    % retrieve stack or movie matrix
                    switch class(src.data)
                        case 'lib.pointer'
                            ptrData = get(src.data);
                            mat = ptrData.Value;
                        otherwise
                            mat = src.data;
                    end
                    sz = size(mat);
                    
                    % timepoints
                    if isfield(src,'x')
                        switch class(src.x)
                            case 'lib.pointer'
                                ptrX = get(src.x);
                                xPts = ptrX.Value;
                            otherwise
                                xPts = src.x;
                        end
                    else
                        xPts = 1:sz(end);
                    end
                    
                    % stimulus time, if provided
                    if isfield(src,'stimuli')
                        switch class(src.stimuli)
                            case 'lib.pointer'
                                ptrStim = get(src.stimuli); % must be a line, NOT a column
                                xStim = ptrStim.Value;
                            otherwise
                                xStim = src.stimuli;
                        end
                        % xStim must be a row
                        xStim = reshape(xStim,1,[]);
                        nStim = length(xStim);
                    else
                        nStim = 0;
                    end
  
                    % save array dimensions
                    dim = size(mat);
                    dtaLen = length(xPts);
                    
                    % Investigate which kind of matrix we have to deal
                    % with:
                    % case 1: single channel 3D stack or movie
                    % case 2: there are 2 channels (actually, this code works
                    %   for an arbitrary number of channels: mat is a 4D matrix of
                    %   dimensions x*y*ch*time;
                    % case 3: any of the previous, but stimuli are delivered.
                    %   stimulus time must be provided.
                    if length(dim) == 3 % dimensions are x*y*time
                        % reshape to x*y*1*time
                        mat = reshape(mat,dim(1),dim(2),1,dim(3));
                        % update dimensions
                        dim = size(mat);
                    end
                    if length(dim) == 4
                        % get the image plot in the selected axes
                        allChildren = findobj(ROI.Parent,'Type','Image');
                        % ROIs MIGHT BE image subtype(they were in 2019a) but can be distinguished:
                        myImage = allChildren(strcmp( arrayfun(@(x) x.Type, allChildren,'UniformOutput',false),'image')); % arrayfun returns a cell array with
                        %                                                                                   the array characters of the field "Type"                      
%                         toc3 = toc
                        % find the pixels belonging to the ROI.
                        % getSelectedPixels returns selected pixels using
                        % logical linear indexing. 
                        myImage = myImage(1); % if there are multiple images, select the 1st one.
                        selectedPxl = getSelectedPixels(myImage,ROI);
%                         toc4 = toc
                        % selectedPxl is a logical array with as many elements as
                        % the number of pixels in a frame. selectedPxl(i)
                        % is true if the i-th pixel (in column order) of
                        % the image is included in the ROI.
                        % Now, we want to obtain a matrix of selected
                        % pixels. The new matrix shape will be N*ch*time
                        % where N is the number of selected pixels in a
                        % frame (i.e. sum(selectedPxl)). To do so, we have
                        % to perform several passages in which the matrix
                        % is reshaped and permuted. All the passages are
                        % condensed in a single line for speeding up the
                        % code.
                        % Passages explained:
                        % 	allSelectedPxl= repmat(selectedPxl,dim(3)*dim(4),1)); % repeat the selected pxls for every 
                        %                                                                      frame of every channel
                        % 	linearized_selected_Mat = mat( allSelectedPxl ); % obtain only the selected pixels in a column vector
                        % 	stackedMat = reshape(linearized_selected_Mat,sum(selectedPxl),dim(3),dim(4)); % reshape in the form   
                        %                                           of (number_of_ROI_pixels)*(n_channels)*(n_frames)
                        % 	line2plot = reshape(mean( stackedMat,1),dim(3),dim(4))'; % compute the average for every channel and
                        %                                               frame, obtaining a matrix of dimensions time*channels
                        lines2plot = reshape( mean( reshape( mat( repmat(selectedPxl,dim(3)*dim(4),1)) ,sum(selectedPxl),dim(3),dim(4)),1,'omitnan'), dim(3), dim(4))';
%                         toc5 = toc
                        % set y axis limits
                        if ~app.holdyButton.Value
                            minY = min(lines2plot,[],'all');
                            maxY = max(lines2plot,[],'all');
%                             disp(ROI.Tag)
%                             disp(num2str([minY, maxY]))
                            app.zProfileAxes.YLim  = [minY-0.05*(maxY-minY),maxY+0.05*(maxY-minY)];
                            app.yToEditField.Value = double(app.zProfileAxes.YLim(2));
                            app.yFromEditField.Value = double(app.zProfileAxes.YLim(1));
                        end
%                         toc6 = toc
                        if isempty(ev)
                            % This ROI has just been created
                            cla(app.zProfileAxes)
                            % save axes position for later
                            pos=app.zProfileAxes.InnerPosition;
                            app.zProfileLine = plot(app.zProfileAxes,xPts,lines2plot,'LineWidth',1);
                            % set x axis limits
                            if ~app.holdyButton.Value
                                app.zProfileAxes.XLim = [xPts(1), xPts(end)];
                                app.xToEditField.Value = app.zProfileAxes.XLim(2);
                                app.xFromEditField.Value = app.zProfileAxes.XLim(1);
                            end
                            if nStim>0
                                % also plot vertical lines at the beginning of every
                                % trial
                                linesX = [xStim; xStim];
                                linesY = ones(size(linesX)).*(app.zProfileAxes.YLim)';
                                app.zProfileTrials = plot(app.zProfileAxes,linesX,linesY,'Color','y');
                                app.zProfileAxes.XLim = [xPts(1), xPts(end)];
                            else
                                delete(app.zProfileTrials)
                                app.zProfileTrials = [];
                            end
                            % set the original position back
                            app.zProfileAxes.InnerPosition=pos;
                        else
                            % update the z line
                            for iLine = 1:length(app.zProfileLine) % there can be 1 or 2 lines
                                app.zProfileLine(iLine).YData = lines2plot(:,iLine);
                            end
                            % also update the limits of the trial lines
                            if nStim>0
                                arrayfun(@(x)(set(x,'YData',app.zProfileAxes.YLim)),app.zProfileTrials)
                            end
                        end
%                         toc7 = toc
                    end
                    if nargout>0
                        varargout{1}=[xPts(:),lines2plot];
                    end
                end
%             end
        end
             
        function refreshSource(app)
            % this function is assigned as a callback for the listener that
            % listens to the UserData property of the currently selected axes. When
            % UserData value changes (i.e. the pointer to the source image
            % stack changes), this function refreshes the z profile plot (if only
            % one ROI is selected).
            
            % Show the plot only if there's only one selcted ROI, or if
            % there's a "temporary" newROI
            
            % step 1) get the selected ROIs in the ROI list
            selRoi_idx = getSelectedRoi(app);
            
            % step 2) check if only one is selected
            nSelRois = sum(selRoi_idx);
            
            % If there's only one selected ROI, update its z-axis plot
            if nSelRois == 1
                zProfile(app,app.storedROI(selRoi_idx).roi,[])
            elseif ~isempty(app.newROI)
                % otherwise, update the z-axis plot of app.newROI
                if isvalid(app.newROI)
                    zProfile(app,app.newROI,[])
                end
            end
        end
        
        function selected_idx =  getSelectedRoi(app)
            % for every selected roi, get the corresponding index (logical indexing)
            if isempty(app.roiListBox.Value)
                selected_idx = false(size(app.roiListBox.Items));
            else
                selected = arrayfun( @(x) strcmp(app.roiListBox.Items, x), app.roiListBox.Value, 'uniformoutput',0);
                selected_idx = logical(sum(cat(1,selected{:}),1));
            end
        end
          
        function roiDeleting(app,targetROI,ev)
            % % If this function is called following to the
            % interactive deletion of a ROI by the user, then
            % ev is the event related to this action. In this case,
            % the ROI will be deleted automatically.
            %
            % If ev is the 'buttonPressed' event from deleteRoiButton, the
            % target roi is one of the rois selected in roiListBox. The roi
            % has to be actively deleted.
            %
            % If ev is the 'buttonPressed' event from Discard button, the
            % target roi is app.newROI. The target ROI is app.newROI and it
            % has to be actively deleted.
            % 
            % If ev is empty instead, it means  that this function has been
            % called during the drawROI function, because a new ROI is going
            % to be drawn and app.newROI must be overwritten. The target ROI is
            % app.newROI and it has to be actively deleted UNLESS it has 
            % been stored already. So, check first if the roi belongs
            % to app.storedROI: if it's so, don't delete it.
            %
            % OCCHIO AI LISTENER!! DEVO VEDERE COME TRATTARLI, CREDO CHE
            % LI DOVREI ELIMINARE IN OGNI CASO. penso sia meglio
            % riscriverla usando switch case:
            %
            if isempty(ev)
               % source = 'drawROI'
                if ~isempty(app.storedROI)
                   % if there is one or more stored ROIs, check if the
                   % target roi is one of them.
                    if ~ismember(targetROI,[app.storedROI(:).roi])
                        delete(targetROI) %it is app.newROI
                        delete(app.temporaryListeners)
                        app.temporaryListeners = [];
                    end
                else
                    delete(targetROI) %it is app.newROI
                    delete(app.temporaryListeners)
                    app.temporaryListeners = [];
                end
            else
                switch  ev.Source
                    case app.deleteRoiButton
                        idx = arrayfun(@(x) isequal(x.roi,targetROI),app.storedROI);
                        try
                        targetROI.delete
                        catch
                            warning('ROI not deleted')
                        end
                        try
                        delete(app.storedROI(idx).listeners)
                        catch
                            warning('Listeners not deleted')
                        end
                        app.storedROI(idx) = [];
                        app.roiListBox.Items(idx) = [];
            
                    case app.DiscardButton
                        delete(targetROI) %it is app.newROI
                        delete(app.temporaryListeners)
                        app.temporaryListeners = [];
                        app.StoreButton.Enable = 'off';
                        app.DiscardButton.Enable = 'off';
            
                    otherwise % ROIdeleting listener
                        delete(targetROI)                        
                        if ~isempty(app.storedROI)
                            % check if target is a stored ROI
                            idx = arrayfun(@(x) isequal(x.roi,targetROI),app.storedROI);
                        else
                            idx = 0;
                        end
                        if sum(idx)>0
                            % stored ROI
                            delete(app.storedROI(idx).listeners)
                            app.storedROI(idx) = [];
                            app.roiListBox.Items(idx) = [];
                        else
                            % temporary ROI
                            delete(app.temporaryListeners)
                            app.temporaryListeners = [];
                            app.StoreButton.Enable = 'off';
                            app.DiscardButton.Enable = 'off';
                        end
                end
            end
        end
        
        function myCloseRequestFunc(app)
            ROImanagerFigureCloseRequest(app,[])
        end
        
        function refreshROI(app)
            % reassign all the stored ROIs to the current Axes. If some of
            % them have been accidentally deleted, re-draw them.
            
            % when the selected axes changes, reassign all the ROIs to it
            % and update their listeners to the image stack source
            if app.FreeButton.Value
                currAx = gca;
            else
                currAx = app.parentAxes( strcmp(app.axesDropDown.Items,app.axesDropDown.Value) );
            end
            
            if ~isempty(app.storedROI)
%               % Set the new axes as Parent
%                 arrayfun(@(x) (set(x,'Parent',newAxes)),app.storedROI)  % MODIFICARE!!!
%                 arrayfun(@(x)(createOrUpdate(x,newAxes,app)),app.storedROI) % MODIFICARE!!!
                % Update the third listener, the one that listens to parent
                % axes UserData property (i.e. the pointer to the source
                % image stack)
%                 arrayfun(@(x) (set(x,'UserData', [x.UserData(1), x.UserData(2),... 
%                     event.proplistener(x.Parent,findprop(matlab.ui.control.UIAxes,'UserData'),...
%                     'PostSet',@(src,ev) refreshSource(app,x))])),app.storedROI);
                for i=1:length(app.storedROI)
                    if isvalid(app.storedROI(i).roi)
                        set(app.storedROI(i).roi,'Parent',currAx)
                    else
                        app.storedROI(i).roi = redrawROI(app,app.storedROI(i),currAx);
                        app.storedROI(i).listeners = recreateListeners(app,app.storedROI(i).roi);
                        
                    end
                end
            end
            
            % redraw the z profile of the selected ROI
            selROI = getSelectedRoi(app);
            if sum(selROI)==1
                zProfile(app,app.storedROI(selROI).roi,[]);
            end
            
        end
        
        function roiObj = redrawROI(app,roistruct,currAx)
            % create the ROI object
            roiObj = eval(roistruct.class);
            % get the name of the properties that are needed to properly re-draw it the ROI
            fnames = fieldnames(roistruct.position);
            % also get their values
            fvalues = cellfun(@(x)(roistruct.position.(x)),fnames,'Uniformoutput',false);
            % set them
            cellfun(@(x,y)(set(roiObj,x,y)),fnames,fvalues);
            
            % also set other properties: get their names
            if isfield(roistruct,'extra')
                if  ~isempty(roistruct.extra)
                    fnames = fieldnames(roistruct.extra);
                    % also get their values
                    fvalues = cellfun(@(x)(roistruct.extra.(x)),fnames,'Uniformoutput',false);
                    % set them
                    cellfun(@(x,y)(set(roiObj,x,y)),fnames,fvalues);
                end
            end
            % finally, assign the roi to the current axes
            roiObj.Parent = currAx;
        end
        
        function sourceListenerUpdate(app)
            if app.SelectaxesButton.Value
                if ~isempty(app.axesDropDown.Items{1})
                    selAxes = app.parentAxes(strcmp( app.axesDropDown.Items, app.axesDropDown.Value));
                else
                    selAxes = matlab.graphics.axis.Axes.empty;
                end
            else
                cf = get(groot,'CurrentFigure');
                if ~isempty(cf)
                    selAxes = get(cf,'CurrentAxes');
                else 
                    selAxes = matlab.graphics.axis.Axes.empty;
                end
            end
            
            if ~isempty(selAxes)
                app.sourceListener = event.proplistener(selAxes,findprop(matlab.ui.control.UIAxes,'UserData'),'PostSet',@(src,ev) refreshSource(app));
            end
        end

        
        function selProp = getSelectedMetrics(app)
            selProp = false(1,7);
% app.colNames = [{'ROI'}, {'Frame'},{'Channel'},{'Mean'},{'Std'},{'Median'},{'Area'},{'Min'},{'Max'},{'Sum'}];
            if app.MeanCheckBox.Value
                selProp(1) = true;
            end
            if app.StandarddeviationCheckBox.Value
                selProp(2) = true;
            end
            if app.MedianCheckBox.Value
                selProp(3) = true;
            end
            if app.AreaCheckBox.Value
                selProp(4) = true;
            end
            if app.MinimumCheckBox.Value
                selProp(5) = true;
            end
            if app.MaximumCheckBox.Value
                selProp(6) = true;
            end
            if app.SumCheckBox.Value
                selProp(7) = true;
            end
        end
        
        function updateTable(app)
            selMetr = getSelectedMetrics(app);
            app.measuresTable.Data = [];
            app.measuresTable.ColumnName = app.colNames([true,true,true,selMetr]);
            if ~isempty(app.completeTable)
                app.measuresTable.Data = app.completeTable(:,[true,true,true,selMetr]);
            else
                app.measuresTable.Data=[];
            end
        end
        
        function duplicateROI(app)
            % rename selected rois
            selRois = getSelectedRoi(app);
            nSelRois = sum(selRois);
            
            % get the current axes
            if app.FreeButton.Value
                currAx = gca;
            else
                currAx = app.parentAxes( strcmp(app.axesDropDown.Items,app.axesDropDown.Value) );
            end
            
            % create new entries in the roi list
            if nSelRois
                % Remove the "mock" last row: is just an empty row to indicate that
                % none of the previously stored ROI is selected
%                 lastTempFlag = strcmp(app.roiListBox.Items{end},'Temp');
%                 if lastTempFlag
%                     app.roiListBox.Items(end) = [];
%                 end
                
%                 % obtain the number of stored rois
%                 nOldRois = length(app.roiListBox.Items);
                % update the roi counter
                app.roiCounter = app.roiCounter + nSelRois;
                % prepare the names for the new rois
                roiNames = cellfun(@(x)([x '_dupl']),app.roiListBox.Items(selRois),'UniformOutput',false);
                app.roiListBox.Items = cat(2,app.roiListBox.Items,roiNames);
                % create the new objects
                newStruct = app.storedROI(selRois);
                for i=1:length(newStruct)
                    newStruct(i).roi = redrawROI(app,app.storedROI(i),currAx);
                    newStruct(i).listeners(1) = addlistener(newStruct(i).roi,'MovingROI',@(src,ev) zProfile(app,src,ev));
                    newStruct(i).listeners(2) = addlistener(newStruct(i).roi,'DeletingROI',@(src,ev) roiDeleting(app,src,ev));
                    newStruct(i).listeners(3) = addlistener(newStruct(i).roi,'ROIMoved',@(src,ev) roiMoved(app,src,ev));
                end
                app.storedROI = cat(1,app.storedROI,newStruct);
            end
            
        end
        
        function roiMoved(app,src,ev)
            % which roi is moving?
            idx = arrayfun(@(x) isequal(x.roi,src),app.storedROI);
            % if it is a stored roi, update its position and relevant
            % fields 
            if any(idx)
                fnames = fieldnames(app.storedROI(idx).position);
                % also get their values
                fvalues = cellfun(@(x)(get(src,x)),fnames,'Uniformoutput',false);
                % update "position" values stored into the stucture
                % storedROI 
                for i=1:length(fnames)
                    app.storedROI(idx).position.(fnames{i})=fvalues{i};
                end
            end
        end
        
        function call_roiListBoxValueChanged(app)
            roiListBoxValueChanged(app, [])
        end
        
        function axesDropDownValueChanged_fcn(app)
            axesDropDownValueChanged(app,[]);
        end
        
        function lstnrs = recreateListeners(app,roi)
            lstnrs = event.listener.empty(0,3);
            lstnrs(1) = addlistener(roi,'MovingROI',@(src,ev) zProfile(app,src,ev));
            lstnrs(2) = addlistener(roi,'DeletingROI',@(src,ev) roiDeleting(app,src,ev));
            lstnrs(3) = addlistener(roi,'ROIMoved',@(src,ev) roiMoved(app,src,ev));
        end
        
        function call_StoreButtonPushed(app)
            StoreButtonPushed(app,[])
        end
        
        function saveROIs(app,fullname)
            % get the indices of the selected ROIs
            selected_idx =  getSelectedRoi(app);
            
            % get a destination folder interactively
%             path = uigetdir(pwd,'Choose a destination folder');
%             figure(app.ROImanagerFigure)
            savedRois = struct('name', app.roiListBox.Items(selected_idx),...
                               'class', {app.storedROI(selected_idx).class},...
                               'position', {app.storedROI(selected_idx).position},...
                               'extra',{app.storedROI(selected_idx).extra});
%             savedRois = app.storedROI(selected_idx);
%             save([path '\ROIset.mat'], 'savedRois')
            
            save(fullname,'savedRois')
        end
        
        function measure(app)
            % get selected ROIs
            selRoi = getSelectedRoi(app);
            nRoi = sum(selRoi);
            
            % get selected metrics
            selMetr = getSelectedMetrics(app);
            nMetrics = numel(selMetr);
            
            % all frames flag
            allFramesFlag = app.AllFramesCheckBox.Value;
            
            % get selected axes
            if app.FreeButton.Value
                currAx = gca;
            else
                currAx = app.parentAxes(strcmp( app.axesDropDown.Items, app.axesDropDown.Value));
            end
            
            % get image and source
            allChildren = findobj(currAx,'Type','Image');
            % ROIs MIGHT BE image subtype(they were in 2019a) but can be distinguished:
            imgIdx = strcmp( arrayfun(@(x) x.Type, allChildren,'UniformOutput',false),'image');
            img = allChildren(imgIdx);
            if allFramesFlag
                if isa(currAx.UserData,'struct')
                    source = currAx.UserData.data;
                else
                    % it's a pointer
                    sourcePtr = get(currAx.UserData.data);
                    source = sourcePtr.Value; % 3D or 4D matrix
                end
                
                dim = size(source);
                if length(dim) == 3
                    source = reshape(source,[dim(1), dim(2), 1, dim(3)]);
                    dim = size(source);
                elseif length(dim)~=4
                    errStr = ['source stack has ' num2str(length(dim)) ' dimensions. Only 3D' ...
                        'or 4D stacks can be processed'];
                    error(errStr)
                end
                nCh = dim(3);
                nFrames = dim(4);
            else
                source = img.CData;
                dim = size(source);
                source = reshape(source,[dim(1), dim(2), 1, 1]);
                dim = size(source);
                nCh = 1;
                nFrames = 1;
            end
            
            % get selected pixels for every roi
            selPxl = arrayfun(@(x)(getSelectedPixels(img,x)), [app.storedROI(selRoi).roi], 'UniformOutput',false);
            
            % build the new matrix
            % remember that: 
%             app.colNames = [{'ROI'},{'Frame'},{'Channel'},{'Mean'},{'Std'},...
%                                           {'Median'},{'Area'},{'Min'},...
%                                           {'Max'},{'Sum'},{'Centroid'}];
%                                       
            newTab = table('Size',[nRoi*nCh*nFrames,length(app.colTypes)],'VariableTypes',app.colTypes,'VariableNames',app.colNames);
            
            % fill roi names
            roisRep = repmat(app.roiListBox.Value,nFrames*nCh,1);
            roisColumn = roisRep(:);
            newTab{:,1} = roisColumn;
            
            % fill frame names
            if nFrames>1
                frStr = repmat( cellstr(num2str((1:nFrames)','%05.f')), 1,nCh  )';
            else
                frStr = repmat({'--'},nCh,1);
            end
            newTab{:,2} = repmat(frStr(:),nRoi,1); % column with frame numbers
            
            % fill channel names
            chStr = repmat( cellstr(num2str((1:nCh)','%1.f')), 1,nFrames  );
            newTab{:,3} = repmat(chStr(:),nRoi,1); % column with frame numbers
            
            
            % measure
%             for iRoi = 1:nRoi
%                 for iFrame = 1:nFrames
%                     for iCh = 1:nCh
%                         currFrame = squeeze(source(:,:,iCh,iFrame));
%                         currPxl = currFrame(selPxl{iRoi});
%                         for iMetric = 1:nMetrics
%                             if selMetr(iMetric)
%                                 newTab{(iRoi-1)*nFrames*nCh+(iFrame-1)*nCh+iCh,iMetric+3} = app.metrics{iMetric}(currPxl);
%                             else
%                                 newTab{(iRoi-1)*nFrames*nCh+(iFrame-1)*nCh+iCh,iMetric+3} = nan(1,1);
%                             end
%                         end
%                     end
%                 end
%             end
            for iRoi = 1:nRoi
                currSelPxl = selPxl{iRoi};
                toBeMeasured = reshape( source( repmat(currSelPxl,nCh*nFrames,1)) ,sum(currSelPxl),nCh,nFrames);
                fromLine = (iRoi-1)*nFrames*nCh+1;
                toLine = iRoi*nFrames*nCh;
                for iMetric = 1:nMetrics
                    if selMetr(iMetric)
                        currMeasure = app.metrics{iMetric}(toBeMeasured);
                        newTab{fromLine:toLine,iMetric+3} = currMeasure(:);
                    else
                        newTab{fromLine:toLine,iMetric+3} = nan(nFrames*nCh,1);
                    end
                end
            end
            
            % initialize the complete table
            % 1) see if there are already measures or not
            notInitialized = false;
            if size(app.completeTable,1) == 1
                firstRoi = app.completeTable{1,1};
                notInitialized = isempty(firstRoi{:});
            end
            
            if notInitialized
                app.completeTable = newTab;
            else
                app.completeTable = cat(1,app.completeTable,newTab);
            end
            
            % show it
            updateTable(app)
        end
        
        function deleteRoiButtonPushed_callFcn(app,event)
            selected_idx_logic =  getSelectedRoi(app);
            selected_idx = find(selected_idx_logic);
            for i = length(selected_idx):-1:1
                % starting from the last element, indexing is not affected
                % by the previous deletions
                roiDeleting(app,app.storedROI(selected_idx(i)).roi,event)
            end
            app.roiListBox.Value = {};
            cla(app.zProfileAxes)
        end
end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            % add folders to path
            this_file = mfilename('fullpath');
            sep=regexp(this_file,filesep);
            this_folder = this_file(1:sep(end)-1);
            addpath([this_folder filesep 'icons'])
            addpath([this_folder filesep 'icons' filesep 'matlab icons'])
            addpath([this_folder filesep 'utilities'])
            
            % check optional argument
            if length(varargin)>1
                error('Too many input arguments for ROImanager app')
            elseif ~isempty(varargin)
                if ischar(varargin{1})
                    if strcmp(varargin{1},'movie')
                        app.axesDropDown.Items = "";
                    else
                        error('Invalid opening option for ROImanager')
                    end
                else
                    if isvalid(varargin{1})
                        app.parentApp = varargin{1};
                        refreshAxesButtonPushed(app, [])
                    else
                        error('The handle to a parent App passed to ROImanager is not valid')
                    end
                end
            else
                % no optional input
                app.FreeButton.Value = 1;
                app.SelectaxesButton.Enable = 'off';
                app.axesDropDown.Enable = 'off';
            end
            % create the sourceListener for the current axes
            sourceListenerUpdate(app)
            
            app.measuresTable.RowName = 'numbered';
            app.roiListBox.Items = {};
            app.roiListBox.Multiselect = 'on';
            
            app.roiCounter = 0;
            
            % create the measure table
            app.colTypes = [{'cell'},{'cell'}, {'cell'},   {'double'},{'double'},{'double'},{'double'},{'double'},{'double'},{'double'}];
            app.colNames = [{'ROI'}, {'Frame'},{'Channel'},{'Mean'},  {'Std'},   {'Median'},{'Area'},  {'Min'},   {'Max'},   {'Sum'}];
            app.completeTable = table('Size',[1,length(app.colTypes)],'VariableTypes',app.colTypes,'VariableNames',app.colNames);
            app.metrics = [{@(x)mean(x,'omitnan')},{@(x)std(x,'omitnan')},{@(x)median(x,'omitnan')},{@(x)(size(x,1))},{@min},{@max},{@(x)sum(x,'omitnan')}];
            app.emptyListenerArray = event.listener.empty(2,0);
            
            app.inDir = pwd;
            app.outDir = pwd;
            
            % delete existing rois
            rois = findobj('Type','images.roi');
            delete(rois);
            
            
        end

        % Close request function: ROImanagerFigure
        function ROImanagerFigureCloseRequest(app, event)
            try
%                 if ~isempty(app.storedROI)
%                     % delete all the listeners:
%                     arrayfun(@(x)( arrayfun(@(y)(y.delete),x.UserData) ),app.storedROI)
%                     % delete all the ROIs:
%                     arrayfun(@(x) (x.delete),app.storedROI)
%                 end
%                 
%                 % do the same for app.newROI, if it exists
%                 if ~isempty(app.newROI)
%                     if isvalid(app.newROI)
%                         % delete the listeners
%                         arrayfun(@(x)(x.delete),app.newROI.UserData)
%                         % delete the object
%                         delete(app.newROI)
%                     end
%                 end
                rois = findobj('Type','images.roi');
                delete(rois);
            catch err
                warning('An error occurred while closing ROImanager and it has been ignored')
            end
            
            delete(app)
        end

        % Button pushed function: RectangleButton
        function RectangleButtonPushed(app, event)
            drawROI(app,'Rectangle')
        end

        % Button pushed function: StoreButton
        function StoreButtonPushed(app, event)
            % ROIs can be deleted interactively left-clicking on them. It
            % the ROI has been deleted, do not go on with this function.
            % Call DiscardButton callback, instead.
            if isvalid(app.newROI)
                % Remove the "mock" last row: is just an empty row to indicate that
                % none of the previously stored ROI is selected
%                 lastTempFlag = strcmp(app.roiListBox.Items{end},'Temp');
%                 if lastTempFlag
%                     app.roiListBox.Items(end) = [];
%                 end
                
                % obtain the number of stored rois
                nOldRois = length(app.roiListBox.Items);
                % update the roi counter
                app.roiCounter = app.roiCounter + 1;
                % prepare the name for the new roi
                roiName = (['ROI_' num2str(app.roiCounter,' %03.f')]);
                if nOldRois > 0
                    app.roiListBox.Items = cat(2,app.roiListBox.Items,{roiName});
                    newStruct = struct('roi', app.newROI,'class',class(app.newROI),'position', [],'listeners',app.temporaryListeners,'extra',[]);
                    newStruct.position = getRoiPosition(app.newROI);
                    app.storedROI = cat(1,app.storedROI,newStruct);
                else
                    app.roiListBox.Items = {roiName};
                    app.storedROI = []; % in order to avoid getting an error when it is already an empty structure.
                    app.storedROI.roi = app.newROI;
                    app.storedROI.class = class(app.newROI);
                    app.storedROI.position = getRoiPosition(app.newROI);
                    app.storedROI.listeners = app.temporaryListeners;
                    app.storedROI.extra = []; % other properties of the roi that may be changed and saved.
                end
                app.roiListBox.Value = app.roiListBox.Items{end}; % select it
            
                app.StoreButton.Enable = 'off';
                app.DiscardButton.Enable = 'off';
            else
                DiscardButtonPushed(app)
            end
        end

        % Value changed function: roiListBox
        function roiListBoxValueChanged(app, event)
            selected_idx =  getSelectedRoi(app);
            
            % Delete the temporary ROI, if it exists and it is not stored
            if ~isempty(app.newROI)
                % delete the temp ROI, if it is not stored
%                 if isvalid(app.newROI) && ~sum(arrayfun(@(x) isequal(x,app.newROI),app.storedROI)) 
                if isvalid(app.newROI) && ~ismember(app.newROI,[app.storedROI(:).roi])                    
                    app.newROI.delete()
                    delete(app.temporaryListeners)
                end
                app.newROI = [];
                app.temporaryListeners = [];
            end
            
            % if only one ROI is selected, plot the z axis profile
            % get the selected ROIs
            if sum(selected_idx)==1
                zProfile(app,app.storedROI(selected_idx).roi,[])
            end
            
            
            % if there's at least one roi selected, make it visible
            if sum(selected_idx)>0
                set([app.storedROI(selected_idx).roi],'Visible','on')
            end
            % If 'show all ROIs' is turned off and there are some unselected
            % ROIs, then turn their visibility off
            if (~app.showAllButton.Value) && (length(selected_idx)>1)
                set([app.storedROI(~selected_idx).roi],'Visible','off')
            end
            
            % Disable the "store" and "discard" buttons because the newROI
            % has been deleted
            app.StoreButton.Enable = 'off';
            app.DiscardButton.Enable = 'off';
        end

        % Button pushed function: EllipseButton
        function EllipseButtonPushed(app, event)
            drawROI(app,'Ellipse')
        end

        % Button pushed function: PolygonButton
        function PolygonButtonPushed(app, event)
            drawROI(app,'Polygon')
        end

        % Value changed function: showAllButton
        function showAllButtonValueChanged(app, event)
            % now hide or show:
            if app.showAllButton.Value
                % show all
                if ~isempty(app.storedROI)
                    set([app.storedROI(:).roi],'Visible','on')
                end
            else
                % hide the ROIs that are not selected
                selected_idx = getSelectedRoi(app);
                if sum(selected_idx)>0
                    set([app.storedROI(selected_idx).roi],'Visible','on')
                end
                if sum(~selected_idx)>0
                    set([app.storedROI(~selected_idx).roi],'Visible','off')
                end
            end
        end

        % Selection changed function: TargetAxesButtonGroup
        function TargetAxesButtonGroupSelectionChanged(app, event)
            if app.FreeButton.Value
                app.axesDropDown.Enable = 'off';
            else
                app.axesDropDown.Enable = 'on';
            end
            refreshROI(app)
        end

        % Button pushed function: saveRoiButton
        function saveRoiButtonPushed(app, event)
            if isempty(app.outDir)
                app.outDir = pwd;
            end
            [fname,newDir] = uiputfile('*.mat','Save file',app.outDir(1:end-1));
            figure(app.ROImanagerFigure)
            if isa(newDir,'double')
                return
            else
                app.outDir = newDir;
            end
            
            app.saveROIs([newDir,fname])
            
        end

        % Button pushed function: loadRoiButton
        function loadRoiButtonPushed(app, event)
            % step 0) load the ROIs
            savedRois = [];
            [fname,tmp_path] = uigetfile('*.mat','',app.inDir);
            figure(app.ROImanagerFigure)
            if isa(fname,'double') % the user selected Cancel
                return
            else
                app.inDir = tmp_path;
                load([tmp_path fname]);
                nLoadedRois = length(savedRois);
                
                %             step 1) append the loaded elements to the storedROI structure.
                %             assign to them empty roi objects and empty listeners: they will be created later
                if isfield(savedRois,'extra')
                    extrafield = {savedRois(:).extra}';
                else
                    extrafield = cell(nLoadedRois,1);
                end
                
                newStruct = struct('roi', repmat({images.roi.Rectangle.empty(1,0)},nLoadedRois,1),...
                    'class',{savedRois(:).class}',...
                    'position',{savedRois(:).position}',...
                    'listeners',repmat({app.emptyListenerArray},nLoadedRois,1),...                    
                    'extra',extrafield);
                if isempty(app.storedROI)
                    app.storedROI = newStruct;
                else
                    app.storedROI = cat(1,app.storedROI,newStruct);
                end
                
                %             step 2) create their entries into the ROIlist. Don't forget to
                %             update the counter!
                new_entries = {savedRois(:).name};
                % check for name unicity
                uniqueNames = false;
                while ~uniqueNames
                    notUniqes = ismember(new_entries,app.roiListBox.Items);
                    uniqueNames = ~logical(sum(notUniqes));
                    new_entries(notUniqes) = cellfun(@(str)(append(str,'+')),new_entries(notUniqes),"UniformOutput",false);
                end
                
                app.roiCounter = app.roiCounter + length(savedRois);
                if isempty(app.roiListBox.Items)
                    app.roiListBox.Items = new_entries;
                else
                    app.roiListBox.Items = cat(2, app.roiListBox.Items, new_entries); % trasposta è ok?
                end
                
                %             step 3) refresh app.storedROI in order to create the loaded
                %             ROIs and their listeners.
                refreshROI(app)
            end
        end

        % Value changed function: axesDropDown
        function axesDropDownValueChanged(app, event)
            % retrieve selected axes
            selAxes = strcmp( app.axesDropDown.Items, app.axesDropDown.Value);
            % update axes parent app
            if ~isempty(app.axesParentApp)
                app.fromEditField.Value = app.axesParentApp{selAxes};
            end
            % bring the parent app forward, then refocus on this app
            figure(ancestor(app.parentAxes(selAxes),'figure'));
            figure(app.ROImanagerFigure);
            % point the source listener to the UserData property of the new
            % axes.
            sourceListenerUpdate(app)
            % call the function for ROI reassignment
            refreshROI(app)
        end

        % Button pushed function: FreehandButton
        function FreehandButtonPushed(app, event)
            drawROI(app,'Freehand')
        end

        % Button pushed function: DiscardButton
        function DiscardButtonPushed(app, event)
            if isvalid(app.newROI)
                roiDeleting(app,app.newROI,event)
            else
                % elimina i callback, se ancora validi
                if ~isempty(find(isvalid(app.temporaryListeners),1))
                    delete(app.temporaryListeners)
                end
            end
            
            % deactivate the button (until a new ROI is drawn)
            app.DiscardButton.Enable = 'off';
            app.StoreButton.Enable = 'off';
        end

        % Button pushed function: deleteRoiButton
        function deleteRoiButtonPushed(app, event)
            
            deleteRoiButtonPushed_callFcn(app,event)
        end

        % Button pushed function: MeasureButton
        function MeasureButtonPushed(app, event)
            measure(app)
        end

        % Button pushed function: refreshAxesButton
        function refreshAxesButtonPushed(app, event)
            if app.SelectaxesButton.Value
                [app.parentAxes,app.axesParentApp,~] = lookForAxes(app,[],[],[]);
            
                app.axesDropDown.Items = {app.parentAxes(:).Tag};
                app.axesDropDown.Value = app.axesDropDown.Items{1};
                app.fromEditField.Value = app.axesParentApp{1};
            end
        end

        % Value changed function: yToEditField
        function yToEditFieldValueChanged(app, event)
            value = app.yToEditField.Value;
            app.zProfileAxes.YLim(2) = value;
        end

        % Value changed function: yFromEditField
        function yFromEditFieldValueChanged(app, event)
            value = app.yFromEditField.Value;
            app.zProfileAxes.YLim(1) = value;
        end

        % Value changed function: xFromEditField
        function xFromEditFieldValueChanged(app, event)
            value = app.xFromEditField.Value;
            app.zProfileAxes.XLim(1) = value;
        end

        % Value changed function: xToEditField
        function xToEditFieldValueChanged(app, event)
            value = app.xToEditField.Value;
            app.zProfileAxes.XLim(2) = value;
        end

        % Menu selected function: RenameMenu
        function RenameMenuSelected(app, event)
            % rename selected rois
            selRois = getSelectedRoi(app);
            nSelRois = sum(selRois);
            
            % create an input dialog window and get the new names specified
            % by the user
            defNames = app.roiListBox.Items(selRois);
            newNames = inputdlg(repmat({''},nSelRois,1), 'Type new names',...
                                1,defNames);
           
            % check if new names are repeated
            if length(unique(newNames)) < length(newNames)
                warning('off','backtrace')
                warning('Two or more of the new names are equal')
                return
%                 RenameMenuSelected(app, [])
            end
            % check if any of the new names will create a conflict with one
            % of the unchanged names
            if ~isempty(find(ismember(newNames,app.roiListBox.Items(~selRois)),1))
                warning('off','backtrace')
                warning(['One or more new names are equal to one or more names'...
                    'in the list'])
                return
%                 RenameMenuSelected(app, [])
            end
            
            % assign the new names
            app.roiListBox.Items(selRois) = newNames;
            app.roiListBox.Value = newNames;

        end

        % Menu selected function: SelectAllMenu
        function SelectAllMenuSelected(app, event)
            app.roiListBox.Value = app.roiListBox.Items;
        end

        % Cell selection callback: measuresTable
        function measuresTableCellSelection(app, event)
            app.measuresTableSelectedIndices = event.Indices;
        end

        % Menu selected function: CopyMenu
        function CopyMenuSelected(app, event)
            selectedIndices = [min(app.measuresTableSelectedIndices(:,1)) min(app.measuresTableSelectedIndices(:,2))...
                max(app.measuresTableSelectedIndices(:,1)) max(app.measuresTableSelectedIndices(:,2))];
            SelTab2Clip(app.measuresTable,selectedIndices)
        end

        % Menu selected function: DeleteMenu
        function DeleteMenuSelected(app, event)
            selectedRows = [min(app.measuresTableSelectedIndices(:,1)),...
                            max(app.measuresTableSelectedIndices(:,1))];
            app.completeTable(selectedRows(1):selectedRows(2),:) = [];
            updateTable(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            selectedRows = [min(app.measuresTableSelectedIndices(:,1)),...
                            max(app.measuresTableSelectedIndices(:,1))];
            measures = app.measuresTable.Data(selectedRows(1):selectedRows(2),:);
            uisave('measures','measureTable.mat')
        end

        % Window key press function: ROImanagerFigure
        function ROImanagerFigureWindowKeyPress(app, event)
            key = event.Key;
            
        end

        % Callback function
        function LogscaleCheckBoxValueChanged(app, event)
            value = app.LogscaleCheckBox.Value;
            if value
                app.zProfileAxes.XScale = 'log';
            else
                app.zProfileAxes.XScale = 'linear';
            end
        end

        % Button pushed function: ExportprofileButton
        function ExportprofileButtonPushed(app, event)
            selROI = getSelectedRoi(app);
            roiname = app.roiListBox.Items{selROI};
            x = vertcat(app.zProfileLine(:).XData);
            y = vertcat(app.zProfileLine(:).YData);
%             uisave([{'x'},{'y'}],['zProfile_' roiname '.mat'])
            [fname,fold] = uiputfile('.mat','Save profile',[app.outDir 'zProfile_' roiname '.mat']);
            if ~isa(fold,'double')
                save([fold fname],'x','y')
                app.outDir = fold;
            end
            figure(app.ROImanagerFigure)
%             save(['zProfile_' roiname '.mat'],'x','y')
        end

        % Value changed function: Showch1CheckBox
        function Showch1CheckBoxValueChanged(app, event)
            value = app.Showch1CheckBox.Value;
            if ~isempty(app.zProfileLine)
                if isvalid(app.zProfileLine(1))
                    if value
                        app.zProfileLine(1).Visible = 'on';
                    else
                        app.zProfileLine(1).Visible = 'off';
                    end
                end
            end
        end

        % Value changed function: Showch2CheckBox
        function Showch2CheckBoxValueChanged(app, event)
            value = app.Showch2CheckBox.Value;
            if numel(app.zProfileLine)>1
                if isvalid(app.zProfileLine(2))
                    if value
                        app.zProfileLine(2).Visible = 'on';
                    else
                        app.zProfileLine(2).Visible = 'off';
                    end
                end
            end
        end

        % Menu selected function: DuplicateMenu
        function DuplicateMenuSelected(app, event)
            app.duplicateROI
        end

        % Button pushed function: XResetButton
        function XResetButtonPushed(app, event)
            if ~isempty(app.zProfileLine)
                app.xFromEditField.Value=app.zProfileLine.XData(1);
                app.xToEditField.Value=app.zProfileLine.XData(end);
                app.zProfileAxes.XLim=[app.xFromEditField.Value,app.xToEditField.Value];
            end
        end

        % Button pushed function: YResetButton
        function YResetButtonPushed(app, event)
           if ~isempty(app.zProfileLine)
                app.yFromEditField.Value=app.zProfileLine.YData(1);
                app.yToEditField.Value=app.zProfileLine.YData(end);
                app.zProfileAxes.YLim=[app.yFromEditField.Value,app.yToEditField.Value];
           end
        end

        % Button pushed function: XForwardButton
        function XForwardButtonPushed(app, event)
            range=app.xToEditField.Value-app.xFromEditField.Value;
            if range>0
                app.xFromEditField.Value=app.xFromEditField.Value+range;
                app.xToEditField.Value=app.xToEditField.Value+range;
                app.zProfileAxes.XLim=[app.xFromEditField.Value,app.xToEditField.Value];
            end
        end

        % Button pushed function: XBackwardButton
        function XBackwardButtonPushed(app, event)
            range=app.xToEditField.Value-app.xFromEditField.Value;
            if range>0
                app.xFromEditField.Value=app.xFromEditField.Value-range;
                app.xToEditField.Value=app.xToEditField.Value-range;
                app.zProfileAxes.XLim=[app.xFromEditField.Value,app.xToEditField.Value];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ROImanagerFigure = uifigure('Visible', 'off');
            app.ROImanagerFigure.Name = 'Roi manager';
            app.ROImanagerFigure.CloseRequestFcn = createCallbackFcn(app, @ROImanagerFigureCloseRequest, true);
            app.ROImanagerFigure.WindowKeyPressFcn = createCallbackFcn(app, @ROImanagerFigureWindowKeyPress, true);
            app.ROIshapePanel = uipanel(app.ROImanagerFigure);
            app.ROIshapePanel.Title = 'ROI shape';
            app.RectangleButton = uibutton(app.ROIshapePanel, 'push');
            app.RectangleButton.ButtonPushedFcn = createCallbackFcn(app, @RectangleButtonPushed, true);
            app.RectangleButton.Icon = 'draw_rectangle_24.png';
            app.RectangleButton.Tooltip = {'Rectangle'};
            app.RectangleButton.Text = '';
            app.EllipseButton = uibutton(app.ROIshapePanel, 'push');
            app.EllipseButton.ButtonPushedFcn = createCallbackFcn(app, @EllipseButtonPushed, true);
            app.EllipseButton.Icon = 'draw_ellipse_24.png';
            app.EllipseButton.Tooltip = {'Ellipse'};
            app.EllipseButton.Text = '';
            app.PolygonButton = uibutton(app.ROIshapePanel, 'push');
            app.PolygonButton.ButtonPushedFcn = createCallbackFcn(app, @PolygonButtonPushed, true);
            app.PolygonButton.Icon = 'draw_polygon_24.png';
            app.PolygonButton.Tooltip = {'Polygon'};
            app.PolygonButton.Text = '';
            app.FreehandButton = uibutton(app.ROIshapePanel, 'push');
            app.FreehandButton.ButtonPushedFcn = createCallbackFcn(app, @FreehandButtonPushed, true);
            app.FreehandButton.Icon = 'DrawFreehand_24.png';
            app.FreehandButton.Tooltip = {'Freehand'};
            app.FreehandButton.Text = '';
            app.TabGroup = uitabgroup(app.ROImanagerFigure);
            app.ZaxisprofileTab = uitab(app.TabGroup);
            app.ZaxisprofileTab.Title = 'Z axis profile';
            app.xToEditField = uieditfield(app.ZaxisprofileTab, 'numeric');
            app.xToEditField.ValueChangedFcn = createCallbackFcn(app, @xToEditFieldValueChanged, true);
            app.xFromEditField = uieditfield(app.ZaxisprofileTab, 'numeric');
            app.xFromEditField.ValueChangedFcn = createCallbackFcn(app, @xFromEditFieldValueChanged, true);
            app.yFromEditField = uieditfield(app.ZaxisprofileTab, 'numeric');
            app.yFromEditField.ValueChangedFcn = createCallbackFcn(app, @yFromEditFieldValueChanged, true);
            app.yToEditField = uieditfield(app.ZaxisprofileTab, 'numeric');
            app.yToEditField.ValueChangedFcn = createCallbackFcn(app, @yToEditFieldValueChanged, true);
            app.holdyButton = uibutton(app.ZaxisprofileTab, 'state');
            app.holdyButton.Text = 'hold';
            app.holdxButton = uibutton(app.ZaxisprofileTab, 'state');
            app.holdxButton.Text = 'Hold';
            app.Showch1CheckBox = uicheckbox(app.ZaxisprofileTab);
            app.Showch1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Showch1CheckBoxValueChanged, true);
            app.Showch1CheckBox.Text = 'Show ch1';
            app.Showch1CheckBox.Value = true;
            app.Showch2CheckBox = uicheckbox(app.ZaxisprofileTab);
            app.Showch2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Showch2CheckBoxValueChanged, true);
            app.Showch2CheckBox.Text = 'Show ch2';
            app.XResetButton = uibutton(app.ZaxisprofileTab, 'push');
            app.XResetButton.ButtonPushedFcn = createCallbackFcn(app, @XResetButtonPushed, true);
            app.XResetButton.Text = 'Reset';
            app.XBackwardButton = uibutton(app.ZaxisprofileTab, 'push');
            app.XBackwardButton.ButtonPushedFcn = createCallbackFcn(app, @XBackwardButtonPushed, true);
            app.XBackwardButton.Text = '<';
            app.XForwardButton = uibutton(app.ZaxisprofileTab, 'push');
            app.XForwardButton.ButtonPushedFcn = createCallbackFcn(app, @XForwardButtonPushed, true);
            app.XForwardButton.Text = '>';
            app.YResetButton = uibutton(app.ZaxisprofileTab, 'push');
            app.YResetButton.ButtonPushedFcn = createCallbackFcn(app, @YResetButtonPushed, true);
            app.YResetButton.Text = 'Reset';
            app.ExportprofileButton = uibutton(app.ZaxisprofileTab, 'push');
            app.ExportprofileButton.ButtonPushedFcn = createCallbackFcn(app, @ExportprofileButtonPushed, true);
            app.ExportprofileButton.Text = 'Export profile';
            app.zProfileAxes = uiaxes(app.ZaxisprofileTab);
            app.zProfileAxes.Tag = 'zProfileAxes';
            app.MeasureTab = uitab(app.TabGroup);
            app.MeasureTab.Title = 'Measure';
            app.measuresTable = uitable(app.MeasureTab);
            app.measuresTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.measuresTable.RowName = {};
            app.measuresTable.CellSelectionCallback = createCallbackFcn(app, @measuresTableCellSelection, true);
            app.SaveButton = uibutton(app.MeasureTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.MeasureButton = uibutton(app.MeasureTab, 'push');
            app.MeasureButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureButtonPushed, true);
            app.MeasureButton.Text = 'Measure';
            app.AllFramesCheckBox = uicheckbox(app.MeasureTab);
            app.AllFramesCheckBox.Text = 'All Frames';
            app.SetMeasuresTab = uitab(app.TabGroup);
            app.SetMeasuresTab.Title = 'Set Measures';
            app.MinimumCheckBox = uicheckbox(app.SetMeasuresTab);
            app.MinimumCheckBox.Text = 'Minimum';
            app.MaximumCheckBox = uicheckbox(app.SetMeasuresTab);
            app.MaximumCheckBox.Text = 'Maximum';
            app.MeanCheckBox = uicheckbox(app.SetMeasuresTab);
            app.MeanCheckBox.Text = 'Mean';
            app.MeanCheckBox.Value = true;
            app.MedianCheckBox = uicheckbox(app.SetMeasuresTab);
            app.MedianCheckBox.Text = 'Median';
            app.StandarddeviationCheckBox = uicheckbox(app.SetMeasuresTab);
            app.StandarddeviationCheckBox.Text = 'Standard deviation';
            app.SumCheckBox = uicheckbox(app.SetMeasuresTab);
            app.SumCheckBox.Text = 'Sum';
            app.AreaCheckBox = uicheckbox(app.SetMeasuresTab);
            app.AreaCheckBox.Text = 'Area';
            app.saveRoiButton = uibutton(app.ROImanagerFigure, 'push');
            app.saveRoiButton.ButtonPushedFcn = createCallbackFcn(app, @saveRoiButtonPushed, true);
            app.saveRoiButton.Icon = 'file_save.png';
            app.saveRoiButton.Tooltip = {'Save selected'};
            app.saveRoiButton.Text = '';
            app.showAllButton = uibutton(app.ROImanagerFigure, 'state');
            app.showAllButton.ValueChangedFcn = createCallbackFcn(app, @showAllButtonValueChanged, true);
            app.showAllButton.Tooltip = {'Show all'};
            app.showAllButton.Icon = 'eye_icon.png';
            app.showAllButton.Text = '';
            app.TargetAxesButtonGroup = uibuttongroup(app.ROImanagerFigure);
            app.TargetAxesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TargetAxesButtonGroupSelectionChanged, true);
            app.TargetAxesButtonGroup.Title = 'Target Axes';
            app.FreeButton = uiradiobutton(app.TargetAxesButtonGroup);
            app.FreeButton.Tooltip = {'Select any axes object (NOT uixes)'};
            app.FreeButton.Text = 'Free';
            app.SelectaxesButton = uiradiobutton(app.TargetAxesButtonGroup);
            app.SelectaxesButton.Tooltip = {'Choose among the uiaxes of the parent app'};
            app.SelectaxesButton.Text = 'Select axes';
            app.SelectaxesButton.Value = true;
            app.axesDropDown = uidropdown(app.TargetAxesButtonGroup);
            app.axesDropDown.ValueChangedFcn = createCallbackFcn(app, @axesDropDownValueChanged, true);
            app.refreshAxesButton = uibutton(app.TargetAxesButtonGroup, 'push');
            app.refreshAxesButton.ButtonPushedFcn = createCallbackFcn(app, @refreshAxesButtonPushed, true);
            app.refreshAxesButton.Icon = 'Reset_24.png';
            app.refreshAxesButton.Tooltip = {'Save selected'};
            app.refreshAxesButton.Text = '';
            app.fromEditFieldLabel = uilabel(app.TargetAxesButtonGroup);
            app.fromEditFieldLabel.Text = 'from:';
            app.fromEditField = uieditfield(app.TargetAxesButtonGroup, 'text');
            app.fromEditField.Editable = 'off';
            app.loadRoiButton = uibutton(app.ROImanagerFigure, 'push');
            app.loadRoiButton.ButtonPushedFcn = createCallbackFcn(app, @loadRoiButtonPushed, true);
            app.loadRoiButton.Icon = 'upfolder.gif';
            app.loadRoiButton.Tooltip = {'Load'};
            app.loadRoiButton.Text = '';
            app.StoreButton = uibutton(app.ROImanagerFigure, 'push');
            app.StoreButton.ButtonPushedFcn = createCallbackFcn(app, @StoreButtonPushed, true);
            app.StoreButton.Enable = 'off';
            app.StoreButton.Text = 'Store';
            app.DiscardButton = uibutton(app.ROImanagerFigure, 'push');
            app.DiscardButton.ButtonPushedFcn = createCallbackFcn(app, @DiscardButtonPushed, true);
            app.DiscardButton.Enable = 'off';
            app.DiscardButton.Text = 'Discard';
            app.ROIlistListBoxLabel = uilabel(app.ROImanagerFigure);
            app.ROIlistListBoxLabel.Text = 'ROI list';
            app.roiListBox = uilistbox(app.ROImanagerFigure);
            app.roiListBox.ValueChangedFcn = createCallbackFcn(app, @roiListBoxValueChanged, true);
            app.deleteRoiButton = uibutton(app.ROImanagerFigure, 'push');
            app.deleteRoiButton.ButtonPushedFcn = createCallbackFcn(app, @deleteRoiButtonPushed, true);
            app.deleteRoiButton.Icon = 'ClearAll_24.png';
            app.deleteRoiButton.Tooltip = {'Delete selected'};
            app.deleteRoiButton.Text = '';
            app.ROIlistContextMenu = uicontextmenu(app.ROImanagerFigure);
            % Assign app.ROIlistContextMenu
            app.roiListBox.ContextMenu = app.ROIlistContextMenu;
            app.RenameMenu = uimenu(app.ROIlistContextMenu);
            app.RenameMenu.MenuSelectedFcn = createCallbackFcn(app, @RenameMenuSelected, true);
            app.RenameMenu.Text = 'Rename';
            app.SelectAllMenu = uimenu(app.ROIlistContextMenu);
            app.SelectAllMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectAllMenuSelected, true);
            app.SelectAllMenu.Text = 'Select All';
            app.DuplicateMenu = uimenu(app.ROIlistContextMenu);
            app.DuplicateMenu.MenuSelectedFcn = createCallbackFcn(app, @DuplicateMenuSelected, true);
            app.DuplicateMenu.Text = 'Duplicate';
            app.tableContextMenu = uicontextmenu(app.ROImanagerFigure);
            % Assign app.tableContextMenu
            app.measuresTable.ContextMenu = app.tableContextMenu;
            app.CopyMenu = uimenu(app.tableContextMenu);
            app.CopyMenu.MenuSelectedFcn = createCallbackFcn(app, @CopyMenuSelected, true);
            app.CopyMenu.Text = 'Copy';
            app.DeleteMenu = uimenu(app.tableContextMenu);
            app.DeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteMenuSelected, true);
            app.DeleteMenu.Text = 'Delete';
            % Show the figure after all components are created
            app.ROImanagerFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ROImanager(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ROImanagerFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ROImanagerFigure)
        end
    end
end