classdef eROIClo_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mainFigure                      matlab.ui.Figure
        Step1LoadprocessedstacksPanel   matlab.ui.container.Panel
        fileLabel                       matlab.ui.control.Label
        LoadButton                      matlab.ui.control.Button
        FormatDropDownLabel             matlab.ui.control.Label
        FormatDropDown                  matlab.ui.control.DropDown
        Step3drawROIsPanel              matlab.ui.container.Panel
        AutomaticPanel                  matlab.ui.container.Panel
        Areapxl2minEditFieldLabel       matlab.ui.control.Label
        Areapxl2minEditField            matlab.ui.control.NumericEditField
        maxEditFieldLabel_2             matlab.ui.control.Label
        maxEditField                    matlab.ui.control.NumericEditField
        AutodrawroisButton_2            matlab.ui.control.Button
        ClearoldROIsCheckBox            matlab.ui.control.CheckBox
        RemoveplanarbkgCheckBox         matlab.ui.control.CheckBox
        ManualPanel                     matlab.ui.container.Panel
        SeeGreenStackButton             matlab.ui.control.Button
        SeeRedStackButton               matlab.ui.control.Button
        refineROIsButton                matlab.ui.control.Button
        CopyLumiAxesButton              matlab.ui.control.Button
        SeeMergeStackButton             matlab.ui.control.Button
        OpenROImanagerButton            matlab.ui.control.StateButton
        Step2binarizationPanel          matlab.ui.container.Panel
        thrButtonGroup                  matlab.ui.container.ButtonGroup
        globalButton                    matlab.ui.control.RadioButton
        adaptiveButton                  matlab.ui.control.RadioButton
        nhoodEditFieldLabel             matlab.ui.control.Label
        nhoodEditField                  matlab.ui.control.NumericEditField
        SensitivityLabel                matlab.ui.control.Label
        thresholdSlider                 matlab.ui.control.Slider
        ThresholdEditField              matlab.ui.control.NumericEditField
        OpenLabel                       matlab.ui.control.Label
        dilateEditField                 matlab.ui.control.NumericEditField
        ErodeLabel                      matlab.ui.control.Label
        erodeEditField                  matlab.ui.control.NumericEditField
        binaryMaskCheckBox              matlab.ui.control.CheckBox
        DirectexportButton              matlab.ui.control.Button
        ExportimageButton               matlab.ui.control.Button
        VisualizationPanel              matlab.ui.container.Panel
        SelectedwavelengthSpinnerLabel  matlab.ui.control.Label
        SelectedwavelengthSpinner       matlab.ui.control.Spinner
        greenLUTPanel                   matlab.ui.container.Panel
        minLabel                        matlab.ui.control.Label
        CMinEditField                   matlab.ui.control.NumericEditField
        maxEditFieldLabel               matlab.ui.control.Label
        CMaxEditField                   matlab.ui.control.NumericEditField
        ResetButton                     matlab.ui.control.Button
        SelectedaxesDropDownLabel       matlab.ui.control.Label
        SelectedaxesDropDown            matlab.ui.control.DropDown
        LogYaxisCheckBox                matlab.ui.control.CheckBox
        histoAxes                       matlab.ui.control.UIAxes
        CopyforExcelandsaveROIsButton   matlab.ui.control.Button
        greenAxes                       matlab.ui.control.UIAxes
        redAxes                         matlab.ui.control.UIAxes
        mergeAxes                       matlab.ui.control.UIAxes
        lumiAxes                        matlab.ui.control.UIAxes
        deleteRoisContextMenu           matlab.ui.container.ContextMenu
        DeleteallroisMenu               matlab.ui.container.Menu
    end

    
    properties (Access = public)
        dirIn % Input folder name
        fileIn % input file name
        % image objects displayed into axes
        chImg % 2-element vector of graphic objects of type "Image", with the 
              % image of the green and red channel resplectively.
        mergeImg % image object of the merge channel. CData is an RGB image
        lumiImg % image object of the "luminance" channel (average of both 
                % channels at all the wavelenghts)
        gre % greem ch
        red % red channel
        lum % luminance
        n_wl % number of wavelengths
        x % width (pxl)
        y % height (pxl)
        
        % visualization controllers. 
        chAxes % 3-element vector of graphic objects of class "Axes".
               % (1=green chsnnel, 2=red channel, 3=lumi channel).
               
        cmin % structure with field "Value" containing the first element of
             % the property CData for each Axes
        cmax % structure with field "Value" containing the second element of
             % the property CData for each Axes

        roimanagerHandle % Handle to the app ROImanager
        bin_mask % binary mask.
%         masks % not sure if we need this property
        outDir % Path to the output folder
%         template % Description        
    end
    
    methods (Access = public)
        
        
        function showChannels(app)
            % find which image to plot
            wl=app.SelectedwavelengthSpinner.Value;
            
            % plot
            app.chImg(1).CData=app.gre(:,:,wl);
            app.chImg(2).CData=app.red(:,:,wl);
            
            % update the merged image
            updateMergedImg(app,1);
            updateMergedImg(app,2);
            
            % update histogram
            plotHisto(app)
            
            
        end
        
        function applymask(app,channel)
            % apply binary mask to ca++ movie and assign it to the pointer structure into axes UserData 
%             tmp = app.caMovie.movie .* app.ch(channel).mask;
%             tmp(tmp==0)=NaN;
%             app.chAxes(channel).UserData.data = tmp;
%             app.ch(channel).mask = app.chImg(channel).AlphaData;
            updateMergedImg(app,channel)
        end
        
        function updateMergedImg(app,channel)
            if channel==1
                mergechannel=2;
            else
                mergechannel=1;
            end
            img = app.chImg(channel).CData .* app.bin_mask;
%             img(img==0)=nan;
            app.mergeImg.CData(:,:,mergechannel) =  rescale(img,0,1,'inputmin',app.cmin(channel).Value,'inputmax', app.cmax(channel).Value);
            app.mergeAxes.XLim = [1,size(img,2)];
            app.mergeAxes.YLim = [1,size(img,1)];
        end
        
        function refineRois(app,channel)
%             if channel==1
%                 excludetag = "red";
                newtag = "roi";
%                 col=app.roiColors(1,:);
%             else
%                 excludetag = "green";
%                 newtag = "red";
%                 col=app.roiColors(2,:);
%             end
            % select all the rois from the chilren of our axes
            children = app.lumiAxes.Children;
            rois=children(arrayfun(@(x)(~isempty(regexp(class(x),'images.roi.','once'))),children));
            % exclude rois from the other channel
%             rois(strcmp({rois(:).Tag},excludetag)) = [];
            rois = flipud(rois); % the first roi is the last that has been drawn
            
            % find rois of the selected channel that are already there.
            oldrois = rois(strcmp({rois(:).Tag},newtag));
            oldlabels = {oldrois(:).Label};
            alllabels = arrayfun(@num2str,(1:length(rois))','UniformOutput',false);
            newlabels = alllabels( ~cellfun(@(x)(any(strcmp(x,oldlabels))),alllabels) );
            newlabels = newlabels(1: (length(rois)-length(oldrois))); % be sure
            
            newrois = rois(~strcmp({rois(:).Tag},newtag));
            for i = 1:numel(newrois)
%             for i = numel(rois):-1:1 
                if ~strcmp(newrois(i).Tag,newtag)
                    
                    thisroi = newrois(i)==[app.roimanagerHandle.storedROI.roi];

                    % intersection between binarized roi and mask
                    roimask=createMask(newrois(i));
                    inters = roimask & app.bin_mask;
                    
                    % if there are multiple elements, select the bigger one
                    stats = regionprops(inters,{'Area','PixelIdxList'});
                    [~,wider_i] = max([stats(:).Area]);
                    img = false(size(inters));
                    img(stats(wider_i).PixelIdxList) = true;
%
%                     edges = bwboundaries(img,'noholes');
%                     if isa(edges,'cell')
%                         edges=edges{1};
%                     end
                    edges = bwboundaries(img);
                    
                    if length(edges)>1
                        l = cellfun(@length,edges);
                        [~,si] = sort(l,'descend');
                        
                        ext = edges{si(1)};
                        int = edges{si(2)};
    
                        contour = [ext;int];
                    else
                        contour = edges{1};
                    end
                    if isa(newrois(i),'images.roi.Polygon')
                        % update coordinates
                        newrois(i).Position=fliplr(contour(1:2:end,:));
                    else
                        currroi=newrois(i);
                        delete(currroi)
                        newrois(i) = drawpolygon(app.lumiAxes,'Position',fliplr(contour(1:2:end,:)));
                    end
                    
                    % set roi aspect
                    newrois(i).MarkerSize = 0.1;
%                     newrois(i).Color = col;
                    
                    % set roi tag
                    newrois(i).Tag = newtag;
                    
                    % set roi label
                    newrois(i).Label = newlabels{i};
                
                    % update ROImanager
                    app.roimanagerHandle.storedROI(thisroi).roi = newrois(i);
                    app.roimanagerHandle.storedROI(thisroi).position.Position = newrois(i).Position;
                    app.roimanagerHandle.storedROI(thisroi).extra.MarkerSize = newrois(i).MarkerSize;
%                     app.roimanagerHandle.storedROI(thisroi).extra.Color = newrois(i).Color;
                    app.roimanagerHandle.storedROI(thisroi).extra.Tag = newrois(i).Tag;
                    app.roimanagerHandle.storedROI(thisroi).extra.Label = newrois(i).Label;
                    
                    app.roimanagerHandle.roiListBox.Items{thisroi} = sprintf('%s %s',newtag, newlabels{i});
                end
            end
            % update ROIs
%             updatefromROImanagerButtonPushed(app)
        end
        
        function numbers = getroinumbers(app,roiname,tag)
            % tag is either 'red' or 'green'
            selectedRoi = strcmp(app.roiListBox.Items,roiname);
            rois = [app.roimanagerHandle.storedROI.roi];
            these_rois = strcmp({rois(:).Tag},tag);
            these_rois(selectedRoi) = false;
            these_rois_names=app.roimanagerHandle.roiListBox.Items(these_rois);
            numbers=cellfun(@(x)(str2double(x(regexp(x,' '):end))),these_rois_names);
            if isempty(numbers)
                numbers = 0;
            end
        end
                
        
        function autodrawRois(app,channel,rangeArea)
%             if channel==1
                newtag = "roi";
%                 col= app.roiColors(1,:);
%             else
%                 newtag = "red";
%                 col= app.roiColors(2,:);
%             end
%             app.roimanagerHandle
            
            stats = regionprops(app.bin_mask,{'Area','PixelIdxList'});
            stats([stats(:).Area]<rangeArea(1)|[stats(:).Area]>rangeArea(2))=[];
            pols=images.roi.Polygon.empty;
            for i = 1:length(stats)
                thismask=false(size(app.bin_mask));
                thismask(stats(i).PixelIdxList)=true;
                edges = bwboundaries(thismask);       
                if length(edges)>1
                    l = cellfun(@length,edges);
                    [~,si] = sort(l,'descend');
            
                    ext = edges{si(1)};
                    int = edges{si(2)};
            
                    contour = [ext;int];
                else
                    contour = edges{1};
                end
                pols(i)=drawpolygon(app.lumiAxes,'Position',fliplr(contour(1:2:end,:)));
                app.roimanagerHandle.newROI=pols(i);
                call_StoreButtonPushed(app.roimanagerHandle)
                pols(i).MarkerSize = 0.1;
%                 pols(i).Color = col;
            
                % set roi tag
                pols(i).Tag = newtag;
            
                % set roi label
                pols(i).Label = num2str(i);
                
                % update ROImanager
                app.roimanagerHandle.storedROI(end).roi = pols(i);
                app.roimanagerHandle.storedROI(end).position.Position = pols(i).Position;
                app.roimanagerHandle.storedROI(end).extra.MarkerSize = pols(i).MarkerSize;
                app.roimanagerHandle.storedROI(end).extra.Color = pols(i).Color;
                app.roimanagerHandle.storedROI(end).extra.Tag = pols(i).Tag;
                app.roimanagerHandle.storedROI(end).extra.Label = pols(i).Label;
            
                app.roimanagerHandle.roiListBox.Items{end} = sprintf('%s %03i',newtag,i);
            end
            
        end
        
        function openExtAxes(app,channel)
            f = figure;
            switch channel
                case 3
                    h = copyobj(app.lumiAxes,f);
                case 4
                    h = copyobj(app.mergeAxes,f);
                otherwise
                    h = copyobj(app.chAxes(channel),f);
            end
            h.Units = 'normalized';
            h.OuterPosition = [0.05 0.05 0.9 0.9];
        end
        
        function loadFcn(app,file_in,tmpdir_in)
            % store selected filename and path
            app.dirIn = tmpdir_in;
            app.fileIn = file_in;
            
            % import ch1
            info = imfinfo([tmpdir_in,file_in]);
            app.n_wl = numel(info);
            app.y= info(1).Height;
            app.x= info(1).Width;
            app.gre=nan(app.y,app.x,app.n_wl);
            for i=1:app.n_wl
                app.gre(:,:,i) = imread([tmpdir_in,file_in],"tif",i);
            end
            
            % import ch2
            file_in_red= strrep(file_in,'Ch1','Ch2');
            app.red=nan(app.y,app.x,app.n_wl);
            for i=1:app.n_wl
                app.red(:,:,i) = imread([tmpdir_in,file_in_red],"tif",i);
            end
            
            % luminance channel
            app.lum = mean(cat(3,app.gre,app.red),3,'omitnan');
            
            % inizialize images
            app.chImg(1) = imagesc(app.greenAxes,app.gre(:,:,1));
            app.chImg(2) = imagesc(app.redAxes,app.red(:,:,1));
            axis(app.greenAxes,[1 app.x 1 app.y])
            axis(app.redAxes,[1 app.x 1 app.y])
            app.lumiImg=imagesc(app.lumiAxes,app.lum);
            axis(app.lumiAxes,[1 app.x 1 app.y])
            app.mergeImg = imagesc(app.mergeAxes,cat(3,app.red(:,:,1),app.gre(:,:,1),zeros(app.y,app.x)));
            axis(app.mergeAxes,[1 app.x 1 app.y])
            
            % initialize cmin and cmax
            if isempty(app.cmin)
                app.cmin(1).Value = min(app.chImg(1).CData,[],'all');
                app.cmin(2).Value = min(app.chImg(2).CData,[],'all');
                app.cmin(3).Value = min(app.lum,[],'all');
                app.cmax(1).Value = max(app.chImg(1).CData,[],'all');
                app.cmax(2).Value = max(app.chImg(2).CData,[],'all');
                app.cmax(3).Value = max(app.lum,[],'all');
            end
            % initialize mask
            app.bin_mask=ones(app.y,app.x);
            
            % plot
            showChannels(app)
                        
            % display folder name
%             foldParsed = split(app.dirIn,filesep);
%             app.fileLabel.Text = foldParsed{end-1};
            app.fileLabel.Text = app.fileIn;
            
            % replot mask
            binaryMaskCheckBoxValueChanged(app,[])
            
            % store wavelengths
            str = struct('data',cat(3,app.gre,app.red),'x',repmat(1:app.n_wl,1,2));
            app.greenAxes.UserData = str;
            app.redAxes.UserData = str;
            app.lumiAxes.UserData = str;
            app.mergeAxes.UserData = str;
            
            % update wl spinner
            if app.n_wl>1
                app.SelectedwavelengthSpinner.Limits = [1,app.n_wl];
                app.SelectedwavelengthSpinner.Enable = 'on';
            else
                app.SelectedwavelengthSpinner.Limits = [1,inf];
                app.SelectedwavelengthSpinner.Enable = 'off';
            end
        end
        
        
        function plotHisto(app)
            switch app.SelectedaxesDropDown.Value
                case 'Green'
                    img=app.chImg(1).CData;
                case 'Red'
                    img=app.chImg(2).CData;
                case 'Luminance'
                    img=app.lum;
            end
            limits = [max(min(img,[],'all'),-20), min(max(img,[],'all'),4095)];
            histogram(app.histoAxes,img,linspace(limits(1),limits(2),512));
            app.histoAxes.XLim = [limits(1), limits(2)];
            app.histoAxes.YLim = [1 Inf];
            if app.LogYaxisCheckBox.Value
                app.histoAxes.YScale='log';
            else
                app.histoAxes.YScale='linear';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % initialize images
            app.chImg = matlab.graphics.primitive.Image.empty(2,0);
            app.chImg(1) = imagesc(app.greenAxes,nan);
            app.chImg(2) = imagesc(app.redAxes,nan);
            app.mergeImg = imagesc(app.mergeAxes,[nan,nan,nan]);

            % initialize colormaps
            app.greenAxes.Colormap = [zeros(4096,1),linspace(0,1,4096)',zeros(4096,1)];
            app.redAxes.Colormap = [linspace(0,1,4096)',zeros(4096,1),zeros(4096,1)];
            app.lumiAxes.Colormap = turbo;
            
            % group controllers
            app.chAxes = [app.greenAxes, app.redAxes];
%             app.histoaxes = [app.histoAxes, app.redHistoAxes];
%             app.thrsliders = [app.thresholdSlider, app.redThresholdSlider];
%             app.binarycheck = [app.binaryMaskCheckBox, app.redBinaryMaskCheckBox];
            
            
            % initialize ROI listbox
%             app.roiListBox.Items = {};
%             app.roiListBox.Multiselect = 'on';
            
            % initialize output folder
            tmp = mfilename('fullpath');
            sepind = regexp(tmp, filesep);
            app.outDir = tmp(1:sepind(end));
            
            % add to path
            this_file = mfilename('fullpath');
            sep=regexp(this_file,filesep);
            this_folder = this_file(1:sep(end)-1);
            addpath([this_folder filesep 'utilities'])
            addpath([this_folder filesep 'ImageManager'])
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % select a preprocessed tiff file generated with
            % "macro_preprocess_wavelengths.mlapp"
            
            [file_in,tmpdir_in] = uigetfile([app.dirIn '*.tif']);
            figure(app.mainFigure)
            if isa(tmpdir_in,'double')
                return
            end
            loadFcn(app,file_in,tmpdir_in)
        end

        % Value changing function: thresholdSlider
        function thresholdSliderValueChanging(app, event)
            % this callback compute and applies a binary mask based on the
            % selected method (Global or Adaptive) and threshold.
            app.ThresholdEditField.Value = app.thresholdSlider.Value;
            if app.globalButton.Value
                % Common binarization with global threshold.
                thr = app.thresholdSlider.Value;
                app.bin_mask = imbinarize(app.lum,thr);
            else
                % Binarization with a local (adaptive) threshold. For this
                % method to work, the input image must have no inf or nan
                % values, as values have to be rescaled between 0 and 1. 
                sens = app.thresholdSlider.Value;
                img = app.lum;
                % substitute Inf and NaN with 80th centile
                img(img==-inf | img==inf)=nan;
                img(isnan(img))= quantile(img,0.8,'all');
                % rescale and apply a gamma
                img = rescale(img,0,1).^0.4;
                app.bin_mask = imbinarize(img,adaptthresh(img,sens,'NeighborhoodSize',app.nhoodEditField.Value,'Statistic','gaussian'));

            end
            if app.dilateEditField.Value>0
                dil_strel=strel("disk",app.dilateEditField.Value,4);
                app.bin_mask = imopen(app.bin_mask,dil_strel);
            end
            if app.erodeEditField.Value>0
                ero_strel=strel("disk",app.erodeEditField.Value,4);
                app.bin_mask = imerode(app.bin_mask,ero_strel);
            end
            app.lumiImg.AlphaData = app.bin_mask;
            drawnow
        end

        % Value changed function: thresholdSlider
        function thresholdSliderValueChanged(app, event)
            thresholdSliderValueChanging(app, [])
%             app.bin_mask = app.chImg(1).AlphaData;
            if ~app.binaryMaskCheckBox.Value
                app.chImg(1).AlphaData=ones(size(app.chImg(1).CData));
                app.chImg(2).AlphaData=ones(size(app.chImg(1).CData));
                app.lumiImg(1).AlphaData=ones(size(app.chImg(1).CData));
                app.mergeImg(1).AlphaData=ones(size(app.chImg(1).CData));
            else
                app.chImg(1).AlphaData=app.bin_mask;
                app.chImg(2).AlphaData=app.bin_mask;
                app.lumiImg(1).AlphaData=app.bin_mask;
                app.mergeImg(1).AlphaData=app.bin_mask;
            end
            
            applymask(app,1)
            
        end

        % Callback function
        function OpenROImanagerButtonValueChanged(app, event)
            value = app.OpenROImanagerButton.Value;
            if value
                if ~isempty(app.roimanagerHandle)
                    if isvalid(app.roimanagerHandle)
                        delete(app.roimanagerHandle)                        
                    end
                end
               app.roimanagerHandle = ROImanager(app);
            else
               delete(app.roimanagerHandle);
            end
        end

        % Value changed function: CMaxEditField, CMinEditField
        function greenCMaxEditFieldValueChanged(app, event)
            m=app.CMinEditField.Value;
            M=app.CMaxEditField.Value;
            switch app.SelectedaxesDropDown.Value
                case 'Green'
                    app.greenAxes.CLim = [m, M];
                    app.cmin(1).Value=m;
                    app.cmax(1).Value=M;
                    updateMergedImg(app,1)
                case 'Red'
                    app.redAxes.CLim = [m, M];
                    app.cmin(2).Value=m;
                    app.cmax(2).Value=M;
                    updateMergedImg(app,2)
                case 'Luminance'
                    app.lumiAxes.CLim = [m, M];
                    if app.globalButton.Value
                        app.thresholdSlider.Limits = [m,M];
                    end
                    
            end


        end

        % Value changed function: binaryMaskCheckBox
        function binaryMaskCheckBoxValueChanged(app, event)
            if app.binaryMaskCheckBox.Value
                thresholdSliderValueChanged(app, event)
            else
                app.chImg(1).AlphaData=ones(size(app.chImg(1).CData));
                app.chImg(2).AlphaData=ones(size(app.chImg(2).CData));
                app.lumiImg.AlphaData=ones(size(app.chImg(2).CData));
                app.mergeImg.AlphaData=ones(size(app.chImg(2).CData));
                app.bin_mask=ones(size(app.chImg(1).CData));
                updateMergedImg(app,1)
                updateMergedImg(app,2)
            end
        end

        % Button pushed function: refineROIsButton
        function refineROIsButtonPushed(app, event)
            refineRois(app)
        end

        % Callback function
        function VisualizationButtonGroupSelectionChanged(app, event)
            showChannel(app,1)
            if ~isempty(app.ch(2).maxProj)
                showChannel(app,2)
            end
            
        end

        % Callback function
        function DrawprofilesButtonPushed(app, event)
            updatefromROImanagerButtonPushed(app, event)
            nrois = numel(app.roiListBox.Items);
            t = app.caMovie.metadata.tpoints;
            profile = nan(nrois,length(t));
            maxspan=0;
            colors = zeros(nrois,3);
            tags = cell(nrois,1);
            for i=1:nrois
                % if a ROI does not exist anymore, draw it again onto the
                % green axes
                if ~isvalid(app.roimanagerHandle.storedROI(i).roi)
                    app.roimanagerHandle.storedROI(i).roi = app.roimanagerHandle.redrawROI(app.roimanagerHandle.storedROI(i),app.greenAxes);
                end
                % store the time profile into 'p'
                p=app.roimanagerHandle.zProfile(app.roimanagerHandle.storedROI(i).roi,[]);
                tags(i) = {app.roimanagerHandle.storedROI(i).roi.Tag};
                if isempty(tags{i})
                    tags(i) = {'--'};
                end
                colors(i,:)=app.roimanagerHandle.storedROI(i).roi.Color;
                profile(i,:)=p(:,2);
                if app.ComputedFFCheckBox.Value
                    fmin = min(profile(i,:));
                    profile(i,:) = (profile(i,:)-fmin)./fmin;
                end
                currentspan = (max(profile(i,:),[],'all')- min(profile(i,:),[],'all'));
                if currentspan>maxspan
                    maxspan=currentspan;
                end
            end
            levels = (0:nrois-1)*maxspan;
            app.profileAxes.ColorOrder = colors;
            plot(app.profileAxes,t,profile'+levels-mean(profile,2)')
            app.profileAxes.YTick = levels;
            app.profileAxes.YTickLabels = cellfun(@(x)(x(regexp(x,' '):end)),app.roimanagerHandle.roiListBox.Items,'UniformOutput',false);
            app.profileAxes.YTickLabels(cellfun(@isempty,app.profileAxes.YTickLabels)) = {'--'};
            app.profileAxes.XLim = [t(1), t(end)];
            app.profileAxes.Color = [1 1 1];
            app.profiles.lines = profile;
            app.profiles.t = t;
            app.profiles.tags = tags;
            app.profiles.numbers = app.profileAxes.YTickLabels;
            
%             app.roimanagerHandle.roiListBox.Value = app.roimanagerHandle.roiListBox.Items;
%             app.roimanagerHandle.axesDropDownValueChanged_fcn();
%             app.roimanagerHandle.axesDropDown.Value = app.roimanagerHandle.axesDropDown.Items(strcmp(app.roimanagerHandle.axesDropDown.Items,'Merge'));
%             app.roimanagerHandle.call_roiListBoxValueChanged()
        end

        % Button pushed function: DirectexportButton
        function DirectexportButtonPushed(app, event)
            fr = getframe(app.mergeAxes);
            imwrite(fr.cdata,sprintf('%s%s%s_merge.tif',app.outDir,filesep,app.fileLabel.Text))
            disp('Image saved')
        end

        % Selection changed function: thrButtonGroup
        function thrButtonGroupSelectionChanged(app, event)
            selectedButton = app.thrButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Global'
                    app.thresholdSlider.Limits = [app.CMinEditField.Value,app.CMaxEditField.Value];
                case 'Adaptive'
                    [first,last]=evaluateSens(app.lumiImg.CData);
                    if isempty(first)
                        first=0;
                    end
                    if isempty(last)
                        last=1;
                    end
                    app.thresholdSlider.Limits = [first last];
            end
            if app.binaryMaskCheckBox.Value
                app.thresholdSliderValueChanging([])
                app.thresholdSliderValueChanged([])
            end
        end

        % Callback function
        function AutodrawroisButtonPushed(app, event)
            mindiam=app.MinimumdiametermicronsEditField.Value./app.ch(1).metadata.dx_um;
            autodrawRois(app,1,pi*mindiam.^2)
        end

        % Callback function
        function CopyGreenAxesButtonPushed(app, event)
            openExtAxes(app,1)
        end

        % Callback function
        function CopyRedAxesButtonPushed(app, event)
            openExtAxes(app,2)
        end

        % Callback function
        function AutolabelroisButtonPushed(app, event)
            % Green rois havew been drawn on the green binary map. This
            % function label as 'red' the rois whos overlap with the red
            % binary mask is >= n% of the roi area. n is set by the user with the
            % appropriate edit field 
            
            % update rois from ROImanager
            updatefromROImanagerButtonPushed(app, [])
            n=numel(app.roimanagerHandle.storedROI);
            ko=false(1,n);
            thr = app.MinredfractionEditField.Value; % max fraction of roi area
            for i=1:n
                thisroi = app.roimanagerHandle.storedROI(i).roi;
            
            	% intersection between binarized roi and mask
                roimask=createMask(thisroi);
                inters = roimask & app.ch(2).mask;
            
                % compute areas
                roiProps = regionprops(roimask,{'Area'});
                roiArea = sum([roiProps(:).Area]);
                intProps = regionprops(inters,{'Area'});
                intArea = sum([intProps(:).Area]);
                
                % assign roi
                if intArea/roiArea>=thr
                    ko(i)=true;
                end
            end
            app.roiListBox.Value = app.roiListBox.Items(ko);
            LabelasredMenuSelected(app, [])
            app.roiListBox.Value = app.roiListBox.Items(~ko);
            LabelasgreenMenuSelected(app, [])
        end

        % Callback function
        function CLimEditFieldValueChanged(app, event)
            value = app.CLimEditField.Value;
            app.stxcorrAxes.CLim = [-value, value];
        end

        % Callback function
        function FromsEditFieldValueChanged(app, event)
            lims = [app.FromsEditField.Value, app.TosEditField.Value];
            app.data1axes.XLim = lims;
            app.data2axes.XLim = lims;
            app.stxcorrAxes.XLim = lims;
        end

        % Button pushed function: ExportimageButton
        function ExportimageButtonPushed(app, event)
            fname = sprintf('%s%s%s_merge.tif',app.outDir,filesep,app.fileLabel.Text);
            [newfilename,newOutDir] = uiputfile('*.tif','Save image',fname);
            
            fr = getframe(app.mergeAxes);
            imwrite(fr.cdata,strcat(newOutDir,newfilename))
            disp('Image saved')
        end

        % Value changed function: nhoodEditField
        function nhoodEditFieldValueChanged(app, event)
            if app.binaryMaskCheckBox.Value
                
                if app.thrButtonGroup.SelectedObject == app.adaptiveButton
                    [first,last]=evaluateSens(app.chImg(1).CData,app.nhoodEditField.Value);
                    if isempty(first)
                        first=0;
                    end
                    if isempty(last)
                        last=1;
                    end
                    app.thresholdSlider.Limits = [first last];
                end
                
                
                app.thresholdSliderValueChanging([])
                app.thresholdSliderValueChanged([])
            end
        end

        % Value changed function: SelectedwavelengthSpinner
        function SelectedwavelengthSpinnerValueChanged(app, event)
%             wl = app.SelectedwavelengthSpinner.Value;
%             app.chImg(1).CData=app.gre(:,:,wl);
%             app.chImg(2).CData=app.red(:,:,wl);
%             updateMergedImg(app,1)
%             updateMergedImg(app,2)
            app.showChannels
        end

        % Value changed function: SelectedaxesDropDown
        function SelectedaxesDropDownValueChanged(app, event)
            value = app.SelectedaxesDropDown.Value;
            switch value
                case 'Green'
                    app.CMinEditField.Value=app.cmin(1).Value;
                    app.CMaxEditField.Value=app.cmax(1).Value;
                case 'Red'
                    app.CMinEditField.Value=app.cmin(2).Value;
                    app.CMaxEditField.Value=app.cmax(2).Value;
                case 'Luminance'
                    app.CMinEditField.Value=app.cmin(3).Value;
                    app.CMaxEditField.Value=app.cmax(3).Value;
            end
            plotHisto(app)
        end

        % Value changed function: OpenROImanagerButton
        function OpenROImanagerButtonValueChanged2(app, event)
            value = app.OpenROImanagerButton.Value;
            if value
                % open
                app.roimanagerHandle = ROImanager(app);
            else
                app.roimanagerHandle.delete
                app.roimanagerHandle = [];
            end
        end

        % Button pushed function: SeeGreenStackButton
        function SeeGreenStackButtonPushed2(app, event)
%             openExtAxes(app,1)
            f=figure('Name',sprintf('Merge %s',app.fileIn));
            
            % remove nan
            g=app.gre;
            g(isnan(g))=app.greenAxes.CLim(1);
            
            s=mySliceViewer(f,g);
            s.Colormap=app.greenAxes.Colormap;
            s.DisplayRange=app.greenAxes.CLim;
        end

        % Button pushed function: SeeRedStackButton
        function SeeRedStackButtonPushed2(app, event)
%             openExtAxes(app,2)
            f=figure('Name',sprintf('Merge %s',app.fileIn));
            
            % remove nan
            r=app.red;
            r(isnan(r))=app.redAxes.CLim(1);
            
            s=mySliceViewer(f,r);
            s.Colormap=app.redAxes.Colormap;
            s.DisplayRange=app.redAxes.CLim;
        end

        % Button pushed function: CopyLumiAxesButton
        function CopyLumiAxesButtonPushed(app, event)
            openExtAxes(app,3)
        end

        % Button pushed function: AutodrawroisButton_2
        function AutodrawroisButton_2Pushed(app, event)
            if app.ClearoldROIsCheckBox.Value
                % select all the ROIs
                app.roimanagerHandle.roiListBox.Value = app.roimanagerHandle.roiListBox.Items;
                % delete them
                event=struct('Source',app.roimanagerHandle.deleteRoiButton);
                app.roimanagerHandle.deleteRoiButtonPushed_callFcn(event);
                drawnow
            end
            
            minArea=app.Areapxl2minEditField.Value;
            maxArea=app.maxEditField.Value;
            autodrawRois(app,1,[minArea,maxArea])
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            value = app.ThresholdEditField.Value;
            app.thresholdSlider.Value=value;
            app.thresholdSliderValueChanging
            app.thresholdSliderValueChanged
        end

        % Button pushed function: SeeMergeStackButton
        function SeeMergeStackButtonPushed(app, event)
%             openExtAxes(app,4)
            figure('Name',sprintf('Merge %s',app.fileIn));
            % combine green and red channels into a stack of RGB images
            % NOTE: sliceVier requires the RGB stack dimensions to be:
            % y,x,frames,3
            stack=zeros(app.y,app.x,app.n_wl,3);
            % rescale the green channel to 0-1
            g=rescale(app.gre,0,1,...
                "InputMin",app.greenAxes.CLim(1),"InputMax",app.greenAxes.CLim(2));
            g(isnan(g))=app.greenAxes.CLim(1);
            stack(:,:,:,2) = g;
            % rescale the red channel to 0-1
            r = rescale(app.red,0,1,...
                "InputMin",app.redAxes.CLim(1),"InputMax",app.redAxes.CLim(2));
            r(isnan(r))=app.redAxes.CLim(1);
            stack(:,:,:,1) = r;
%             mySliceViewer(f,stack);
            sliceViewer(stack);
        end

        % Button pushed function: CopyforExcelandsaveROIsButton
        function CopyforExcelandsaveROIsButtonPushed(app, event)
            % save ROIs: work on ROImanager
            % select all the ROIs
            app.roimanagerHandle.roiListBox.Value = app.roimanagerHandle.roiListBox.Items;
            % create the filename
            fname= strcat( "ROIset_",strrep(app.fileIn,'.tif','.mat'));
            % call save function
            app.roimanagerHandle.saveROIs(strcat(app.dirIn,fname))
            
            % compute ROIs average 
            app.roimanagerHandle.completeTable = []; % reset table first
            app.roimanagerHandle.updateTable()
            app.roimanagerHandle.AllFramesCheckBox.Value=true; % all "frames" (i.e: wavelengths and channels)
            % only measure the mean value
            app.roimanagerHandle.MeanCheckBox.Value = true;
            app.roimanagerHandle.MinimumCheckBox.Value = false; 
            app.roimanagerHandle.MaximumCheckBox.Value = false; 
            app.roimanagerHandle.StandarddeviationCheckBox.Value = false; 
            app.roimanagerHandle.MedianCheckBox.Value = false; 
            app.roimanagerHandle.SumCheckBox.Value = false;
            app.roimanagerHandle.AreaCheckBox.Value = false; 
 
            app.roimanagerHandle.measure()
            
            data=app.roimanagerHandle.completeTable{:,4};
%             n_rois=numel(app.roimanagerHandle.storedROI);
            data_resh=reshape(data,app.n_wl*2,[]);
            % make format string
            fmt='';
            for i=1:app.n_wl*2
                fmt=strcat(fmt,'%.4f\t');
            end
%             fmt(end)=[];
            fmt=strcat(fmt,'\n');
            str=sprintf(fmt,data_resh);
            clipboard("copy",str)
            disp('Output copied to clipboard')
        end

        % Value changed function: LogYaxisCheckBox
        function LogYaxisCheckBoxValueChanged(app, event)
            value = app.LogYaxisCheckBox.Value;
            if value
                app.histoAxes.YScale = 'log';
            else
                app.histoAxes.YScale = 'linear';
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mainFigure = uifigure('Visible', 'off');
            app.mainFigure.Name = 'eROIClo';
            app.Step1LoadprocessedstacksPanel = uipanel(app.mainFigure);
            app.Step1LoadprocessedstacksPanel.Title = 'Step 1: Load processed stacks';
            app.fileLabel = uilabel(app.Step1LoadprocessedstacksPanel);
            app.fileLabel.Text = 'No file';
            app.LoadButton = uibutton(app.Step1LoadprocessedstacksPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.FormatDropDownLabel = uilabel(app.Step1LoadprocessedstacksPanel);
            app.FormatDropDownLabel.Text = 'Format:';
            app.FormatDropDown = uidropdown(app.Step1LoadprocessedstacksPanel);
            app.FormatDropDown.Items = {'Multi-tiff'};
            app.FormatDropDown.Value = 'Multi-tiff';
            app.Step3drawROIsPanel = uipanel(app.mainFigure);
            app.Step3drawROIsPanel.Title = 'Step 3: draw ROIs';
            app.AutomaticPanel = uipanel(app.Step3drawROIsPanel);
            app.AutomaticPanel.Title = 'Automatic';
            app.Areapxl2minEditFieldLabel = uilabel(app.AutomaticPanel);
            app.Areapxl2minEditFieldLabel.Text = 'Area (pxl^2) min:';
            app.Areapxl2minEditField = uieditfield(app.AutomaticPanel, 'numeric');
            app.Areapxl2minEditField.Value = 100;
            app.maxEditFieldLabel_2 = uilabel(app.AutomaticPanel);
            app.maxEditFieldLabel_2.Text = 'max:';
            app.maxEditField = uieditfield(app.AutomaticPanel, 'numeric');
            app.maxEditField.Value = 2500;
            app.AutodrawroisButton_2 = uibutton(app.AutomaticPanel, 'push');
            app.AutodrawroisButton_2.ButtonPushedFcn = createCallbackFcn(app, @AutodrawroisButton_2Pushed, true);
            app.AutodrawroisButton_2.Text = 'Auto draw rois';
            app.ClearoldROIsCheckBox = uicheckbox(app.AutomaticPanel);
            app.ClearoldROIsCheckBox.Text = 'Clear old ROIs';
            app.ClearoldROIsCheckBox.Value = true;
            app.RemoveplanarbkgCheckBox = uicheckbox(app.AutomaticPanel);
            app.RemoveplanarbkgCheckBox.Text = 'Remove planar bkg';
            app.RemoveplanarbkgCheckBox.Value = true;
            app.ManualPanel = uipanel(app.Step3drawROIsPanel);
            app.ManualPanel.Title = 'Manual';
            app.SeeGreenStackButton = uibutton(app.ManualPanel, 'push');
            app.SeeGreenStackButton.ButtonPushedFcn = createCallbackFcn(app, @SeeGreenStackButtonPushed2, true);
            app.SeeGreenStackButton.Text = 'See Green Stack';
            app.SeeRedStackButton = uibutton(app.ManualPanel, 'push');
            app.SeeRedStackButton.ButtonPushedFcn = createCallbackFcn(app, @SeeRedStackButtonPushed2, true);
            app.SeeRedStackButton.Text = 'See Red Stack';
            app.refineROIsButton = uibutton(app.ManualPanel, 'push');
            app.refineROIsButton.ButtonPushedFcn = createCallbackFcn(app, @refineROIsButtonPushed, true);
            app.refineROIsButton.Text = 'Refine ROIs';
            app.CopyLumiAxesButton = uibutton(app.ManualPanel, 'push');
            app.CopyLumiAxesButton.ButtonPushedFcn = createCallbackFcn(app, @CopyLumiAxesButtonPushed, true);
            app.CopyLumiAxesButton.Text = 'Copy Lumi Axes';
            app.SeeMergeStackButton = uibutton(app.ManualPanel, 'push');
            app.SeeMergeStackButton.ButtonPushedFcn = createCallbackFcn(app, @SeeMergeStackButtonPushed, true);
            app.SeeMergeStackButton.Text = 'See Merge Stack';
            app.OpenROImanagerButton = uibutton(app.Step3drawROIsPanel, 'state');
            app.OpenROImanagerButton.ValueChangedFcn = createCallbackFcn(app, @OpenROImanagerButtonValueChanged2, true);
            app.OpenROImanagerButton.Text = 'Open ROImanager';
            app.Step2binarizationPanel = uipanel(app.mainFigure);
            app.Step2binarizationPanel.Title = 'Step 2: binarization';
            app.thrButtonGroup = uibuttongroup(app.Step2binarizationPanel);
            app.thrButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @thrButtonGroupSelectionChanged, true);
            app.thrButtonGroup.Title = 'Threshold type:';
            app.globalButton = uiradiobutton(app.thrButtonGroup);
            app.globalButton.Text = 'Global';
            app.adaptiveButton = uiradiobutton(app.thrButtonGroup);
            app.adaptiveButton.Text = 'Adaptive';
            app.adaptiveButton.Value = true;
            app.nhoodEditFieldLabel = uilabel(app.thrButtonGroup);
            app.nhoodEditFieldLabel.Text = 'nhood';
            app.nhoodEditField = uieditfield(app.thrButtonGroup, 'numeric');
            app.nhoodEditField.ValueChangedFcn = createCallbackFcn(app, @nhoodEditFieldValueChanged, true);
            app.nhoodEditField.Value = 45;
            app.SensitivityLabel = uilabel(app.Step2binarizationPanel);
            app.SensitivityLabel.Text = 'Sensitivity';
            app.thresholdSlider = uislider(app.Step2binarizationPanel);
            app.thresholdSlider.Limits = [0 4095];
            app.thresholdSlider.ValueChangedFcn = createCallbackFcn(app, @thresholdSliderValueChanged, true);
            app.thresholdSlider.ValueChangingFcn = createCallbackFcn(app, @thresholdSliderValueChanging, true);
            app.ThresholdEditField = uieditfield(app.Step2binarizationPanel, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.OpenLabel = uilabel(app.Step2binarizationPanel);
            app.OpenLabel.Text = 'Open';
            app.dilateEditField = uieditfield(app.Step2binarizationPanel, 'numeric');
            app.dilateEditField.Value = 2;
            app.ErodeLabel = uilabel(app.Step2binarizationPanel);
            app.ErodeLabel.Text = 'Erode';
            app.erodeEditField = uieditfield(app.Step2binarizationPanel, 'numeric');
            app.erodeEditField.Value = 2;
            app.binaryMaskCheckBox = uicheckbox(app.Step2binarizationPanel);
            app.binaryMaskCheckBox.ValueChangedFcn = createCallbackFcn(app, @binaryMaskCheckBoxValueChanged, true);
            app.binaryMaskCheckBox.Text = 'Overlay binarization';
            app.DirectexportButton = uibutton(app.mainFigure, 'push');
            app.DirectexportButton.ButtonPushedFcn = createCallbackFcn(app, @DirectexportButtonPushed, true);
            app.DirectexportButton.Text = 'Direct export';
            app.ExportimageButton = uibutton(app.mainFigure, 'push');
            app.ExportimageButton.ButtonPushedFcn = createCallbackFcn(app, @ExportimageButtonPushed, true);
            app.ExportimageButton.Text = 'Export image...';
            app.VisualizationPanel = uipanel(app.mainFigure);
            app.VisualizationPanel.Title = 'Visualization';
            app.SelectedwavelengthSpinnerLabel = uilabel(app.VisualizationPanel);
            app.SelectedwavelengthSpinnerLabel.Text = 'Selected wavelength';
            app.SelectedwavelengthSpinner = uispinner(app.VisualizationPanel);
            app.SelectedwavelengthSpinner.ValueChangedFcn = createCallbackFcn(app, @SelectedwavelengthSpinnerValueChanged, true);
            app.SelectedwavelengthSpinner.Value = 1;
            app.greenLUTPanel = uipanel(app.VisualizationPanel);
            app.greenLUTPanel.Title = 'Adjust LUT';
            app.minLabel = uilabel(app.greenLUTPanel);
            app.minLabel.Text = 'min';
            app.CMinEditField = uieditfield(app.greenLUTPanel, 'numeric');
            app.CMinEditField.ValueChangedFcn = createCallbackFcn(app, @greenCMaxEditFieldValueChanged, true);
            app.maxEditFieldLabel = uilabel(app.greenLUTPanel);
            app.maxEditFieldLabel.Text = 'max';
            app.CMaxEditField = uieditfield(app.greenLUTPanel, 'numeric');
            app.CMaxEditField.ValueChangedFcn = createCallbackFcn(app, @greenCMaxEditFieldValueChanged, true);
            app.CMaxEditField.Value = 4095;
            app.ResetButton = uibutton(app.greenLUTPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SelectedaxesDropDownLabel = uilabel(app.VisualizationPanel);
            app.SelectedaxesDropDownLabel.Text = 'Selected axes';
            app.SelectedaxesDropDown = uidropdown(app.VisualizationPanel);
            app.SelectedaxesDropDown.Items = {'Green', 'Red', 'Luminance'};
            app.SelectedaxesDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectedaxesDropDownValueChanged, true);
            app.SelectedaxesDropDown.Value = 'Green';
            app.LogYaxisCheckBox = uicheckbox(app.VisualizationPanel);
            app.LogYaxisCheckBox.ValueChangedFcn = createCallbackFcn(app, @LogYaxisCheckBoxValueChanged, true);
            app.LogYaxisCheckBox.Text = 'Log Y axis';
            app.histoAxes = uiaxes(app.VisualizationPanel);
            app.histoAxes.XLim = [0 4095];
            app.histoAxes.YTick = [];
            app.histoAxes.Tag = 'greenHisto';
            app.CopyforExcelandsaveROIsButton = uibutton(app.mainFigure, 'push');
            app.CopyforExcelandsaveROIsButton.ButtonPushedFcn = createCallbackFcn(app, @CopyforExcelandsaveROIsButtonPushed, true);
            app.CopyforExcelandsaveROIsButton.Text = 'Copy for Excel and save ROIs';
            app.greenAxes = uiaxes(app.mainFigure);
            title(app.greenAxes, 'Green')
            app.greenAxes.YDir = 'reverse';
            app.greenAxes.XTick = [];
            app.greenAxes.YTick = [];
            app.greenAxes.Tag = 'Green';
            app.redAxes = uiaxes(app.mainFigure);
            title(app.redAxes, 'Red')
            app.redAxes.YDir = 'reverse';
            app.redAxes.XTick = [];
            app.redAxes.YTick = [];
            app.redAxes.Tag = 'Red';
            app.mergeAxes = uiaxes(app.mainFigure);
            title(app.mergeAxes, 'Merge')
            app.mergeAxes.YDir = 'reverse';
            app.mergeAxes.XTick = [];
            app.mergeAxes.YTick = [];
            app.mergeAxes.Tag = 'Merge';
            app.lumiAxes = uiaxes(app.mainFigure);
            title(app.lumiAxes, 'Luminance')
            app.lumiAxes.YDir = 'reverse';
            app.lumiAxes.XTick = [];
            app.lumiAxes.YTick = [];
            app.lumiAxes.Tag = 'Lumi';
            app.deleteRoisContextMenu = uicontextmenu(app.mainFigure);
            % Assign app.deleteRoisContextMenu
            app.greenAxes.ContextMenu = app.deleteRoisContextMenu;
            app.redAxes.ContextMenu = app.deleteRoisContextMenu;
            app.DeleteallroisMenu = uimenu(app.deleteRoisContextMenu);
            app.DeleteallroisMenu.Text = 'Delete all rois';
            % Show the figure after all components are created
            app.mainFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = eROIClo_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mainFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mainFigure)
        end
    end
end