classdef macro_process_wavelengths < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChlorideImaging_preprocessing  matlab.ui.Figure
        MedianfilteronimagesCheckBox   matlab.ui.control.CheckBox
        ZstackCheckBox                 matlab.ui.control.CheckBox
        FlatcorrectionCheckBox         matlab.ui.control.CheckBox
        RegisterwavelengthsCheckBox    matlab.ui.control.CheckBox
        Darkch1Label                   matlab.ui.control.Label
        Darkch1EditField               matlab.ui.control.NumericEditField
        Darkch2EditFieldLabel          matlab.ui.control.Label
        Darkch2EditField               matlab.ui.control.NumericEditField
        LoadandprocessButton           matlab.ui.control.Button
        ofzplanesperfieldLabel         matlab.ui.control.Label
        ofzplanesperfieldEditField     matlab.ui.control.NumericEditField
        ReprocessButton                matlab.ui.control.Button
        ReaddarkButton                 matlab.ui.control.Button
        SizepxlLabel                   matlab.ui.control.Label
        SizepxlEditField               matlab.ui.control.NumericEditField
        MedianfiltersizepxlEditFieldLabel  matlab.ui.control.Label
        MedianfiltersizepxlEditField   matlab.ui.control.NumericEditField
    end

    properties (Access = public)
        mainfolder % Path to the main folder of the last selection.
        folders % Selected folders containing the imaging files.
        data_raw % Images as returned by the function "import_PrairieMultifolder"
    end
    
    methods (Access = public)
        
        function compute(app)
            % size
            [n_y,n_x,n_ch,n_wl,n_frames]=size(app.data_raw);
            
            % If "Median filter" is selected, apply it to each frame. This
            % step is particularly important in case of a z-stack:
            % performing a maximum projection produces a bias toward high
            % values that depends on the standard deviation of the noise
            % distribution. Applying a median filter BEFORE the maximum
            % projection minimizes this bias.
            filter_size=app.SizepxlEditField.Value;
            data_raw_filt = app.data_raw;
            if app.MedianfilteronimagesCheckBox.Value
                disp('Applying median filter...')
                for ich=1:n_ch
                    for i_wl=1:n_wl
                        for i_frame=1:n_frames
                            data_raw_filt(:,:,ich,i_wl,i_frame)=medfilt2(app.data_raw(:,:,ich,i_wl,i_frame),[1 1].*filter_size,'symmetric');
                        end
                    end
                end
                med_name='MedianFilt3';
                clear filter_size
            else
                disp('Median filter skipped...')
                med_name='noMedianFilt';
            end
            
            % Multiple planes per field? It should not be necessary to
            % specify the number of "zeta" positions and the number of
            % fields, since this info is contained in the .xml file (x,y,z
            % position and "Cycle" are attributes specified for each
            % frame). However, accounting for both z-stacks and multiple
            % fields (cycles) would require to modify the function
            % "import_PrairieTimeSequence.m". Also, file formats other than
            % the output of PrairieView may lack such information. Thus, I
            % thought it was safer to explicit the number of z planes, if
            % any.
            if app.ZstackCheckBox.Value
                % How many zetas? 
                n_z=app.ofzplanesperfieldEditField.Value;
                % Reshape data_raw. "Zetas" from the same field are consecutive.
                data_raw_resh=reshape(data_raw_filt,n_y,n_x,n_ch,n_wl,n_z,n_frames/n_z);
                % Compute the maximum projection.
                data_raw2=max(data_raw_resh,[],5);
                % Update the number of fields. If there is just one filed,
                % the last dimension is singleton and it is totally fine
                % with the rest of the code.
                [n_y,n_x,n_ch,n_wl,n_fields]=size(data_raw2);
%                 data_raw2=reshape(data_raw2,n_y,n_x,n_ch,n_wl,n_fields); % this line is doing nothing.... isn't it?
            else
                data_raw2=data_raw_filt;
            end
            
            % Dark subtraction. Use Matlab magical powers to avoid for
            % loops. Everyone hate for loops.
            dark = [app.Darkch1EditField.Value, app.Darkch1EditField.Value];
            data_noDark=data_raw2-reshape(dark,1,1,2);
            
            % Flat correction
            if app.FlatcorrectionCheckBox.Value
                % load flats
                [flats,ratios,folder_flats]=loadFlats(app.mainfolder); %#ok<ASGLU> 
                % Median filter on flat field images.
                filter_size = app.MedianfiltersizepxlEditField.Value;
                for ich=1:n_ch
                    for iwl=1:n_wl
                        flats(:,:,ich,iwl)=medfilt2(flats(:,:,ich,iwl),[1 1].*filter_size);
                    end
                end
                % clip small values to avoid division by 0. Anyway, if some values
                % are so low, it's not good news.
                flats(flats<0.05)=0.05; 
                % check that # of wavelengths matches
                assert(size(flats,4)==size(app.data_raw,4),"# experiment wavelengths don't match with flats wavelengths")
                % divide
                data=data_noDark./flats;
                name_flat='FC'; % Field Correction
            else
                data=data_noDark;
                name_flat='noFC'; % no Field Correction
            end
            
            % Register the "wavelength" stack.
            if app.RegisterwavelengthsCheckBox.Value
                % Use smoothed image for the registration, to reduce the
                % possible contribution of "patterned noise", if any.
                data_filt = zeros(size(data));
                for i=1:n_fields
                    for ii=1:n_wl
                        for iii=1:n_ch
                            data_filt(:,:,iii,ii,i) = imgaussfilt(data(:,:,iii,ii,i),1);
                        end
                    end
                end
                
                % initialize a matrix with the registered images
                data_reg = zeros(size(data));
                % Register every field.
                for i=1:n_fields
                    fprintf('Aligning field %i of %i...\n',i,n_fields)
                    % Use the average of both channels and all the
                    % wavelengths as template for the registration.
                    template = mean(data_filt(:,:,:,:,i),[3 4]);
                    % Use the mean of both channels (combo) to align each
                    % wavelength.
                    combo    = squeeze(mean(data_filt(:,:,:,:,i),3));
                    % The function "myregistration" performs a ROI-based,
                    % rigid translation finding the maximum of the cross
                    % correlation. It outputs the shifts for x and y.
                    [~,deltas] = myregistration(combo,template);
                    % Eventually translate the wavelenths.
                    for i_ch = 1:n_ch
                        data_reg(:,:,i_ch,:,i)=imreg_trasl(squeeze(data(:,:,i_ch,:,i)),deltas);
                    end
                end
                name_align='Reg';
                disp('Alignment complete')
            else
                data_reg=data;
                name_align='noReg';
                disp('Alignment skipped')
            end
            
            % Give a look at the first field.
            tmp=squeeze(mean(data_reg(:,:,:,:,1),3));
            tmp(tmp<-50)=0;
            tmp(tmp>3000)=4000;
            tmp(isnan(tmp))=0;
            % SliceViewer generates an ImageJ-like window.
            sliceViewer(tmp);
            set(gca,'CLim',[0 600])
            
%             % look at its histogram
%             figure
%             histogram(data_reg(:,:,1,:,1),'normalization','probability','facecolor','g','edgecolor','none')
%             hold on
%             histogram(data_reg(:,:,2,:,1),'normalization','probability','facecolor','r','edgecolor','none')
%             set(gca,'yscale','log')
%             legend('Ch1','Ch2')
%             xlabel('Intensity (au)')
%             ylabel('Count frequency')
            
            % Save Images
            % prepare Tiff object tags structures
            tagstruct.Photometric=Tiff.Photometric.MinIsBlack;
            tagstruct.Compression=Tiff.Compression.PackBits;
            tagstruct.BitsPerSample=32;
            tagstruct.SamplesPerPixel=1;
            tagstruct.SubFileType=2;
            tagstruct.SampleFormat=Tiff.SampleFormat.IEEEFP;
            tagstruct.ImageLength=n_y;
            tagstruct.ImageWidth=n_x;
            tagstruct.PlanarConfiguration=Tiff.PlanarConfiguration.Chunky;
            tagstruct.TileLength=n_y;
            tagstruct.TileWidth=n_x;
            
            outDir=app.mainfolder;
            % Loop to save each field.
            for i_field=1:n_fields
                fprintf('Saving field %i of %i...\n',i_field,n_fields)
                for i_ch=1:n_ch
                    if n_fields>1
                        % In case of multiple fields, save each field
                        % separately.
                        filename = sprintf('Field_%03i-%s %s %s Ch%i.tif',i_field,name_flat,name_align,med_name,i_ch);
                    else
                        % In case of a single field, maintain the original
                        % name of the folders; just add some descriptors of
                        % the preprocessing pipeline.
                        pieces=strsplit(app.folders{1},filesep);
                        piece=pieces{end};
                        bassi=regexp(piece,'_');
                        alti=regexp(piece,'-');
                        tutti=[alti,bassi];
                        tutti=sort(tutti);
                        tag=piece(1:tutti(2)-1);
                        filename = sprintf('%s-%s %s %s Ch%i.tif',tag,name_flat,name_align,med_name,i_ch);
                    end
                    % create the Tiff object.
                    t = Tiff([outDir filesep filename],'w');
                    % First, save the first wavelength.
                    setTag(t,tagstruct)
                    t.write(single(data_reg(:,:,i_ch,1,i_field)));
                    % The, save the other wavelengths
                    for i_wl=2:n_wl
                        writeDirectory(t);
                        setTag(t,tagstruct)
                        t.write(single(data_reg(:,:,i_ch,i_wl,i_field)));
                    end
                end
            end
            close(t)
            disp('done') 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % add folder with functions
            this_file = mfilename('fullpath');
            sep=regexp(this_file,filesep);
            this_folder = this_file(1:sep(end)-1);
            addpath([this_folder filesep 'preprocess_utilities'])
            
            app.mainfolder= [pwd filesep];
        end

        % Button pushed function: LoadandprocessButton
        function LoadandprocessButtonPushed(app, event)
            % load images. Select all the wavelengths of a field. If "Flat 
            % correction" is selected, an error is returned if the
            % number of selected wavelenghts does not match the number of
            % wavelengths in the flat field images.
            app.folders=uigetmultidir(app.mainfolder);
            figure(app.ChlorideImaging_preprocessing)
            if isempty(app.folders)
                return
            else
                i_sep=regexp(app.folders{1},filesep);
                app.mainfolder=app.folders{1}(1:i_sep(end)-1);
            end
            
            % import
            app.data_raw=import_PrairieMultifolder(app.folders); % data dimensions are: y*x*ch*wavelength*frame
            % The dimension "frame" can be:
            %   -a single image
            %   -a z-series
            %   -single images acquired at different x-y positions
            %   -z-series acquired at different x-y positions.
            
            % set saturating pixels to NaN
            app.data_raw(app.data_raw>=4095)=nan;
            
            compute(app)
        end

        % Button pushed function: ReprocessButton
        function ReprocessButtonPushed(app, event)
            compute(app)
        end

        % Button pushed function: ReaddarkButton
        function ReadDarkButtonPushed(app, event)
            % Interactive selection of a folder with dark frames
            dark_folder = uigetdir(app.mainfolder);
            figure(app.ChlorideImaging_preprocessing)
            if dark_folder==0
                return
            end
            disp('Reading dark...')
            dark = readDark(dark_folder);
            % Update the edit fields that contain the dark values
            app.Darkch1EditField.Value=dark(1);
            app.Darkch2EditField.Value=dark(2);
            disp('dark values updated')
            % save the path to the folder of the "dark" as app.mainfolder
            slash=regexp(dark_folder,filesep);
            app.mainfolder=dark_folder(1:slash(end)-1);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChlorideImaging_preprocessing = uifigure('Visible', 'off');
            app.ChlorideImaging_preprocessing.Name = 'MATLAB App';
            app.MedianfilteronimagesCheckBox = uicheckbox(app.ChlorideImaging_preprocessing);
            app.MedianfilteronimagesCheckBox.Text = 'Median filter on images.';
            app.MedianfilteronimagesCheckBox.Value = true;
            app.ZstackCheckBox = uicheckbox(app.ChlorideImaging_preprocessing);
            app.ZstackCheckBox.Text = 'Z stack.';
            app.ZstackCheckBox.Value = true;
            app.FlatcorrectionCheckBox = uicheckbox(app.ChlorideImaging_preprocessing);
            app.FlatcorrectionCheckBox.Text = 'Flat correction';
            app.FlatcorrectionCheckBox.Value = true;
            app.RegisterwavelengthsCheckBox = uicheckbox(app.ChlorideImaging_preprocessing);
            app.RegisterwavelengthsCheckBox.Tooltip = {'Align images acquired at different excitation wavelengths. '};
            app.RegisterwavelengthsCheckBox.Text = 'Register wavelengths';
            app.Darkch1Label = uilabel(app.ChlorideImaging_preprocessing);
            app.Darkch1Label.Text = 'Dark ch1';
            app.Darkch1EditField = uieditfield(app.ChlorideImaging_preprocessing, 'numeric');
            app.Darkch1EditField.Value = 100;
            app.Darkch2EditFieldLabel = uilabel(app.ChlorideImaging_preprocessing);
            app.Darkch2EditFieldLabel.Text = 'Dark ch2';
            app.Darkch2EditField = uieditfield(app.ChlorideImaging_preprocessing, 'numeric');
            app.Darkch2EditField.Value = 100;
            app.LoadandprocessButton = uibutton(app.ChlorideImaging_preprocessing, 'push');
            app.LoadandprocessButton.ButtonPushedFcn = createCallbackFcn(app, @LoadandprocessButtonPushed, true);
            app.LoadandprocessButton.Text = 'Load and process';
            app.ofzplanesperfieldLabel = uilabel(app.ChlorideImaging_preprocessing);
            app.ofzplanesperfieldLabel.Text = '# of z planes per field:';
            app.ofzplanesperfieldEditField = uieditfield(app.ChlorideImaging_preprocessing, 'numeric');
            app.ofzplanesperfieldEditField.Value = 4;
            app.ReprocessButton = uibutton(app.ChlorideImaging_preprocessing, 'push');
            app.ReprocessButton.ButtonPushedFcn = createCallbackFcn(app, @ReprocessButtonPushed, true);
            app.ReprocessButton.Text = 'Re-process';
            app.ReaddarkButton = uibutton(app.ChlorideImaging_preprocessing, 'push');
            app.ReaddarkButton.ButtonPushedFcn = createCallbackFcn(app, @ReadDarkButtonPushed, true);
            app.ReaddarkButton.Tooltip = {'Select a "dark" recording (image or time series) and compute "dark" values from there. Otherwise, manually edit the edit fields above.'};
            app.ReaddarkButton.Text = 'Read dark';
            app.SizepxlLabel = uilabel(app.ChlorideImaging_preprocessing);
            app.SizepxlLabel.Text = 'Size (pxl):';
            app.SizepxlEditField = uieditfield(app.ChlorideImaging_preprocessing, 'numeric');
            app.SizepxlEditField.Value = 3;
            app.MedianfiltersizepxlEditFieldLabel = uilabel(app.ChlorideImaging_preprocessing);
            app.MedianfiltersizepxlEditFieldLabel.Text = 'Median filter size (pxl)';
            app.MedianfiltersizepxlEditField = uieditfield(app.ChlorideImaging_preprocessing, 'numeric');
            app.MedianfiltersizepxlEditField.Value = 3;
            % Show the figure after all components are created
            app.ChlorideImaging_preprocessing.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = macro_process_wavelengths

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ChlorideImaging_preprocessing)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChlorideImaging_preprocessing)
        end
    end
end