classdef CurrentSourceDensityApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SourceTab                      matlab.ui.container.Tab
        AverageButton                  matlab.ui.control.StateButton
        bpButtonGroup                  matlab.ui.container.ButtonGroup
        UnfilteredButton               matlab.ui.control.RadioButton
        BandPassButton                 matlab.ui.control.RadioButton
        bpTrialSpinner                 matlab.ui.control.Spinner
        TrialSpinnerLabel              matlab.ui.control.Label
        TrialSpinner                   matlab.ui.control.Spinner
        ChannelintervalPanel           matlab.ui.container.Panel
        FromEditFieldLabel             matlab.ui.control.Label
        ChFromEditField                matlab.ui.control.NumericEditField
        ToEditFieldLabel               matlab.ui.control.Label
        ChToEditField                  matlab.ui.control.NumericEditField
        SpatialinfoTab                 matlab.ui.container.Tab
        ElectrodesspacingumEditFieldLabel  matlab.ui.control.Label
        ElectrodesspacingumEditField   matlab.ui.control.NumericEditField
        depthButtonGroup               matlab.ui.container.ButtonGroup
        DeepestelectrodedepthumButton  matlab.ui.control.RadioButton
        ShallowestelectrodedepthumButton  matlab.ui.control.RadioButton
        deepElectrZ                    matlab.ui.control.NumericEditField
        shallowElectrZ                 matlab.ui.control.NumericEditField
        ComputationTab                 matlab.ui.container.Tab
        ZaxisinterpfactorEditFieldLabel  matlab.ui.control.Label
        ZaxisinterpfactorEditField     matlab.ui.control.NumericEditField
        MethodButtonGroup              matlab.ui.container.ButtonGroup
        InverseButton                  matlab.ui.control.RadioButton
        ClassicButton                  matlab.ui.control.RadioButton
        EventsradiusumEditFieldLabel   matlab.ui.control.Label
        EventsradiusumEditField        matlab.ui.control.NumericEditField
        VisualizationTab               matlab.ui.container.Tab
        TimePanel                      matlab.ui.container.Panel
        fromsEditFieldLabel            matlab.ui.control.Label
        t_fromEditField                matlab.ui.control.NumericEditField
        tosEditFieldLabel              matlab.ui.control.Label
        t_toEditField                  matlab.ui.control.NumericEditField
        t_ResetButton                  matlab.ui.control.Button
        t_previous                     matlab.ui.control.Button
        t_next                         matlab.ui.control.Button
        DepthPanel                     matlab.ui.container.Panel
        fromumLabel                    matlab.ui.control.Label
        z_fromEditField                matlab.ui.control.NumericEditField
        toumLabel                      matlab.ui.control.Label
        z_toEditField                  matlab.ui.control.NumericEditField
        z_ResetButton                  matlab.ui.control.Button
        ComputeButton                  matlab.ui.control.Button
        mainAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        parentApp % ZebraExploreApp
        csd % current source density matrix
        z % vector with selected channel depth in micrometers
        t % time vector in seconds
        source % 2D matrix with dimensions time x channels
        cb % colorbar handle
    end
    
    methods (Access = public)
        
        function plotCSD(app)
            nch = size(app.source,2);
            csd = app.csd;
            csd(isnan(csd))=0;
            intfactor = app.ZaxisinterpfactorEditField.Value;
            csd_int = imresize(csd,[size(csd,1) (nch-1).*intfactor+1],'bicubic');
            
            cla(app.mainAxes)
            app.mainAxes.NextPlot="add";
            spacing = app.ElectrodesspacingumEditField.Value;
            imagesc(app.mainAxes,app.t,linspace(app.z(1),app.z(end),size(csd_int,2)),csd_int')
            Cmax = max(abs(csd_int),[],'all');
            plot(app.mainAxes,app.t,(rescale(app.source,0,1).*2-1).*spacing+app.z,'k')
            set(app.mainAxes,'CLim',[-1 1].*Cmax,...
                'ydir','normal',...
                'xlim',[app.t(1) app.t(end)],...
                'ylim',[app.z(1)-spacing app.z(end)+spacing])
            
            set(app.mainAxes,'XLim',[app.t_fromEditField.Value, app.t_toEditField.Value])
            app.z_ResetButtonPushed([])
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp)
            app.parentApp = parentApp;
            
            % initialize graphic objects
            app.ChToEditField.Value = app.parentApp.nCh;
            set(app.mainAxes,'colormap',unionjack)
            app.cb=colorbar(app.mainAxes);
            app.cb.Label.String = 'Current density (\muA mm^-^3)';
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            switch app.parentApp.uipanel15.SelectedObject
                case app.parentApp.mVck
                    factor = 10^-3;
                case app.parentApp.microVck
                    factor = 10^-6;
            end
            
            % source selection
            chFrom = app.ChFromEditField.Value;
            chTo = app.ChToEditField.Value;
            tr = app.TrialSpinner.Value;
            switch app.bpButtonGroup.SelectedObject
                case app.UnfilteredButton
                    % unfiltered LFP
                    if app.AverageButton.Value
                        % average
                        source = app.parentApp.meanLFP(chFrom:chTo,:);
                    else
                        % single trial
                        source = squeeze(app.parentApp.meanLFP(chFrom:chTo,tr,:));
                    end
                case app.BandPassButton
                    % filtered LFP
                    bp = app.bpTrialSpinner.Value;
                    if app.AverageButton.Value
                        % average
                        source = squeeze(app.parentApp.meanBP(bp,chFrom:chTo,:));
                    else
                        % single trial
                        source = squeeze(app.parentApp.bandPassed_LFP(bp,chFrom:chTo,tr,:));
                    end
                    
            end
            app.source = source'.*factor; % volts
            app.t = app.parentApp.x; % seconds
            SR = app.parentApp.acqF; % sampling rate
            spacing = app.ElectrodesspacingumEditField.Value*10^-6; %meters
            
            switch app.MethodButtonGroup.SelectedObject
                case app.ClassicButton
                    app.csd = CSD(app.source,SR,spacing);
                case app.InverseButton
                    radius = app.EventsradiusumEditField.Value;
                    app.csd = CSD(app.source,SR,spacing,'inverse',radius.*10^-6,'plot',false);
            end
            
            
            % compute depth vector
            nch = size(app.source,2);
            switch app.depthButtonGroup.SelectedObject
                case app.ShallowestelectrodedepthumButton
                    depth = -app.shallowElectrZ.Value; %um
                    app.z = depth-spacing.*10^6.*(nch-1) : spacing.*10^6 : depth;          
                    
                case app.DeepestelectrodedepthumButton
                    depth = -app.deepElectrZ.Value; %um
                    app.z = depth : spacing.*10^6 : depth+spacing.*10^6.*(nch-1);          
            end 
           
            disp('CSD computed')
            plotCSD(app)
            
        end

        % Value changed function: t_fromEditField, t_toEditField
        function t_toEditFieldValueChanged(app, event)
            to = app.t_toEditField.Value;
            from = app.t_fromEditField.Value;
            app.mainAxes.XLim = [from to];
        end

        % Button pushed function: t_ResetButton
        function t_ResetButtonPushed(app, event)
%             app.t_toEditField.Value = app.t(end);
%             app.t_fromEditField.Value = app.t(1);
            app.t_toEditField.Value = inf;
            app.t_fromEditField.Value = -inf;
            
            app.t_toEditFieldValueChanged([])
        end

        % Button pushed function: t_next
        function t_nextButtonPushed(app, event)
            range = app.t_toEditField.Value - app.t_fromEditField.Value;
            app.t_toEditField.Value = app.t_toEditField.Value+range;
            app.t_fromEditField.Value = app.t_fromEditField.Value+range;
            app.t_toEditFieldValueChanged([])
        end

        % Button pushed function: t_previous
        function t_previousButtonPushed(app, event)
            range = app.t_toEditField.Value - app.t_fromEditField.Value;
            app.t_toEditField.Value = app.t_toEditField.Value-range;
            app.t_fromEditField.Value = app.t_fromEditField.Value-range;
            app.t_toEditFieldValueChanged([])
        end

        % Value changed function: z_fromEditField, z_toEditField
        function z_toEditFieldValueChanged(app, event)
            to = app.z_toEditField.Value;
            from = app.z_fromEditField.Value;
            app.mainAxes.YLim = [from to];
        end

        % Button pushed function: z_ResetButton
        function z_ResetButtonPushed(app, event)
            spacing = app.ElectrodesspacingumEditField.Value;
            app.z_toEditField.Value = app.z(end)+spacing;
            app.z_fromEditField.Value = app.z(1)-spacing;
            app.z_toEditFieldValueChanged([])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SourceTab = uitab(app.TabGroup);
            app.SourceTab.Title = 'Source';
            app.AverageButton = uibutton(app.SourceTab, 'state');
            app.AverageButton.Text = 'Average';
            app.bpButtonGroup = uibuttongroup(app.SourceTab);
            app.UnfilteredButton = uiradiobutton(app.bpButtonGroup);
            app.UnfilteredButton.Text = 'Unfiltered';
            app.UnfilteredButton.Value = true;
            app.BandPassButton = uiradiobutton(app.bpButtonGroup);
            app.BandPassButton.Text = 'Band Pass';
            app.bpTrialSpinner = uispinner(app.bpButtonGroup);
            app.bpTrialSpinner.Value = 1;
            app.TrialSpinnerLabel = uilabel(app.SourceTab);
            app.TrialSpinnerLabel.Text = 'Trial';
            app.TrialSpinner = uispinner(app.SourceTab);
            app.TrialSpinner.Value = 1;
            app.ChannelintervalPanel = uipanel(app.SourceTab);
            app.ChannelintervalPanel.Title = 'Channel interval';
            app.FromEditFieldLabel = uilabel(app.ChannelintervalPanel);
            app.FromEditFieldLabel.Text = 'From';
            app.ChFromEditField = uieditfield(app.ChannelintervalPanel, 'numeric');
            app.ChFromEditField.Value = 1;
            app.ToEditFieldLabel = uilabel(app.ChannelintervalPanel);
            app.ToEditFieldLabel.Text = 'To';
            app.ChToEditField = uieditfield(app.ChannelintervalPanel, 'numeric');
            app.ChToEditField.Value = 1;
            app.SpatialinfoTab = uitab(app.TabGroup);
            app.SpatialinfoTab.Title = 'Spatial info';
            app.ElectrodesspacingumEditFieldLabel = uilabel(app.SpatialinfoTab);
            app.ElectrodesspacingumEditFieldLabel.Text = 'Electrodes spacing (um)';
            app.ElectrodesspacingumEditField = uieditfield(app.SpatialinfoTab, 'numeric');
            app.ElectrodesspacingumEditField.Value = 25;
            app.depthButtonGroup = uibuttongroup(app.SpatialinfoTab);
            app.DeepestelectrodedepthumButton = uiradiobutton(app.depthButtonGroup);
            app.DeepestelectrodedepthumButton.Text = 'Deepest electrode depth (um)';
            app.DeepestelectrodedepthumButton.Value = true;
            app.ShallowestelectrodedepthumButton = uiradiobutton(app.depthButtonGroup);
            app.ShallowestelectrodedepthumButton.Text = 'Shallowest electrode depth (um)';
            app.deepElectrZ = uieditfield(app.depthButtonGroup, 'numeric');
            app.shallowElectrZ = uieditfield(app.depthButtonGroup, 'numeric');
            app.ComputationTab = uitab(app.TabGroup);
            app.ComputationTab.Title = 'Computation';
            app.ZaxisinterpfactorEditFieldLabel = uilabel(app.ComputationTab);
            app.ZaxisinterpfactorEditFieldLabel.Text = {'Z axis'; 'interp. factor'};
            app.ZaxisinterpfactorEditField = uieditfield(app.ComputationTab, 'numeric');
            app.ZaxisinterpfactorEditField.Value = 10;
            app.MethodButtonGroup = uibuttongroup(app.ComputationTab);
            app.MethodButtonGroup.Title = 'Method';
            app.InverseButton = uiradiobutton(app.MethodButtonGroup);
            app.InverseButton.Text = 'Inverse';
            app.InverseButton.Value = true;
            app.ClassicButton = uiradiobutton(app.MethodButtonGroup);
            app.ClassicButton.Text = 'Classic';
            app.EventsradiusumEditFieldLabel = uilabel(app.MethodButtonGroup);
            app.EventsradiusumEditFieldLabel.Text = 'Events radius (um)';
            app.EventsradiusumEditField = uieditfield(app.MethodButtonGroup, 'numeric');
            app.EventsradiusumEditField.Value = 75;
            app.VisualizationTab = uitab(app.TabGroup);
            app.VisualizationTab.Title = 'Visualization';
            app.TimePanel = uipanel(app.VisualizationTab);
            app.TimePanel.Title = 'Time';
            app.fromsEditFieldLabel = uilabel(app.TimePanel);
            app.fromsEditFieldLabel.Text = 'from (s)';
            app.t_fromEditField = uieditfield(app.TimePanel, 'numeric');
            app.t_fromEditField.ValueChangedFcn = createCallbackFcn(app, @t_toEditFieldValueChanged, true);
            app.tosEditFieldLabel = uilabel(app.TimePanel);
            app.tosEditFieldLabel.Text = 'to (s)';
            app.t_toEditField = uieditfield(app.TimePanel, 'numeric');
            app.t_toEditField.ValueChangedFcn = createCallbackFcn(app, @t_toEditFieldValueChanged, true);
            app.t_toEditField.Value = 1;
            app.t_ResetButton = uibutton(app.TimePanel, 'push');
            app.t_ResetButton.ButtonPushedFcn = createCallbackFcn(app, @t_ResetButtonPushed, true);
            app.t_ResetButton.Text = 'Reset';
            app.t_previous = uibutton(app.TimePanel, 'push');
            app.t_previous.ButtonPushedFcn = createCallbackFcn(app, @t_previousButtonPushed, true);
            app.t_previous.Text = '<';
            app.t_next = uibutton(app.TimePanel, 'push');
            app.t_next.ButtonPushedFcn = createCallbackFcn(app, @t_nextButtonPushed, true);
            app.t_next.Text = '>';
            app.DepthPanel = uipanel(app.VisualizationTab);
            app.DepthPanel.Title = 'Depth';
            app.fromumLabel = uilabel(app.DepthPanel);
            app.fromumLabel.Text = 'from (um)';
            app.z_fromEditField = uieditfield(app.DepthPanel, 'numeric');
            app.z_fromEditField.ValueChangedFcn = createCallbackFcn(app, @z_toEditFieldValueChanged, true);
            app.toumLabel = uilabel(app.DepthPanel);
            app.toumLabel.Text = 'to (um)';
            app.z_toEditField = uieditfield(app.DepthPanel, 'numeric');
            app.z_toEditField.ValueChangedFcn = createCallbackFcn(app, @z_toEditFieldValueChanged, true);
            app.z_toEditField.Value = 1;
            app.z_ResetButton = uibutton(app.DepthPanel, 'push');
            app.z_ResetButton.ButtonPushedFcn = createCallbackFcn(app, @z_ResetButtonPushed, true);
            app.z_ResetButton.Text = 'Reset';
            app.ComputeButton = uibutton(app.UIFigure, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.mainAxes = uiaxes(app.UIFigure);
            xlabel(app.mainAxes, 'Time (s)')
            ylabel(app.mainAxes, 'Depth (\mum)')
            zlabel(app.mainAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CurrentSourceDensityApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end