classdef NNexusInputSetup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        PortADropDownLabel      matlab.ui.control.Label
        PortADropDown           matlab.ui.control.DropDown
        AnalogInputsCheckBox    matlab.ui.control.CheckBox
        DigitalInputsCheckBox   matlab.ui.control.CheckBox
        DigitalOutputsCheckBox  matlab.ui.control.CheckBox
        portA_CheckBox          matlab.ui.control.CheckBox
        portB_CheckBox          matlab.ui.control.CheckBox
        portC_CheckBox          matlab.ui.control.CheckBox
        portD_CheckBox          matlab.ui.control.CheckBox
        ProbeLabel              matlab.ui.control.Label
        ChadaptorLabel          matlab.ui.control.Label
        portA_nCh               matlab.ui.control.DropDown
        portB_nCh               matlab.ui.control.DropDown
        portC_nCh               matlab.ui.control.DropDown
        portD_nCh               matlab.ui.control.DropDown
        ShowprobesButton        matlab.ui.control.Button
        DoneButton              matlab.ui.control.Button
        AI1gainLabel            matlab.ui.control.Label
        AI1_gainEditField       matlab.ui.control.NumericEditField
        AI2gainLabel            matlab.ui.control.Label
        AI2_gainEditField       matlab.ui.control.NumericEditField
        PortBDropDownLabel      matlab.ui.control.Label
        PortBDropDown           matlab.ui.control.DropDown
        PortCDropDownLabel      matlab.ui.control.Label
        PortCDropDown           matlab.ui.control.DropDown
        PortDDropDownLabel      matlab.ui.control.Label
        PortDDropDown           matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        parentApp % ZebraExplore handle
        folder % folder that contains probe-specific subfolders
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if isa(varargin{1},'string') || isa(varargin{1},'char')
                app.folder = varargin{1};
            elseif isvalid(varargin{1})
                app.parentApp = varargin{1};
                app.folder = [app.parentApp.zebraPath 'NNprobes'];
            else
                error('inconsistent input argument')
            end
            
            
            dircontent = dir(app.folder);
            dircontent(strcmp({dircontent.name}, '.') | strcmp({dircontent.name}, '..')) = []; % elimination of ghost folders
            isfolder = [dircontent(:).isdir]';
            folders = {dircontent(isfolder).name};

            app.PortADropDown.Items = [folders,'All'];
            app.PortBDropDown.Items = [folders,'All'];
            app.PortCDropDown.Items = [folders,'All'];
            app.PortDDropDown.Items = [folders,'All'];
        end

        % Button pushed function: ShowprobesButton
        function ShowprobesButtonPushed(app, event)
            probes = app.PortADropDown.Items;
            for i = 1:numel(probes)
                img = imread([app.folder filesep probes{i} filesep 'image.jpg']);
                figure('Name',probes{i})
                ax = axes;
                imshow(img)
            end
            
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
           
            % create the NNexusSetup structure
            portnames = [{'port A'};{'port B'};{'port C'};{'port D'};...
                        {'AI'};{'DI'};{'DO'};];
            str = struct('port',portnames,'probe',cell(7,1),...
                        'chOrder', cell(7,1),'gain',cell(7,1));
            
            currCh = 0;
            % port A
            if app.portA_CheckBox.Value
                probe = app.PortADropDown.Value;
                str(1).probe = probe;
                nch = str2double(app.portA_nCh.Value);
                if strcmp(probe,'All')
                    str(1).chOrder = (1:nch)';
                    str(1).gain = ones(nch,1);
                else
                    load([app.folder filesep probe filesep 'orderedCh.mat'])
                    str(1).chOrder = ord;
                    str(1).gain = ones(size(ord));
                end
                currCh = currCh + nch;
            else
                str(1).chOrder = [];
                str(1).gain = [];
            end
            
            % port B
            if app.portB_CheckBox.Value
                probe = app.PortBDropDown.Value;
                str(2).probe = probe;
                load([app.folder filesep probe filesep 'orderedCh.mat'])
                str(2).chOrder = ord+currCh;
                str(2).gain = ones(size(ord));
                currCh = currCh+ str2double(app.portB_nCh.Value);
            else
                str(2).chOrder = [];
                str(2).gain = [];
            end
            
            % port C
            if app.portC_CheckBox.Value
                probe = app.PortBDropDown.Value;
                str(3).probe = probe;
                load([app.folder filesep probe filesep 'orderedCh.mat'])
                str(3).chOrder = ord+currCh;
                str(3).gain = ones(size(ord));
                currCh = currCh+ str2double(app.portB_nCh.Value);
            else
                str(3).chOrder = [];
                str(3).gain = [];
            end
            
            % port D
            if app.portD_CheckBox.Value
                probe = app.PortBDropDown.Value;
                str(4).probe = probe;
                load([app.folder filesep probe filesep 'orderedCh.mat'])
                str(4).chOrder = ord+currCh;
                str(4).gain = ones(size(ord));
                currCh = currCh+ str2double(app.portB_nCh.Value);
            else
                str(4).chOrder = [];
                str(4).gain = [];
            end
            
            % Analog inputs
            if app.AnalogInputsCheckBox.Value
                str(5).chOrder = [1; 2] + currCh;
                str(5).gain = [app.AI1_gainEditField.Value;app.AI2_gainEditField.Value]./10^6; % 1000 is a conversion factor from V to microV
            else
                str(5).chOrder = [];
                str(5).gain = [];
            end
            currCh = currCh+2; % Smartbox Pro saves analog inputs even if they are not selected in the acquisition panel
            
            
            % Digital inputs
            if app.DigitalInputsCheckBox.Value
                str(6).chOrder = [1; 2] + currCh;
                str(6).gain = [1; 1];
                currCh = currCh+2;
            else
                str(6).chOrder = [];
                str(6).gain = [];
            end
            
            % Digital Outputs
            if app.DigitalOutputsCheckBox.Value
                str(7).chOrder = [1; 2] + currCh;
                str(7).gain = [1; 1];
                currCh = currCh+2;
            else
                str(7).chOrder = [];
                str(7).gain = [];
            end
            
            if isempty(app.parentApp)
                global NNexusStruct
                NNexusStruct = str;
%                 assignin("base","NNexusSetup",str)
            else
                app.parentApp.NNexusSetup = str;
            end
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                DoneButtonPushed(app, [])
            catch
                warning('An error occurred in the close request function')
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PortADropDownLabel = uilabel(app.UIFigure);
            app.PortADropDownLabel.Text = 'Port A:';
            app.PortADropDown = uidropdown(app.UIFigure);
            app.PortADropDown.Items = {'A1x16-3mm-25-177', 'Q1X1-tet-3mm-121', 'A2x2-tet-3mm-150-150-121'};
            app.PortADropDown.Value = 'A1x16-3mm-25-177';
            app.AnalogInputsCheckBox = uicheckbox(app.UIFigure);
            app.AnalogInputsCheckBox.Text = 'Analog Inputs';
            app.AnalogInputsCheckBox.Value = true;
            app.DigitalInputsCheckBox = uicheckbox(app.UIFigure);
            app.DigitalInputsCheckBox.Text = 'Digital Inputs';
            app.DigitalInputsCheckBox.Value = true;
            app.DigitalOutputsCheckBox = uicheckbox(app.UIFigure);
            app.DigitalOutputsCheckBox.Text = 'Digital Outputs';
            app.portA_CheckBox = uicheckbox(app.UIFigure);
            app.portA_CheckBox.Text = '';
            app.portB_CheckBox = uicheckbox(app.UIFigure);
            app.portB_CheckBox.Text = '';
            app.portC_CheckBox = uicheckbox(app.UIFigure);
            app.portC_CheckBox.Text = '';
            app.portD_CheckBox = uicheckbox(app.UIFigure);
            app.portD_CheckBox.Text = '';
            app.ProbeLabel = uilabel(app.UIFigure);
            app.ProbeLabel.Text = 'Probe';
            app.ChadaptorLabel = uilabel(app.UIFigure);
            app.ChadaptorLabel.Text = '#Ch adaptor';
            app.portA_nCh = uidropdown(app.UIFigure);
            app.portA_nCh.Items = {'4', '16', '32', '64', '128'};
            app.portA_nCh.Value = '64';
            app.portB_nCh = uidropdown(app.UIFigure);
            app.portB_nCh.Items = {'4', '16', '32', '64', '128'};
            app.portB_nCh.Value = '64';
            app.portC_nCh = uidropdown(app.UIFigure);
            app.portC_nCh.Items = {'4', '16', '32', '64', '128'};
            app.portC_nCh.Value = '64';
            app.portD_nCh = uidropdown(app.UIFigure);
            app.portD_nCh.Items = {'4', '16', '32', '64', '128'};
            app.portD_nCh.Value = '64';
            app.ShowprobesButton = uibutton(app.UIFigure, 'push');
            app.ShowprobesButton.ButtonPushedFcn = createCallbackFcn(app, @ShowprobesButtonPushed, true);
            app.ShowprobesButton.Text = 'Show probes';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.AI1gainLabel = uilabel(app.UIFigure);
            app.AI1gainLabel.Text = 'AI1 gain:';
            app.AI1_gainEditField = uieditfield(app.UIFigure, 'numeric');
            app.AI1_gainEditField.Value = 500;
            app.AI2gainLabel = uilabel(app.UIFigure);
            app.AI2gainLabel.Text = 'AI2 gain:';
            app.AI2_gainEditField = uieditfield(app.UIFigure, 'numeric');
            app.AI2_gainEditField.Value = 500;
            app.PortBDropDownLabel = uilabel(app.UIFigure);
            app.PortBDropDownLabel.Text = 'Port B:';
            app.PortBDropDown = uidropdown(app.UIFigure);
            app.PortBDropDown.Items = {'A1x16-3mm-25-177', 'Q1X1-tet-3mm-121', 'A2x2-tet-3mm-150-150-121'};
            app.PortBDropDown.Value = 'A1x16-3mm-25-177';
            app.PortCDropDownLabel = uilabel(app.UIFigure);
            app.PortCDropDownLabel.Text = 'Port C:';
            app.PortCDropDown = uidropdown(app.UIFigure);
            app.PortCDropDown.Items = {'A1x16-3mm-25-177', 'Q1X1-tet-3mm-121', 'A2x2-tet-3mm-150-150-121'};
            app.PortCDropDown.Value = 'A1x16-3mm-25-177';
            app.PortDDropDownLabel = uilabel(app.UIFigure);
            app.PortDDropDownLabel.Text = 'Port D:';
            app.PortDDropDown = uidropdown(app.UIFigure);
            app.PortDDropDown.Items = {'A1x16-3mm-25-177', 'Q1X1-tet-3mm-121', 'A2x2-tet-3mm-150-150-121'};
            app.PortDDropDown.Value = 'A1x16-3mm-25-177';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NNexusInputSetup(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end