classdef SingleUnits_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ExtractionTab                   matlab.ui.container.Tab
        uibuttongroup1                  matlab.ui.container.ButtonGroup
        text4                           matlab.ui.control.Label
        text5                           matlab.ui.control.Label
        label_nMax                      matlab.ui.control.Label
        label_nMin                      matlab.ui.control.Label
        text5_2                         matlab.ui.control.Label
        label_nTot                      matlab.ui.control.Label
        calculate_SU                    matlab.ui.control.Button
        reset_SU                        matlab.ui.control.Button
        HPhisto_bt                      matlab.ui.control.Button
        AlignmentPanel                  matlab.ui.container.Panel
        beforethepeakmsLabel            matlab.ui.control.Label
        preSEditField                   matlab.ui.control.NumericEditField
        afterthepeakmsLabel             matlab.ui.control.Label
        postSEditField                  matlab.ui.control.NumericEditField
        RefractoryperiodmsLabel         matlab.ui.control.Label
        refEditField                    matlab.ui.control.NumericEditField
        InterpolationCheckBox           matlab.ui.control.CheckBox
        FactorLabel                     matlab.ui.control.Label
        FactorEditField                 matlab.ui.control.NumericEditField
        uibuttongroup3                  matlab.ui.container.ButtonGroup
        checkbox_plotLFP                matlab.ui.control.CheckBox
        checkbox_SUmax                  matlab.ui.control.CheckBox
        checkbox_SUmin                  matlab.ui.control.CheckBox
        pushbutton_scroll_left          matlab.ui.control.Button
        pushbutton_reset                matlab.ui.control.Button
        pushbutton_scroll_right         matlab.ui.control.Button
        edit_tStart_plot                matlab.ui.control.EditField
        edit_tEnd_plot                  matlab.ui.control.EditField
        ReplotButton                    matlab.ui.control.Button
        checkbox_hp                     matlab.ui.control.CheckBox
        TimesLabel                      matlab.ui.control.Label
        HPaxes                          matlab.ui.control.UIAxes
        LFPaxes                         matlab.ui.control.UIAxes
        SUaxes                          matlab.ui.control.UIAxes
        ThresholdunitsofstdEditFieldLabel  matlab.ui.control.Label
        ThresholdunitsofstdEditField    matlab.ui.control.NumericEditField
        SNREditFieldLabel               matlab.ui.control.Label
        SNREditField                    matlab.ui.control.NumericEditField
        MUATab                          matlab.ui.container.Tab
        RasterplotPanel                 matlab.ui.container.Panel
        rasterPlot_bt                   matlab.ui.control.Button
        GaussiankernelbandwidthsLabel   matlab.ui.control.Label
        kernelBW_edit                   matlab.ui.control.NumericEditField
        betweenspikesandBPLabel         matlab.ui.control.Label
        BpSelection_2                   matlab.ui.control.Spinner
        TimewindowforspectracomputationPanel  matlab.ui.container.Panel
        BetweenEditFieldLabel           matlab.ui.control.Label
        BetweenEditField                matlab.ui.control.NumericEditField
        andEditFieldLabel               matlab.ui.control.Label
        andEditField                    matlab.ui.control.NumericEditField
        sLabel                          matlab.ui.control.Label
        AlsocomputecoherencyspectrumCheckBox  matlab.ui.control.CheckBox
        SavelogCheckBox                 matlab.ui.control.CheckBox
        SpikecounterPanel               matlab.ui.container.Panel
        Window1fromtosEditFieldLabel    matlab.ui.control.Label
        Window1fromtosEditField         matlab.ui.control.NumericEditField
        Window2fromtosEditFieldLabel    matlab.ui.control.Label
        Window2fromtosEditField         matlab.ui.control.NumericEditField
        w1to                            matlab.ui.control.NumericEditField
        w2to                            matlab.ui.control.NumericEditField
        CountButton                     matlab.ui.control.Button
        AvgMUAfreqHzEditFieldLabel      matlab.ui.control.Label
        AvgMUAfreq1HzEditField          matlab.ui.control.NumericEditField
        AvgMUAfreqHzEditField_2Label    matlab.ui.control.Label
        AvgMUAfreq2HzEditField          matlab.ui.control.NumericEditField
        ClusteringTab                   matlab.ui.container.Tab
        FeatureExtractionButtonGroup    matlab.ui.container.ButtonGroup
        WTButton                        matlab.ui.control.RadioButton
        PCAButton                       matlab.ui.control.RadioButton
        tSNEButton                      matlab.ui.control.RadioButton
        TabGroup3                       matlab.ui.container.TabGroup
        WTTab                           matlab.ui.container.Tab
        PCAwtCheckBox                   matlab.ui.control.CheckBox
        LevelEditFieldLabel             matlab.ui.control.Label
        LevelEditField                  matlab.ui.control.NumericEditField
        MininputsEditFieldLabel         matlab.ui.control.Label
        MininputsEditField              matlab.ui.control.NumericEditField
        MaxinputsEditFieldLabel         matlab.ui.control.Label
        MaxinputsEditField              matlab.ui.control.NumericEditField
        WaveletDropDownLabel            matlab.ui.control.Label
        WaveletDropDown                 matlab.ui.control.DropDown
        PCATab                          matlab.ui.container.Tab
        ComponentsEditField_2Label      matlab.ui.control.Label
        Components_pcaEditField         matlab.ui.control.NumericEditField
        tSNETab                         matlab.ui.container.Tab
        ComponentsEditFieldLabel        matlab.ui.control.Label
        ComponentsEditField             matlab.ui.control.NumericEditField
        DistanceDropDownLabel           matlab.ui.control.Label
        DistanceDropDown                matlab.ui.control.DropDown
        ExaggerationEditFieldLabel      matlab.ui.control.Label
        ExaggerationEditField           matlab.ui.control.NumericEditField
        PerplexityEditFieldLabel        matlab.ui.control.Label
        PerplexityEditField             matlab.ui.control.NumericEditField
        PCAbeforeCheckBox               matlab.ui.control.CheckBox
        withofcomponentsEditFieldLabel  matlab.ui.control.Label
        withofcomponentsEditField       matlab.ui.control.NumericEditField
        NMFTab                          matlab.ui.container.Tab
        ComponentsEditField_2Label_2    matlab.ui.control.Label
        Components_nmfEditField         matlab.ui.control.NumericEditField
        NMFButton                       matlab.ui.control.RadioButton
        ClusteringButtonGroup           matlab.ui.container.ButtonGroup
        SuperparamagneticButton         matlab.ui.control.RadioButton
        KmeansButton                    matlab.ui.control.RadioButton
        Panel_2                         matlab.ui.container.Panel
        kNNEditFieldLabel               matlab.ui.control.Label
        kNNEditField                    matlab.ui.control.NumericEditField
        minsizeclustersEditFieldLabel   matlab.ui.control.Label
        minsizeclustersEditField        matlab.ui.control.NumericEditField
        WaveformButton                  matlab.ui.control.RadioButton
        ButtonGroupWF                   matlab.ui.container.ButtonGroup
        KmeanstemplateButton            matlab.ui.control.RadioButton
        PearsoncorrButton               matlab.ui.control.RadioButton
        thrEditFieldLabel               matlab.ui.control.Label
        thrEditField                    matlab.ui.control.NumericEditField
        Panel                           matlab.ui.container.Panel
        ShowclusternumberevaluationCheckBox  matlab.ui.control.CheckBox
        CalinskiHarabaszCheckBox        matlab.ui.control.CheckBox
        SilhouetteEvalCheckBox          matlab.ui.control.CheckBox
        Templatematching                matlab.ui.control.CheckBox
        MaxClusterNumberEditFieldLabel  matlab.ui.control.Label
        MaxClusNumKEditField            matlab.ui.control.NumericEditField
        DBSCANButton                    matlab.ui.control.RadioButton
        GMMButton                       matlab.ui.control.RadioButton
        Panel_3                         matlab.ui.container.Panel
        nhoodradiusEditFieldLabel       matlab.ui.control.Label
        nhoodradiusEditField            matlab.ui.control.NumericEditField
        minptsEditFieldLabel            matlab.ui.control.Label
        minptsEditField                 matlab.ui.control.NumericEditField
        TabGroup2                       matlab.ui.container.TabGroup
        KmeansTab                       matlab.ui.container.Tab
        NumberofidentifiedclustersEditFieldLabel  matlab.ui.control.Label
        NumClusEditField                matlab.ui.control.NumericEditField
        ShowclusternumberSpinnerLabel   matlab.ui.control.Label
        ShowClusNumSpinner              matlab.ui.control.Spinner
        ShowspikenumberSpinnerLabel     matlab.ui.control.Label
        ShowSpkNumSpinner               matlab.ui.control.Spinner
        ShowMeanSpkCheckBox             matlab.ui.control.CheckBox
        DimensionfortheclustersplotSwitchLabel  matlab.ui.control.Label
        DimPlotClusSwitch               matlab.ui.control.Switch
        AllClusCheckBox                 matlab.ui.control.CheckBox
        AllSpkCheckBox                  matlab.ui.control.CheckBox
        overLabel                       matlab.ui.control.Label
        totSpkLabel                     matlab.ui.control.Label
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes6                         matlab.ui.control.UIAxes
        UIAxes7                         matlab.ui.control.UIAxes
        WaveformTab                     matlab.ui.container.Tab
        NumberofclustersEditFieldLabel  matlab.ui.control.Label
        NumClusWFEditField              matlab.ui.control.NumericEditField
        ShowclusternumberSpinnerLabel_2  matlab.ui.control.Label
        ShowClusSpinner                 matlab.ui.control.Spinner
        NumberofspikesEditFieldLabel    matlab.ui.control.Label
        NumSpkEditField                 matlab.ui.control.NumericEditField
        ShowspikenumberSpinnerLabel_2   matlab.ui.control.Label
        ShowSpkSpinner                  matlab.ui.control.Spinner
        MeanSpikeCheckBox               matlab.ui.control.CheckBox
        UIAxes9                         matlab.ui.control.UIAxes
        UIAxes9_2                       matlab.ui.control.UIAxes
        SPCTab                          matlab.ui.container.Tab
        ClusterSpinnerLabel             matlab.ui.control.Label
        ClusterSpinner                  matlab.ui.control.Spinner
        ShowallCheckBox                 matlab.ui.control.CheckBox
        DetectedclustersEditFieldLabel  matlab.ui.control.Label
        DetectedclustersEditField       matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        ButtonPanel                     matlab.ui.container.Panel
        EXTRACTButton                   matlab.ui.control.Button
        NumberofextractedfeaturesLabel  matlab.ui.control.Label
        NumSelFeatEditField             matlab.ui.control.NumericEditField
        CLUSTERButton                   matlab.ui.control.Button
        PLOTButton                      matlab.ui.control.Button
        UpDownTab                       matlab.ui.container.Tab
        uibuttongroup4                  matlab.ui.container.ButtonGroup
        checkbox_savetimes              matlab.ui.control.CheckBox
        edit_ltpnumber                  matlab.ui.control.EditField
        text8                           matlab.ui.control.Label
        uitable_output                  matlab.ui.control.Table
        CopyTableButton                 matlab.ui.control.Button
        AssignButton                    matlab.ui.control.Button
        uibuttongroup4_2                matlab.ui.container.ButtonGroup
        uitable_output_2                matlab.ui.control.Table
        ComputeButton                   matlab.ui.control.Button
        CopyTableButton_2               matlab.ui.control.Button
        TimespentinUSsEditFieldLabel    matlab.ui.control.Label
        TimespentinUSsEditField         matlab.ui.control.EditField
        TimespentinDSsEditFieldLabel    matlab.ui.control.Label
        TimespentinDSsEditField         matlab.ui.control.EditField
        notassignedperiodsLabel         matlab.ui.control.Label
        notassignedperiodsEditField     matlab.ui.control.EditField
        OnlyworksonchannelandtrialselectedinZebraLabel  matlab.ui.control.Label
        ScrollLeftButton                matlab.ui.control.StateButton
        ScrollRigthButton               matlab.ui.control.StateButton
        ResetButton                     matlab.ui.control.StateButton
        leftlimEditField                matlab.ui.control.NumericEditField
        rightlimEditField               matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        UIAxes8                         matlab.ui.control.UIAxes
        text2                           matlab.ui.control.Label
        ChannelSpinnerLabel             matlab.ui.control.Label
        chNumber                        matlab.ui.control.Spinner
        TrialSpinnerLabel               matlab.ui.control.Label
        trNumber                        matlab.ui.control.Spinner
        BandPassSpinnerLabel            matlab.ui.control.Label
        BpSelection                     matlab.ui.control.Spinner
        NNprobeCheckBox                 matlab.ui.control.CheckBox
    end

    % Comment by Didi: Notes on how to use the program.
    % 1) Single Units extraction was done according to Quiroga et al. In order
    % to stick to the recommendations in the paper, use high pass filtered data
    % of 300 Hz set in the ZebraExplore main program.
    
    % 2) This program uses results from the EventDetection program. To use
    % this, you must have computed in EventDetection first. Note that
    % all information used here from EventDetection is the start and end time
    % of up and down states. Depending on your goal, you can therefore select
    % on which channel you want to identify up and down states by setting the
    % channel in EventDetection. It is recommended to use the channel with the clearest up states to
    % detect up and down state times in EventDetection, and then keep these times
    % for both channel calculations in the single units program
    
    % 3) The order of execution is important: always click calculate in the SU
    % box on top first, only then you can plot and/or calculate in up and down
    % states
    
    % 4) To save the timing of positive and negative single units, as well as
    % of up and downs states, click the save times button, and make sure the
    % lpt number is set correctly is you used a sum (more than 1) of recordings
    % at the ephys setup
    
    properties (Access = private)
        
    end
    
    properties (Access = public)
        parentApp % ZebraMain handle
        LFP_hp % high pass filtered trace from the parent app
        x % time vector from the parent app
        xidx % indices of the time vector
        x_max_time % right limit in s of time axis for plots in the extraction tab
        x_max_index % right limit in indeces of time axis for plots in the extraction tab
        x_min_time % left limit in s of time axis for plots in the extraction tab
        x_min_index % left limit in indeces of time axis for plots in the extraction tab
        fireRate_smooth
        spikestruc % structure array (channels x trials) with fields: spikes, ipeaks, intspk - described below
        snr % signal to noise ratio for each channel (average peak / "noise")
        pre % time in ms before the peak
        post % time in ms after the peak
        thr % array containing thresholds for each channel of the recording
        isUP % array contaning all the indeces of the timepoints corresponding to the UP states calculated in Event Detection in main Zebra
        isDOWN % array contaning all the indeces of the timepoints corresponding to the DOWN states calculated in Event Detection in main Zebra
        tinUS % total time (s) spent in US
        tinDS % total time (s) spent in DS
        vecSUUp % logic cell array (channel x trial) assigning each (raw) spike to UP states with same index correspondence to ipeaks
        vecSUDown % logic cell array (channel x trial) assigning each (raw) spike to DOWN states with same index correspondence to ipeaks
        coeff % coeffiecient extracted with Haar's WT and selected by KS statistical test
        featAndClust % structure collecting the every spike of each channel getting rid of the trials, output of feature extraction and clustering steps
    end
    
    methods (Access = private)
        function findpeaks(app)
            % function to identify number and timing of positively and negatively deflecting peaks
            
            % let's start by creating vectors contaning the time points and the high-pass filtered data
            app.LFP_hp = reshape(app.parentApp.bandPassed_LFP(app.BpSelection.Value,:,:,:), size(app.parentApp.bandPassed_LFP, [2, 3, 4])); % we now have the high pass filtered data in a 3D matrix
            
            % Now find a threshold for every channel, as proposed by Quiroga et al.
            sigma = zeros(1,app.parentApp.nCh);
            app.thr = zeros(1,app.parentApp.nCh); %stores the calculated threshold for each channel
            for i=1:app.parentApp.nCh
                tmp = squeeze(app.LFP_hp(i,:,:)); %tmp is a 2D matrix with dimension trials and time.
                sigma(i) = median(abs(tmp(:))/0.6745); %the median is calculated over all the channel timepoints
                app.thr(i) = app.ThresholdunitsofstdEditField.Value*sigma(i);
            end
            
            % Teresa
            % Parameters:
            ref_ms = app.refEditField.Value; % refractory period in ms
            ref = (ref_ms/1000)*app.parentApp.acqF; % refractory period in indeces
            before = app.preSEditField.Value; % time in ms to consider before the peak
            app.pre = ceil((before/1000)*app.parentApp.acqF); % number of time-points before the peak in indeces
            after = app.postSEditField.Value; % time in ms to consider after the peak
            app.post = ceil((after/1000)*app.parentApp.acqF); % number of time-points after the peak in indeces
            
            % initialization of a structure array channles x trials with fields:
            % spikes (matrix of extracted spikes, number of spikes x data points),
            % ipeaks (array containing the time of the peak of each spike)
            % intspk (matrix of interpolated, number of spikes x data
            % points), featSpk (matrix with the extracted features),
            % clusKmeans (array vector contaning the clusters found via k-means)
            app.spikestruc = struct('spikes',cell(app.parentApp.nCh,app.parentApp.nTrials), 'ipeaks',cell(app.parentApp.nCh,app.parentApp.nTrials), ...
                'intspk',cell(app.parentApp.nCh,app.parentApp.nTrials)); %'featSpk',cell(app.parentApp.nCh,app.parentApp.nTrials), ...
            %                 'clusKmeans', cell(app.parentApp.nCh,app.parentApp.nTrials));
            
            for iCh = 1:app.parentApp.nCh             % loop on channels
                for iTr = 1:app.parentApp.nTrials     % loop on trials
                    disp(['processing trial ' num2str(iTr) ' of channel ' num2str(iCh)]) % for debug purpouse
                    
                    logictemp = squeeze(abs(app.LFP_hp(iCh,iTr,:)) >= app.thr(iCh)); % logic array: 1 for suprathr abs(data)
                    temp = diff(logictemp); % array: 1 (first time >thr but one index before), 0, -1 (when it goes back)
                    % definition of the temporal window of each event
                    start = find(temp == 1) + 1;
                    stop = find(temp == -1);
                    
                    % continue the analysis only if there are some events:
                    if numel(start) > 0 && numel(stop) > 0
                        
                        % check whether the trace begins or ends with suprathreshold points
                        if start(1) > stop(1)
                            start = [1; start];
                        end
                        if start(end) > stop(end)
                            stop = [stop; length(app.LFP_hp(iCh,iTr,:))];
                        end
                        
                        %                     % single suprathreshold points elimination
                        %                     onepoint = start == stop;
                        %                     start(onepoint) = [];
                        %                     stop(onepoint) = [];
                        %
                        
                        if numel(start) > 1 && numel(stop) > 1
                            % merging of suprathr points that belong to the same event
                            samespk = [false; (start(2:end) - stop(1:end-1)) < ref; false]; % logic array: 1 points that belong to the same spike
                            diffsamespk = diff(samespk); % 1 when the event begins, -1 when it ends; then find the correct indeces:
                            sameEvStart = find(diffsamespk == 1);
                            sameEvStop = find(diffsamespk == -1);
                            if numel(sameEvStart) > 1 && numel(sameEvStop) > 1
                                if sameEvStart(1) > sameEvStop(1) % to adjust whether the trace begins or ends with suprathr points
                                    sameEvStart = [1 sameEvStart];
                                elseif sameEvStart(end) > sameEvStop(end)
                                    sameEvStop = [sameEvStop length(stop)];
                                end
                                
                                for i=1:length(sameEvStart) % merging loop
                                    currentStart = sameEvStart(i);
                                    currentStop = sameEvStop(i);
                                    sameEvIndices = currentStart:currentStop; % indeces of the points that must be considered as one event
                                    start(sameEvIndices(2:end)) = 0; % elimination exceeding points
                                    stop(sameEvIndices(1:end-1)) = 0;
                                end
                                start(start==0)= []; % actual elimination of exceeding points
                                stop(stop==0)= [];
                            end
                        end
                        
                        % find the peaks within each temporal window start:stop
                        peaks = zeros(1,length(start)); % array with abs values of the peaks
                        app.spikestruc(iCh,iTr).ipeaks = zeros(1,length(start)); % array containing the indeces of the peaks
                        for i = 1:length(start)
                            disp([num2str(i) ' of channel ' num2str(iCh)]) % for debug purpouse
                            iStart = start(i);
                            iStop = stop(i);
                            [peaks(i),itemp] = max(abs(app.LFP_hp(iCh,iTr,iStart:iStop)));
                            app.spikestruc(iCh,iTr).ipeaks(i) = itemp + start(i) - 1;
                        end
                        
                        % elimination of putative peaks that are at the
                        % border of the trace and there is not enough space
                        % for pre and post windows
                        app.spikestruc(iCh,iTr).ipeaks(app.spikestruc(iCh,iTr).ipeaks <= app.pre + 2) = [];
                        app.spikestruc(iCh,iTr).ipeaks(app.spikestruc(iCh,iTr).ipeaks >= app.parentApp.dtaLen - app.post -2 + 1) = [];
                        
                        % define the temporal window of spikes and save the
                        % events aligned at the peak (peak index = app.pre+1)
                        windowStart = app.spikestruc(iCh,iTr).ipeaks - app.pre -2;
                        windowStop = app.spikestruc(iCh,iTr).ipeaks + app.post +2;
                        spkwindow = app.pre + app.post + 5; % indeces for looking at waveform + 1 of the peak + 4 points for interpolation
                        app.spikestruc(iCh,iTr).spikes = zeros(length(app.spikestruc(iCh,iTr).ipeaks),spkwindow); % matrix containing each voltage vector of the trace identified by iCh,iTr
                        
                        for i=1:length(app.spikestruc(iCh,iTr).ipeaks)
                            app.spikestruc(iCh,iTr).spikes(i,:) = app.LFP_hp(iCh,iTr,windowStart(i):windowStop(i));
                        end
                    end
                end
                disp(['Peaks identified'])
                
                spk=mean(abs(cat(1,app.spikestruc(iCh,:).spikes)),1);
                if isempty(spk)||any(isnan(spk))
                    app.snr(iCh)=nan;
                else
                    app.snr(iCh)= spk(app.pre+3)/sigma(iCh); % spike peak is at index app.pre+3
                end
                
                % this function then creates also a new structure to save the
                % products of the feature extraction step and of the
                % clustering step. In this structure we get rid of the trials since
                % in the same recording the electrode is not moved and
                % thereby it should detect always the same single units.
                
                app.featAndClust = struct('noTrSpk', cell(app.parentApp.nCh,1),'featSpk',cell(app.parentApp.nCh,1), ...
                    'clusKmeans', cell(app.parentApp.nCh,1), 'featWF', cell(app.parentApp.nCh,1), 'clusWF', cell(app.parentApp.nCh,1));
                % contatenation of spikes in the channels without trials;
                % extracted features; clusters obtained with the kmeans
                % algorithm; features collected for the waveform
                % clustering; clusters obtained with the waveforms.
            end
            
            % interpolation
            if app.InterpolationCheckBox.Value
                interpolation(app)
            end
            for iCh = 1:app.parentApp.nCh             % loop on channels
                for iTr = 1:app.parentApp.nTrials     % loop on trials
                    app.spikestruc(iCh,iTr).spikes = app.spikestruc(iCh,iTr).spikes(:,3:end-3); % eliminate the 4 points added for interpolating
                end
            end
            %             app.spikestruc(:,:).spikes = app.spikestruc(:,:).spikes(:,3:end-3); % eliminate the 4 points added for interpolating
            
        end
        
        
        function interpolation(app)
            % this function computes the interpolation of the spike waveforms
            % and finds and stores the number and timing of peaks;
            % it interpolates with cubic splines to improve alignment.
            intFactor = app.FactorEditField.Value;
            
            for iCh = 1:app.parentApp.nCh             % loop on channels
                for iTr = 1:app.parentApp.nTrials     % loop on trials
                    if size(app.spikestruc(iCh,iTr).spikes,1)>0 % control: if there are spikes
                        olds = 1:size(app.spikestruc(iCh,iTr).spikes,2); % current number of samples
                        ints = 1/intFactor : 1/intFactor : size(app.spikestruc(iCh,iTr).spikes,2); % new number of sample to be simulated with interpolation
                        intspikes = spline(olds,app.spikestruc(iCh,iTr).spikes,ints); % cubic spline interpolation. intspikes is a matrix spikes x interpol data
                        intspikes = intspikes(:,6:end-5); % removal of spurious peaks at the end of interpol trace
                        aroundpeak = ((app.pre+3)*intFactor)-intFactor : ((app.pre+3)*intFactor)+intFactor; % interval around the old peak position
                        [intpeak,itemp] = max(abs(intspikes(:,aroundpeak)),[],2);
                        intpeakidx = itemp + (app.pre+2)*intFactor -intFactor  -1; % index of the peak in the size(intspikes,2)
                        
                        app.spikestruc(iCh,iTr).intspk = zeros(length(intpeak),app.pre + app.post +1);
                        for i = 1:length(intpeak)
                            vecSamples = intpeakidx - (intFactor*app.pre) : intFactor : intpeakidx + (intFactor*app.post);
                            app.spikestruc(iCh,iTr).intspk(i,:) = intspikes(i,vecSamples);
                        end
                    end
                end
            end
            disp(['interpolation computed'])
        end
        
        function showSuOutput(app)
            % show the SU numbers on the appropriate GUI objects
            ch = app.chNumber.Value;
            tr = app.trNumber.Value;
            
            if ~isempty(app.spikestruc(ch,tr).spikes)
                posSU = sum(app.spikestruc(ch,tr).spikes(:,app.pre+1) > 0); % remember: app.pre+1 is the index of the peak
                negSU = sum(app.spikestruc(ch,tr).spikes(:,app.pre+1) < 0);
                % Show the total number of upward going and downward going peaks
                set(app.label_nMax, 'Text', num2str(posSU));
                set(app.label_nMin, 'Text', num2str(negSU));
                set(app.label_nTot, 'Text', num2str(length(app.spikestruc(ch,tr).ipeaks)));
                set(app.SNREditField, 'Value', app.snr(ch))
            else
                set(app.label_nMax, 'Text', num2str(0));
                set(app.label_nMin, 'Text', num2str(0));
                set(app.label_nTot, 'Text', 'not found');
                set(app.SNREditField, 'Value', 0)
            end
            % Also call the plot function
            plotMasterFunction(app)
        end
        
        function plotMasterFunction(app)
            % this is the "master" plot function. It calls specific plot functions accorting to the respective checkbox state
            
            %clear all the axes for first
            cla(app.LFPaxes);
            cla(app.SUaxes);
            cla(app.HPaxes);
            % note that LFPaxes and SUaxes 'NextPlot' property is set to 'add' by default (they always "hold on")
            
            % Find the X axes:
            % convert x axis to time
            acqF = app.parentApp.acqF; % acquisition frequency
            app.x = app.parentApp.x; % time vector
            app.xidx = 1:app.parentApp.dtaLen; % indeces of the time vector
            if isempty(get(app.edit_tStart_plot, 'Value')) % if the user did not enter the start time, i.e. the edit text box is empty
                app.x_min_time = app.x(1); % the start time in seconds
                app.x_min_index = app.xidx(1); % index of the start time
            else
                app.x_min_time = str2double(get(app.edit_tStart_plot, 'Value')); % if the user entered the start time, find the entered number
                if app.x_min_time < app.x(1) % quality control
                    app.x_min_time = app.x(1);
                end
                app.x_min_index = find(app.x>app.x_min_time,1,'first');
            end
            % the same for the end time
            if isempty(get(app.edit_tEnd_plot, 'Value'))
                app.x_max_time = app.x(end);
                app.x_max_index = app.xidx(end);
            else
                app.x_max_time = str2double(get(app.edit_tEnd_plot, 'Value'));
                if app.x_max_time > app.x(end) % quality control
                    app.x_max_time = app.x(end);
                end
                app.x_max_index = find(app.x<app.x_max_time,1,'last');
            end
            
            % if end time is smaller than start time
            if app.x_max_time <= app.x_min_time
                error('end time has to be larger than start time')
            end
            
            %call the specific plot functions
            if app.checkbox_plotLFP.Value
                plotLFP(app)
            end
            if app.checkbox_SUmax.Value
                plotSUmax(app)
            end
            if app.checkbox_SUmin.Value
                plotSUmin(app)
            end
            if app.checkbox_hp.Value
                plotHP(app)
            end
        end
        
        function plotLFP(app)
            % function to plot the LFP
            
            % Obtain  the LFP vector:
            ch = app.chNumber.Value;
            tr = app.trNumber.Value;
            LFPreal = squeeze(app.parentApp.LFP(ch,tr,:));
            
            % Find the Y axes:
            % min and max values of the LFP
            LFPreal_min = min(LFPreal);
            LFPreal_max = max(LFPreal);
            y_min = LFPreal_min;
            y_max = LFPreal_max;
            
            %             if LFPreal_min < 0
            %                 LFP_min_temp = ceil(10*abs(LFPreal_min));
            %                 y_min = -LFP_min_temp/10;
            %             else
            %                 LFP_min_temp = ceil(10*LFPreal_min);
            %                 y_min = LFP_min_temp/10;
            %             end
            %             if LFPreal_max < 0
            %                 LFP_max_temp = ceil(10*abs(LFPreal_max));
            %                 y_max = -LFP_max_temp/10;
            %             else
            %                 LFP_max_temp = ceil(10*LFPreal_max);
            %                 y_max = LFP_max_temp/10;
            %             end
            
            % to maintain the inner position of the plot constant
            axInPos = app.LFPaxes.InnerPosition; % store the original inner position of the axes
            
            % Now plot the LFP:
            plot(app.LFPaxes,app.x(app.x_min_index:app.x_max_index), LFPreal(app.x_min_index:app.x_max_index), 'k');
            xlim(app.LFPaxes,[app.x_min_time app.x_max_time]);
            ylim(app.LFPaxes,[y_min y_max]);
            
            axInPosDiff = axInPos - app.LFPaxes.InnerPosition; % calculate the difference between the original and the current ax inner position
            app.LFPaxes.InnerPosition = app.LFPaxes.InnerPosition + axInPosDiff; % change position such that inner position matches the original one
        end
        
        function plotHP(app)
            ch = app.chNumber.Value;
            tr = app.trNumber.Value;
            % to maintain the inner position of the plot constant
            axInPos = app.HPaxes.InnerPosition; % store the original inner position of the axes
            
            plot(app.HPaxes,app.x(app.x_min_index:app.x_max_index),squeeze(app.LFP_hp(ch,tr,app.x_min_index:app.x_max_index)),'k')
            plot(app.HPaxes,[app.x(app.x_min_index),app.x(app.x_max_index)],[app.thr(ch),app.thr(ch)],'Color','r')
            plot(app.HPaxes,[app.x(app.x_min_index),app.x(app.x_max_index)],[-app.thr(ch),-app.thr(ch)],'Color','b')
            app.HPaxes.XLim = [app.x(app.x_min_index),app.x(app.x_max_index)];
            
            axInPosDiff = axInPos - app.HPaxes.InnerPosition; % calculate the difference between the original and the current ax inner position
            app.HPaxes.InnerPosition = app.HPaxes.InnerPosition + axInPosDiff; % change position such that inner position matches the original one
        end
        
        function plotSUmax(app)
            % function to plot the positive 'peaks' (single units)
            % use the same x limits as created for the LFP
            ch = app.chNumber.Value;
            tr = app.trNumber.Value;
            idxtrace = 1:app.parentApp.dtaLen;
            xlim(app.SUaxes,[app.x_min_time app.x_max_time]);
            vector = app.spikestruc(ch,tr).ipeaks(:);
            y_su_max = ismember(idxtrace, vector(squeeze(app.LFP_hp(ch, tr, vector))> 0)); % creates a vector of the same length of idxtrace, with values 0 or 1 if the same value
            % is a member of ipeaks field, in other words if there is a spike.
            
            % to maintain the inner position of the plot constant
            axInPos = app.SUaxes.InnerPosition; % store the original inner position of the axes
            
            plot(app.SUaxes,app.x(app.x_min_index:app.x_max_index), y_su_max(app.x_min_index:app.x_max_index), 'r');
            
            axInPosDiff = axInPos - app.SUaxes.InnerPosition; % calculate the difference between the original and the current ax inner position
            app.SUaxes.InnerPosition = app.SUaxes.InnerPosition + axInPosDiff; % change position such that inner position matches the original one
        end
        
        function plotSUmin(app)
            % function to plot the negative 'peaks' (single units)
            ch = app.chNumber.Value;
            tr = app.trNumber.Value;
            idxtrace = 1:app.parentApp.dtaLen;
            xlim(app.SUaxes,[app.x_min_time app.x_max_time]);
            %             y_su_min = ismember(idxtrace, app.spikestruc(ch,tr).ipeaks(:)<0);
            vector = app.spikestruc(ch,tr).ipeaks(:);
            y_su_min = ismember(idxtrace, vector(squeeze(app.LFP_hp(ch, tr, vector))< 0));
            
            % to maintain the inner position of the plot constant
            axInPos = app.SUaxes.InnerPosition; % store the original inner position of the axes
            
            plot(app.SUaxes,app.x(app.x_min_index:app.x_max_index), -1*y_su_min(app.x_min_index:app.x_max_index), 'b');
            
            
            axInPosDiff = axInPos - app.SUaxes.InnerPosition; % calculate the difference between the original and the current ax inner position
            app.SUaxes.InnerPosition = app.SUaxes.InnerPosition + axInPosDiff; % change position such that inner position matches the original one
        end
        
        function w = fixedKernelBandwidth(app,fireRate_raw)
            t = ((fireRate_raw > 0)-1)*app.parentApp.sp; %spike times
            t = find(fireRate_raw(app.chNumber.Value,:) > 0); %spike indices
            
            %             minimization function as in shimazaki 2009
            
            toBeMin = @(omega) shimazakiFixed(app,omega,t);
            options = optimset('Display','Iter');
            w = fminsearch(toBeMin,60,options);
            w = fminsearch(toBeMin,60);
        end
        
        function res = shimazakiFixed(app,w,t)
            N = length(t);
            s=0;
            for j=2:N
                for i=1:j-1
                    s = s+exp(-(t(i)-t(j))^2/(2*w)^2)-2*sqrt(2)*exp(-(t(i)-t(j))^2/(2*w^2));
                end
            end
            res = N/w+2/w*s; %to be continued
        end
        
        function [out] = fromSUpeaks_to_LFPpeaks(app,sourceI,sourceV)
            %             sourceI can be app.maxAt o  app.minAt;
            %             sourceV can be app.LFP_hp or app.parentApp.LFP
            posit = sourceI; % position of SU (expressed in indices)
            
            y_tmp = num2cell(sourceV,3); %the cell y{i,j} stores a vector all the points from sourceV(i,j,:), so from the j-th trial of the i-th channel
            y = cellfun(@squeeze,y_tmp,'UniformOutput',0);
            
            fun = @(ind,x) x(ind);
            out_tmp = cellfun(fun,posit,y,'UniformOutput',0);
            
            out = cell(app.parentApp.nCh,1);
            for iCh = 1:app.parentApp.nCh
                out{iCh,1} = vertcat(out_tmp{iCh,:});
            end
        end
        
        function FeatureExtraction(app)
            % firstly, in this function we actually get rid of the trials
            % via concatenation in the featAndClust struct.
            
            % then, this function extracts features according to the selected
            % type of analysis and saves them into a matrix that will serve
            % the clustering algorithm: app.spikestruc(ch,tr).featSpk is a
            % matrix containing a spike per row and the selected
            % coefficients per column. The statistical significance of the
            % most descriptive features is assessed by the Lilliefors - KS
            % test.
            % Moreover, it also saves the column indeces (featidx) of the
            % features that are identified by the Lilliefors
            % test  that are ordered according to their distance-from-gaussianity
            % (the most significant descriptive features for spike sorting
            % purpose).
            
            % future implementation: switching among the types
            % of analyses and write their code: haar, other wavelet, pca, waveform
            
            
            for iCh = 1:app.parentApp.nCh             % loop on channels
                if app.InterpolationCheckBox.Value
                    app.featAndClust(iCh).noTrSpk = cat(1,app.spikestruc(iCh,:).intspk);
                else
                    app.featAndClust(iCh).noTrSpk = cat(1,app.spikestruc(iCh,:).spikes);
                end
            end
            
            
            % control: how many peaks are there in the channels/trials?
            toBeAnalyzed = true(app.parentApp.nCh, 1);
            for iCh = 1:app.parentApp.nCh             % loop on channels
                if size(app.featAndClust(iCh).noTrSpk,1) <=1
                    toBeAnalyzed(iCh) = false;
                end
            end
            
            disp('Starting feature extraction...')
            tic
            if app.WTButton.Value
                % requirement: wavelet analysis toolbox
                par.wtType = app.WaveletDropDown.Value; % type of wavelet
                par.lvl = app.LevelEditField.Value; % level of the transform
                par.min = app.MininputsEditField.Value;  % dimensionality reduction: min # of data points to be used for clustering
                par.max = app.MaxinputsEditField.Value; % dimensionality reduction: max # of data points to be used for clustering expressed in a fraction
                for iCh = 1:app.parentApp.nCh             % loop on channels
                    if toBeAnalyzed(iCh)
                        app.featAndClust(iCh).featSpk = featExtrWT(app.featAndClust(iCh).noTrSpk, par);
                    else
                        app.featAndClust(iCh).featSpk = [];
                    end
                    if app.PCAwtCheckBox.Value
                        temp = app.featAndClust(iCh).featSpk;
                        [~, app.featAndClust(iCh).featSpk] = pca(temp);
                    end
                end
            end
            
            if app.PCAButton.Value
                comp = app.Components_pcaEditField.Value;
                for iCh = 1:app.parentApp.nCh             % loop on channels
                    if toBeAnalyzed(iCh)
                        [~, app.featAndClust(iCh).featSpk] = pca(app.featAndClust(iCh).noTrSpk, 'NumComponents', comp);
                    else
                        app.featAndClust(iCh).featSpk = [];
                    end
                end
            end
            
            if app.tSNEButton.Value
                comp = app.ComponentsEditField.Value;
                for iCh = 1:app.parentApp.nCh             % loop on channels
                    if toBeAnalyzed(iCh)
                        app.featAndClust(iCh).featSpk = tsne(app.featAndClust(iCh).noTrSpk,...
                            'NumDimensions', comp,...
                            "NumPCAComponents",app.PCAbeforeCheckBox.Value*app.withofcomponentsEditField.Value,...
                            "Exaggeration",app.ExaggerationEditField.Value,...
                            "Perplexity",min(size(app.featAndClust(iCh).noTrSpk,1),app.PerplexityEditField.Value),...
                            "Distance",app.DistanceDropDown.Value, ...
                            'Standardize',false); % spearmann mi piace! segrega benissimo!!!!
                    else
                        app.featAndClust(iCh).featSpk = [];
                    end
                end
            end
            
            if app.NMFButton.Value
                comp = app.Components_nmfEditField.Value;
                for iCh = 1:app.parentApp.nCh             % loop on channels
                    if toBeAnalyzed(iCh)
                        [app.featAndClust(iCh).featSpk,~] = nnmf(app.featAndClust(iCh).noTrSpk,comp); % spearmann mi piace! segrega benissimo!!!!
                    else
                        app.featAndClust(iCh).featSpk = [];
                    end
                end
            end
            
            fprintf('Features extracted in %.2f s\n',toc)
        end
        
        function Clustering(app)
            switch app.ClusteringButtonGroup.SelectedObject.Text
                case 'Superparamagnetic'
                case 'K-means' % SALVA I VALORI best_K. FARE UNA STRUTTURA SOLO CLUSTERING?
                    max_K = app.MaxClusNumKEditField.Value; % maximum number of cluster chosen by the user
                    if app.ShowclusternumberevaluationCheckBox.Value
                        f = figure;
                    end
                    for iCh = 1:app.parentApp.nCh             % loop on channels
                        if size(app.featAndClust(iCh).noTrSpk,1) > 5
                            %                         if ~isempty(app.featAndClust(iCh).featSpk)
                            % this line directly computes the k-means and the
                            % goodness of the clustering using the Calinski -
                            % Harabasz method or silhouette evaluation.
                            if app.CalinskiHarabaszCheckBox.Value
                                eva = evalclusters(app.featAndClust(iCh).featSpk,'kmeans','CalinskiHarabasz','KList',[2:max_K]);
                                if app.ShowclusternumberevaluationCheckBox.Value
                                    subplot(1,app.parentApp.nCh,iCh, 'Parent', f)
                                    plot(eva)
                                    title(['Channel ' num2str(iCh)])
                                end
                                best_K = eva.OptimalK;
                                disp(['ch ' num2str(iCh) 'K =' num2str(best_K)])
                            elseif app.SilhouetteEvalCheckBox.Value
                                eva = evalclusters(app.featAndClust(iCh).featSpk,'kmeans','silhouette','KList',[2:max_K]);
                                if app.ShowclusternumberevaluationCheckBox.Value
                                    subplot(1,app.parentApp.nCh,iCh,'Parent', f)
                                    plot(eva)
                                    title(['Channel ' num2str(iCh)])
                                end
                                best_K = eva.OptimalK;
                            end
                            
                            % then we run a k-mean algorithm using the optimal
                            % number of cluster
                            app.featAndClust(iCh).clusKmeans = kmeans(app.featAndClust(iCh).featSpk,best_K);
                            
                            % perform template matching
                            if app.Templatematching.Value
                                for iCh = 1:app.parentApp.nCh             % loop on channels
                                    if size(app.featAndClust(iCh).noTrSpk,1) > 5
                                        maxClus =  max(app.featAndClust(iCh).clusKmeans); % maximum number of clusters in the selected channel
                                        spk = app.featAndClust(iCh).noTrSpk; % matrix of spikes in the selected channel
                                        for i = 1:maxClus
                                            SpkClusNum = app.featAndClust(iCh).clusKmeans == i; % logic array indicating the spikes of the selected cluster
                                            app.featAndClust(iCh).featWF(i,:) = mean(spk(SpkClusNum,:),1); % matrix contaning the mean spike per cluster
                                        end
                                        % now using these mean spikes do
                                        % again the clustering
                                        submean = [];
                                        for j = 1:maxClus
                                            temp = sum(abs(spk - app.featAndClust(iCh).featWF(j,:)),2);
                                            submean = [submean temp];
                                        end
                                        [~ , wfclus_index] = min(submean,[],2);
                                        app.featAndClust(iCh).clusKmeans = wfclus_index;
                                    end
                                end
                            end
                        else
                            app.NumClusEditField.Value = 0;
                            app.featAndClust(iCh).clusKmeans = [];
                        end
                        
                    end
                    app.ShowClusNumSpinner.Value = 1;
                    app.ShowSpkNumSpinner.Value = 1;
                    chNumber_Callback(app)

                case 'Waveform'
                    switch app.ButtonGroupWF.SelectedObject.Text
                        case 'K-means template'                   
                            % run the wt and k-means to find the mean
                            % spikes.
                            app.WTButton.Value = true;
                            FeatureExtraction(app);
                            app.KmeansButton.Value = true;
                            app.CalinskiHarabaszCheckBox.Value = true;
                            Clustering(app);
                            app.WaveformButton.Value = true;
                            
                            for iCh = 1:app.parentApp.nCh             % loop on channels
                                if size(app.featAndClust(iCh).noTrSpk,1) > 5
                                    maxClus =  max(app.featAndClust(iCh).clusKmeans); % maximum number of clusters in the selected channel
                                    spk = app.featAndClust(iCh).noTrSpk; % matrix of spikes in the selected channel
                                    for i = 1:maxClus
                                        SpkClusNum = app.featAndClust(iCh).clusKmeans == i; % logic array indicating the spikes of the selected cluster
                                        app.featAndClust(iCh).featWF(i,:) = mean(spk(SpkClusNum,:),1); % matrix contaning the mean spike per cluster
                                    end
                                    % now using these mean spikes I do again the
                                    % clustering
                                    submean = [];
                                    for j = 1:maxClus
                                        temp = sum(abs(spk - app.featAndClust(iCh).featWF(j,:)),2);
                                        submean = [submean temp];
                                    end
                                    [~ , wfclus_index] = min(submean,[],2);
                                    app.featAndClust(iCh).clusWF = wfclus_index;
                                end
                            end
                            % set the edit fields and spinners for the cluster
                            ch = app.chNumber.Value; % selected channel in the top spinner
                            app.NumClusWFEditField.Value = max(app.featAndClust(ch).clusWF); % number of cluster in the selected channel
                            app.ShowClusSpinner.Limits = [1, max(app.featAndClust(ch).clusWF)];
                            
                        case 'Pearson corr'
                            % 1. save the spikes
                            for iCh = 1:app.parentApp.nCh             % loop on channels
                                if app.InterpolationCheckBox.Value
                                    app.featAndClust(iCh).noTrSpk = cat(1,app.spikestruc(iCh,:).intspk);
                                else
                                    app.featAndClust(iCh).noTrSpk = cat(1,app.spikestruc(iCh,:).spikes);
                                end
                            end
                            % do the pearson correlation
                            for iCh = 1:app.parentApp.nCh  
                                X = app.featAndClust(iCh).noTrSpk'; % X is the input matrix samples x spikes
                                rho = corr(X);
                            end
                    end
                    
                case 'DBSCAN'
                    epsilon = app.nhoodradiusEditField.Value;
                    minpts = app.minptsEditField.Value;
                    for iCh = 1:app.parentApp.nCh
                        if size(app.featAndClust(iCh).noTrSpk,1) > 5
                            app.featAndClust(iCh).clusKmeans = dbscan(app.featAndClust(iCh).featSpk,epsilon,minpts);
                        end
                    end
                    app.ShowClusNumSpinner.Value = 1;
                    app.ShowSpkNumSpinner.Value = 1;
                    chNumber_Callback(app)
            
                case 'GMM'
                    % Gaussian Mixture Model
                    
                    % 1) Find the best number of gaussian distributions
                    k = 1:app.MaxClusNumKEditField.Value; % maximum number of cluster chosen by the user
                    nK = numel(k);
%                     Sigma = {'diagonal','full'};
                    Sigma = {'full'};
                    nSigma = numel(Sigma);
                    SharedCovariance = {true,false};
                    SCtext = {'true','false'};
                    nSC = numel(SharedCovariance);
                    RegularizationValue = 0.01;
                    options = statset('MaxIter',10000);

                    % Preallocation
                    gm = cell(nK,nSigma,nSC);         
                    aic = zeros(nK,nSigma,nSC);
                    bic = zeros(nK,nSigma,nSC);
                    converged = false(nK,nSigma,nSC);
                    
                    % Fit all models
                    for iCh = 1:app.parentApp.nCh
                        if size(app.featAndClust(iCh).featSpk,1)>size(app.featAndClust(iCh).featSpk,2) % more rows than columns
                            for m = 1:nSC
                                for j = 1:nSigma
                                    for i = 1:nK
                                        gm{i,j,m} = fitgmdist(app.featAndClust(iCh).featSpk,k(i),...
                                            'CovarianceType',Sigma{j},...
                                            'SharedCovariance',SharedCovariance{m},...
                                            'RegularizationValue',RegularizationValue,...
                                            'Options',options);
                                        aic(i,j,m) = gm{i,j,m}.AIC;
                                        bic(i,j,m) = gm{i,j,m}.BIC;
                                        converged(i,j,m) = gm{i,j,m}.Converged;
                                    end
                                end
                            end
                            
                            [~,bestModel_idx] = min(aic,[],'all','linear');
                            app.featAndClust(iCh).clusKmeans = cluster(gm{bestModel_idx},app.featAndClust(iCh).featSpk);
                        else
                            app.featAndClust(iCh).clusKmeans = ones(size(app.featAndClust(iCh).featSpk,1),1);
                        end
                    end
                    app.ShowClusNumSpinner.Value = 1;
                    app.ShowSpkNumSpinner.Value = 1;
                    chNumber_Callback(app)
            end
            disp('Clustering finished')
        end
        
        function PlotClusters(app)
            % preparation of variables:
            ch = app.chNumber.Value; % selected channel in the top spinner
            time = (0:size(app.featAndClust(ch).noTrSpk,2)-1)/app.parentApp.acqF;
            time = time - ((app.pre+1) / app.parentApp.acqF); % time axis with 0 in correspondence of the peak
            spk = app.featAndClust(ch).noTrSpk; % matrix of spikes
            
            switch app.ClusteringButtonGroup.SelectedObject.Text                
                case 'Waveform'
                    cla(app.UIAxes9_2), cla(app.UIAxes9)
                    
                    % set the edit fields and spinners for the spikes
                    app.NumSpkEditField.Value = sum(app.featAndClust(ch).clusWF ==  app.ShowClusSpinner.Value); % number of spikes in the selected cluster
                    app.ShowSpkSpinner.Limits = [0, app.NumSpkEditField.Value];
                    
                    colors = [0.5,0.5,0.5; turbo(app.NumClusWFEditField.Value)];
                    % plot
                    plot(app.UIAxes9, time, app.featAndClust(ch).featWF(app.ShowClusSpinner.Value,:),'Color', colors(app.ShowClusSpinner.Value+1,:))
                    currCl = app.ShowClusSpinner.Value;
                    SpkClusNum = app.featAndClust(ch).clusWF == currCl; % logic array indicating the spikes of the selected cluster
                    if sum(SpkClusNum) > 0
                        if app.ShowSpkSpinner.Value == 0
                            % plot all the spikes in the cluster
                            plot(app.UIAxes9_2, time, spk(SpkClusNum,:), 'Color', [colors(currCl+1,:) 0.5]);
                        else
                            % plot the spike selected through the spinner
                            cla(app.UIAxes9_2)
                            SpkNum =  app.ShowSpkSpinner.Value;
                            thisClust = spk(SpkClusNum,:); % matrix which rows are the spikes of the selected cluster
                            plot(app.UIAxes9_2, time, thisClust(SpkNum,:), 'Color', [colors(currCl+1,:) 0.5]);
                        end
                        if app.MeanSpikeCheckBox.Value
                            meanspk = mean(spk(SpkClusNum,:),1);
                            plot(app.UIAxes9, time, meanspk, 'Color', colors(currCl+1,:), 'LineWidth', 2, "LineStyle","--");
                            plot(app.UIAxes9_2, time, meanspk, 'Color', colors(currCl+1,:), 'LineWidth', 2);
                        end
                    else
                        cla(app.UIAxes9_2), cla(app.UIAxes9)
                    end
                    
                otherwise
                        % plot the spikes. Possible combinations:
                    % 1) plot all the clusters via (1.1) plotting just the
                    % spikes, or (1.2) only the clusters' means, or (1.3)
                    % both;
                    % 2) plot just the selected cluster and (2.1) all the
                    % spikes of that cluster or (2.2) just one spike, and
                    % also the mean (2.3).
                    % mode: Next Plot Add
                    
                    cla(app.UIAxes5)
                    app.NumClusEditField.Value = max(app.featAndClust(ch).clusKmeans);
                    colors = [0.5,0.5,0.5;turbo(app.NumClusEditField.Value)]; % add grey for unclustered spikes
                    
                    % set the combination of plots
                    if app.AllClusCheckBox.Value
                        app.ShowClusNumSpinner.Enable = 'off';
                        app.ShowSpkNumSpinner.Enable = 'off';
                        app.totSpkLabel.Text = '0';
                        app.totSpkLabel.Enable = 'off';
                        start = 1;
                        stop =  max(app.featAndClust(ch).clusKmeans);
                    else
                        if max(app.featAndClust(ch).clusKmeans) >1
                            app.ShowClusNumSpinner.Enable = 'on';
                            app.ShowClusNumSpinner.Limits = [1, max(app.featAndClust(ch).clusKmeans)];
                        else
                            app.ShowClusNumSpinner.Enable = 'off';
                            app.ShowClusNumSpinner.Limits = [1, inf];
                        end
                        app.totSpkLabel.Enable = 'on';
                        app.totSpkLabel.Text = num2str(sum(app.featAndClust(ch,1).clusKmeans ==  app.ShowClusNumSpinner.Value));
                        app.ShowSpkNumSpinner.Enable = 'on';
                        start =  app.ShowClusNumSpinner.Value; % selected cluster
                        stop =  app.ShowClusNumSpinner.Value;
                        if sum(app.featAndClust(ch).clusKmeans ==  app.ShowClusNumSpinner.Value) == 1
                            app.ShowSpkNumSpinner.Limits = [1, inf];
                            app.ShowSpkNumSpinner.Enable = 'off';
                            app.ShowSpkNumSpinner.Value = 1;
                        else
                            if sum(app.featAndClust(ch,1).clusKmeans ==  app.ShowClusNumSpinner.Value) >1
                                app.ShowSpkNumSpinner.Limits = [1, sum(app.featAndClust(ch,1).clusKmeans ==  app.ShowClusNumSpinner.Value)];
                            else
                                app.ShowSpkNumSpinner.Enable = "off";
                                app.ShowSpkNumSpinner.Limits = [1,inf];
                            end
                        end
                    end
                    
                    
                    % plot
                    if max(app.featAndClust(ch).clusKmeans) >1
                        for i= start : stop
                            SpkClusNum = app.featAndClust(ch).clusKmeans == i; % logic array indicating the spikes of the selected cluster
                            if app.AllSpkCheckBox.Value
                                allspkplot = plot(app.UIAxes5, time.*1000, spk(SpkClusNum,:), 'Color', [colors(i+1,:) 0.5]);
                            elseif start == stop %  single spike
                                cla(app.UIAxes5)
                                SpkNum =  app.ShowSpkNumSpinner.Value;
                                thisClust = spk(SpkClusNum,:); % matrix which rows are the spikes of the selected cluster
                                plot(app.UIAxes5, time.*1000, thisClust(SpkNum,:), 'Color', colors(i+1,:));
                            end
                            
                            if app.ShowMeanSpkCheckBox.Value
                                meanspk = mean(spk(SpkClusNum,:),1);
                                plot(app.UIAxes5, time.*1000, meanspk, 'Color', colors(i+1,:), 'LineWidth', 2);
                            end
                        end
                        app.UIAxes5.XLim = [time(1) time(end)].*1000;
                    end
                    % scatter plot
                    cla(app.UIAxes6)
                    clus = app.featAndClust(ch).clusKmeans;
                    clus(clus==-1)=0;
                    scatcol = colors(clus+1,:);
                    if max(app.featAndClust(ch).clusKmeans) >1
                        if app.DimPlotClusSwitch.Value == '2-D'
                            spkfeat1 = app.featAndClust(ch).featSpk(:,end);
                            spkfeat2 = app.featAndClust(ch).featSpk(:,end-1);
                            scatter(app.UIAxes6, spkfeat1, spkfeat2,[],scatcol, 'filled')
                            app.UIAxes6.View = [0 90];
                        else
                            spkfeat1 = app.featAndClust(ch).featSpk(:,end);
                            spkfeat2 = app.featAndClust(ch).featSpk(:,end-1);
                            spkfeat3 = app.featAndClust(ch).featSpk(:,end-2);
                            scatter3(app.UIAxes6, spkfeat1, spkfeat2,spkfeat3,[],scatcol, 'filled')
                            app.UIAxes6.ZLabel.String = 'Feature 3';
                        end
                    end
                    % ISI histogram
                    cla(app.UIAxes7)
                    if max(app.featAndClust(ch).clusKmeans) >1
                        % Inter Spike Interval calculation for the selected
                        % cluster and channel
                        if app.AllClusCheckBox.Value
                            app.UIAxes7.Visible = 'off';
                        else
                            app.UIAxes7.Visible = 'on';
                            currclus = app.featAndClust(ch).clusKmeans == app.ShowClusNumSpinner.Value; % indeces of the spikes in the cluster
                            thisChannelSpikes=cat(2,app.spikestruc(ch,:).ipeaks);
                            currpeak = thisChannelSpikes(currclus);
                            currisi = (diff(currpeak)).*app.parentApp.sp*1000;
                            binw=2; % ms
                            [N,edges] = histcounts(currisi,0:binw:100);
%                             h=histogram(app.UIAxes7,currisi, 'normalization','probability', 'BinWidth', 5,...
%                                 'FaceColor', colors(app.ShowClusNumSpinner.Value,:),'FaceAlpha', 1, 'EdgeColor',colors(app.ShowClusNumSpinner.Value,:));
                            bar(app.UIAxes7,edges(1:end-1)+binw/2,N./numel(currisi)*100,'FaceColor',colors(app.ShowClusNumSpinner.Value+1,:))
                            app.UIAxes7.YLim = [0 max(N./numel(currisi)*100)];
                        end
                    end
                    
            end
            
        end
    end
    
    methods (Access = public)
        
        function setSpinners(app)
            % update BP spinner
            if get(app.parentApp.NumberofBPsEditField,'Value') == 1
                app.BpSelection.Limits = [1,inf];
                app.BpSelection.Enable = 'off';
                app.BpSelection.Value = 1;
            else
                app.BpSelection.Limits(2) = app.parentApp.NumberofBPsEditField.Value;
                app.BpSelection.Enable = 'on';
            end
            % updated channel spinner
            if ~isempty(app.parentApp.nCh)
                if app.parentApp.nCh == 1
                    app.chNumber.Limits = [1,inf];
                    app.chNumber.Enable = 'off';
                else
                    app.chNumber.Limits = [1,app.parentApp.nCh];
                    app.chNumber.Enable = 'on';
                end
            end
            % updated trial spinner
            if ~isempty(app.parentApp.nTrials)
                if app.parentApp.nTrials == 1
                    app.trNumber.Limits = [1,inf];
                    app.trNumber.Enable = 'off';
                else
                    app.trNumber.Limits = [1,app.parentApp.nTrials];
                    app.trNumber.Enable = 'on';
                end
            end
        end
        
        function calculate_SU_fcn(app)
            calculate_SU_Callback(app, [])
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function singleUnits_OpeningFcn(app, parentApp)
            %             % This function has no output args, see OutputFcn.
            %             % hObject    handle to figure
            %             % eventdata  reserved - to be defined in a future version of MATLAB
            %             % handles    structure with handles and user data (see GUIDATA)
            %             % varargin   command line arguments to singleunits2 (see VARARGIN)
            %
            %             % Choose default command line output for singleunits2
            %             app.output = hObject;
            %             recover the handle to ZebraExplore
            app.parentApp = parentApp;
            
            %
            
            %             %initialize channels and trials spinners
            
            setSpinners(app)
            
            % Set the outer position of the LFP, HP, SU axes constant
%             app.LFPaxes.PositionConstraint = 'innerposition';
            %             app.LFPaxes.Position = [177,368,520,136];
            innerpos = app.LFPaxes.InnerPosition;
%             app.HPaxes.PositionConstraint = 'innerposition';
            %             app.HPaxes.Position = [177,232,520,136];
            app.HPaxes.InnerPosition(1,3) = innerpos(1,3);
            app.SUaxes.PositionConstraint = 'innerposition';
            %             app.SUaxes.Position = [177,95,520,136];
%             app.SUaxes.InnerPosition(1,3) = innerpos(1,3);
        end

        % Value changed function: chNumber
        function chNumber_Callback(app, event)
            switch app.TabGroup.SelectedTab.Title
                % channel in extraction tab
                case 'Extraction'
                    plotMasterFunction(app)
                    showSuOutput(app)
                    
                    % channel in clustering tab
                case 'Clustering'
                    ch = app.chNumber.Value;
                    % set the spinner for the cluster and the spike to be shown
                    if app.KmeansButton.Value || app.DBSCANButton.Value
                        if isempty(app.featAndClust(ch).clusKmeans) || max(app.featAndClust(ch).clusKmeans)==1
                            app.ShowClusNumSpinner.Limits = [1, inf];
                            app.ShowClusNumSpinner.Enable = 'off';
                            app.ShowSpkNumSpinner.Limits = [1, inf];
                            app.ShowSpkNumSpinner.Enable = 'off';
                        else
                            app.NumClusEditField.Value = max(app.featAndClust(ch).clusKmeans);
                            app.ShowClusNumSpinner.Limits = [1, max(app.featAndClust(ch).clusKmeans)];
                            if ~app.AllClusCheckBox.Value
                                app.ShowClusNumSpinner.Enable = 'on';
                            end
                            if sum(app.featAndClust(ch).clusKmeans ==  app.ShowClusNumSpinner.Value) > 1
                                app.ShowSpkNumSpinner.Limits = [1, sum(app.featAndClust(ch).clusKmeans ==  app.ShowClusNumSpinner.Value)];
                            else
                                app.ShowSpkNumSpinner.Limits = [1, inf];
                                app.ShowSpkNumSpinner.Enable = 'off';
                            end
                            if ~app.AllSpkCheckBox.Value
                                app.ShowSpkNumSpinner.Enable = 'on';
                            end
                        end
                    end
                    
                    if app.WaveformButton.Value
                        if isempty(app.featAndClust(ch).clusWF)
                            app.NumClusWFEditField.Value = 0;
                            app.ShowClusSpinner.Limits = [1, inf];
                            app.ShowClusSpinner.Enable = 'off';
                            app.NumSpkEditField.Value = 0;
                            app.ShowSpkSpinner.Limits = [1, inf];
                            app.ShowSpkSpinner.Enable = 'off';
                        else
                            app.NumClusWFEditField.Value = max(app.featAndClust(ch).clusWF);
                            app.ShowClusSpinner.Limits = [1, max(app.featAndClust(ch).clusWF)];
                            app.ShowSpkSpinner.Limits = [1, sum(app.featAndClust(ch).clusWF ==  app.ShowClusSpinner.Value)];
                        end
                    end
                    PlotClusters(app)
                case 'UpDown'
                    CalcSUUPDSCh_Callback(app,[])
            end
        end

        % Value changed function: trNumber
        function trNumber_Callback(app, event)
            plotMasterFunction(app)
            showSuOutput(app)
        end

        % Value changed function: checkbox_SUmax, checkbox_SUmin, 
        % checkbox_hp, checkbox_plotLFP
        function checkbox_plotLFP_Callback(app, event)
            plotMasterFunction(app)
        end

        % Button pushed function: reset_SU
        function reset_SU_Callback(app, event)
            set(app.label_nMax, 'Text', '-');
            set(app.label_nMin, 'Text', '-');
            set(app.label_nTot, 'Text', '-');
        end

        % Button pushed function: HPhisto_bt
        function HPhisto_bt_Callback(app, event)
            %% This function is build by Gabriele, comment it out to prevent it from interrupting with the intended purpose of the program
            ch = app.chNumber.Value;
            tr = app.trNumber.Value;
            
            hp = app.LFP_hp(ch,:,:);
            hp = hp(:);
            figure
            ax = axes;
            histogram(hp,linspace(min(hp),max(hp),21))
            ax.YScale = 'log';
            hold on
            plot([-app.thr(1),-app.thr(1)],ylim,'r')
            plot([app.thr(1),app.thr(1)],ylim,'r')
            ax.XLabel.String = 'Voltage';
            ax.YLabel.String = 'Conunts';
            su = hp>app.thr(ch) | hp<-app.thr(ch);
            counts = sum(su);
            su_freq = counts/length(hp);
            ax.Title.String = ['SU number: ' num2str(counts) ',  SU frequency: ' num2str(su_freq)];
            
%             %% also check if SU amplitudes correlate with LFP amplitude %%----------------------------------------------
%             %             use the function fromSUpeaks_to_LFPpeaks(app,sourceI,sourceV), where:
%             %             -sourceI is the source of SU indices; it can be app.minAt or app.maxAt
%             %             -sourceV is the source of LFP values; it can be app.LFP_hp or app.parentApp.LFP.
%             
%             posHp = fromSUpeaks_to_LFPpeaks(app,app.x_max_index,app.LFP_hp); %high-passed LFP values relative to positive-SU timepoints
%             negHp = fromSUpeaks_to_LFPpeaks(app,app.x_mix_index,app.LFP_hp); %high-passed LFP values relative to negative-SU timepoints
%             
%             posLfp = fromSUpeaks_to_LFPpeaks(app,app.x_max_index,app.parentApp.LFP); %high-passed LFP values relative to positive-SU timepoints
%             negLfp = fromSUpeaks_to_LFPpeaks(app,app.x_mix_index,app.parentApp.LFP); %high-passed LFP values relative to negative-SU timepoints
%             
%             totHp = cellfun(@vertcat,posHp,negHp,'UniformOutput',0);
%             totLfp = cellfun(@vertcat,posLfp,negLfp,'UniformOutput',0);
%             
%             figure
%             axCorr = axes;
%             plot(axCorr,totLfp{app.chNumber.Value},totHp{app.chNumber.Value},'o')
%             axCorr.XLabel.String = 'LFP amplitude (mV)';
%             axCorr.YLabel.String = 'high-passed LFP amplitude (mV)';
%             plot(axCorr,abs(totLfp{app.chNumber.Value}),abs(totHp{app.chNumber.Value}),'o')
%             axCorr.XLabel.String = 'absolute LFP amplitude (mV)';
%             axCorr.YLabel.String = 'absolute high-passed LFP amplitude (mV)';
%             axCorr.Title.String = 'Correlation between LFP and hp-filtered LFP at SU peaks';
%             
%             [p,S] = polyfit(abs(totLfp{app.chNumber.Value}),abs(totHp{app.chNumber.Value}),1);
%             hold(axCorr,'on')
%             plot(axCorr,abs(totLfp{app.chNumber.Value}),p(2)+abs(totLfp{app.chNumber.Value}).*p(1));
%             [rho,pval] = corr([abs(totLfp{app.chNumber.Value}),abs(totHp{app.chNumber.Value})],Y);
%             hold(axCorr, 'on')
%             r = corrcoef(abs(totLfp{app.chNumber.Value}),abs(totHp{app.chNumber.Value}));
%             txt = text(axCorr,0,0,0,['r_P_e_a_r_s_o_n = ' num2str(r(1,2))]);
%             txt.Position = [axCorr.XLim(2)-txt.Extent(3), axCorr.YLim(2)-txt.Extent(4), 0]; %position txt to the upper-right corner
%             
%             also for all the points, not only for SU
%             figure
%             axCorr = axes;
%             plot(axCorr,totLfp{app.chNumber.Value},totHp{app.chNumber.Value},'o')
%             axCorr.XLabel.String = 'LFP amplitude (mV)';
%             axCorr.YLabel.String = 'high-passed LFP amplitude (mV)';
%             X = abs(app.parentApp.LFP(app.chNumber.Value,:,:));
%             X = X(:);
%             Y = abs(app.parentApp.bandPassed_LFP(5,app.chNumber.Value,:,:));
%             Y = Y(:);
%             plot(axCorr,X,Y,'o')
%             axCorr.XLabel.String = 'absolute LFP amplitude (mV)';
%             axCorr.YLabel.String = 'absolute high-passed LFP amplitude (mV)';
%             axCorr.Title.String = 'Correlation between LFP and hp-filtered LFP (all points)';
%             
%             [p,S] = polyfit(X,Y,1);
%             hold(axCorr,'on')
%             plot(axCorr,X,p(2)+Y.*p(1));
%             [rho,pval] = corr([abs(totLfp{app.chNumber.Value}),abs(totHp{app.chNumber.Value})],Y);
%             hold(axCorr, 'on')
%             r = corrcoef(X,Y);
%             txt = text(axCorr,0,0,0,['r_P_e_a_r_s_o_n = ' num2str(r(1,2))]);
%             txt.Position = [axCorr.XLim(2)-txt.Extent(3), axCorr.YLim(2)-txt.Extent(4), 0]; %position txt to the upper-right corner
%             
        end

        % Button pushed function: calculate_SU
        function calculate_SU_Callback(app, event)
            % this function will execute the findpeaks function and display
            % counted positive and negative peaks
            % if the checkbox Neuronexus probe is selected the callback
            % exclude the last 4 channels of the file because they
            % correspond to the analog and digital inputs. In this way,
            % only the neuronexus channels will remain for the analysis.
            % We also arrange the matrix creating an array contaning the
            % time points and the high-pass filtered data only (excluding
            % the bandpass dimension).
            
            app.LFP_hp = reshape(app.parentApp.bandPassed_LFP(app.BpSelection.Value,:,:,:), size(app.parentApp.bandPassed_LFP, [2, 3, 4])); % we now have the high pass filtered data in a 3D matrix      
            if app.NNprobeCheckBox.Value
                app.LFP_hp = app.LFP_hp(1:end-4,:,:);
            end
            
            findpeaks(app);
            
            showSuOutput(app); % show the output on the GUI
            
            % the structure featAndClust concerns SUA, but it can be
            % initialized to make it compatible with MUA analysis.
            for iCh = 1:app.parentApp.nCh             % loop on channels
                if app.InterpolationCheckBox.Value
                    app.featAndClust(iCh).noTrSpk = cat(1,app.spikestruc(iCh,:).intspk);
                else
                    app.featAndClust(iCh).noTrSpk = cat(1,app.spikestruc(iCh,:).spikes);
                end
                app.featAndClust(iCh).clusKmeans = ones(size(app.featAndClust(iCh).noTrSpk,1),1);
            end
        end

        % Button pushed function: ReplotButton
        function replot_Callback(app, event)
            %just call the plotMasterFunction
            plotMasterFunction(app)
        end

        % Value changed function: edit_tStart_plot
        function edit_tStart_plot_Callback(app, event)
            plotMasterFunction(app)
        end

        % Value changed function: edit_tEnd_plot
        function edit_tEnd_plot_Callback(app, event)
            plotMasterFunction(app)
        end

        % Button pushed function: pushbutton_reset
        function pushbutton_reset_Callback(app, event)
            % hObject    handle to pushbutton_reset (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %function to reset the time to the entire range of the LFP recording
            %if get(hObject, 'Value')
            % set the edit boxes of the start time and end time to empty again
            set(app.edit_tStart_plot, 'Value', '');
            set(app.edit_tEnd_plot, 'Value', '');
            % then replot everything; the plot master function will automatically
            % determine the entire range
            plotMasterFunction(app);
            %end
        end

        % Button pushed function: pushbutton_scroll_left
        function pushbutton_scroll_left_Callback(app, event)
            %function to scroll though the plots of LFP and single units
            
            currentx_min = str2double(get(app.edit_tStart_plot, 'Value'));
            currentx_max = str2double(get(app.edit_tEnd_plot, 'Value'));
            % find the current distance between the start and end time points
            diff = currentx_max-currentx_min;
            % use it to calculate the new timepoints
            newx_min = currentx_min-diff;
            newx_max = currentx_max-diff;
            %                 if newx_min >= 0 && newx_max <= app.time(end) %test that the new time points fall within the time range of the entire recording
            if newx_min < app.x(1) %quality control
                newx_min = app.x(1);
                newx_max = app.x(1)+diff;
            end
            % if yes, you can update the tstart and tend edit boxes
            set(app.edit_tStart_plot, 'Value', num2str(newx_min));
            set(app.edit_tEnd_plot, 'Value', num2str(newx_max));
            %and then re-execute the plotting
            
            plotMasterFunction(app)
        end

        % Button pushed function: pushbutton_scroll_right
        function pushbutton_scroll_right_Callback(app, event)
            % function to scroll right through the plots, the same as the left
            % scrolling function above
            
            currentx_min = str2double(get(app.edit_tStart_plot, 'Value'));
            currentx_max = str2double(get(app.edit_tEnd_plot, 'Value'));
            diff = currentx_max-currentx_min;
            newx_min = currentx_min+diff;
            newx_max = currentx_max+diff;
            if newx_max > app.x(end)
                newx_max = app.x(end);
                newx_min = app.x(end)+diff;
            end
            set(app.edit_tStart_plot, 'Value', num2str(newx_min));
            set(app.edit_tEnd_plot, 'Value', num2str(newx_max));
            
            plotMasterFunction(app)
        end

        % Button pushed function: AssignButton
        function calculateSUUPDOWN_Callback(app, event)
            % Remember: choose in EventDetection from which channel you
            % want to identify up states.
            % Advice: for the initial analysis of Neuronexus traces that is
            % done concomitant with the patch sys, we should select the
            % patch channel to find up states in EvDet. Then, since the
            % same events appear to be also in NN traces (even though we
            % are not used to recognized them yet), we count the single
            % units over all the NN channels in correspondence of the UP
            % and DOWN states detected in the patch rec trace.
            
            % This callback first extracts indeces of UP and DOWN states
            % calculated in the ParentApp - EventDetection section.
            % It show the time interval of US and DS and prepares the
            % sections in the plot below.
            % Then it counts the number of single units that occur in UP
            % and DOWN states over all the channels and creates the first
            % table with the counting of all the single units.
            
            % 1. Retrieve indeces of UP and DOWN states
            %    and display their duration
            app.isUP = []; % array contaning all the indeces of the timepoints corresponding to the UP states
            app.tinUS = 0; % time in sec passed in the US
            for i = 1:app.parentApp.evDet.NUS % number of UP states
                temp = app.parentApp.evDet.upstates.fromI(i):app.parentApp.evDet.upstates.toI(i); % indeces of the US
                app.isUP = [app.isUP, temp];
                temp2 = (temp(end) - temp(1) +1)*app.parentApp.sp; % duration of the US in sec
                app.tinUS = app.tinUS + temp2;
            end
            app.TimespentinUSsEditField.Value = num2str(app.tinUS);
            
            app.isDOWN = []; % array contaning all the indeces of the timepoints corresponding to the DOWN states
            app.tinDS = 0; % time in sec passed in the DS
            for i = 1:app.parentApp.evDet.NDS % number of Diwn states
                temp = app.parentApp.evDet.downstates.fromI(i):app.parentApp.evDet.downstates.toI(i);
                app.isDOWN = [app.isDOWN, temp];
                temp2 = (temp(end) - temp(1) +1)*app.parentApp.sp; % duration of the DS in sec
                app.tinDS = app.tinDS + temp2;
            end
            app.TimespentinDSsEditField.Value = num2str(app.tinDS);
            
            % not assigned interval of the trace to US and DS
            noUSDS = (app.parentApp.dtaLen*app.parentApp.sp) - app.tinUS - app.tinDS;
            app.notassignedperiodsEditField.Value = num2str(noUSDS);
            
            % 2. Color the plot "background" with US and DS temporal windows
            cla(app.UIAxes8)
            ax = app.UIAxes8;
            ax.XLim = [0 app.parentApp.dtaLen*app.parentApp.sp];
            heigth = max(cell2mat({app.featAndClust(:).clusKmeans}'));
            ax.YLim = [0, heigth+0.5];
            Yvertx = [0; 0; heigth+0.5; heigth+0.5];
            % plot US regions
            for i = 1:app.parentApp.evDet.NUS
                x1 = app.parentApp.evDet.upstates.fromI(i)*app.parentApp.sp;
                x2 = app.parentApp.evDet.upstates.toI(i)*app.parentApp.sp;
                Xvertx = [x1; x2; x2; x1];
                patch(app.UIAxes8,Xvertx,Yvertx,'r','FaceAlpha', .1,'EdgeAlpha', 0)
            end
            % plot DS regions
            for i = 1:app.parentApp.evDet.NDS
                x1 = app.parentApp.evDet.downstates.fromI(i)*app.parentApp.sp;
                x2 = app.parentApp.evDet.downstates.toI(i)*app.parentApp.sp;
                Xvertx = [x1; x2; x2; x1];
                patch(app.UIAxes8,Xvertx,Yvertx,'g','FaceAlpha', .1, 'EdgeAlpha', 0)
            end
            % update the time interval limits
            app.leftlimEditField.Value = 0;
            app.rightlimEditField.Value = app.parentApp.dtaLen*app.parentApp.sp;
            
            % 3. Count the single units in the UP and DOWN states
            % logic cell array with 1 for spk in Up and Down states which
            % maintains index correspondence to app.spikestruc.ipeaks
            app.vecSUUp = cell(app.parentApp.nCh,app.parentApp.nTrials);
            app.vecSUDown = cell(app.parentApp.nCh,app.parentApp.nTrials);
            % raw number of spikes in Up and Down states for each channel
            % in each trial
            nSUUp = zeros(app.parentApp.nCh,app.parentApp.nTrials);
            nSUDown = zeros(app.parentApp.nCh,app.parentApp.nTrials);
            % percentage of spikes in Up and Down states for each channel
            % in each trial as the probability for a SU of a given Ch,Tr
            % pair to belong either to US or DS
            pSUUp = zeros(app.parentApp.nCh,app.parentApp.nTrials);
            pSUDown = zeros(app.parentApp.nCh,app.parentApp.nTrials);
            % normalization of the number of spikes respect to the actual
            % duration of the US and DS
            fnormSUUp = zeros(app.parentApp.nCh,app.parentApp.nTrials);
            fnormSUDown = zeros(app.parentApp.nCh,app.parentApp.nTrials);
            for iCh = 1:app.parentApp.nCh             % loop on channels
                for iTr = 1:app.parentApp.nTrials     % loop on trials
                    app.vecSUUp{iCh,iTr} = ismember(app.spikestruc(iCh,iTr).ipeaks, app.isUP);
                    nSUUp(iCh,iTr) = sum(app.vecSUUp{iCh,iTr});
                    pSUUp(iCh,iTr) = (nSUUp(iCh,iTr)/ length(app.spikestruc(iCh,iTr).ipeaks))*100;
                    fnormSUUp(iCh,iTr) = nSUUp(iCh,iTr)./app.tinUS;
                    app.vecSUDown{iCh,iTr} = ismember(app.spikestruc(iCh,iTr).ipeaks, app.isDOWN);
                    nSUDown(iCh,iTr) = sum(app.vecSUDown{iCh,iTr});
                    pSUDown(iCh,iTr) = (nSUDown(iCh,iTr)/ length(app.spikestruc(iCh,iTr).ipeaks))*100;
                    fnormSUDown(iCh,iTr) = nSUDown(iCh,iTr)./app.tinDS;
                end
            end
            
            % 4. Creation of the table: rows are the channels, columns are
            % the raw number of peaks and the correspondent percentage in
            % up and down states
            list = zeros(app.parentApp.nCh,6);
            % problema trial: intanto ce n'è uno... ma poi? :D
            list(:,1) = nSUUp;
            list(:,2) = fnormSUUp;
            list(:,3) = pSUUp;
            list(:,4) = nSUDown;
            list(:,5) = fnormSUDown;
            list(:,6) = pSUDown;
            if isempty(list)
                emptylist = {};
                set(app.uitable_output, 'Data', emptylist);
            else
                list = num2cell(list);
            end
            % row names
            rowName = cell(app.parentApp.nCh,1);
            for i = 1:app.parentApp.nCh
                rowName(i) = {['Ch ' num2str(i)]};
            end
            % table creation
            set(app.uitable_output, 'Data', list, 'RowName', rowName);
        end

        % Button pushed function: CopyTableButton
        function CopyTableButtonPushed(app, event)
            % To copy the data from the table which contains SU counts in US and DS to the clipboard
            tablesize = size(app.uitable_output.Data);
            SelTab2Clip(app.parentApp,app.uitable_output,[1 1 tablesize(1) tablesize(2)])
            % from ZebraExplore:
            % Created a function to copy data to clipboard from outtable.
            % selectionIndices is a 4-element vector containing the
            % upper-left corner and the bottom-right corner indices
        end

        % Button pushed function: rasterPlot_bt
        function rasterPlot_Callback(app, event)
            % sore some parameters first
            nTr = app.parentApp.nTrials;
            len = app.parentApp.dtaLen;
            selCh = app.chNumber.Value;
            
            % get the name of the file (to be shown in the plot)
            switch app.parentApp.dataFormat.Value
                case 'Neuronexus'
                    filename = strsplit(app.parentApp.file_in,'__');
                    filename = filename{1};
                otherwise
                    filename = app.parentApp.file_in(1:end-4);
            end
            
            trInterval = [1 app.parentApp.nTrials];
            % Build the raster plot matrix for the selected channels
            rasterMat = zeros(nTr,len); %inizialize the matrix first
            for iTr = 1:nTr
                rasterMat(iTr,app.spikestruc(selCh,iTr).ipeaks) = true;
            end
            spikeBinary = logical(rasterMat); % matrix for the rasterPlot
%             rasterMat(:,app.x>=1.1 & app.x<=1.2) = false;
%             spikeBinary(:,app.x>=1.1 & app.x<=1.2) = false;
            
            % start plotting
            figure("Name",sprintf('File %s, channel %i',filename,selCh))
            
            % first subplot: raster plot
            ax1 = subplot(3,1,1);
            for i = trInterval(2):-1:trInterval(1)
                % draw vertical lines for spikes
                currY = [i-1;i];
                spk = spikeBinary(i,:);
                x = app.x(repmat(find(spk),2,1));
                if ~isempty(x)
                    plot(ax1,x,currY,'k')
                end
                hold on
            end
            xlabel('Time (s)')
            ylabel('Trials')
            set(ax1,'YTick',(trInterval(1)-1:trInterval(2)-1)+0.5,...
                'YTickLabel',cellstr(num2str((trInterval(1):trInterval(2))')),...
                'YDir','reverse','XLim',[app.x(1),app.x(end)])
            
            % average MUA probability
            ax2 = subplot(3,1,2);
            w = app.kernelBW_edit.Value / app.parentApp.sp;
            s = -length(app.x)/2:length(app.x)/2; %kernel domain
            ker = (sqrt(2*pi)*w)^(-1) * exp(-1*(s).^2./(2*w^2));
            convolved = conv(mean(rasterMat(trInterval(1):trInterval(2),:),1),ker,'same');
            plot(app.x,convolved)
            xlabel('Time (s)')
            ylabel('Integrated unit prob')
            set(ax2,'XLim',[app.x(1),app.x(end)])
            
            % average PSD of the MUA
            params.Fs = 1/(app.x(2)-app.x(1));
            params.tapers = [2,3];
            params.pad = 2;
            
            win = [app.BetweenEditField.Value app.andEditField.Value];
            tmp= convolved(app.x>=win(1) & app.x<=win(2))';
%             [s,f]=mtspectrumc(tmp-mean(tmp),params);
            [X,f] = myPsd(tmp-mean(tmp),1/(app.x(2)-app.x(1)));
            s = abs(X).^2;
            
            convTr = zeros(size(rasterMat));
            sTr = zeros(nTr,length(s));
            
            for i = 1:nTr
                convTr(i,:) = conv(rasterMat(i,:),ker,'same');
                tmp = convTr(i,app.x>=win(1) & app.x<=win(2))';
%                 sTr(i,:) = mtspectrumc(tmp-mean(tmp),params);
                X = myPsd(tmp-mean(tmp),1/(app.x(2)-app.x(1)));
                sTr(i,:) = abs(X).^2;
            end
            ax1 = subplot(3,1,3);
            plot(f,s)
            hold on
            plot(f,mean(sTr,1))
            set(ax1,'XScale','log','XLim',[1 120],'XGrid','on','YScale','log','YGrid','on')
            xlabel('Frequency (Hz')
            ylabel('Power')
            title('Average PSD of the multiunit activity')
            legend('psd of the avg','Average psd')

            if app.AlsocomputecoherencyspectrumCheckBox.Value
                if app.BpSelection_2.Value>0
                    bp = app.BpSelection_2.Value;
                    lfp = squeeze(app.parentApp.bandPassed_LFP(bp,selCh,:,:))';
                else
                    lfp = squeeze(app.parentApp.workLFP(selCh,:,:))';
                end
                params.trialave = 1;
                [C,phi,S12,S1,S2,f]=coherencypb(lfp,rasterMat',params);
                figure("Name",sprintf('File %s, channel %i',filename,selCh))
                ax = subplot(2,1,1);
                plot(f,C)
                xlim(ax,[10 100])
                xlabel('Frequency (Hz)')
                ylabel('Coherency')
                title('Coherency')
                ax = subplot(2,1,2);
                plot(f,phi*180/pi,'o-')
                xlim(ax,[10 100])
                xlabel('Frequency (Hz)')
                ylabel('Phase (degrees)')
                title('Phase')
            end
% fireRate_raw = squeeze(sum(rasterMat,2)); % 2D matrix, channels x time
%             figure
%             ax1 = subplot(2,1,1);
%             plot(ax1,fireRate_raw(1,:))
%             spikeTimes = cell(nTr,1);
%             for i = 1:nTr
%                 spikeTimes = find(spikeBinary > 0);
%             end
%             spikeTimes = mat2cell(find(spikeBinary > 0),ones(1:nCh),ones(1:nTr));
%             spikeTimes = num2cell(find(spikeBinary > 0),3);
%             
%             plotSpikeRaster(squeeze(spikeBinary(app.chNumber.Value,:,:)),'PlotType','vertline','TimePerBin',0.002);
%             ax1.YTickLabel = num2cell(1:nTr);
%             uncorrectTimes = str2double(ax1.XTickLabel);
%             ax1.XTickLabel = num2str((uncorrectTimes)*app.parentApp.sp); %always starts from 0
%             ax1.Title.String = 'raw fire rate';
%             ax1.XLabel.String = 'Time (s)';
%             
%             app.x = indices
%             gaussian kernel function with parameter w.
%             w = app.kernelBW_edit.Value; % totally random, just try
%             w = fixedKernelBandwidth(app,fireRate_raw); %vediamo cosa viene
%             disp(w)
%             s = -len/2:len/2; %kernel domain
%             ker = (sqrt(2*pi)*w)^(-1) * exp(-1*(s).^2./(2*w^2));
%             
%             fireRate_smooth = conv(fireRate_raw(1,:),ker);
%             app.fireRate_smooth = conv(ker,fireRate_raw(app.chNumber.Value,:),'same');
%             ax2 = subplot(2,1,2);
%             t4plot = (0:length(app.fireRate_smooth)-1)*app.parentApp.sp;
%             plot(ax2,t4plot,app.fireRate_smooth)
%             ax2.Title.String = ['smooth fire rate (kernel BW = ' num2str(w) ')'];
%             ax2.XLabel.String = 'Time (s)';
        end

        % Value changed function: checkbox_savetimes
        function checkbox_savetimes_Callback(app, event)
            if app.checkbox_savetimes.Value
                acqF = app.parentApp.acqF;
                nus = app.nus;
                nds = app.nds;
                %create the several columns first: first timing positive single units
                %states, then timing negative single units, timing up states start and end and timing
                %down states start and end
                correctmaxAt = app.maxAt{app.chNumber.Value, app.trNumber.Value};
                correctminAt = app.minAt{app.chNumber.Value, app.trNumber.Value};
                A = (correctmaxAt-1)./acqF;
                B = (correctminAt-1)./acqF;
                C = app.parentApp.evDet.upstates.fromT(1:nus);
                D = app.parentApp.evDet.upstates.toT(1:nus);
                E = app.parentApp.evDet.downstates.fromT(1:nds);
                F = app.parentApp.evDet.downstates.toT(1:nds);
                
                % then create the to be printed struct
                output = struct('TimeposSU', A, 'TimenegSU', B, 'UPstart', C, 'UPend', D, 'DOWNstart', E, 'DOWNend', F);
                
                
                %then create the text file. First find the correct name. I added the
                %possibility to add ltp number so that when several sums are recorded
                %at the ephys setup, which appear in the same folder, each of these
                %sums the data is separately stored
                name = app.edit_ltpnumber.Value;
                channel = app.chNumber.Value;
                fileout = [app.parentApp.dir_in 'SUanalysis' name 'Ch' num2str(channel) '.dat'];
                lengthstruct = length(fieldnames(output));
                fields = fieldnames(output);
                % find the field with the longest length
                ls = [app.nMax(channel, app.trNumber.Value) app.nMin(channel, app.trNumber.Value) app.nus app.nds];
                maximum = max(ls);
                
                % open and start printing
                fid = fopen(fileout, 'w');
                fprintf(fid,'%s\t', fields {:});
                fprintf(fid, '\n');
                for i =  1:maximum
                    for ff = 1:lengthstruct
                        if i > length(output.(fields{ff}))
                            fprintf(fid, '\t');
                        else
                            fprintf(fid,'%g\t', output.(fields{ff})(i));
                        end
                    end
                    fprintf(fid,'\n');
                end
                %fid = fclose(fid);
                fclose(fid);
            end
        end

        % Button pushed function: CLUSTERButton
        function ComputeClustering(app, event)
            % it calls the clustering function 
            Clustering(app);
        end

        % Button pushed function: EXTRACTButton
        function FeatExtraction(app, event)
            % this callback triggers the feature extraction function:
            FeatureExtraction(app);
            ch = app.chNumber.Value;
            app.NumSelFeatEditField.Value = size((app.featAndClust(ch).featSpk),2);
        end

        % Value changed function: AllClusCheckBox
        function AllClusCheckBoxValueChanged(app, event)
            PlotClusters(app);
        end

        % Value changed function: AllSpkCheckBox
        function AllSpkCheckBoxValueChanged(app, event)
            PlotClusters(app);
        end

        % Value changed function: ShowMeanSpkCheckBox
        function ShowMeanSpkCheckBoxValueChanged(app, event)
            PlotClusters(app);
        end

        % Value changed function: ShowClusNumSpinner
        function ShowClusNumSpinnerValueChanged(app, event)
            PlotClusters(app);
        end

        % Value changed function: ShowSpkNumSpinner
        function ShowSpkNumSpinnerValueChanged(app, event)
            PlotClusters(app);
        end

        % Value changed function: DimPlotClusSwitch
        function DimPlotClusSwitchValueChanged(app, event)
            PlotClusters(app);
        end

        % Value changed function: CalinskiHarabaszCheckBox
        function CalinskiHarabaszCheckBoxValueChanged(app, event)
            if  app.CalinskiHarabaszCheckBox.Value
                app.SilhouetteEvalCheckBox.Enable = 'off';
            else
                app.SilhouetteEvalCheckBox.Enable = 'on';
            end
        end

        % Value changed function: SilhouetteEvalCheckBox
        function SilhouetteEvalCheckBoxValueChanged(app, event)
            if  app.SilhouetteEvalCheckBox.Value
                app.CalinskiHarabaszCheckBox.Enable = 'off';
            else
                app.CalinskiHarabaszCheckBox.Enable = 'on';
            end
        end

        % Callback function
        function CopyTableButton_2Pushed(app, event)
            
        end

        % Button pushed function: CopyTableButton_2
        function CopyTableButton_2Pushed2(app, event)
            % To copy the data from the table which contains SU counts in US and DS to the clipboard
            tablesize = size(app.uitable_output_2.Data);
            SelTab2Clip(app.parentApp,app.uitable_output_2,[1 1 tablesize(1) tablesize(2)])
            % from ZebraExplore:
            % Created a function to copy data to clipboard from outtable.
            % selectionIndices is a 4-element vector containing the
            % upper-left corner and the bottom-right corner indices
        end

        % Button pushed function: ComputeButton
        function CalcSUUPDSCh_Callback(app, event)
            % Remember: choose in EventDetection from which channel you
            % want to identify up states.
            % Advice: for the initial analysis of Neuronexus traces that is
            % done concomitant with the patch sys, we should select the
            % patch channel to find up states in EvDet. Then, since the
            % same events appear to be also in NN traces (even though we
            % are not used to recognized them yet), we count the single
            % units over all the NN channels in correspondence of the UP
            % and DOWN states detected in the patch rec trace.
            
            % This callback assigns the clusters of the channel selected on
            % the top of UI to up and down states. It also plots the raster
            % plots of the clusters in the axes already housing US and DS
            % temporal windows.
            
            % 1. prepare the temporary variable cluster to store the output
            % of the clustering function
            switch app.ClusteringButtonGroup.SelectedObject.Text
                case 'K-means'
                    clusters = {app.featAndClust(:).clusKmeans};
                case 'Waveform'
                    clusters = {app.featAndClust(:).clusWF};
            end
            % 2. compute the presence of each cluster into US and DS and do
            % the raster plot
            tmptable = []; %initialization of the table that will be shown in the UI
            for iCh = 1:app.parentApp.nCh
                ipk = app.spikestruc(iCh,1).ipeaks'; % i get the peak index for each spike in that iCh
                nClust = max(clusters{iCh}); % i get the maximum cluster number in that ch
                if ~isempty(nClust)
                    list_clust = zeros(nClust, 8); % table initialization
                    rowNameCh = repmat(iCh,nClust, 1); % first column: channel
                    rowNameClu = (1:nClust)'; % second column: clusters of the channel
                    % computation
                    for iClu = 1:nClust
                        PeakInClu = ipk(clusters{iCh} == iClu); % peak indeces belonging to cluster iClu
                        CluUP = ismember(PeakInClu, app.isUP); % those peaks in US
                        list_clust(iClu,1) = sum(CluUP);
                        list_clust(iClu,2) = sum(CluUP)./app.tinUS;
                        list_clust(iClu,3) = (sum(CluUP)/sum(clusters{iCh} == iClu))*100;
                        list_clust(iClu,4) = (sum(CluUP)/sum(app.vecSUUp{iCh,1}))*100;
                        CluDown = ismember(PeakInClu, app.isDOWN);
                        list_clust(iClu,5) = sum(CluDown);
                        list_clust(iClu,6) = sum(CluDown)./app.tinDS;
                        list_clust(iClu,7) = (sum(CluDown)/sum(clusters{iCh} == iClu))*100;
                        list_clust(iClu,8) = (sum(CluDown)/sum(app.vecSUDown{iCh,1}))*100;
                    end
                    % raster plot
                    if iCh == app.chNumber.Value
                        colors = turbo(nClust);
                        if  ~isempty(app.UIAxes8.UserData)
                            app.UIAxes8.UserData.delete
                        end
                        for iClu = 1:nClust
                            PeakInClu = ipk(clusters{iCh} == iClu);
                            PeakInClu = PeakInClu*app.parentApp.sp;
                            y = ones(1,size(PeakInClu,1)).*iClu;
                            p(iClu) = plot(app.UIAxes8, PeakInClu, y, '|','MarkerSize', 20, 'Linewidth',1,"Color",colors(iClu,:));
                        end
                        app.UIAxes8.UserData = p;
                        app.UIAxes8.YLim = [0, nClust + 1]; % plot arrangements
                        app.UIAxes8.YTick = [1:nClust];
                        app.UIAxes8.YTickLabel = [1:nClust];
                    end
                    % table creation
                    current_table = cat(2,rowNameCh,rowNameClu,list_clust);
                    tmptable = cat(1,tmptable,current_table);
                end
            end
            set(app.uitable_output_2, 'Data', num2cell(tmptable)); % table display
        end

        % Value changed function: ScrollLeftButton
        function ScrollLeftButtonValueChanged(app, event)
            window = app.rightlimEditField.Value - app.leftlimEditField.Value;
            app.leftlimEditField.Value = app.leftlimEditField.Value - window;
            if  app.leftlimEditField.Value < 0
                app.leftlimEditField.Value = 0;
                app.Label_2.Visible = 'on';
                app.Label_2.Text = ({'No time-' ; 'travel admitted'});
            else
                app.rightlimEditField.Value = app.rightlimEditField.Value - window;
                app.Label_2.Visible = 'off';
                app.Label.Visible = 'off';
            end
            app.UIAxes8.XLim = [app.leftlimEditField.Value,app.rightlimEditField.Value];
        end

        % Value changed function: ScrollRigthButton
        function ScrollRigthButtonValueChanged(app, event)
            window = app.rightlimEditField.Value - app.leftlimEditField.Value;
            app.rightlimEditField.Value = app.rightlimEditField.Value + window;
            if  app.rightlimEditField.Value > app.parentApp.dtaLen*app.parentApp.sp
                app.rightlimEditField.Value = app.parentApp.dtaLen*app.parentApp.sp;
                app.Label.Visible = 'on';
                app.Label.Text = ({'No jumps in'; 'the future admitted'});
            else
                app.leftlimEditField.Value = app.leftlimEditField.Value + window;
                app.Label.Visible = 'off';
                app.Label_2.Visible = 'off';
            end
            app.UIAxes8.XLim = [app.leftlimEditField.Value,app.rightlimEditField.Value];
        end

        % Value changed function: leftlimEditField
        function leftlimEditFieldValueChanged(app, event)
            if app.leftlimEditField.Value > app.rightlimEditField.Value
                app.Label_2.Visible = 'on';
                app.Label_2.Text = ({'Left lim must' ; 'be smaller'});
            end
            app.Label_2.Visible = 'off';
            app.UIAxes8.XLim = [app.leftlimEditField.Value,app.rightlimEditField.Value];
        end

        % Value changed function: rightlimEditField
        function rightlimEditFieldValueChanged(app, event)
            if app.rightlimEditField.Value < app.leftlimEditField.Value
                app.Label.Visible = 'on';
                app.Label.Text = ({'Rigth lim must' ; 'be bigger'});
            end
            app.Label.Visible = 'off';
            app.UIAxes8.XLim = [app.leftlimEditField.Value,app.rightlimEditField.Value];
        end

        % Value changed function: ResetButton
        function ResetButtonValueChanged(app, event)
            value = app.ResetButton.Value;
            app.UIAxes8.XLim = [0,app.parentApp.dtaLen*app.parentApp.sp];
            app.leftlimEditField.Value = 0;
            app.rightlimEditField.Value = app.parentApp.dtaLen*app.parentApp.sp;
            app.Label.Visible = 'off';
            app.Label_2.Visible = 'off';
        end

        % Value changed function: ShowClusSpinner
        function ShowClusSpinnerValueChanged(app, event)
            PlotClusters(app)
        end

        % Value changed function: ShowSpkSpinner
        function ShowSpkSpinnerValueChanged(app, event)
            PlotClusters(app)
        end

        % Value changed function: MeanSpikeCheckBox
        function MeanSpikeCheckBoxValueChanged(app, event)
            PlotClusters(app)
        end

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
            PlotClusters(app)
        end

        % Button pushed function: CountButton
        function CountButtonPushed(app, event)
            % sore some parameters first
            nTr = app.parentApp.nTrials;
            len = app.parentApp.dtaLen;
            selCh = app.chNumber.Value;

            % Build the raster plot matrix for the selected channels
            rasterMat = zeros(nTr,len); %inizialize the matrix first
            for iTr = 1:nTr
                rasterMat(iTr,app.spikestruc(selCh,iTr).ipeaks) = true;
            end
            spikeBinary = logical(rasterMat); % matrix for the rasterPlot
            
            wind1_logic = app.x>=app.Window1fromtosEditField.Value & app.x<= app.w1to.Value;
            wind2_logic = app.x>=app.Window2fromtosEditField.Value & app.x<= app.w2to.Value;
            
            spike1 = sum(spikeBinary(:,wind1_logic),"all")./(app.w1to.Value - app.Window1fromtosEditField.Value);
            spike2 = sum(spikeBinary(:,wind2_logic),"all")./(app.w2to.Value - app.Window2fromtosEditField.Value);
            
            app.AvgMUAfreq1HzEditField.Value = spike1;
            app.AvgMUAfreq2HzEditField.Value = spike2;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'SingleUnitApp';
            app.TabGroup = uitabgroup(app.figure1);
            app.ExtractionTab = uitab(app.TabGroup);
            app.ExtractionTab.Title = 'Extraction';
            app.uibuttongroup1 = uibuttongroup(app.ExtractionTab);
            app.uibuttongroup1.Title = 'Count Number SU';
            app.text4 = uilabel(app.uibuttongroup1);
            app.text4.Text = 'Number of + peaks:';
            app.text5 = uilabel(app.uibuttongroup1);
            app.text5.Text = 'Number of - peaks:';
            app.label_nMax = uilabel(app.uibuttongroup1);
            app.label_nMax.Text = '-';
            app.label_nMin = uilabel(app.uibuttongroup1);
            app.label_nMin.Text = '-';
            app.text5_2 = uilabel(app.uibuttongroup1);
            app.text5_2.Text = 'Peaks in the whole selected channel:';
            app.label_nTot = uilabel(app.uibuttongroup1);
            app.label_nTot.Text = '-';
            app.calculate_SU = uibutton(app.ExtractionTab, 'push');
            app.calculate_SU.ButtonPushedFcn = createCallbackFcn(app, @calculate_SU_Callback, true);
            app.calculate_SU.Text = 'Calculate';
            app.reset_SU = uibutton(app.ExtractionTab, 'push');
            app.reset_SU.ButtonPushedFcn = createCallbackFcn(app, @reset_SU_Callback, true);
            app.reset_SU.Text = 'Reset';
            app.HPhisto_bt = uibutton(app.ExtractionTab, 'push');
            app.HPhisto_bt.ButtonPushedFcn = createCallbackFcn(app, @HPhisto_bt_Callback, true);
            app.HPhisto_bt.Text = 'HP histogram';
            app.AlignmentPanel = uipanel(app.ExtractionTab);
            app.AlignmentPanel.Title = 'Alignment';
            app.beforethepeakmsLabel = uilabel(app.AlignmentPanel);
            app.beforethepeakmsLabel.Tooltip = {'Number of time points before the peak'};
            app.beforethepeakmsLabel.Text = 'before the peak (ms)';
            app.preSEditField = uieditfield(app.AlignmentPanel, 'numeric');
            app.preSEditField.Value = 0.66;
            app.afterthepeakmsLabel = uilabel(app.AlignmentPanel);
            app.afterthepeakmsLabel.Tooltip = {'Number of time points before the peak'};
            app.afterthepeakmsLabel.Text = 'after the peak (ms)';
            app.postSEditField = uieditfield(app.AlignmentPanel, 'numeric');
            app.postSEditField.Tooltip = {'Number of samples after the peak'};
            app.postSEditField.Value = 1.46;
            app.RefractoryperiodmsLabel = uilabel(app.AlignmentPanel);
            app.RefractoryperiodmsLabel.Tooltip = {'Temporal window between two following spikes'};
            app.RefractoryperiodmsLabel.Text = 'Refractory period (ms)';
            app.refEditField = uieditfield(app.AlignmentPanel, 'numeric');
            app.refEditField.Tooltip = {'Number of samples after the peak'};
            app.refEditField.Value = 1.5;
            app.InterpolationCheckBox = uicheckbox(app.AlignmentPanel);
            app.InterpolationCheckBox.Text = 'Interpolation';
            app.InterpolationCheckBox.Value = true;
            app.FactorLabel = uilabel(app.AlignmentPanel);
            app.FactorLabel.Text = '- Factor';
            app.FactorEditField = uieditfield(app.AlignmentPanel, 'numeric');
            app.FactorEditField.Value = 5;
            app.uibuttongroup3 = uibuttongroup(app.ExtractionTab);
            app.uibuttongroup3.Title = 'Plotting';
            app.checkbox_plotLFP = uicheckbox(app.uibuttongroup3);
            app.checkbox_plotLFP.ValueChangedFcn = createCallbackFcn(app, @checkbox_plotLFP_Callback, true);
            app.checkbox_plotLFP.Text = 'Plot LFP';
            app.checkbox_plotLFP.Value = true;
            app.checkbox_SUmax = uicheckbox(app.uibuttongroup3);
            app.checkbox_SUmax.ValueChangedFcn = createCallbackFcn(app, @checkbox_plotLFP_Callback, true);
            app.checkbox_SUmax.Text = 'Plot positive SU';
            app.checkbox_SUmax.Value = true;
            app.checkbox_SUmin = uicheckbox(app.uibuttongroup3);
            app.checkbox_SUmin.ValueChangedFcn = createCallbackFcn(app, @checkbox_plotLFP_Callback, true);
            app.checkbox_SUmin.Text = 'Plot negative SU';
            app.checkbox_SUmin.Value = true;
            app.pushbutton_scroll_left = uibutton(app.uibuttongroup3, 'push');
            app.pushbutton_scroll_left.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_scroll_left_Callback, true);
            app.pushbutton_scroll_left.Text = '< scroll';
            app.pushbutton_reset = uibutton(app.uibuttongroup3, 'push');
            app.pushbutton_reset.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_reset_Callback, true);
            app.pushbutton_reset.Text = 'Reset';
            app.pushbutton_scroll_right = uibutton(app.uibuttongroup3, 'push');
            app.pushbutton_scroll_right.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_scroll_right_Callback, true);
            app.pushbutton_scroll_right.Text = 'scroll >';
            app.edit_tStart_plot = uieditfield(app.uibuttongroup3, 'text');
            app.edit_tStart_plot.ValueChangedFcn = createCallbackFcn(app, @edit_tStart_plot_Callback, true);
            app.edit_tEnd_plot = uieditfield(app.uibuttongroup3, 'text');
            app.edit_tEnd_plot.ValueChangedFcn = createCallbackFcn(app, @edit_tEnd_plot_Callback, true);
            app.ReplotButton = uibutton(app.uibuttongroup3, 'push');
            app.ReplotButton.ButtonPushedFcn = createCallbackFcn(app, @replot_Callback, true);
            app.ReplotButton.Text = 'Replot';
            app.checkbox_hp = uicheckbox(app.uibuttongroup3);
            app.checkbox_hp.ValueChangedFcn = createCallbackFcn(app, @checkbox_plotLFP_Callback, true);
            app.checkbox_hp.Text = 'Plot high-passed LFP';
            app.checkbox_hp.Value = true;
            app.TimesLabel = uilabel(app.uibuttongroup3);
            app.TimesLabel.Text = 'Time (s)';
            app.HPaxes = uiaxes(app.uibuttongroup3);
            ylabel(app.HPaxes, 'filtered LFP ')
            app.HPaxes.XTickLabel = '';
            app.LFPaxes = uiaxes(app.uibuttongroup3);
            ylabel(app.LFPaxes, 'LFP')
            app.LFPaxes.XTickLabel = '';
            app.SUaxes = uiaxes(app.uibuttongroup3);
            ylabel(app.SUaxes, 'SU')
            app.ThresholdunitsofstdEditFieldLabel = uilabel(app.ExtractionTab);
            app.ThresholdunitsofstdEditFieldLabel.Text = 'Threshold (units of std)';
            app.ThresholdunitsofstdEditField = uieditfield(app.ExtractionTab, 'numeric');
            app.ThresholdunitsofstdEditField.Value = 4;
            app.SNREditFieldLabel = uilabel(app.ExtractionTab);
            app.SNREditFieldLabel.Text = 'SNR';
            app.SNREditField = uieditfield(app.ExtractionTab, 'numeric');
            app.SNREditField.Editable = 'off';
            app.MUATab = uitab(app.TabGroup);
            app.MUATab.Title = 'MUA';
            app.RasterplotPanel = uipanel(app.MUATab);
            app.RasterplotPanel.Title = 'Raster plot';
            app.rasterPlot_bt = uibutton(app.RasterplotPanel, 'push');
            app.rasterPlot_bt.ButtonPushedFcn = createCallbackFcn(app, @rasterPlot_Callback, true);
            app.rasterPlot_bt.Text = 'Compute';
            app.GaussiankernelbandwidthsLabel = uilabel(app.RasterplotPanel);
            app.GaussiankernelbandwidthsLabel.Text = 'Gaussian kernel bandwidth (s)';
            app.kernelBW_edit = uieditfield(app.RasterplotPanel, 'numeric');
            app.kernelBW_edit.Value = 0.005;
            app.betweenspikesandBPLabel = uilabel(app.RasterplotPanel);
            app.betweenspikesandBPLabel.Text = 'between spikes and BP:';
            app.BpSelection_2 = uispinner(app.RasterplotPanel);
            app.BpSelection_2.Limits = [0 Inf];
            app.BpSelection_2.Tooltip = {'BP0 means wideband LFP'};
            app.TimewindowforspectracomputationPanel = uipanel(app.RasterplotPanel);
            app.TimewindowforspectracomputationPanel.Title = 'Time window for spectra computation';
            app.BetweenEditFieldLabel = uilabel(app.TimewindowforspectracomputationPanel);
            app.BetweenEditFieldLabel.Text = 'Between:';
            app.BetweenEditField = uieditfield(app.TimewindowforspectracomputationPanel, 'numeric');
            app.BetweenEditField.Value = -Inf;
            app.andEditFieldLabel = uilabel(app.TimewindowforspectracomputationPanel);
            app.andEditFieldLabel.Text = 'and';
            app.andEditField = uieditfield(app.TimewindowforspectracomputationPanel, 'numeric');
            app.andEditField.Value = Inf;
            app.sLabel = uilabel(app.TimewindowforspectracomputationPanel);
            app.sLabel.Text = 's';
            app.AlsocomputecoherencyspectrumCheckBox = uicheckbox(app.RasterplotPanel);
            app.AlsocomputecoherencyspectrumCheckBox.Text = 'Also compute coherency spectrum';
            app.SavelogCheckBox = uicheckbox(app.RasterplotPanel);
            app.SavelogCheckBox.Text = 'Save log';
            app.SpikecounterPanel = uipanel(app.MUATab);
            app.SpikecounterPanel.Title = 'Spike counter';
            app.Window1fromtosEditFieldLabel = uilabel(app.SpikecounterPanel);
            app.Window1fromtosEditFieldLabel.Text = 'Window 1: from, to (s)';
            app.Window1fromtosEditField = uieditfield(app.SpikecounterPanel, 'numeric');
            app.Window2fromtosEditFieldLabel = uilabel(app.SpikecounterPanel);
            app.Window2fromtosEditFieldLabel.Text = 'Window 2: from, to (s)';
            app.Window2fromtosEditField = uieditfield(app.SpikecounterPanel, 'numeric');
            app.w1to = uieditfield(app.SpikecounterPanel, 'numeric');
            app.w2to = uieditfield(app.SpikecounterPanel, 'numeric');
            app.CountButton = uibutton(app.SpikecounterPanel, 'push');
            app.CountButton.ButtonPushedFcn = createCallbackFcn(app, @CountButtonPushed, true);
            app.CountButton.Text = 'Count';
            app.AvgMUAfreqHzEditFieldLabel = uilabel(app.SpikecounterPanel);
            app.AvgMUAfreqHzEditFieldLabel.Text = 'Avg MUA freq (Hz)';
            app.AvgMUAfreq1HzEditField = uieditfield(app.SpikecounterPanel, 'numeric');
            app.AvgMUAfreq1HzEditField.Editable = 'off';
            app.AvgMUAfreqHzEditField_2Label = uilabel(app.SpikecounterPanel);
            app.AvgMUAfreqHzEditField_2Label.Text = 'Avg MUA freq (Hz)';
            app.AvgMUAfreq2HzEditField = uieditfield(app.SpikecounterPanel, 'numeric');
            app.AvgMUAfreq2HzEditField.Editable = 'off';
            app.ClusteringTab = uitab(app.TabGroup);
            app.ClusteringTab.Title = 'Clustering';
            app.FeatureExtractionButtonGroup = uibuttongroup(app.ClusteringTab);
            app.FeatureExtractionButtonGroup.Title = 'Feature Extraction';
            app.WTButton = uiradiobutton(app.FeatureExtractionButtonGroup);
            app.WTButton.Text = 'Wavelet Transform';
            app.WTButton.Value = true;
            app.PCAButton = uiradiobutton(app.FeatureExtractionButtonGroup);
            app.PCAButton.Text = 'PCA';
            app.tSNEButton = uiradiobutton(app.FeatureExtractionButtonGroup);
            app.tSNEButton.Text = 't-SNE';
            app.TabGroup3 = uitabgroup(app.FeatureExtractionButtonGroup);
            app.WTTab = uitab(app.TabGroup3);
            app.WTTab.Title = 'WT';
            app.PCAwtCheckBox = uicheckbox(app.WTTab);
            app.PCAwtCheckBox.Text = 'PCA';
            app.LevelEditFieldLabel = uilabel(app.WTTab);
            app.LevelEditFieldLabel.Text = 'Level';
            app.LevelEditField = uieditfield(app.WTTab, 'numeric');
            app.LevelEditField.Limits = [0 Inf];
            app.LevelEditField.Value = 4;
            app.MininputsEditFieldLabel = uilabel(app.WTTab);
            app.MininputsEditFieldLabel.Text = 'Min inputs';
            app.MininputsEditField = uieditfield(app.WTTab, 'numeric');
            app.MininputsEditField.Value = 10;
            app.MaxinputsEditFieldLabel = uilabel(app.WTTab);
            app.MaxinputsEditFieldLabel.Text = 'Max inputs';
            app.MaxinputsEditField = uieditfield(app.WTTab, 'numeric');
            app.MaxinputsEditField.Limits = [0 1];
            app.MaxinputsEditField.Tooltip = {'Fraction of the event lenght'};
            app.MaxinputsEditField.Value = 0.75;
            app.WaveletDropDownLabel = uilabel(app.WTTab);
            app.WaveletDropDownLabel.Text = 'Wavelet';
            app.WaveletDropDown = uidropdown(app.WTTab);
            app.WaveletDropDown.Items = {'haar', 'morlet', 'bump'};
            app.WaveletDropDown.Value = 'haar';
            app.PCATab = uitab(app.TabGroup3);
            app.PCATab.Title = 'PCA';
            app.ComponentsEditField_2Label = uilabel(app.PCATab);
            app.ComponentsEditField_2Label.Text = 'Components';
            app.Components_pcaEditField = uieditfield(app.PCATab, 'numeric');
            app.Components_pcaEditField.Value = 3;
            app.tSNETab = uitab(app.TabGroup3);
            app.tSNETab.Title = 'tSNE';
            app.ComponentsEditFieldLabel = uilabel(app.tSNETab);
            app.ComponentsEditFieldLabel.Text = 'Components';
            app.ComponentsEditField = uieditfield(app.tSNETab, 'numeric');
            app.ComponentsEditField.Value = 3;
            app.DistanceDropDownLabel = uilabel(app.tSNETab);
            app.DistanceDropDownLabel.Text = 'Distance';
            app.DistanceDropDown = uidropdown(app.tSNETab);
            app.DistanceDropDown.Items = {'euclidean', 'spearman', 'correlation', 'cosine', 'seuclidean', 'cityblock', 'chebychev', 'minkowski', 'mahalanobis', 'hamming', 'jaccard'};
            app.DistanceDropDown.Value = 'spearman';
            app.ExaggerationEditFieldLabel = uilabel(app.tSNETab);
            app.ExaggerationEditFieldLabel.Text = 'Exaggeration';
            app.ExaggerationEditField = uieditfield(app.tSNETab, 'numeric');
            app.ExaggerationEditField.Value = 4;
            app.PerplexityEditFieldLabel = uilabel(app.tSNETab);
            app.PerplexityEditFieldLabel.Text = 'Perplexity';
            app.PerplexityEditField = uieditfield(app.tSNETab, 'numeric');
            app.PerplexityEditField.Value = 30;
            app.PCAbeforeCheckBox = uicheckbox(app.tSNETab);
            app.PCAbeforeCheckBox.Text = 'PCA before';
            app.withofcomponentsEditFieldLabel = uilabel(app.tSNETab);
            app.withofcomponentsEditFieldLabel.Text = 'with # of components:';
            app.withofcomponentsEditField = uieditfield(app.tSNETab, 'numeric');
            app.withofcomponentsEditField.Value = 10;
            app.NMFTab = uitab(app.TabGroup3);
            app.NMFTab.Title = 'NMF';
            app.ComponentsEditField_2Label_2 = uilabel(app.NMFTab);
            app.ComponentsEditField_2Label_2.Text = 'Components';
            app.Components_nmfEditField = uieditfield(app.NMFTab, 'numeric');
            app.Components_nmfEditField.Value = 3;
            app.NMFButton = uiradiobutton(app.FeatureExtractionButtonGroup);
            app.NMFButton.Text = 'NMF';
            app.ClusteringButtonGroup = uibuttongroup(app.ClusteringTab);
            app.ClusteringButtonGroup.Tooltip = {'Minimum number of cluster by-default is 2'};
            app.ClusteringButtonGroup.Title = 'Clustering';
            app.SuperparamagneticButton = uiradiobutton(app.ClusteringButtonGroup);
            app.SuperparamagneticButton.Text = 'Superparamagnetic';
            app.KmeansButton = uiradiobutton(app.ClusteringButtonGroup);
            app.KmeansButton.Text = 'K-means';
            app.KmeansButton.Value = true;
            app.Panel_2 = uipanel(app.ClusteringButtonGroup);
            app.kNNEditFieldLabel = uilabel(app.Panel_2);
            app.kNNEditFieldLabel.Text = 'k-NN';
            app.kNNEditField = uieditfield(app.Panel_2, 'numeric');
            app.kNNEditField.Value = 11;
            app.minsizeclustersEditFieldLabel = uilabel(app.Panel_2);
            app.minsizeclustersEditFieldLabel.Text = 'min size clusters';
            app.minsizeclustersEditField = uieditfield(app.Panel_2, 'numeric');
            app.minsizeclustersEditField.Value = 20;
            app.WaveformButton = uiradiobutton(app.ClusteringButtonGroup);
            app.WaveformButton.Text = 'Waveform';
            app.ButtonGroupWF = uibuttongroup(app.ClusteringButtonGroup);
            app.KmeanstemplateButton = uiradiobutton(app.ButtonGroupWF);
            app.KmeanstemplateButton.Text = 'K-means template';
            app.KmeanstemplateButton.Value = true;
            app.PearsoncorrButton = uiradiobutton(app.ButtonGroupWF);
            app.PearsoncorrButton.Text = 'Pearson corr';
            app.thrEditFieldLabel = uilabel(app.ButtonGroupWF);
            app.thrEditFieldLabel.Text = 'thr ';
            app.thrEditField = uieditfield(app.ButtonGroupWF, 'numeric');
            app.Panel = uipanel(app.ClusteringButtonGroup);
            app.ShowclusternumberevaluationCheckBox = uicheckbox(app.Panel);
            app.ShowclusternumberevaluationCheckBox.Text = 'Show cluster number evaluation';
            app.CalinskiHarabaszCheckBox = uicheckbox(app.Panel);
            app.CalinskiHarabaszCheckBox.ValueChangedFcn = createCallbackFcn(app, @CalinskiHarabaszCheckBoxValueChanged, true);
            app.CalinskiHarabaszCheckBox.Text = 'Calinski-Harabasz Evaluation';
            app.SilhouetteEvalCheckBox = uicheckbox(app.Panel);
            app.SilhouetteEvalCheckBox.ValueChangedFcn = createCallbackFcn(app, @SilhouetteEvalCheckBoxValueChanged, true);
            app.SilhouetteEvalCheckBox.Text = 'Silhouette Evaluation';
            app.SilhouetteEvalCheckBox.Value = true;
            app.Templatematching = uicheckbox(app.Panel);
            app.Templatematching.Text = 'Template matching';
            app.MaxClusterNumberEditFieldLabel = uilabel(app.Panel);
            app.MaxClusterNumberEditFieldLabel.Text = 'Max Cluster Number';
            app.MaxClusNumKEditField = uieditfield(app.Panel, 'numeric');
            app.MaxClusNumKEditField.Value = 10;
            app.DBSCANButton = uiradiobutton(app.ClusteringButtonGroup);
            app.DBSCANButton.Text = 'DBSCAN';
            app.GMMButton = uiradiobutton(app.ClusteringButtonGroup);
            app.GMMButton.Text = 'GMM';
            app.Panel_3 = uipanel(app.ClusteringButtonGroup);
            app.nhoodradiusEditFieldLabel = uilabel(app.Panel_3);
            app.nhoodradiusEditFieldLabel.Text = 'nhood radius';
            app.nhoodradiusEditField = uieditfield(app.Panel_3, 'numeric');
            app.nhoodradiusEditField.Value = 40;
            app.minptsEditFieldLabel = uilabel(app.Panel_3);
            app.minptsEditFieldLabel.Text = 'min pts';
            app.minptsEditField = uieditfield(app.Panel_3, 'numeric');
            app.minptsEditField.Tooltip = {'Minimum number of neighbors required for core point'};
            app.minptsEditField.Value = 5;
            app.TabGroup2 = uitabgroup(app.ClusteringTab);
            app.KmeansTab = uitab(app.TabGroup2);
            app.KmeansTab.Title = 'K-means';
            app.NumberofidentifiedclustersEditFieldLabel = uilabel(app.KmeansTab);
            app.NumberofidentifiedclustersEditFieldLabel.Text = 'Number of identified clusters:';
            app.NumClusEditField = uieditfield(app.KmeansTab, 'numeric');
            app.NumClusEditField.Limits = [0 Inf];
            app.NumClusEditField.Editable = 'off';
            app.ShowclusternumberSpinnerLabel = uilabel(app.KmeansTab);
            app.ShowclusternumberSpinnerLabel.Text = 'Show cluster number:';
            app.ShowClusNumSpinner = uispinner(app.KmeansTab);
            app.ShowClusNumSpinner.ValueChangedFcn = createCallbackFcn(app, @ShowClusNumSpinnerValueChanged, true);
            app.ShowspikenumberSpinnerLabel = uilabel(app.KmeansTab);
            app.ShowspikenumberSpinnerLabel.Text = 'Show spike number:';
            app.ShowSpkNumSpinner = uispinner(app.KmeansTab);
            app.ShowSpkNumSpinner.Limits = [0 Inf];
            app.ShowSpkNumSpinner.ValueChangedFcn = createCallbackFcn(app, @ShowSpkNumSpinnerValueChanged, true);
            app.ShowSpkNumSpinner.Tooltip = {'0 means showing all the spikes of the selected cluster together'};
            app.ShowMeanSpkCheckBox = uicheckbox(app.KmeansTab);
            app.ShowMeanSpkCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowMeanSpkCheckBoxValueChanged, true);
            app.ShowMeanSpkCheckBox.Text = 'Show the mean spike';
            app.DimensionfortheclustersplotSwitchLabel = uilabel(app.KmeansTab);
            app.DimensionfortheclustersplotSwitchLabel.Text = 'Dimension for the clusters'' plot';
            app.DimPlotClusSwitch = uiswitch(app.KmeansTab, 'slider');
            app.DimPlotClusSwitch.Items = {'2-D', '3-D'};
            app.DimPlotClusSwitch.ValueChangedFcn = createCallbackFcn(app, @DimPlotClusSwitchValueChanged, true);
            app.DimPlotClusSwitch.Value = '2-D';
            app.AllClusCheckBox = uicheckbox(app.KmeansTab);
            app.AllClusCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllClusCheckBoxValueChanged, true);
            app.AllClusCheckBox.Text = 'Plot all the clusters';
            app.AllClusCheckBox.Value = true;
            app.AllSpkCheckBox = uicheckbox(app.KmeansTab);
            app.AllSpkCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllSpkCheckBoxValueChanged, true);
            app.AllSpkCheckBox.Text = 'Plot all the spikes';
            app.overLabel = uilabel(app.KmeansTab);
            app.overLabel.Text = 'over';
            app.totSpkLabel = uilabel(app.KmeansTab);
            app.totSpkLabel.Text = '0 ';
            app.UIAxes5 = uiaxes(app.KmeansTab);
            title(app.UIAxes5, 'Sorted Spikes')
            xlabel(app.UIAxes5, 'time (ms)')
            ylabel(app.UIAxes5, 'Amplitude (uV)')
            app.UIAxes6 = uiaxes(app.KmeansTab);
            title(app.UIAxes6, 'Clusters')
            xlabel(app.UIAxes6, 'Feature 1')
            ylabel(app.UIAxes6, 'Feature 2')
            app.UIAxes7 = uiaxes(app.KmeansTab);
            title(app.UIAxes7, 'ISI')
            xlabel(app.UIAxes7, 'interspike interval (ms)')
            ylabel(app.UIAxes7, '%')
            app.WaveformTab = uitab(app.TabGroup2);
            app.WaveformTab.Title = 'Waveform';
            app.NumberofclustersEditFieldLabel = uilabel(app.WaveformTab);
            app.NumberofclustersEditFieldLabel.Text = 'Number of clusters';
            app.NumClusWFEditField = uieditfield(app.WaveformTab, 'numeric');
            app.NumClusWFEditField.Editable = 'off';
            app.ShowclusternumberSpinnerLabel_2 = uilabel(app.WaveformTab);
            app.ShowclusternumberSpinnerLabel_2.Text = 'Show cluster number';
            app.ShowClusSpinner = uispinner(app.WaveformTab);
            app.ShowClusSpinner.ValueChangedFcn = createCallbackFcn(app, @ShowClusSpinnerValueChanged, true);
            app.NumberofspikesEditFieldLabel = uilabel(app.WaveformTab);
            app.NumberofspikesEditFieldLabel.Text = 'Number of spikes';
            app.NumSpkEditField = uieditfield(app.WaveformTab, 'numeric');
            app.ShowspikenumberSpinnerLabel_2 = uilabel(app.WaveformTab);
            app.ShowspikenumberSpinnerLabel_2.Text = 'Show spike number';
            app.ShowSpkSpinner = uispinner(app.WaveformTab);
            app.ShowSpkSpinner.ValueChangedFcn = createCallbackFcn(app, @ShowSpkSpinnerValueChanged, true);
            app.ShowSpkSpinner.Tooltip = {'if 0, plot all the spikes in the cluster'};
            app.MeanSpikeCheckBox = uicheckbox(app.WaveformTab);
            app.MeanSpikeCheckBox.ValueChangedFcn = createCallbackFcn(app, @MeanSpikeCheckBoxValueChanged, true);
            app.MeanSpikeCheckBox.Text = 'Mean Spike';
            app.UIAxes9 = uiaxes(app.WaveformTab);
            title(app.UIAxes9, 'Feature Spike')
            xlabel(app.UIAxes9, 'tims (s)')
            ylabel(app.UIAxes9, 'Amplitude (uV)')
            zlabel(app.UIAxes9, 'Z')
            app.UIAxes9_2 = uiaxes(app.WaveformTab);
            title(app.UIAxes9_2, 'Spike')
            xlabel(app.UIAxes9_2, 'tims (ms)')
            ylabel(app.UIAxes9_2, 'Amplitude (uV)')
            zlabel(app.UIAxes9_2, 'Z')
            app.SPCTab = uitab(app.TabGroup2);
            app.SPCTab.Title = 'SPC';
            app.ClusterSpinnerLabel = uilabel(app.SPCTab);
            app.ClusterSpinnerLabel.Text = 'Cluster';
            app.ClusterSpinner = uispinner(app.SPCTab);
            app.ShowallCheckBox = uicheckbox(app.SPCTab);
            app.ShowallCheckBox.Text = 'Show all';
            app.DetectedclustersEditFieldLabel = uilabel(app.SPCTab);
            app.DetectedclustersEditFieldLabel.Text = 'Detected clusters';
            app.DetectedclustersEditField = uieditfield(app.SPCTab, 'numeric');
            app.UIAxes = uiaxes(app.SPCTab);
            title(app.UIAxes, 'Spike')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.SPCTab);
            title(app.UIAxes2, 'ISI')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.SPCTab);
            title(app.UIAxes3, 'Projections')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes4 = uiaxes(app.SPCTab);
            title(app.UIAxes4, 'Temperature')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.ButtonPanel = uipanel(app.ClusteringTab);
            app.ButtonPanel.Title = 'Action Panel';
            app.EXTRACTButton = uibutton(app.ButtonPanel, 'push');
            app.EXTRACTButton.ButtonPushedFcn = createCallbackFcn(app, @FeatExtraction, true);
            app.EXTRACTButton.Text = 'EXTRACT';
            app.NumberofextractedfeaturesLabel = uilabel(app.ButtonPanel);
            app.NumberofextractedfeaturesLabel.Text = {'Number of'; 'extracted features:'};
            app.NumSelFeatEditField = uieditfield(app.ButtonPanel, 'numeric');
            app.NumSelFeatEditField.Editable = 'off';
            app.CLUSTERButton = uibutton(app.ButtonPanel, 'push');
            app.CLUSTERButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeClustering, true);
            app.CLUSTERButton.Text = 'CLUSTER';
            app.PLOTButton = uibutton(app.ButtonPanel, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            app.UpDownTab = uitab(app.TabGroup);
            app.UpDownTab.Title = 'UpDown';
            app.uibuttongroup4 = uibuttongroup(app.UpDownTab);
            app.uibuttongroup4.Title = 'SU in Up and Down states';
            app.checkbox_savetimes = uicheckbox(app.uibuttongroup4);
            app.checkbox_savetimes.ValueChangedFcn = createCallbackFcn(app, @checkbox_savetimes_Callback, true);
            app.checkbox_savetimes.Text = 'Save Times';
            app.edit_ltpnumber = uieditfield(app.uibuttongroup4, 'text');
            app.edit_ltpnumber.Value = 'ltp001';
            app.text8 = uilabel(app.uibuttongroup4);
            app.text8.Text = {'type ltp number if'; 'more than one sum'; 'in the same folder'};
            app.uitable_output = uitable(app.uibuttongroup4);
            app.uitable_output.ColumnName = {'#SU in US'; 'norm over US'; 'p(SUinUS/allSU)'; '#SU in DS'; 'norm over DS'; 'p(USinDS/allSU)'};
            app.uitable_output.RowName = {};
            app.uitable_output.ColumnEditable = [false false false];
            app.CopyTableButton = uibutton(app.uibuttongroup4, 'push');
            app.CopyTableButton.ButtonPushedFcn = createCallbackFcn(app, @CopyTableButtonPushed, true);
            app.CopyTableButton.Text = 'Copy Table ';
            app.AssignButton = uibutton(app.uibuttongroup4, 'push');
            app.AssignButton.ButtonPushedFcn = createCallbackFcn(app, @calculateSUUPDOWN_Callback, true);
            app.AssignButton.Text = 'Assign';
            app.uibuttongroup4_2 = uibuttongroup(app.UpDownTab);
            app.uibuttongroup4_2.Title = 'Clusters subdivision in Up and Down states for the selected channel on top';
            app.uitable_output_2 = uitable(app.uibuttongroup4_2);
            app.uitable_output_2.ColumnName = {'channel'; 'cluster'; '#SU in US'; 'firing freq in US(Hz)'; 'SUinUS/SUclus(%)'; 'SUinUS/SUinUSch(%)'; '#SU in DS'; 'firing freq in DS(Hz)'; 'SUinDS/SUclus(%)'; 'SUinDS/SUinDSch(%)'};
            app.uitable_output_2.RowName = {};
            app.uitable_output_2.ColumnEditable = [false false false];
            app.ComputeButton = uibutton(app.uibuttongroup4_2, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @CalcSUUPDSCh_Callback, true);
            app.ComputeButton.Text = 'Compute';
            app.CopyTableButton_2 = uibutton(app.uibuttongroup4_2, 'push');
            app.CopyTableButton_2.ButtonPushedFcn = createCallbackFcn(app, @CopyTableButton_2Pushed2, true);
            app.CopyTableButton_2.Text = 'Copy Table ';
            app.TimespentinUSsEditFieldLabel = uilabel(app.UpDownTab);
            app.TimespentinUSsEditFieldLabel.Text = 'Time spent in US (s):';
            app.TimespentinUSsEditField = uieditfield(app.UpDownTab, 'text');
            app.TimespentinDSsEditFieldLabel = uilabel(app.UpDownTab);
            app.TimespentinDSsEditFieldLabel.Text = 'Time spent in DS (s):';
            app.TimespentinDSsEditField = uieditfield(app.UpDownTab, 'text');
            app.notassignedperiodsLabel = uilabel(app.UpDownTab);
            app.notassignedperiodsLabel.Text = 'not assigned period (s):';
            app.notassignedperiodsEditField = uieditfield(app.UpDownTab, 'text');
            app.OnlyworksonchannelandtrialselectedinZebraLabel = uilabel(app.UpDownTab);
            app.OnlyworksonchannelandtrialselectedinZebraLabel.Text = {'It considers Up and Down states detected in'; ' ZebraExplore main window'; 'for the selected channel and trial.'};
            app.ScrollLeftButton = uibutton(app.UpDownTab, 'state');
            app.ScrollLeftButton.ValueChangedFcn = createCallbackFcn(app, @ScrollLeftButtonValueChanged, true);
            app.ScrollLeftButton.Text = '< Scroll';
            app.ScrollRigthButton = uibutton(app.UpDownTab, 'state');
            app.ScrollRigthButton.ValueChangedFcn = createCallbackFcn(app, @ScrollRigthButtonValueChanged, true);
            app.ScrollRigthButton.Text = 'Scroll >';
            app.ResetButton = uibutton(app.UpDownTab, 'state');
            app.ResetButton.ValueChangedFcn = createCallbackFcn(app, @ResetButtonValueChanged, true);
            app.ResetButton.Text = 'Reset';
            app.leftlimEditField = uieditfield(app.UpDownTab, 'numeric');
            app.leftlimEditField.ValueChangedFcn = createCallbackFcn(app, @leftlimEditFieldValueChanged, true);
            app.rightlimEditField = uieditfield(app.UpDownTab, 'numeric');
            app.rightlimEditField.ValueChangedFcn = createCallbackFcn(app, @rightlimEditFieldValueChanged, true);
            app.Label = uilabel(app.UpDownTab);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UpDownTab);
            app.Label_2.Text = '';
            app.UIAxes8 = uiaxes(app.UpDownTab);
            title(app.UIAxes8, 'Raster plot in US & DS')
            xlabel(app.UIAxes8, 'time (s)')
            ylabel(app.UIAxes8, 'Cluster')
            zlabel(app.UIAxes8, 'Z')
            app.UIAxes8.YTick = [0 0.5 1];
            app.UIAxes8.YTickLabel = {'0'; '0.5'; '1'};
            app.text2 = uilabel(app.figure1);
            app.text2.Text = {'Use high pass filtered data'; 'in the range of  300-3000 Hz'};
            app.ChannelSpinnerLabel = uilabel(app.figure1);
            app.ChannelSpinnerLabel.Text = 'Channel';
            app.chNumber = uispinner(app.figure1);
            app.chNumber.Limits = [1 Inf];
            app.chNumber.ValueChangedFcn = createCallbackFcn(app, @chNumber_Callback, true);
            app.chNumber.Value = 1;
            app.TrialSpinnerLabel = uilabel(app.figure1);
            app.TrialSpinnerLabel.Text = 'Trial';
            app.trNumber = uispinner(app.figure1);
            app.trNumber.Limits = [1 Inf];
            app.trNumber.ValueChangedFcn = createCallbackFcn(app, @trNumber_Callback, true);
            app.trNumber.Value = 1;
            app.BandPassSpinnerLabel = uilabel(app.figure1);
            app.BandPassSpinnerLabel.Text = 'BandPass';
            app.BpSelection = uispinner(app.figure1);
            app.BpSelection.Limits = [1 Inf];
            app.BpSelection.Value = 5;
            app.NNprobeCheckBox = uicheckbox(app.figure1);
            app.NNprobeCheckBox.Text = 'Neuronexus probe';
            app.NNprobeCheckBox.Value = true;
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SingleUnits_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)singleUnits_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end