classdef ZebraReceptiveFieldApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainFigure               matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LoadlogButton            matlab.ui.control.Button
        TimefromsEditFieldLabel  matlab.ui.control.Label
        TimefromsEditField       matlab.ui.control.NumericEditField
        TimetosEditFieldLabel    matlab.ui.control.Label
        TimetosEditField         matlab.ui.control.NumericEditField
        ChannelSpinnerLabel      matlab.ui.control.Label
        ChannelSpinner           matlab.ui.control.Spinner
        BPpowerSpinnerLabel      matlab.ui.control.Label
        BPpowerSpinner           matlab.ui.control.Spinner
        ComputeButton            matlab.ui.control.Button
        RecFieldAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        parentApp % ZebraExplore handle
        currDir % directory with log files
        resp
        order
        screenSize
        nrows
        ncols
    end
    
    methods (Access = public)
        
        function plotRecField(app)
            ch = app.ChannelSpinner.Value;
            x = 0:app.screenSize(1)/app.ncols:app.screenSize(1);
            y = 0:app.screenSize(2)/app.nrows:app.screenSize(2);
            imagesc(app.RecFieldAxes,x,y,squeeze(app.resp(ch,:,:)))
            
            dx = app.screenSize(1)/app.ncols;
            dy = app.screenSize(2)/app.nrows;
            set(app.RecFieldAxes,'XLim',[-dx,app.screenSize(1)+dx])
            set(app.RecFieldAxes,'YLim',[-dy,app.screenSize(2)+dy])
            set(app.RecFieldAxes,'PlotBoxAspectRatio',[app.screenSize,1])
            set(app.RecFieldAxes,'Colormap',gray)
            % now 2D gaussian fit
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp)
            app.parentApp = parentApp;
            app.currDir = parentApp.dir_in;
            colorbar(app.RecFieldAxes)
        end

        % Button pushed function: LoadlogButton
        function LoadlogButtonPushed(app, event)
            [file,newDir]=uigetfile([app.currDir '*.mat'],'Get a log file');
            if isa(file,'double')
                return
            else
                app.currDir = newDir;
%                 t=readtable([newDir,file],"ReadVariableNames",true);
                load([newDir,file])
                app.order = order;
                app.screenSize = r;
                app.nrows = nrows;
                app.ncols = ncols;
                
            end
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            nRep = numel(app.order)/max(app.order);
            bp = app.BPpowerSpinner.Value;
            nCh = app.parentApp.nCh;
            t = app.parentApp.BPpower(bp).time;
            
            % order the spots and compute the average
            nt = length(t);
            ordLFP = nan(nCh,nRep,nt);
            for i = 1:max(app.order)
                ordLFP(:,i,:) = squeeze(mean(app.parentApp.BPpower(bp).power(:,app.order==i,:),2));
            end
            
            % integrate over the selected time window
            w_logic = t>=app.TimefromsEditField.Value & t<=app.TimetosEditField.Value;
            app.resp = reshape(trapz(t(w_logic),ordLFP(:,:,w_logic),3),nCh,app.nrows,app.ncols);
            
            % plot
            app.plotRecField
        end

        % Value changed function: ChannelSpinner
        function ChannelSpinnerValueChanged(app, event)
            app.plotRecField
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainFigure = uifigure('Visible', 'off');
            app.MainFigure.Name = 'Receptive field visualizer';
            app.GridLayout = uigridlayout(app.MainFigure);
            app.LoadlogButton = uibutton(app.GridLayout, 'push');
            app.LoadlogButton.ButtonPushedFcn = createCallbackFcn(app, @LoadlogButtonPushed, true);
            app.LoadlogButton.Text = 'Load log';
            app.TimefromsEditFieldLabel = uilabel(app.GridLayout);
            app.TimefromsEditFieldLabel.Text = 'Time from (s)';
            app.TimefromsEditField = uieditfield(app.GridLayout, 'numeric');
            app.TimetosEditFieldLabel = uilabel(app.GridLayout);
            app.TimetosEditFieldLabel.Text = 'Time to (s)';
            app.TimetosEditField = uieditfield(app.GridLayout, 'numeric');
            app.TimetosEditField.Value = 1;
            app.ChannelSpinnerLabel = uilabel(app.GridLayout);
            app.ChannelSpinnerLabel.Text = 'Channel';
            app.ChannelSpinner = uispinner(app.GridLayout);
            app.ChannelSpinner.ValueChangedFcn = createCallbackFcn(app, @ChannelSpinnerValueChanged, true);
            app.ChannelSpinner.Value = 1;
            app.BPpowerSpinnerLabel = uilabel(app.GridLayout);
            app.BPpowerSpinnerLabel.Text = 'BP power';
            app.BPpowerSpinner = uispinner(app.GridLayout);
            app.BPpowerSpinner.Value = 1;
            app.ComputeButton = uibutton(app.GridLayout, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.RecFieldAxes = uiaxes(app.GridLayout);
            title(app.RecFieldAxes, 'Receptive field')
            zlabel(app.RecFieldAxes, 'Z')
            % Show the figure after all components are created
            app.MainFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ZebraReceptiveFieldApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainFigure)
        end
    end
end