classdef ZebraSpectreApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ZebraSpectre                   matlab.ui.Figure
        toT                            matlab.ui.container.Panel
        clearSpectre                   matlab.ui.control.Button
        saveSpectra                    matlab.ui.control.Button
        sendToClipboard                matlab.ui.control.Button
        infoString                     matlab.ui.control.Label
        computeSpectra                 matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        MainTab                        matlab.ui.container.Tab
        text3                          matlab.ui.control.Label
        normSpectraCk                  matlab.ui.control.CheckBox
        normLowFreq                    matlab.ui.control.NumericEditField
        normHighFreq                   matlab.ui.control.NumericEditField
        subMean_ck                     matlab.ui.control.CheckBox
        detrend_ck                     matlab.ui.control.CheckBox
        MethodDropDownLabel            matlab.ui.control.Label
        MethodDropDown                 matlab.ui.control.DropDown
        BandwidthHzEditField_2         matlab.ui.control.NumericEditField
        PrefilterCheckBox              matlab.ui.control.CheckBox
        BandwidthHzEditFieldLabel      matlab.ui.control.Label
        BandwidthHzEditField           matlab.ui.control.NumericEditField
        VisualizationTab               matlab.ui.container.Tab
        text1                          matlab.ui.control.Label
        text2                          matlab.ui.control.Label
        freqLog                        matlab.ui.control.CheckBox
        minPower                       matlab.ui.control.NumericEditField
        maxPower                       matlab.ui.control.NumericEditField
        minFreq                        matlab.ui.control.NumericEditField
        maxFreq                        matlab.ui.control.NumericEditField
        ParamsTab                      matlab.ui.container.Tab
        TabGroup2                      matlab.ui.container.TabGroup
        ChronuxTab                     matlab.ui.container.Tab
        MultitaperanalysisPanel        matlab.ui.container.Panel
        TBproductEditFieldLabel        matlab.ui.control.Label
        TBproductEditField             matlab.ui.control.NumericEditField
        NoftapersEditFieldLabel        matlab.ui.control.Label
        NoftapersEditField             matlab.ui.control.NumericEditField
        FFTTab                         matlab.ui.container.Tab
        WindowfunctionDropDownLabel    matlab.ui.control.Label
        WindowfunctionDropDown         matlab.ui.control.DropDown
        WelchTab                       matlab.ui.container.Tab
        WindowsEditFieldLabel          matlab.ui.control.Label
        WindowsEditField               matlab.ui.control.NumericEditField
        StepsEditFieldLabel            matlab.ui.control.Label
        StepsEditField                 matlab.ui.control.NumericEditField
        ZeropaddingEditFieldLabel      matlab.ui.control.Label
        ZeropaddingEditField           matlab.ui.control.NumericEditField
        ErrorintervalEditFieldLabel    matlab.ui.control.Label
        ErrorintervalEditField         matlab.ui.control.NumericEditField
        ComputeerrorsDropDownLabel     matlab.ui.control.Label
        ComputeerrorsDropDown          matlab.ui.control.DropDown
        AverageTab                     matlab.ui.container.Tab
        computelogbeforeaveragingCheckBox  matlab.ui.control.CheckBox
        confidenceintervalsCheckBox    matlab.ui.control.CheckBox
        ConfidenceintervalEditField_2  matlab.ui.control.NumericEditField
        WindowsTab                     matlab.ui.container.Tab
        windowCk                       matlab.ui.control.CheckBox
        text8                          matlab.ui.control.Label
        window2Ck                      matlab.ui.control.CheckBox
        text9                          matlab.ui.control.Label
        fromTtxt                       matlab.ui.control.NumericEditField
        toTtxt                         matlab.ui.control.NumericEditField
        fromT2txt                      matlab.ui.control.NumericEditField
        toT2txt                        matlab.ui.control.NumericEditField
        WhiteningTab                   matlab.ui.container.Tab
        whitenByFreq                   matlab.ui.control.CheckBox
        WhiteningCheckBox              matlab.ui.control.CheckBox
        ButtonGroup                    matlab.ui.container.ButtonGroup
        WhitenbyfrequencymultiplicationButton  matlab.ui.control.RadioButton
        WhitenbyfittingButton          matlab.ui.control.RadioButton
        text4                          matlab.ui.control.Label
        leftLimitWh                    matlab.ui.control.NumericEditField
        rightLimitWh                   matlab.ui.control.NumericEditField
        whiteOut                       matlab.ui.control.Button
        spectreTitle                   matlab.ui.control.Label
        powerSpectra                   matlab.ui.control.UIAxes
        whitenedSpectra                matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        parentApp % ZebraExplore handle
        
        powerS %computed power spectra (channel * trial * frequency)
        meanPWS %average power spectrum among trials (channel * frequency)
        pwsf %power spectra frequencies
        pwl %length(pwsf)
        
        powerS2 %the same as before, but for the 2nd time window (if selected)
        meanPWS2
        pwsf2
        pwl2
                
        whWinLeft
        whWinRight
        whiteFlag
        whitenedPS % Description
        
        powerS_err % error on the power spectrum
        powerS_err2
        meanPWS_err
        meanPWS2_err
    end
    
    methods (Access = private)
        function normF = computeSpectraNormalisationFactors(app)
            % first: compute the indexes that define the normalisation interval
            lowF = app.normLowFreq.Value;
            highF = app.normHighFreq.Value;
            i = 1;
            f = app.pwsf(1);
            while f < lowF
                i = i+1;
                f = app.pwsf(i);
            end
            iStart = i;
            while f < highF
                i = i+1;
                f = app.pwsf(i);
            end
            iEnd = i-1;
            % second: compute the mean value of spectral power for channel 1
            %        tmp(1:app.nCh,1:app.pwl)
            %norm = sum(app.powerS(1,app.currentTrial,iStart:iEnd));
            normF(1) = sum(tmp(1,iStart:iEnd));
            % compute the integral of the others channels and compute the
            % array of the normalization factors.
            for j=2:app.parentApp.nCh
                normF(j) = normF(1)/sum(tmp (j,iStart:iEnd));
            end
            normF(1) = 1;
        end


        function plotWhitenedSpectra(app)
            % Now plot the whitened spectra (if computed...) 
            if app.whiteFlag && app.WhiteningCheckBox.Value 
                %axes(app.whitenedSpectra);
                plot(app.whitenedSpectra,app.pwsf,app.whitenedPS,'LineWidth',2);
                minF = app.minFreq.Value;
                maxF = app.maxFreq.Value;
                maxP = app.maxPower.Value;
                minP = app.minPower.Value;
                if get(app.whitenByFreq,'Value')
                    %axis([minF maxF lim(3) lim(4)]);
                    app.whitenedSpectra.XLim = [minF maxF];
                    app.whitenedSpectra.YLim = [lim(3) lim(4)];
                else
                    %axis([minF maxF -5 5]);
                    app.whitenedSpectra.XLim = [minF maxF];
                    app.whitenedSpectra.YLim = [-5 5];
                end
                if get(app.freqLog,'Value')
                    set(app.whitenedSpectra,'XScale','log');
                else
                    set(app.whitenedSpectra,'XScale','lin');
                end
                app.whitenedSpectra.XGrid = 'on';
                app.whitenedSpectra.YGrid = 'on';
                app.whitenedSpectra.XLabel.String = 'Frequency (Hz)';
                app.whitenedSpectra.YLabel.String = 'Delta power (Db)';
            end
        end
    end
    
    methods (Access = public)
        
        function computeSpectra_function(app) %calls computeSpectra_callback
            computeSpectra_Callback(app,[])
        end
        
        function plotPowerSpectra(app)
            % This function has been moved to ZebraSpectre on December 2017
            % hObject and handles refer to ZebraSpectre.
            
            % First thing first: define the page title
%             app.parentApp = zebraguidata(app.parentObject);
            dirs = regexp(app.parentApp.dir_in,filesep,'split');
                
            l = size(dirs);
            set(app.spectreTitle,'Text',dirs(l(1,2)-1));
            
            tmp = [];
            %axes(app.powerSpectra);
            hold(app.powerSpectra, 'on')
            lim = [app.powerSpectra.XLim, app.powerSpectra.YLim];
            cla(app.powerSpectra)
            
            % for the time being, I use the flags handled by the parent process
            % (ZebraExplore)
            
            flagMean = get(app.parentApp.plotMeanFlag,'Value');
            flagMP = get(app.parentApp.plotMultiCh,'Value');
            flagTr =  get(app.parentApp.plotTrCk,'Value');
            flagN = get(app.normSpectraCk,'Value');
            nch = app.parentApp.nCh;
            pwl = app.pwl;
            ctr = app.parentApp.currentTrial;
            cch = app.parentApp.currentCh;
            if flagMP
                if flagMean
                    tmp(1:nch,1:pwl) = app.meanPWS (1:nch,1:pwl);
                    % plot the mean traces
                    app.powerSpectra.ColorOrderIndex = 1;
                    if flagN
                        % compute etc must be moved here from ZebraExplore
                        norm = app.computeSpectraNormalisationFactors();
                    else
                        norm(1:nch) = 1;
                    end
                    for i=1:nch
                        tmp(i,:) = norm(i) * tmp(i,:);
                    end
                    plot(app.powerSpectra,app.pwsf,10*log10(tmp.'),'LineWidth',2);            % spectra in decibels
                end
                if flagTr
                    % plot the current trace
                    tmp(1:nch,1:pwl) = app.powerS (1:nch,ctr,1:pwl);
                    % plot the mean traces
                    app.powerSpectra.ColorOrderIndex = 1;
                    if flagN
                        norm = app.computeSpectraNormalisationFactors();
                    else
                        norm(1:nch) = 1;
                    end
                    for i=1:nch
                        tmp(i,:) = norm(i) * tmp(i,:);
                    end
                    plot(app.powerSpectra,app.pwsf,10*log10(tmp.')); % spectra in decibels
                end
            else
                color1 = app.powerSpectra.ColorOrder(1,:);
                color2 = app.powerSpectra.ColorOrder(2,:);
                if flagMean
                    tmp = squeeze(app.meanPWS (cch,1:pwl));
                    plot(app.powerSpectra,app.pwsf,10*log10(tmp),'Color',color1,'LineWidth',2); % spectra in decibels
                    if ~isempty(app.meanPWS2)
                        plot(app.powerSpectra,app.pwsf2,10*log10(squeeze(app.meanPWS2 (cch,:))),'Color',color2,'LineWidth',2);
                    end
                end
                if flagTr
                    tmp = squeeze(app.powerS (cch,ctr,1:pwl));
                    plot(app.powerSpectra,app.pwsf,10*log10(tmp),'Color',color1); % spectra in decibels
                    if ~isempty(app.powerS2)
                        plot(app.powerSpectra,app.pwsf2,10*log10(squeeze(app.powerS2 (cch,ctr,:))),'Color',color2);
                    end
                end
            end
            lim(1) = app.minFreq.Value;
            lim(2) = app.maxFreq.Value;
            lim(3) = app.minPower.Value;
            lim(4) = app.maxPower.Value;
            %axis (lim);
            app.powerSpectra.XLim = [lim(1), lim(2)];
            app.powerSpectra.YLim = [lim(3), lim(4)];
            
            if get(app.freqLog,'Value')
                set(app.powerSpectra,'XScale','log');
            else
                set(app.powerSpectra,'XScale','lin');
            end
            app.powerSpectra.YLabel.String = 'Power (dB)'; 
        end
    
        function saveParamsSpectre(app)
    	    propList = string(properties(app)); % get all the properies of the main app as string vector
    	    propListLogic = false(size(propList)); % initialize a logic vector, that will be used to tell apart checkboxes and edit fields from the rest
    	
    	    for i=1:length(propList)
    		if isobject(app.(propList(i))) && isprop(app.(propList(i)),'Type') % only isprop is not enough
    		    if strcmp(app.(propList(i)).Type,'uinumericeditfield') || strcmp(app.(propList(i)).Type,'uicheckbox')
    				propListLogic(i) = true;
    		    end
    		end
    	    end
    	
    	    paramsTable = table('size', [sum(propListLogic), 2],'VariableTypes', {'string', 'double'}, 'VariableNames', {'Name', 'Value'});
    	    paramsTable.Name = propList(propListLogic); % list of only checkboxes and editable textboxes
    	
    	    % Extract all the names, values and callbacks of the checkboxes and editable textboxes of the spectre App
    	    for i=1:height(paramsTable)
    	        paramsTable.Value(i) = app.(paramsTable.Name(i)).Value;
    	    end
    	
    	    app.parentApp.paramsStruct.spectreParamsTable = paramsTable;
        end


        function loadParamsSpectre(app)
            for i = 1 : height(app.parentApp.paramsStruct.spectreParamsTable)

		app.(app.parentApp.paramsStruct.spectreParamsTable.Name(i)).Value = app.parentApp.paramsStruct.spectreParamsTable.Value(i);
                if ~isempty(app.(app.parentApp.paramsStruct.spectreParamsTable.Name(i)).ValueChangedFcn)
                    try
                        app.(app.parentApp.paramsStruct.spectreParamsTable.Name(i)).ValueChangedFcn(app.(app.parentApp.paramsStruct.spectreParamsTable.Name(i)),[])
                    catch ME
                        warning('off','backtrace')
                        warning(strcat("Error while executing ", app.parentApp.paramsStruct.spectreParamsTable.Name(i), "'s Callback"))
                        warning(ME.message)
                        disp(newline)
                    end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp)
            app.parentApp = parentApp; 

            app.whWinLeft = 0;      % to be used for spectral peak computation
            app.whWinRight = 0;
            app.whiteFlag = 0;
             
            %axes(app.powerSpectra);
            app.powerSpectra.XLabel.String = '';
            app.powerSpectra.YLabel.String = 'Power (Db)';
            
            app.whitenedSpectra.YLabel.String = ' ';
        end

        % Window button motion function: ZebraSpectre
        function ZebraSpectre_WindowButtonMotionFcn(app, event)
%             C = get (app.powerSpectra, 'CurrentPoint');
%             txOut = sprintf('Frequency (Hz): %0.1f  Power (Db): %0.2f', C(1,1),C(1,2));
%             set (app.infoString,'String',txOut);
        end

        % Button pushed function: clearSpectre
        function clearSpectre_Callback(app, event)
            cla(app.powerSpectra)
        end

        % Button pushed function: computeSpectra
        function computeSpectra_Callback(app, event)
            % Here we compute the power spectra after reading all the options specfied
            % in the GUI. This function is called by ZebraExplore upon loading of a new
            % data and it is evoked locally within the ZebraSpectre GUI everytime that
            % we change the computation paramenters

            % remember: parentApp point to ZebraExplore
            
            
            app.powerS = [];
            app.powerS2 = [];
            if (app.parentApp.fileInNum)
                tmp = [];
                tmp2 = [];
                if get(app.windowCk,'Value')
                    % read the temporal limits of spectra computation converting from sec to points
                    tin = 1+round(app.parentApp.acqF * app.fromTtxt.Value) - app.parentApp.timeOffset_i; %GAB 2019/02/24: offset added
                    tfin = round(app.parentApp.acqF * app.toTtxt.Value) - app.parentApp.timeOffset_i; %GAB 2019/02/24: offset added
                    if app.window2Ck.Value %gab 2018/11/04: second window
                        tin2 = round(1+app.parentApp.acqF * app.fromT2txt.Value) - app.parentApp.timeOffset_i; %GAB 2019/02/24: offset added
                        tfin2 = round(app.parentApp.acqF * app.toT2txt.Value) - app.parentApp.timeOffset_i; %GAB 2019/02/24: offset added
                    end
                else
                    tin = 1;
                    tfin = app.parentApp.dtaLen;
                end
                % get rid of the for loops! both mtspectrumc and pwelch
                % accept a matrix in the form of time*channels as an input:
                % let's reshape it!
                tmp = reshape(permute(app.parentApp.workLFP(:,:,tin:tfin),[3 2 1]),tfin-tin+1,app.parentApp.nCh*app.parentApp.nTrials);
                if app.windowCk.Value && app.window2Ck.Value
                    tmp2 = reshape(permute(app.parentApp.workLFP(:,:,tin2:tfin2),[3 2 1]),tfin2-tin2+1,app.parentApp.nCh*app.parentApp.nTrials);
                end
                % tmp is now a matrix with timepoints on rows and
                % channels*trials as columns.  
                
                % mean subtraction, if desired
                if app.subMean_ck.Value
                    tmp = tmp - mean(tmp,1);      
                    if app.window2Ck.Value && app.windowCk.Value
                        tmp2 = tmp2 - mean(tmp2,1);
                    end
                end
                % detrend, if desired
                if app.detrend_ck.Value
                    tmp = detrend(tmp);      
                    if app.window2Ck.Value && app.windowCk.Value
                        tmp2 = detrend(tmp2); 
                    end
                end
                
                if app.PrefilterCheckBox.Value
                    band = [app.BandwidthHzEditField.Value app.BandwidthHzEditField_2.Value];
                    if band(1) == 0 % lower bound = 0 means low-pass
                        [b,a] = butter(app.parentApp.filterOrder,2*band(2)*app.parentApp.sp,'low');  %Low pass filter
                    elseif band(2) == Inf % upper bound = inf means high-pass
                        [b,a] = butter(app.parentApp.filterOrder,2*band(1)*app.parentApp.sp, 'high');% high pass and single units
                    else % band-pass
                        [b,a] = butter(app.parentApp.filterOrder,2*band(:)*app.parentApp.sp);
                    end
                    tmp = filtfilt(b,a,tmp);
                    if app.window2Ck.Value && app.windowCk.Value
                        tmp2 = filtfilt(b,a,tmp2);
                    end
                end
                
                % prepare for computation
                disp('Begin spectra computation...')
                switch app.MethodDropDown.Value
                    case 'Chronux (multitaper)'
                        params.tapers = [app.TBproductEditField.Value app.NoftapersEditField.Value];
                        params.Fs = app.parentApp.acqF;
                        params.fpass = [0 0.5*app.parentApp.acqF];
                        params.pad = app.ZeropaddingEditField.Value;
                        switch app.ComputeerrorsDropDown.Value
                            case 'jackknife'
                                params.err = [2,app.ErrorintervalEditField.Value];
                            otherwise
                                params.err = [1,app.ErrorintervalEditField.Value];
                                
                        end
                        [pxx,f,Serr] = mtspectrumc(tmp, params);
                        f = f';
                        if strcmp(app.ComputeerrorsDropDown.Value,'no error')
                            Serr = [];
                        end
                        
                        if app.window2Ck.Value && app.windowCk.Value %gab 2018/11/04: second window
                            [pxx2,f2,Serr2] = mtspectrumc(tmp2, params);
                            f2 = f2';
                            if strcmp(app.ComputeerrorsDropDown.Value,'no error')
                                Serr2 = [];
                            end
                        end
                    
                    case 'FFT'
                        % no padding
                        switch app.WindowfunctionDropDown.Value
                            case 'Rectangular (no windowing)'
                                w = ones(size(tmp,1),1);
                            case 'Hamming'
                                w = hamming(size(tmp,1),'periodic');
                        end
                        [X,f] = myPsd(tmp.*w,app.parentApp.acqF);
                        pxx = abs(X).^2;
                        Serr = [];
                        if app.window2Ck.Value && app.windowCk.Value %gab 2018/11/04: second window
                            switch app.WindowfunctionDropDown.Value
                                case 'Rectangular (no windowing)'
                                    w2 = ones(size(tmp2,1),1);
                                case 'Hamming'
                                    w2 = hamming(size(tmp2,1));
                            end
                            [X2,f2] = myPsd(tmp2.*w2,app.parentApp.acqF);
                            pxx2 = abs(X2).^2;
                            Serr2 = [];
                        end
                        
                    case 'Welch (multitaper)'
                        w_pts = round(app.WindowsEditField.Value./app.parentApp.sp);
                        w_step = round(app.StepsEditField.Value./app.parentApp.sp);
                        nfft = max(size(tmp,1),2^nextpow2(size(tmp,1)+app.ZeropaddingEditField.Value));
                        [pxx,f,Serr] = pwelch(tmp,w_pts,w_pts-w_step,nfft,app.parentApp.acqF,'Confidencelevel',app.ErrorintervalEditField.Value);
                        Serr = permute(reshape(Serr',2,size(pxx,1),size(pxx,2)),[1,3,2]);
                        if app.window2Ck.Value %gab 2018/11/04: second window
                            nfft2 = max(size(tmp2,1),2^nextpow2(size(tmp2,1)+app.ZeropaddingEditField.Value));
                            [pxx2,f2,Serr2] = pwelch(tmp2,w_pts,w_pts-w_step,nfft2,app.parentApp.acqF,'Confidencelevel',app.ErrorintervalEditField.Value);
                            Serr2 = permute(reshape(Serr2',2,size(pxx2,1),size(pxx2,2)),[1,3,2]);
                        end
                end
                
                if strcmp(app.ComputeerrorsDropDown.Value,'no error')
                    Serr = [];
                    if app.window2Ck.Value
                        Serr2=[];
                    end
                end 
                
                l = length(f);
                app.powerS = permute(reshape(pxx,l,app.parentApp.nTrials,app.parentApp.nCh),[3,2,1]);
%                 if ~isempty(Serr)
%                     app.powerS_err = permute(reshape(Serr,l,app.parentApp.nTrials,app.parentApp.nCh),[3,2,1]);
%                 end
                if app.window2Ck.Value && app.windowCk.Value%gab 2018/11/04: second window
                    l2 = size(f2);
                    app.powerS2 = permute(reshape(pxx2,length(f2),app.parentApp.nTrials,app.parentApp.nCh),[3,2,1]);
%                     if ~isempty(Serr2)
%                         app.powerS_err2 = permute(reshape(Serr2,l,app.parentApp.nTrials,app.parentApp.nCh),[3,2,1]);
%                     end
                end
  
                app.pwsf = f;
                app.pwl = l;
                if app.window2Ck.Value && app.windowCk.Value %gab 2018/11/04: second window
                    app.pwsf2 = f2;
                    app.pwl2 = l2;
                else
                    app.pwsf2 = [];
                    app.pwl2 = [];
                end
            end
            clear tmp tmp2
            
            % compute mean
            app.meanPWS = [];
            app.meanPWS2 = [];
            % If ntrial > 1 than compute the mean over trials.
            if get(app.parentApp.skip1trialCk,'value')
                trialBegins = 2 - app.parentApp.alreadySkipped; %gab, 2019/05/29
            else
                trialBegins = 1;
            end
            if ((app.parentApp.nTrials-trialBegins)>1)
                if app.computelogbeforeaveragingCheckBox.Value
                    app.meanPWS = 10.^(squeeze(mean(log10(app.powerS(:,trialBegins:app.parentApp.nTrials,:)),2)));
                else
                    app.meanPWS = squeeze(mean(app.powerS(:,trialBegins:app.parentApp.nTrials,:),2));
                end
                
                if app.window2Ck.Value && app.windowCk.Value
                    if app.computelogbeforeaveragingCheckBox.Value
                        app.meanPWS2 = 10.^(squeeze(mean(log10(app.powerS2(:,trialBegins:app.parentApp.nTrials,:)),2)));
                    else
                        app.meanPWS2 = squeeze(mean(app.powerS2(:,trialBegins:app.parentApp.nTrials,:),2));
                    end
                else
                    app.meanPWS2 = [];
                end
            end
            
            
            % compute whitened spectra, if selected
            % whiteOut_Callback(hObject, [], handles);
            if app.WhiteningCheckBox.Value
                app.whiteOut_Callback()
            else
                app.whiteFlag = 0;
            end

            %plotPowerSpectra (hObject, handles);        % finally, plot the spectra
            app.plotPowerSpectra()
            disp('Spectra computed!')
        end

        % Value changed function: maxFreq
        function maxFreq_Callback(app, event)
            app.plotPowerSpectra();
        end

        % Value changed function: maxPower
        function maxPower_Callback(app, event)
            app.plotPowerSpectra();
        end

        % Value changed function: minFreq
        function minFreq_Callback(app, event)
            app.plotPowerSpectra();
        end

        % Value changed function: minPower
        function minPower_Callback(app, event)
            app.plotPowerSpectra();
        end

        % Button pushed function: saveSpectra
        function saveSpectra_Callback(app, event)
            
            % Save the displayed and whitened spectra in the current directory
                        
            fileOut = [app.parentApp.dir_in app.parentApp.file_in(1:end-5) '_PWS.dat'];
            % check what data to save (single trial or mean over trials)
            flagMd = get(app.parentApp.plotMeanFlag,'Value');
            tmpOut = [];
            if flagMd 
                % save all channels of the mean spectra. Reserve one column for the
                % ordinates.
                tmpOut (2:app.parentApp.nCh+1,1:app.pwl) = app.meanPWS (1:app.parentApp.nCh,1:app.pwl);
            else
                % save the spectra of all channels of the current trial
                tmpOut (2:app.parentApp.nCh+1,1:app.pwl) = app.powerS (1:app.parentApp.nCh,app.parentApp.currentTrial,1:app.pwl);
            end
            tmpOut(1,:) = app.pwsf (:);
            if app.whiteFlag
                tmpOut(app.parentApp.nCh+2,:) = app.whitenedPS (:);
            end
            
            % creat the format descriptor
            writematrix(tmpOut',fileOut,'Delimiter','tab')
%             fmtSt='';
%             for ii=1:app.parentApp.nCh+1      % add 2 for the x axis and the whitened spectra.
%                fmtSt=[fmtSt '%12.10f '] ;
%             end
%             fmtSt=[fmtSt '%12.10f\n'];       % start new line
%             
%             fid = fopen(fileOut,'w');
%             fprintf (fid,fmtSt,tmpOut);
%             fclose(fid);
            
            %ENRICO 29/10/2018 ADDED THE MATLAB VARIABLE
            if app.parentApp.internalSynchCk.Value
                pws.meanPWS = squeeze(app.meanPWS(app.parentApp.currentCh,1:app.pwl))';
            end
            pws.trialsPWS = squeeze(app.powerS(app.parentApp.currentCh,:,1:app.pwl));
            pws.pwsf = app.pwsf(:);
            save([app.parentApp.dir_in app.parentApp.file_in(1:end-5) '_PWS.mat'],'pws')
            disp('spectra saved')
        end

        % Button pushed function: sendToClipboard
        function sendToClipboard_Callback(app, event)
            
            % send to the clipboard
            
            % set(gcf,'units','pixels');
            % gcfPosition = get(gcf,'position');      % get the position of the Zebra Spectre figure expressed in pixels
            % %imageData = screencapture(app.inset_WB, [], 'clipboard');
            % imageData = screencapture(gcf, gcfPosition, 'clipboard');
            hgexport(gcf,'-clipboard');
        end

        % Button pushed function: whiteOut
        function whiteOut_Callback(app, event)
            
            % Compute the linear fit to the log log spectra and divide the spectra by
            % this function. This process allows a better visualization of local peaks
            % in the power spectra
                        
            fileOut = [app.parentApp.dir_in 'PWS.dat'];
            % check what data is displayied (single trial or mean over trials)
            flagMd = get(app.parentApp.plotMeanFlag,'Value');
            tmpOut = [];
            
            % if flagMd
            %     % Load the power of each channels in a temporary array.
            %     spectra (2:app.parentApp.nCh+1,1:app.parentApp.pwl) = app.parentApp.meanPWS (1:app.parentApp.nCh,1:app.parentApp.pwl);
            % else
            %     % save the spectra of all channels of the current trial
            % %    tmpOut (2:app.parentApp.nCh+1,1:app.parentApp.pwl) = app.parentApp.powerS (1:app.parentApp.nCh,app.parentApp.currentTrial,1:app.parentApp.pwl);
            % end
            
            ch = app.parentApp.currentCh;
            tr = app.parentApp.currentTrial;
            
            frequency(1,:) = app.pwsf (:);
            if get(app.whitenByFreq,'Value')
                % whitening by multiplication of the power by the frequency
                if flagMd
                    % whiteout the mean spectra
                    spectra(1:app.pwl) = app.meanPWS(ch,1:app.pwl);
                else
                    % white out the current trial
                    %app.parentApp.powerS (1:app.parentApp.nCh,app.parentApp.currentTrial,1:app.parentApp.pwl);
                    spectra(1:app.pwl) = app.powerS (ch, tr, 1:app.pwl);
                end 
                % now divide element by element the power by the frequency
                app.whitenedPS = 10*log10(spectra .* frequency);
            else
                % whitening by linear fit of the log log spectra
%                 freqMin = str2double(get (app.leftLimitWh, 'String'));
                freqMin = app.leftLimitWh.Value;
%                 freqMax = str2double(get (app.rightLimitWh, 'String'));
                freqMax = app.rightLimitWh.Value;
                freqMinI = 0;
                freqMaxI = 0;
                for i=1:app.pwl
                    % I know, this is lousy code...
                    f = app.pwsf(i);
                    if f >= freqMin
                        if freqMinI == 0
                            freqMinI = i;
                        end
                    end
                    if f <= freqMax
                        freqMaxI = i;
                    end
                end
                % now the freqMinI and freqMaxI contain the limits for the slope fitting
                % the computation is performed only for the current channel and trial
            
                fitF = [];
                fitF(1:freqMaxI-freqMinI+1,1:2) = 1;
                fitF(1:freqMaxI-freqMinI+1,2) = log10(app.pwsf (freqMinI:freqMaxI));
                if flagMd
                    % whiteout the mean spectra
                    fitS = 10*log10(app.meanPWS (ch, freqMinI:freqMaxI));
                    spectra = 10*log10(app.meanPWS(ch,1:app.pwl));
                else
                    % white out the current trial
                    %app.parentApp.powerS (1:app.parentApp.nCh,app.parentApp.currentTrial,1:app.parentApp.pwl);
                    fitS(1:freqMaxI-freqMinI+1) = 10*log10(app.powerS (ch, tr, freqMinI:freqMaxI));
                    spectra(1:app.pwl) = 10*log10(app.powerS (ch, tr, 1:app.pwl));
                end
                % fit of the power expressed in Db
                fitTerms = fitF\fitS';
                % compute the linear regression
                PWSlinearFit = fitTerms(2)*log10(app.pwsf) + fitTerms(1);
                app.whitenedPS = spectra - PWSlinearFit';
            end
            
            app.whiteFlag = 1;
            plotWhitenedSpectra(app)
            disp('Whitening done')
        end

        % Value changed function: windowCk
        function windowCk_Callback(app, event)
            if app.windowCk.Value
                app.window2Ck.Enable = 'on';
            else
                app.window2Ck.Enable = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ZebraSpectre = uifigure('Visible', 'off');
            app.ZebraSpectre.Name = 'ZebraSpectre';
            app.ZebraSpectre.WindowButtonMotionFcn = createCallbackFcn(app, @ZebraSpectre_WindowButtonMotionFcn, true);
            app.toT = uipanel(app.ZebraSpectre);
            app.toT.Title = 'Options';
            app.clearSpectre = uibutton(app.toT, 'push');
            app.clearSpectre.ButtonPushedFcn = createCallbackFcn(app, @clearSpectre_Callback, true);
            app.clearSpectre.Text = 'Clear';
            app.saveSpectra = uibutton(app.toT, 'push');
            app.saveSpectra.ButtonPushedFcn = createCallbackFcn(app, @saveSpectra_Callback, true);
            app.saveSpectra.Text = 'Save';
            app.sendToClipboard = uibutton(app.toT, 'push');
            app.sendToClipboard.ButtonPushedFcn = createCallbackFcn(app, @sendToClipboard_Callback, true);
            app.sendToClipboard.Tooltip = 'Copy the entire figure to the clipboard';
            app.sendToClipboard.Text = 'Clipboard';
            app.infoString = uilabel(app.toT);
            app.infoString.Tooltip = 'Pointer position on the axis';
            app.infoString.Text = 'Frequency (Hz):        Power (Db):';
            app.computeSpectra = uibutton(app.toT, 'push');
            app.computeSpectra.ButtonPushedFcn = createCallbackFcn(app, @computeSpectra_Callback, true);
            app.computeSpectra.Tooltip = 'Remove the linear trend of the power spectra';
            app.computeSpectra.Text = 'Compute';
            app.TabGroup = uitabgroup(app.toT);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.text3 = uilabel(app.MainTab);
            app.text3.Text = '';
            app.normSpectraCk = uicheckbox(app.MainTab);
            app.normSpectraCk.Text = 'Normalize spectra between (Hz)';
            app.normLowFreq = uieditfield(app.MainTab, 'numeric');
            app.normLowFreq.Value = 0.1;
            app.normHighFreq = uieditfield(app.MainTab, 'numeric');
            app.normHighFreq.Value = 1;
            app.subMean_ck = uicheckbox(app.MainTab);
            app.subMean_ck.Text = 'Subtract mean';
            app.detrend_ck = uicheckbox(app.MainTab);
            app.detrend_ck.Text = 'Detrend';
            app.MethodDropDownLabel = uilabel(app.MainTab);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.MainTab);
            app.MethodDropDown.Items = {'Chronux (multitaper)', 'FFT', 'Welch (multitaper)'};
            app.MethodDropDown.Value = 'Chronux (multitaper)';
            app.BandwidthHzEditField_2 = uieditfield(app.MainTab, 'numeric');
            app.BandwidthHzEditField_2.Limits = [0 Inf];
            app.BandwidthHzEditField_2.Value = Inf;
            app.PrefilterCheckBox = uicheckbox(app.MainTab);
            app.PrefilterCheckBox.Text = 'Pre-filter';
            app.BandwidthHzEditFieldLabel = uilabel(app.MainTab);
            app.BandwidthHzEditFieldLabel.Text = 'Bandwidth (Hz)';
            app.BandwidthHzEditField = uieditfield(app.MainTab, 'numeric');
            app.BandwidthHzEditField.Limits = [0 Inf];
            app.VisualizationTab = uitab(app.TabGroup);
            app.VisualizationTab.Title = 'Visualization';
            app.text1 = uilabel(app.VisualizationTab);
            app.text1.Text = 'Set min & max power (dB):';
            app.text2 = uilabel(app.VisualizationTab);
            app.text2.Text = 'Set min & max freq (Hz):';
            app.freqLog = uicheckbox(app.VisualizationTab);
            app.freqLog.Tooltip = 'Use log or linear scale for the frequency axis';
            app.freqLog.Text = 'Frequency log scale';
            app.freqLog.Value = true;
            app.minPower = uieditfield(app.VisualizationTab, 'numeric');
            app.minPower.ValueChangedFcn = createCallbackFcn(app, @minPower_Callback, true);
            app.minPower.Value = -70;
            app.maxPower = uieditfield(app.VisualizationTab, 'numeric');
            app.maxPower.ValueChangedFcn = createCallbackFcn(app, @maxPower_Callback, true);
            app.maxPower.Value = -30;
            app.minFreq = uieditfield(app.VisualizationTab, 'numeric');
            app.minFreq.ValueChangedFcn = createCallbackFcn(app, @minFreq_Callback, true);
            app.minFreq.Value = 8;
            app.maxFreq = uieditfield(app.VisualizationTab, 'numeric');
            app.maxFreq.ValueChangedFcn = createCallbackFcn(app, @maxFreq_Callback, true);
            app.maxFreq.Value = 200;
            app.ParamsTab = uitab(app.TabGroup);
            app.ParamsTab.Title = 'Params';
            app.TabGroup2 = uitabgroup(app.ParamsTab);
            app.ChronuxTab = uitab(app.TabGroup2);
            app.ChronuxTab.Title = 'Chronux';
            app.MultitaperanalysisPanel = uipanel(app.ChronuxTab);
            app.MultitaperanalysisPanel.Title = 'Multitaper analysis';
            app.TBproductEditFieldLabel = uilabel(app.MultitaperanalysisPanel);
            app.TBproductEditFieldLabel.Text = 'TB product';
            app.TBproductEditField = uieditfield(app.MultitaperanalysisPanel, 'numeric');
            app.TBproductEditField.Value = 2;
            app.NoftapersEditFieldLabel = uilabel(app.MultitaperanalysisPanel);
            app.NoftapersEditFieldLabel.Text = 'N of tapers';
            app.NoftapersEditField = uieditfield(app.MultitaperanalysisPanel, 'numeric');
            app.NoftapersEditField.Value = 3;
            app.FFTTab = uitab(app.TabGroup2);
            app.FFTTab.Title = 'FFT';
            app.WindowfunctionDropDownLabel = uilabel(app.FFTTab);
            app.WindowfunctionDropDownLabel.Text = 'Window function';
            app.WindowfunctionDropDown = uidropdown(app.FFTTab);
            app.WindowfunctionDropDown.Items = {'Rectangular (no windowing)', 'Hamming'};
            app.WindowfunctionDropDown.Value = 'Rectangular (no windowing)';
            app.WelchTab = uitab(app.TabGroup2);
            app.WelchTab.Title = 'Welch';
            app.WindowsEditFieldLabel = uilabel(app.WelchTab);
            app.WindowsEditFieldLabel.Text = 'Window (s)';
            app.WindowsEditField = uieditfield(app.WelchTab, 'numeric');
            app.WindowsEditField.Value = 0.5;
            app.StepsEditFieldLabel = uilabel(app.WelchTab);
            app.StepsEditFieldLabel.Text = 'Step (s)';
            app.StepsEditField = uieditfield(app.WelchTab, 'numeric');
            app.StepsEditField.Value = 0.1;
            app.ZeropaddingEditFieldLabel = uilabel(app.ParamsTab);
            app.ZeropaddingEditFieldLabel.Text = 'Zero padding';
            app.ZeropaddingEditField = uieditfield(app.ParamsTab, 'numeric');
            app.ZeropaddingEditField.Tooltip = {'-1 = no padding'; '0 = next power of 2'; '1 = 2nd next power of 2'; '...'};
            app.ErrorintervalEditFieldLabel = uilabel(app.ParamsTab);
            app.ErrorintervalEditFieldLabel.Text = 'Error interval';
            app.ErrorintervalEditField = uieditfield(app.ParamsTab, 'numeric');
            app.ErrorintervalEditField.Value = 0.95;
            app.ComputeerrorsDropDownLabel = uilabel(app.ParamsTab);
            app.ComputeerrorsDropDownLabel.Text = 'Compute errors:';
            app.ComputeerrorsDropDown = uidropdown(app.ParamsTab);
            app.ComputeerrorsDropDown.Items = {'no error', 'theoretical', 'jackknife'};
            app.ComputeerrorsDropDown.Value = 'no error';
            app.AverageTab = uitab(app.TabGroup);
            app.AverageTab.Title = 'Average';
            app.computelogbeforeaveragingCheckBox = uicheckbox(app.AverageTab);
            app.computelogbeforeaveragingCheckBox.Text = 'compute log before averaging';
            app.confidenceintervalsCheckBox = uicheckbox(app.AverageTab);
            app.confidenceintervalsCheckBox.Enable = 'off';
            app.confidenceintervalsCheckBox.Text = 'confidence intervals';
            app.ConfidenceintervalEditField_2 = uieditfield(app.AverageTab, 'numeric');
            app.ConfidenceintervalEditField_2.Editable = 'off';
            app.ConfidenceintervalEditField_2.Value = 0.95;
            app.WindowsTab = uitab(app.TabGroup);
            app.WindowsTab.Title = 'Windows';
            app.windowCk = uicheckbox(app.WindowsTab);
            app.windowCk.ValueChangedFcn = createCallbackFcn(app, @windowCk_Callback, true);
            app.windowCk.Tooltip = 'Compute the power spectra only in the specified window';
            app.windowCk.Text = 'Compute on "window 1", from (s)';
            app.text8 = uilabel(app.WindowsTab);
            app.text8.Text = 'to (s)';
            app.window2Ck = uicheckbox(app.WindowsTab);
            app.window2Ck.Enable = 'off';
            app.window2Ck.Tooltip = 'Compute the power spectra only in the specified window';
            app.window2Ck.Text = 'Compute on "window 2", from (s)';
            app.text9 = uilabel(app.WindowsTab);
            app.text9.Text = 'to (s)';
            app.fromTtxt = uieditfield(app.WindowsTab, 'numeric');
            app.fromTtxt.Value = 1;
            app.toTtxt = uieditfield(app.WindowsTab, 'numeric');
            app.toTtxt.Value = 1;
            app.fromT2txt = uieditfield(app.WindowsTab, 'numeric');
            app.fromT2txt.Value = 1;
            app.toT2txt = uieditfield(app.WindowsTab, 'numeric');
            app.toT2txt.Value = 1;
            app.WhiteningTab = uitab(app.TabGroup);
            app.WhiteningTab.Title = 'Whitening';
            app.whitenByFreq = uicheckbox(app.WhiteningTab);
            app.whitenByFreq.Tooltip = 'Whiten by multipling the power for the relative frequency';
            app.whitenByFreq.Text = 'Whiten by frequency multiplication';
            app.WhiteningCheckBox = uicheckbox(app.WhiteningTab);
            app.WhiteningCheckBox.Text = 'Whitening';
            app.ButtonGroup = uibuttongroup(app.WhiteningTab);
            app.WhitenbyfrequencymultiplicationButton = uiradiobutton(app.ButtonGroup);
            app.WhitenbyfrequencymultiplicationButton.Text = 'Whiten by frequency multiplication';
            app.WhitenbyfittingButton = uiradiobutton(app.ButtonGroup);
            app.WhitenbyfittingButton.Text = 'Whiten by fitting';
            app.WhitenbyfittingButton.Value = true;
            app.text4 = uilabel(app.ButtonGroup);
            app.text4.Tooltip = 'Range for compensation of spectra linear slope';
            app.text4.Text = 'Whitening range (Hz):';
            app.leftLimitWh = uieditfield(app.ButtonGroup, 'numeric');
            app.leftLimitWh.Value = 8;
            app.rightLimitWh = uieditfield(app.ButtonGroup, 'numeric');
            app.rightLimitWh.Value = 100;
            app.whiteOut = uibutton(app.WhiteningTab, 'push');
            app.whiteOut.ButtonPushedFcn = createCallbackFcn(app, @whiteOut_Callback, true);
            app.whiteOut.Tooltip = 'Remove the linear trend of the power spectra';
            app.whiteOut.Text = 'White Out';
            app.spectreTitle = uilabel(app.ZebraSpectre);
            app.spectreTitle.Text = '';
            app.powerSpectra = uiaxes(app.ZebraSpectre);
            app.powerSpectra.XLim = [8 200];
            app.powerSpectra.YLim = [-70 -30];
            app.powerSpectra.XScale = 'log';
            app.whitenedSpectra = uiaxes(app.ZebraSpectre);
            app.whitenedSpectra.XLim = [8 200];
            app.whitenedSpectra.YLim = [-10 10];
            app.whitenedSpectra.XScale = 'log';
            % Show the figure after all components are created
            app.ZebraSpectre.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ZebraSpectreApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ZebraSpectre)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ZebraSpectre)
        end
    end
end