classdef multiChannelAnalyzer_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                matlab.ui.Figure
        uipanel1               matlab.ui.container.Panel
        chAtxt                 matlab.ui.control.Label
        chBtxt                 matlab.ui.control.Label
        text4                  matlab.ui.control.Label
        text6                  matlab.ui.control.Label
        text8                  matlab.ui.control.Label
        text9                  matlab.ui.control.Label
        text5                  matlab.ui.control.Label
        XcorrBtn               matlab.ui.control.Button
        text10                 matlab.ui.control.Label
        shortTimeXcorr_btn     matlab.ui.control.Button
        crossSpectrum          matlab.ui.control.Button
        shortTimeXspextrum     matlab.ui.control.Button
        corrWindow             matlab.ui.control.NumericEditField
        fromTxt                matlab.ui.control.NumericEditField
        toTxt                  matlab.ui.control.NumericEditField
        wndTxt                 matlab.ui.control.NumericEditField
        ovlTxt                 matlab.ui.control.NumericEditField
        sliderChA              matlab.ui.control.Spinner
        sliderChB              matlab.ui.control.Spinner
        ResetButton            matlab.ui.control.Button
        uipanel2               matlab.ui.container.Panel
        holdOnxCorr            matlab.ui.control.CheckBox
        holdXspectra           matlab.ui.control.CheckBox
        lagMin                 matlab.ui.control.NumericEditField
        lagMax                 matlab.ui.control.NumericEditField
        freqMin                matlab.ui.control.NumericEditField
        freqMax                matlab.ui.control.NumericEditField
        SavexCorrButton        matlab.ui.control.Button
        xCorrSpectra           matlab.ui.control.UIAxes
        crossSpectra           matlab.ui.control.UIAxes
        uipanel3               matlab.ui.container.Panel
        stxcorrmaxy_edit       matlab.ui.control.NumericEditField
        stxcorrminy_edit       matlab.ui.control.NumericEditField
        lfpmaxy_edit           matlab.ui.control.NumericEditField
        lfpminy_edit           matlab.ui.control.NumericEditField
        mint_edit              matlab.ui.control.NumericEditField
        maxt_edit              matlab.ui.control.NumericEditField
        CLimmaxEditFieldLabel  matlab.ui.control.Label
        CLimmaxEditField       matlab.ui.control.NumericEditField
        stxcorrAxes            matlab.ui.control.UIAxes
        lfpAxes                matlab.ui.control.UIAxes
        dataSource             matlab.ui.container.ButtonGroup
        workLFP                matlab.ui.control.RadioButton
        deleakedLFP            matlab.ui.control.RadioButton
        BPsel                  matlab.ui.control.DropDown
        workLFP_2              matlab.ui.control.RadioButton
    end

    
    properties (Access = public)
        parentApp
        
        corr
        lagTime
        STxCorr
        Coher
        xS12
        f
    end
    
    methods (Access = private)
        function [LFP1, LFP2, t] = obtainData(app, ch, interval)
            t_logic = app.parentApp.x >= interval(1) & app.parentApp.x <= interval(2);
            t = app.parentApp.x(t_logic);
            switch get(app.dataSource,'selectedObject')
                case app.workLFP
                    LFP1 = squeeze(app.parentApp.workLFP(ch(1),1,t_logic))';
                    LFP2 = squeeze(app.parentApp.workLFP(ch(2),1,t_logic))';
%                     LFP1 = permute(LFP1,[3 2 1]);
%                     LFP2 = permute(LFP2,[3 2 1]);
                case app.deleakedLFP
                    LFP1 = squeeze(app.parentApp.deleakedLFP(ch(1),1,t_logic))';
                    LFP2 = squeeze(app.parentApp.deleakedLFP(ch(2),1,t_logic))';
%                     LFP1 = permute(LFP1,[4 3 2 1]);
%                     LFP2 = permute(LFP2,[4 3 2 1]);
%                 case app.HP
%                     LFP1 = app.parentApp.bandPassed_LFP(5,ch(1),1,interval(1):interval(2));
%                     LFP2 = app.parentApp.bandPassed_LFP(5,ch(2),1,interval(1):interval(2));
%                     LFP1 = permute(LFP1,[4 3 2 1]);
%                     LFP2 = permute(LFP2,[4 3 2 1]);
%                 case app.BP1
%                     LFP1 = app.parentApp.bandPassed_LFP(2,ch(1),1,interval(1):interval(2));
%                     LFP2 = app.parentApp.bandPassed_LFP(2,ch(2),1,interval(1):interval(2));
%                     LFP1 = permute(LFP1,[4 3 2 1]);
%                     LFP2 = permute(LFP2,[4 3 2 1]);
%                 case app.BP2
%                     LFP1 = app.parentApp.bandPassed_LFP(3,ch(1),1,interval(1):interval(2));
%                     LFP2 = app.parentApp.bandPassed_LFP(3,ch(2),1,interval(1):interval(2));
%                     LFP1 = permute(LFP1,[4 3 2 1]);
%                     LFP2 = permute(LFP2,[4 3 2 1]);
%                 case app.BP3
%                     LFP1 = app.parentApp.bandPassed_LFP(4,ch(1),1,interval(1):interval(2));
%                     LFP2 = app.parentApp.bandPassed_LFP(4,ch(2),1,interval(1):interval(2));
%                     LFP1 = permute(LFP1,[4 3 2 1]);
%                     LFP2 = permute(LFP2,[4 3 2 1]);
                otherwise
                    % a BP is selected
                    
                    % retrieve the selected BP
                    selBP = find(strcmp(app.BPsel.Value,app.BPsel.Items));
                    
                    
                    if app.parentApp.BPcomputed(selBP)
                        LFP1 = squeeze(app.parentApp.bandPassed_LFP(selBP,ch(1),1,t_logic))';
                        LFP2 = squeeze(app.parentApp.bandPassed_LFP(selBP,ch(2),1,t_logic))';
%                         LFP1 = permute(LFP1,[4 3 2 1]);
%                         LFP2 = permute(LFP2,[4 3 2 1]);
                    else
                        error(['Invalid source: BP ' num2str(selBP) ' has not been computed.'])
                    end
                    
            end
        end
        
        function plotLFP(app)
            
            % plot the LFP signal under analysis in the LFP axes. The plot is limited
            % to the analysis interval defined by the from, to fields.
            
            ch(1) = get(app.sliderChA,'Value');
            ch(2) = get(app.sliderChB,'Value');
            
            fromTime = get(app.fromTxt,'Value');
            toTime = get(app.toTxt,'Value');
%             sp = app.parentApp.sp;
%             interval(1) = int32(fromTime/sp +1);
%             if interval(1) < 1, interval(1) = 1;
%                 fromTime = 0;
%             end
%             interval(2) = int32(toTime/sp +1);
%             if (interval(2) > app.parentApp.dtaLen), interval(2) = app.parentApp.dtaLen;
%                 toTime = sp*(app.parentApp.dtaLen-1);
%             end
            [LFP1, LFP2, t] = obtainData(app,ch,[fromTime, toTime]);
            
            
            pos = app.lfpAxes.InnerPosition;
            plot(app.lfpAxes,t,LFP1,t,LFP2)
            axis (app.lfpAxes,[fromTime toTime app.lfpminy_edit.Value app.lfpmaxy_edit.Value]);
            app.lfpAxes.YLabel.String = 'LFP (mV)';
            app.lfpAxes.XLabel.String = 'Time (s)';
            app.lfpAxes.FontSize = 10;
            app.mint_edit.Value = fromTime;
            app.maxt_edit.Value = toTime;
            app.lfpAxes.InnerPosition = pos;
        end
        
        function plotXcorr(app)
%             axes (app.xCorrSpectra)
            l(1) = get(app.lagMin,'Value');
            l(2) = get(app.lagMax,'Value');
            
            % Find out what is the index interval associated to this frequency
            % interval. Parse the app.f array and extract the indexes (ind) included int he interval.
            lag = app.lagTime;
            lag(lag<l(1) | lag>l(2)) = 0;
            ind = find(lag);
            indLeft = ind(1);
            indRight = ind(end);
            
            plot(app.xCorrSpectra,app.lagTime(indLeft:indRight),app.corr(indLeft:indRight))
            axis (app.xCorrSpectra,[l(1) l(2) -inf inf]);
            set(app.xCorrSpectra,'fontsize',8)
        end
        
        function plotXspectrum(app)
%             axes (app.crossSpectra)
            l(1) = get(app.freqMin,'Value');
            l(2) = get(app.freqMax,'Value');
            
            % Find out what is the index interval associated to this frequency
            % interval. Parse the app.f array and extract the indexes (ind) included int he interval.
            freq = app.f;
            freq(freq<l(1) | freq>l(2)) = 0;
            ind = find(freq);
            indLeft = ind(1);
            indRight = ind(end);
            
            %mx = max(app.xS12(indLeft:indRight));
%             plot(app.crossSpectra,app.f(indLeft:indRight),app.xS12(indLeft:indRight),'LineWidth',1)
            plot(app.crossSpectra,app.f(indLeft:indRight),abs(app.xS12(indLeft:indRight)),'LineWidth',1) % gab abs
            axis (app.crossSpectra,[l(1) l(2) -inf inf]);
            set(app.crossSpectra,'fontsize',8)
            set(app.crossSpectra, 'XScale', 'log','Yscale','log')
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function multiChannelAnalyzer_OpeningFcn(app, parentApp)
            % establish the link with the calling process
            app.parentApp = parentApp;
            
            % edit channel limits
            if app.parentApp.fileInNum > 0
                app.sliderChA.Limits(2) = app.parentApp.nCh;
                app.sliderChB.Limits(2) = app.parentApp.nCh;
            end
            
            % import the number of BPs
            app.BPsel.Items = app.parentApp.BPplot_drop.Items;
            
            app.stxcorrAxes.Colormap = unionjack;
        end

        % Button pushed function: XcorrBtn
        function XcorrBtn_Callback(app, event)
            % Compute the Xcorrelation between the selected channels.
                        
            % Initialize data
            lag = [];
            app.corr = [];
            app.lagTime = [];
            
            ch(1) = get(app.sliderChA,'Value');
            ch(2) = get(app.sliderChB,'Value');
            %trial =
            fromTime = get(app.fromTxt,'Value');
            toTime = get(app.toTxt,'Value');
            [LFP1, LFP2,~] = obtainData(app,ch,[fromTime, toTime]);
            
            sp = app.parentApp.sp;
            corrWind = int32(get(app.corrWindow,'Value')/sp);
            
            
            [app.corr, lag] = xcorr(LFP1-mean(LFP1),LFP2-mean(LFP2),corrWind);
            app.lagTime = lag * sp;
%             plotXcorr  (hObject, handles)
            plotXcorr(app)
%             plotLFP(hObject, handles)
            plotLFP(app)
        end

        % Button pushed function: crossSpectrum
        function crossSpectrum_Callback(app, event)
            % Compute the cross spectrum between the selected channels.
            
            % Initialize data
            lag = [];
            app.corr = [];
            app.lagTime = [];
            
            ch(1) = get(app.sliderChA,'Value');
            ch(2) = get(app.sliderChB,'Value');
            %trial =
            fromTime = get(app.fromTxt,'Value');
            toTime = get(app.toTxt,'Value');
            [LFP1, LFP2,~] = obtainData(app,ch,[fromTime, toTime]);
            
            % prepare the parameters for Chronux
            params.tapers = [5 9];
            params.Fs = 1/app.parentApp.sp;
            params.tpass = [0 100];
            params.err = [];
            [app.Coher,phi,app.xS12,S1,S2,app.f] = coherencyc (LFP1,LFP2,params);
            
            plotXspectrum  (app)
            plotLFP(app)
        end

        % Value changed function: freqMax
        function freqMax_Callback(app, event)
            plotXspectrum (app)
        end

        % Value changed function: freqMin
        function freqMin_Callback(app, event)
            plotXspectrum (app)
        end

        % Value changed function: holdOnxCorr
        function holdOnxCorr_Callback(app, event)
            if get(app.holdOnxCorr, 'Value')
                % checkbox is on
%                 hold all (app.xCorrSpectra)
                app.xCorrSpectra.NextPlot = 'add';
            else
%                 hold off (app.xCorrSpectra)
                app.xCorrSpectra.NextPlot = 'replace';
            end
            cla(app.xCorrSpectra)
            plotXcorr (app)
        end

        % Value changed function: holdXspectra
        function holdXspectra_Callback(app, event)
            if get(app.holdXspectra, 'Value')
                % checkbox is on
%                 hold all (app.crossSpectra)
                app.holdXspectra.NextPlot = 'add';
            else
%                 hold off (app.crossSpectra)
                app.holdXspectra.NextPlot = 'replace';
            end
            cla(app.crossSpectra)
            plotXspectrum (app)
        end

        % Value changed function: lagMax
        function lagMax_Callback(app, event)
            plotXcorr (app)
        end

        % Value changed function: lagMin
        function lagMin_Callback(app, event)
            plotXcorr (app)
        end

        % Button pushed function: shortTimeXcorr_btn
        function shortTimeXcorr_btn_Callback(app, event)
            
            % compute the short time cross correlation
            ch(1) = get(app.sliderChA,'Value');
            ch(2) = get(app.sliderChB,'Value');
            
            wnd  = get(app.wndTxt,'Value');
            step = get(app.ovlTxt,'Value');
            % now compute the number of windows
            sp = app.parentApp.sp;
            wndI = int32(wnd/sp); %indices per window
            
            % set time window and step to a multiple of the sampling period
            wnd = floor(wnd/sp)*sp;
            halfwnd = floor(wnd/2/sp)*sp;
            step_i = floor(step/sp);
            step = step_i*sp;
            
%             total = app.parentApp.dtaLen; GAB
            % get input data
            tFrom = get(app.fromTxt,'Value');
            tTo   = get(app.toTxt  ,'Value');
            [LFP1, LFP2, t] = obtainData(app,ch,[tFrom,tTo]);
            
            % calc time windows
            startT = t(1) + halfwnd;
            smallWin_logic = t<=t(1)+wnd;
            smallWin = t(smallWin_logic);
            nwnd = floor((tTo-tFrom-wnd)/step);
            midpoints = startT+(0:nwnd-1)*step;
            % allocate output
%             tmpCorr = zeros(2*wndI+1,nwnd);
            tmpCorr = zeros(2*sum(smallWin_logic)-1,nwnd);
            tmpTime = zeros(1,nwnd);
%             while smallWin(end)<toT
            for i=1:nwnd
                % compute
                tmp1 = LFP1(smallWin_logic) - mean(LFP1(smallWin_logic));
                tmp2 = LFP2(smallWin_logic) - mean(LFP2(smallWin_logic));
                [tmpCorr(:,i),tmpTime] = xcorr(tmp1./std(tmp1),tmp2./std(tmp2),'biased');
                
                % update for next loop
                smallWin_logic = circshift(smallWin_logic,step_i);
            end
            app.STxCorr = tmpCorr;
            app.lagTime = tmpTime*sp;


            % plot
            imagesc(app.stxcorrAxes,midpoints,app.lagTime,app.STxCorr)
            app.stxcorrAxes.Colormap = unionjack;
%             Lim = max(abs(app.STxCorr),[],'all');
%             app.stxcorrAxes.CLim = [-Lim, Lim];
            app.stxcorrAxes.CLim = [-app.CLimmaxEditField.Value, app.CLimmaxEditField.Value];
            axis(app.stxcorrAxes,[tFrom, tTo, app.lagTime(1), app.lagTime(end)])
            app.stxcorrAxes.XTickLabel = {};
            app.stxcorrAxes.YLabel.String = 'Lag (s)';
            app.stxcorrAxes.FontSize = 10;
            app.stxcorrAxes.YDir = 'normal';
            app.stxcorrAxes.InnerPosition(1) = app.lfpAxes.InnerPosition(1);
            app.stxcorrAxes.InnerPosition(3:4) = app.lfpAxes.InnerPosition(3:4);


            % REPLACED BY GAB
%             ch(1) = get(app.sliderChA,'Value');
%             ch(2) = get(app.sliderChB,'Value');
%             
%             wnd  = get(app.wndTxt,'Value');
%             step = get(app.ovlTxt,'Value');
%             % now compute the number of windows
%             sp = app.parentApp.sp;
%             wndI = int32(wnd/sp);
%             
% %             total = app.parentApp.dtaLen; GAB
%             tFrom = get(app.fromTxt,'Value');
%             tTo   = get(app.toTxt  ,'Value');
%             selTime_logic = app.parentApp.x >= tFrom & app.parentApp.x <= tTo;
%             total = numel(app.parentApp.x(selTime_logic));
%             
%             wndPnt = int32(wnd/sp);
%             stepPnt = int32(step/sp);
%             midWnd_len = ceil(wndPnt/2);
% %             midWndBegin = ceil(wndPnt/2);
%             midWndBegin = find(selTime_logic,1) + ceil(wndPnt/2); %GAB
% %             midWndEnd = total - midWndBegin;
%             midWndEnd = find(selTime_logic,1,'last') - midWnd_len;
%             midPoints = midWndBegin:stepPnt:midWndEnd;
%             nwnd = length (midPoints);
%             % preallocate the output
%             tmpCorr = zeros(2*wndI+1,nwnd);
%             tmpTime = zeros(1,nwnd);
%             % now compute the CC for each window
%             % pre allocate the STCC temporary array
% %             tmpCorr = zeros(2*wndI+1,nwnd);
%             for i=1:nwnd
%                 time = wnd/2 + (i-1)*step;
%                 interval(1) = int32(midPoints(i)-midWnd_len);
%                 interval(2) = int32(midPoints(i)+midWnd_len);
%                 if interval(1) == 0, interval(1) = 1;
%                 end
%                 [LFP1, LFP2] = obtainData(app,ch,interval);
%                 [app.corr, lag] = xcorr(LFP1,LFP2,wndI);
%                 tmpCorr(:,i) = app.corr(:);
%                 tmpTime(i) = time;
%             end
%             app.lagTime = lag * app.parentApp.sp;
%             app.STxCorr = tmpCorr;
%             
%             % Plot
% %             axes(app.shortTimeXcorr_btn)
%             imagesc(app.stxcorrAxes,app.parentApp.x(midPoints),app.lagTime,app.STxCorr)
%             axis(app.stxcorrAxes,[tFrom, tTo, app.lagTime(1), app.lagTime(end)])
%             app.stxcorrAxes.XTickLabel = {};
%             app.stxcorrAxes.YLabel.String = 'Lag (s)';
%             app.stxcorrAxes.FontSize = 10;
        end

        % Value changed function: corrWindow
        function corrWindowValueChanged(app, event)
            value = app.corrWindow.Value;
            app.lagMin.Value = -1*value;
            app.lagMax.Value = value;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % set the time window limits so that the whole trace is selected
            minT = app.parentApp.x(1); 
            maxT = app.parentApp.x(end);
            
            app.fromTxt.Value = minT;
            app.toTxt  .Value = maxT;
        end

        % Button pushed function: SavexCorrButton
        function SavexCorrButtonPushed(app, event)
            disp('Saving cross-correlation data...')
            outMat = [app.lagTime; app.corr'];
            outPath = [app.parentApp.dir_in '\' app.parentApp.file_in(1:end-4)];
            fid = fopen([outPath '_xCorr_Ch' num2str(app.sliderChA.Value) '-Ch' num2str(app.sliderChB.Value) '.dat'],'w');
            fmt = '%10.8f %10.8f\n';
            fprintf(fid,fmt,outMat);
            fclose(fid);
            disp('Cross-correlation saved')
        end

        % Drop down opening function: BPsel
        function BPselDropDownOpening(app, event)
            app.BPsel.Items = app.parentApp.BPplot_drop.Items;
        end

        % Value changed function: lfpmaxy_edit, lfpminy_edit
        function lfpmaxy_editValueChanged(app, event)
            app.lfpAxes.YLim = [app.lfpminy_edit.Value app.lfpmaxy_edit.Value];
        end

        % Value changed function: stxcorrmaxy_edit, stxcorrminy_edit
        function stxcorrminy_editValueChanged(app, event)
            app.stxcorrAxes.YLim = [app.stxcorrminy_edit.Value app.stxcorrmaxy_edit.Value];
        end

        % Value changed function: maxt_edit, mint_edit
        function mint_editValueChanged(app, event)
            app.lfpAxes.XLim = [app.mint_edit.Value app.maxt_edit.Value];
            app.stxcorrAxes.XLim = [app.mint_edit.Value app.maxt_edit.Value];
        end

        % Value changed function: CLimmaxEditField
        function CLimmaxEditFieldValueChanged(app, event)
            app.stxcorrAxes.CLim = [-app.CLimmaxEditField.Value, app.CLimmaxEditField.Value];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'multiChannelAnalyzer';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Two way Xcorr and Xspectra';
            app.chAtxt = uilabel(app.uipanel1);
            app.chAtxt.Tooltip = 'Select channels in a two-way comparison';
            app.chAtxt.Text = 'Channel A:';
            app.chBtxt = uilabel(app.uipanel1);
            app.chBtxt.Tooltip = 'Select channels in a two-way comparison';
            app.chBtxt.Text = 'Channel B:';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Tooltip = 'Compute Xcoo and X spctra on the specified interval instead than on the entire data ';
            app.text4.Text = 'Limit computation in an interval (sec)';
            app.text6 = uilabel(app.uipanel1);
            app.text6.Tooltip = 'Width of the lag window for the Xcorr computation';
            app.text6.Text = 'Xcorr window (sec):';
            app.text8 = uilabel(app.uipanel1);
            app.text8.Tooltip = 'Compute Xcoo and X spctra on the specified interval instead than on the entire data ';
            app.text8.Text = 'Window (sec):';
            app.text9 = uilabel(app.uipanel1);
            app.text9.Tooltip = 'Compute Xcoo and X spctra on the specified interval instead than on the entire data ';
            app.text9.Text = 'Overlap (sec):';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Tooltip = 'Compute Xcoo and X spctra on the specified interval instead than on the entire data ';
            app.text5.Text = 'Short time spectra';
            app.XcorrBtn = uibutton(app.uipanel1, 'push');
            app.XcorrBtn.ButtonPushedFcn = createCallbackFcn(app, @XcorrBtn_Callback, true);
            app.XcorrBtn.Text = 'X Corr';
            app.text10 = uilabel(app.uipanel1);
            app.text10.Tooltip = 'Window width for the computation of X correlation';
            app.text10.Text = 'From, to (sec):';
            app.shortTimeXcorr_btn = uibutton(app.uipanel1, 'push');
            app.shortTimeXcorr_btn.ButtonPushedFcn = createCallbackFcn(app, @shortTimeXcorr_btn_Callback, true);
            app.shortTimeXcorr_btn.Tooltip = 'Compute short time X correlation';
            app.shortTimeXcorr_btn.Text = 'ST XCorr';
            app.crossSpectrum = uibutton(app.uipanel1, 'push');
            app.crossSpectrum.ButtonPushedFcn = createCallbackFcn(app, @crossSpectrum_Callback, true);
            app.crossSpectrum.Tooltip = 'Compute the cross spectrum';
            app.crossSpectrum.Text = 'X Spec';
            app.shortTimeXspextrum = uibutton(app.uipanel1, 'push');
            app.shortTimeXspextrum.Tooltip = 'Compute the short time cross spectrum';
            app.shortTimeXspextrum.Text = 'ST Xsp';
            app.corrWindow = uieditfield(app.uipanel1, 'numeric');
            app.corrWindow.ValueChangedFcn = createCallbackFcn(app, @corrWindowValueChanged, true);
            app.corrWindow.Value = 20;
            app.fromTxt = uieditfield(app.uipanel1, 'numeric');
            app.toTxt = uieditfield(app.uipanel1, 'numeric');
            app.toTxt.Value = 10;
            app.wndTxt = uieditfield(app.uipanel1, 'numeric');
            app.wndTxt.Value = 0.5;
            app.ovlTxt = uieditfield(app.uipanel1, 'numeric');
            app.ovlTxt.Value = 0.1;
            app.sliderChA = uispinner(app.uipanel1);
            app.sliderChA.Limits = [1 Inf];
            app.sliderChA.Value = 1;
            app.sliderChB = uispinner(app.uipanel1);
            app.sliderChB.Limits = [1 Inf];
            app.sliderChB.Value = 1;
            app.ResetButton = uibutton(app.uipanel1, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Cross Correlation and cross spectra';
            app.holdOnxCorr = uicheckbox(app.uipanel2);
            app.holdOnxCorr.ValueChangedFcn = createCallbackFcn(app, @holdOnxCorr_Callback, true);
            app.holdOnxCorr.Tooltip = 'Toggle hold on/off the Xcorr spectra display';
            app.holdOnxCorr.Text = 'Hold On X correlation';
            app.holdXspectra = uicheckbox(app.uipanel2);
            app.holdXspectra.ValueChangedFcn = createCallbackFcn(app, @holdXspectra_Callback, true);
            app.holdXspectra.Tooltip = 'Toggle hold on/off the Xcorr spectra display';
            app.holdXspectra.Text = 'Hold On X spectrum';
            app.lagMin = uieditfield(app.uipanel2, 'numeric');
            app.lagMin.ValueChangedFcn = createCallbackFcn(app, @lagMin_Callback, true);
            app.lagMin.Value = -20;
            app.lagMax = uieditfield(app.uipanel2, 'numeric');
            app.lagMax.ValueChangedFcn = createCallbackFcn(app, @lagMax_Callback, true);
            app.lagMax.Value = 20;
            app.freqMin = uieditfield(app.uipanel2, 'numeric');
            app.freqMin.ValueChangedFcn = createCallbackFcn(app, @freqMin_Callback, true);
            app.freqMin.Value = 0.1;
            app.freqMax = uieditfield(app.uipanel2, 'numeric');
            app.freqMax.ValueChangedFcn = createCallbackFcn(app, @freqMax_Callback, true);
            app.freqMax.Value = 100;
            app.SavexCorrButton = uibutton(app.uipanel2, 'push');
            app.SavexCorrButton.ButtonPushedFcn = createCallbackFcn(app, @SavexCorrButtonPushed, true);
            app.SavexCorrButton.Text = 'Save xCorr';
            app.xCorrSpectra = uiaxes(app.uipanel2);
            app.crossSpectra = uiaxes(app.uipanel2);
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = 'Short time spectra and LFP';
            app.stxcorrmaxy_edit = uieditfield(app.uipanel3, 'numeric');
            app.stxcorrmaxy_edit.ValueChangedFcn = createCallbackFcn(app, @stxcorrminy_editValueChanged, true);
            app.stxcorrmaxy_edit.Value = Inf;
            app.stxcorrminy_edit = uieditfield(app.uipanel3, 'numeric');
            app.stxcorrminy_edit.ValueChangedFcn = createCallbackFcn(app, @stxcorrminy_editValueChanged, true);
            app.stxcorrminy_edit.Value = -Inf;
            app.lfpmaxy_edit = uieditfield(app.uipanel3, 'numeric');
            app.lfpmaxy_edit.ValueChangedFcn = createCallbackFcn(app, @lfpmaxy_editValueChanged, true);
            app.lfpmaxy_edit.Value = Inf;
            app.lfpminy_edit = uieditfield(app.uipanel3, 'numeric');
            app.lfpminy_edit.ValueChangedFcn = createCallbackFcn(app, @lfpmaxy_editValueChanged, true);
            app.lfpminy_edit.Value = -Inf;
            app.mint_edit = uieditfield(app.uipanel3, 'numeric');
            app.mint_edit.ValueChangedFcn = createCallbackFcn(app, @mint_editValueChanged, true);
            app.maxt_edit = uieditfield(app.uipanel3, 'numeric');
            app.maxt_edit.ValueChangedFcn = createCallbackFcn(app, @mint_editValueChanged, true);
            app.CLimmaxEditFieldLabel = uilabel(app.uipanel3);
            app.CLimmaxEditFieldLabel.Text = 'CLim max';
            app.CLimmaxEditField = uieditfield(app.uipanel3, 'numeric');
            app.CLimmaxEditField.ValueChangedFcn = createCallbackFcn(app, @CLimmaxEditFieldValueChanged, true);
            app.CLimmaxEditField.Value = 1000;
            app.stxcorrAxes = uiaxes(app.uipanel3);
            ylabel(app.stxcorrAxes, 'Lag (s)')
            app.stxcorrAxes.XTickLabel = '';
            app.lfpAxes = uiaxes(app.uipanel3);
            xlabel(app.lfpAxes, 'Time (s)')
            ylabel(app.lfpAxes, 'LFP (mV)')
            app.dataSource = uibuttongroup(app.figure1);
            app.dataSource.Title = 'Data Source';
            app.workLFP = uiradiobutton(app.dataSource);
            app.workLFP.Text = 'LFP';
            app.workLFP.Value = true;
            app.deleakedLFP = uiradiobutton(app.dataSource);
            app.deleakedLFP.Text = 'Deleaked';
            app.BPsel = uidropdown(app.dataSource);
            app.BPsel.Items = {'BP 1'};
            app.BPsel.DropDownOpeningFcn = createCallbackFcn(app, @BPselDropDownOpening, true);
            app.BPsel.Value = 'BP 1';
            app.workLFP_2 = uiradiobutton(app.dataSource);
            app.workLFP_2.Text = 'Band Passed';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = multiChannelAnalyzer_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)multiChannelAnalyzer_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end